classdef EEG_Clustering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SubmitNumberofClustersButton   matlab.ui.control.Button
        SubmitParametersButton         matlab.ui.control.Button
        Group3SubjectRange             matlab.ui.control.EditField
        Group3SubjectRangeEg3145EditFieldLabel  matlab.ui.control.Label
        Group2SubjectRange             matlab.ui.control.EditField
        Group2SubjectRangeEg1630Label  matlab.ui.control.Label
        Group1SubjectRange             matlab.ui.control.EditField
        Group1SubjectRangeEg115EditFieldLabel  matlab.ui.control.Label
        SubmitGroupRangesButton        matlab.ui.control.Button
        GenerateplotsandstatisticsButton  matlab.ui.control.Button
        LoadclusteredEEGSTUDYfileButton  matlab.ui.control.Button
        StandardDeviationremoveoutliersDropDown  matlab.ui.control.DropDown
        StandardDeviationremoveoutliersDropDownLabel  matlab.ui.control.Label
        NumberofClustersDropDown       matlab.ui.control.DropDown
        NumberofClustersDropDownLabel  matlab.ui.control.Label
        ClusteringMethodDropDown       matlab.ui.control.DropDown
        ClusteringMethodDropDownLabel  matlab.ui.control.Label
        FilteringusingResidualVarianceDropDown  matlab.ui.control.DropDown
        FilteringusingResidualVarianceDropDownLabel  matlab.ui.control.Label
        NumberofGroupsDropDown         matlab.ui.control.DropDown
        NumberofGroupsDropDownLabel    matlab.ui.control.Label
        ResultLabel                    matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        SelectsetfilesfolderButton     matlab.ui.control.Button
    end

    properties (Access = private)
    % --- Core EEG/Study data ---
    ALLEEG              % Store EEG dataset
    STUDY               % Store STUDY structure
    clust_table         % Initial cluster table
    totalSubjects       % Total number of unique subjects
    numGroups           % Number of groups (1-3)
    groupRanges         % Cell array storing subject ranges for each group
    numClusters         % Number of clusters from STUDY
    dipXyz_table2       % Intermediate table needed for dipxyz_clust_table
    dipxyz_clust_table  % Final combined table
    clustGroups         % Cell array to store cluster tables for each group
    mriFilePath         % File path for standard_mri.mat
    mriData             % Loaded MRI data structure
    studyFilePath       % Directory path of the .study file
    residualVariance    % Filtering using residual variance
    clusteringMethod    % Input to pop_clust
    stdDevThreshold     % To remove outliers

    % --- Silhouette / clustering results ---
    bestSilhouetteScore         % Best silhouette score (mean)
    bestNumClusters             % Best cluster number based on mean silhouette
    bestSilhouetteScore_median  % Best silhouette score (median)
    bestNumClusters_median      % Best cluster number based on median
    ClusterRange                % Range of clusters evaluated (e.g., 2:20)
    %clusterRange               % Range of clusters evaluated (e.g., 2:20)
    allClusterAssignments       % Cell array: assignments for each K (2–20%)
    AllClusterAssignments       % Cell array: assignments for each K (2–20%)
    AllSilhouetteScores         % Cell array: silhouette values for each K (2–20)
    allSilhouetteScores         % Cell array: silhouette values for each K (2–20)
    meanSilhouetteScores        % Stores average mean scores for each cluster number
    medianSilhouetteScores      % Stores average median scores for each cluster number
    selectedNumClusters         % User-selected cluster number for Step 5
    ClustAlgo                   % Selected clustering algorithm string
    clustAlgo                   % Selected clustering algorithm string

    % --- Stored summary info from printClusterSummary ---
    NumClustersMean             % Best number of clusters (mean silhouette)
    NumClustersMedian           % Best number of clusters (median silhouette)
    ClusterAssignmentsMean      % Assignments for best K (mean silhouette)
    SilhouetteValsMean          % Silhouette values for best K (mean silhouette)
    ClusterAssignmentsMedian    % Assignments for best K (median silhouette)
    KOverallStats               % Matrix [K, OverallMeanSil, OverallMedianSil] for optimal_kmeans
    SilhouetteValsMedian        % Silhouette values for best K (median silhouette)
    LastClusterSummaryUpdate    % Timestamp of last saved summary

    % --- Final chosen clustering summary (Step 5: submitNumberOfClusters) ---
    FinalNumClusters            % Final chosen cluster number
    FinalAssignments            % Final cluster indices for each component
    FinalSilhouetteVals         % Final silhouette values
    FinalClustAlgo              % Final clustering algorithm name

    % --- Group info ---
    detectedNumGroups           % Detected number of groups (1–3)
    detectedSubjectsPerGroup    % Subjects per group
    groupPrefixes               % Group name prefixes
    StudyFileName               % .study filename
    totalFiles                  % Total .set files
    groupRangesUser             % User-entered group ranges
    hasOutlier logical = false  % Default false

    % --- Non-clustered info ---
    non_clust_sets_length       % Length of sets (non-clustered)
    non_clust_comps_length      % Length of components (non-clustered)

    % --- Subject info ---
    subjectNames_Section1       % Subject names from Section 1
    groupNames_Section1         % Group names from Section 1
    subjectIDs_Section1         % Subject IDs from Section 1
    subID                       % STUDY.cluster.sets
    icID                        % STUDY.cluster.comps
end


     methods (Access = private)
        
        function uniqueFilePath = getUniqueFilePath(app, basePath, fileName)
        % Generate a unique file path by appending a timestamp if the file already exists
        % Inputs:
        %   basePath - Directory path (e.g., app.studyFilePath)
        %   fileName - Base file name (e.g., 'group1_clusters.mat')
        % Output:
        %   uniqueFilePath - File path that does not already exist
        
        % Initial file path
        uniqueFilePath = fullfile(basePath, fileName);
        
        % If the file doesn't exist, return the original path
        if ~exist(uniqueFilePath, 'file')
            return;
        end
        
        % Extract the file name without extension and the extension
        [~, name, ext] = fileparts(fileName);
        
        % Generate a timestamp (e.g., 20250319_143022 for March 19, 2025, 14:30:22)
        timestamp = datestr(now, 'yyyymmdd_HHMMSS');
        
        % Create a new file name with the timestamp
        newFileName = sprintf('%s_%s%s', name, timestamp, ext);
        uniqueFilePath = fullfile(basePath, newFileName);
        
        % Double-check the new file path (in case the timestamped file also exists)
        counter = 1;
        while exist(uniqueFilePath, 'file')
            newFileName = sprintf('%s_%s_%d%s', name, timestamp, counter, ext);
            uniqueFilePath = fullfile(basePath, newFileName);
            counter = counter + 1;
        end
    end
     end


     methods (Access = private)
         function onWelcomeClosed(app, welcomeFig)
        % Resume execution and close modal
        uiresume(welcomeFig);             % ✅ Unpause uiwait
        delete(welcomeFig);               % ✅ Clean close
        app.UIFigure.Visible = 'on';      % ✅ Show main GUI
    end
     end

     methods (Access = private)
         % Helper method to validate subject ranges
function [isValid, groupRanges] = validateSubjectRanges(app)
    % Initialize outputs
    isValid = false;
    groupRanges = cell(1, app.numGroups);

    % Get the total number of subjects from STUDY
    totalSubjects = length(app.STUDY.datasetinfo);
    if totalSubjects == 0
        uialert(app.UIFigure, 'No subjects found in the STUDY file.', 'Validation Error', 'Icon', 'error');
        return;
    end

    % Get the ranges from enabled fields
    ranges = zeros(app.numGroups, 2); % [start, end] for each group
    for g = 1:app.numGroups
        switch g
            case 1
                if app.Group1SubjectRange.Enable == "on"
                    value = app.Group1SubjectRange.Value;
                    if isempty(value)
                        uialert(app.UIFigure, 'Group 1 range cannot be empty.', 'Validation Error', 'Icon', 'error');
                        return;
                    end
                    % Parse the range (e.g., "1:10")
                    rangeParts = split(value, ':');
                    if length(rangeParts) ~= 2
                        uialert(app.UIFigure, 'Group 1 range must be in the format "start:end" (e.g., 1:10).', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    startVal = str2double(rangeParts{1});
                    endVal = str2double(rangeParts{2});
                    if isnan(startVal) || isnan(endVal)
                        uialert(app.UIFigure, 'Invalid format for Group 1 range. Use "start:end" (e.g., 1:10) with numeric values.', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    if startVal >= endVal
                        uialert(app.UIFigure, 'Group 1 range must have start < end (e.g., 1:10).', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    ranges(g, :) = [startVal, endVal];
                end
            case 2
                if app.Group2SubjectRange.Enable == "on"
                    value = app.Group2SubjectRange.Value;
                    if isempty(value)
                        uialert(app.UIFigure, 'Group 2 range cannot be empty.', 'Validation Error', 'Icon', 'error');
                        return;
                    end
                    rangeParts = split(value, ':');
                    if length(rangeParts) ~= 2
                        uialert(app.UIFigure, 'Group 2 range must be in the format "start:end" (e.g., 16:30).', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    startVal = str2double(rangeParts{1});
                    endVal = str2double(rangeParts{2});
                    if isnan(startVal) || isnan(endVal)
                        uialert(app.UIFigure, 'Invalid format for Group 2 range. Use "start:end" (e.g., 16:30) with numeric values.', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    if startVal >= endVal
                        uialert(app.UIFigure, 'Group 2 range must have start < end (e.g., 16:30).', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    ranges(g, :) = [startVal, endVal];
                end
            case 3
                if app.Group3SubjectRange.Enable == "on"
                    value = app.Group3SubjectRange.Value;
                    if isempty(value)
                        uialert(app.UIFigure, 'Group 3 range cannot be empty.', 'Validation Error', 'Icon', 'error');
                        return;
                    end
                    rangeParts = split(value, ':');
                    if length(rangeParts) ~= 2
                        uialert(app.UIFigure, 'Group 3 range must be in the format "start:end" (e.g., 31:45).', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    startVal = str2double(rangeParts{1});
                    endVal = str2double(rangeParts{2});
                    if isnan(startVal) || isnan(endVal)
                        uialert(app.UIFigure, 'Invalid format for Group 3 range. Use "start:end" (e.g., 31:45) with numeric values.', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    if startVal >= endVal
                        uialert(app.UIFigure, 'Group 3 range must have start < end (e.g., 31:45).', ...
                            'Validation Error', 'Icon', 'error');
                        return;
                    end
                    ranges(g, :) = [startVal, endVal];
                end
        end
    end

    % Validate ranges
    % 1. Check for positive integers
    for g = 1:app.numGroups
        startVal = ranges(g, 1);
        endVal = ranges(g, 2);
        if ~isnumeric(startVal) || ~isnumeric(endVal) || startVal < 1 || endVal < startVal || mod(startVal, 1) ~= 0 || mod(endVal, 1) ~= 0
            uialert(app.UIFigure, sprintf('Invalid range for Group %d: Start and end must be positive integers, and end > start.', g), ...
                'Validation Error', 'Icon', 'error');
            return;
        end
    end

    % 2. Check for continuity and no overlap
    for g = 1:app.numGroups-1
        if ranges(g, 2) + 1 ~= ranges(g+1, 1)
            uialert(app.UIFigure, 'Ranges must be continuous with no gaps or overlaps between groups.', ...
                'Validation Error', 'Icon', 'error');
            return;
        end
    end

    % 3. Check that the first group starts at 1 and the last group ends at totalSubjects
    if ranges(1, 1) ~= 1
        uialert(app.UIFigure, 'The first group must start at subject 1.', 'Validation Error', 'Icon', 'error');
        return;
    end
    if ranges(end, 2) ~= totalSubjects
        uialert(app.UIFigure, sprintf('The last group must end at subject %d (total subjects).', totalSubjects), ...
            'Validation Error', 'Icon', 'error');
        return;
    end

    % If all checks pass, store the ranges
    for g = 1:app.numGroups
        groupRanges{g} = ranges(g, 1):ranges(g, 2);
    end

    isValid = true;
end

     end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


    % Step 0: Hide the main app GUI window
    app.UIFigure.Visible = 'off';

    % Step 1: Create modal welcome screen
    welcomeFig = uifigure('Name', 'Welcome to EEG Clustering App', ...
                          'WindowStyle', 'modal', ...
                          'Position', [100, 100, 750, 600], ...
                          'Color', [1 1 1]);

    % Step 2: Locate EEG logo in same folder as .mlapp
    appFullPath = which('EEG_Clustering');
    appFolder = fileparts(appFullPath);
    logoPath = fullfile(appFolder, 'eeg_logo.png');

    % Step 3: Create EEG logo (slide in)
    logoXstart = -150;
    logoXend = 30;
    logoY = 450;
    logoSize = [120, 120];

    if exist(logoPath, 'file')
        eegLogo = uiimage(welcomeFig, ...
            'ImageSource', logoPath, ...
            'Position', [logoXstart, logoY, logoSize]);
        for x = logoXstart:5:logoXend
            eegLogo.Position(1) = x;
            pause(0.01);
        end
    end
% 
%     % Step 4: Instruction text (with fixed "statistics" line break)
% html = [ ...
% '<html><body style="font-family:Segoe UI; font-size:12pt; width:560px;">', ...
% '<h2 style="color:#0072BD;">&#128161; Welcome to the <b>EEG Clustering App</b></h2>', ...
% '<p>This app helps you efficiently create and cluster EEGLAB STUDY files for <b>group-level EEG analysis</b>,<br>', ...
% '<b>data visualization</b>, and <b>statistical comparisons</b> across conditions and subject groups.</p>', ...
% '<br>', ...
% '<h3 style="color:#EDB120;">&#9989; Key Features:</h3><ul>', ...
% '<li>Supports <b>1 to 3 groups, inclusive</b> (e.g., PS, HS)</li>', ...
% '<li>Handles <b>2 to 20 clusters, inclusive</b></li>', ...
% '<li>Auto-detects groups and subjects from filenames</li>', ...
% '</ul>', ...
% '<br>', ...
% '<h3 style="color:#D95319;">&#128221; File Naming Instructions:</h3><ul>', ...
% '<li>Include a <b>group name</b> (e.g., PS, HS) and a <b>subject number</b> (e.g., 01, 02)</li>', ...
% '<li>Use underscores (preferable) or spaces as delimiters</li>', ...
% '<li><code>epoched_data_PS_01.set</code> is a valid example</li>', ...
% '<li>Each file must contain only <b>one numeric part</b> (subject ID)</li>', ...
% '<li>Group names must be <b>consistent</b> within each group</li>', ...
% '<li>Put all the <b>epoched .set</b> files in a single folder; all the outputs will also be saved in the same folder</li>', ...
% '<li>&#9888; If <code>icaact</code> is empty: a. go to <b>EEGLAB → File → Preferences</b> and check/uncheck <b>ICA options</b>.<br>b. make sure <b>"Precompute ICA activations"</b> is enabled.</li>', ...
% '<li>Key output table: <b>dipxyz_clust_table</b></li>', ...
% '</ul>', ...
% '<br>', ...
% '<h3 style="color:#7E2F8E;">&#128200; Clustering Parameters:</h3><ul style="padding-right:10px;">', ...
% '<li><b>Recommended (Default):</b> Set the standard deviation threshold to <b>3</b> for optimal outlier removal</li>', ...
% '<li><b>Automated Cluster Optimization (Submit Parameters):</b> The app computes silhouette scores across cluster solutions (2–20) and identifies the optimal number.<br>', ...
% '<b>Important:</b> This is a <b>data-driven recommendation</b>, not an absolute decision. Higher cluster numbers yield finer granularity but <b>reduce statistical power</b> as fewer subjects contribute to each cluster.<br>', ...
% '<b>Exercise critical judgment (Submit Required Number of Clusters):</b> Evaluate the suggested cluster number against your <b>hypotheses, sample size, and neurophysiological interpretability</b> before finalizing your selection.</li>', ...
% '</ul>', ...
% '<p style="margin-top:12px;">&#10148; Click <b>OK</b> to begin and select your .set folder.</p>', ...
% '</body></html>' ...
% ];
% uilabel(welcomeFig, ...
%     'Text', html, ...
%     'Interpreter', 'html', ...
%     'Position', [160 20 580 530], ...
%     'HorizontalAlignment', 'left', ...
%     'WordWrap', 'on');

% Step 4: Instruction text (with fixed "statistics" line break)
html = [ ...
'<html><body style="font-family:Segoe UI; font-size:11pt; width:560px; line-height:1.4;">', ...
'<h2 style="color:#0072BD; margin-top:5px;">&#128161; <b>EEG Clustering App</b></h2>', ...
'<p style="margin:8px 0;">Automate EEGLAB STUDY creation for <b>group-level EEG analysis</b>, visualization, and statistical comparisons.</p>', ...
'<h3 style="color:#EDB120; margin:10px 0 5px 0;">&#9989; Key Features</h3>', ...
'<p style="margin:5px 0;">✓ Supports <b>1-3 groups</b> | ✓ Handles <b>2-20 clusters</b> | ✓ Auto-detects groups/subjects</p>', ...
'<h3 style="color:#D95319; margin:10px 0 5px 0;">&#128221; File Naming</h3>', ...
'<p style="margin:5px 0; line-height:1.5;">• Include <b>group name</b> (e.g., PS, HS) and <b>subject number</b> (e.g., 01, 02)<br>', ...
'• Use underscores or spaces: <code>epoched_data_PS_01.set</code><br>', ...
'• Only <b>one numeric part</b> per filename (subject ID)<br>', ...
'• Keep group names <b>consistent</b> within each group<br>', ...
'• Place all <b>.set files</b> in a single folder (outputs save here too)<br>', ...
'• &#9888; <b>If icaact empty:</b> EEGLAB → File → Preferences → enable "Precompute ICA activations"<br>', ...
'• Key output: <b>dipxyz_clust_table</b></p>', ...
'<h3 style="color:#7E2F8E; margin:10px 0 5px 0;">&#128200; Clustering Parameters</h3>', ...
'<p style="margin:5px 0; line-height:1.5;">&#11088; <b>Recommended:</b> Use standard deviation threshold <b>3</b> for optimal outlier removal<br><br>', ...
'&#128202; <b>Cluster Optimization:</b> App computes silhouette scores (2-20 clusters) in <b>Submit Parameters</b><br>', ...
'⚠️ <b>Important:</b> Suggested number is data-driven, not absolute. More clusters = finer detail but less statistical power<br>', ...
'&#129504; <b>Final Decision:</b> In <b>Submit Required Number of Clusters</b>, choose based on your hypotheses, sample size, and interpretability</p>', ...
'<p style="margin-top:12px; font-size:12pt;">&#10148; Click <b>OK</b> to begin</p>', ...
'</body></html>' ...
];
uilabel(welcomeFig, ...
    'Text', html, ...
    'Interpreter', 'html', ...
    'Position', [160 30 580 520], ...
    'HorizontalAlignment', 'left', ...
    'WordWrap', 'on');


    % Step 5: OK Button to show app and close welcome screen
    uibutton(welcomeFig, ...
        'Text', 'OK, Let''s Go!', ...
        'Position', [315, 15, 120, 35], ...
        'FontSize', 14, ...
        'ButtonPushedFcn', @(btn, event) onWelcomeClosed(app, welcomeFig));

    % Pause execution until user closes welcome screen
    uiwait(welcomeFig);

    % === Step 6: Apply EEGLAB-like GUI Styling ===

    % Set UIFigure background color (EEGLAB light blue)
    app.UIFigure.Color = [0.66 0.76 1];

    % Find all uicontrols and apply style
    controls = findall(app.UIFigure, 'Type', 'uicontrol');
    for k = 1:length(controls)
        controls(k).FontName = 'MS Sans Serif';
        controls(k).FontSize = 10;
        controls(k).BackgroundColor = [0.66 0.76 1];
        controls(k).ForegroundColor = [0 0 0.4];
    end

    % Optional: emphasize status/result labels
    if isprop(app, 'ResultLabel')
        app.ResultLabel.FontSize = 14;
        app.ResultLabel.FontWeight = 'bold';
        app.ResultLabel.FontColor = [0 0 0];
    end

    if isprop(app, 'StatusLabel')
        app.StatusLabel.FontSize = 14;
        app.StatusLabel.FontWeight = 'bold';
        app.StatusLabel.FontColor = [0 0 0];
    end


        end

        % Button pushed function: SelectsetfilesfolderButton
        function SelectsetfilesfolderButtonPushed(app, event)

    % Step 1: Prompt user to select folder with .set files
    app.StatusLabel.Text = 'Select folder with .set files...';
    app.studyFilePath = uigetdir('', 'Select folder containing .set files');
    if app.studyFilePath == 0
        app.StatusLabel.Text = 'No folder selected';
        app.ResultLabel.Text = 'Please select a folder to proceed';
        fprintf('Error: No folder selected. Please select a folder to proceed.\n');
        return;
    end

    % Step 2: Scan the folder for .set files
    allFiles = dir(fullfile(app.studyFilePath, '*.set'));
    if isempty(allFiles)
        app.StatusLabel.Text = 'No .set files found';
        app.ResultLabel.Text = 'No .set files found in the selected folder';
        fprintf('Error: No .set files found in the selected folder: %s\n', app.studyFilePath);
        return;
    end

    % Step 3: Parse filenames to extract group and subject information
    % Define excludedWords locally and convert to lowercase
    excludedWords = {'data', 'epoched', 'set', 'subject', 'sub'};
    excludedWords = cellfun(@lower, excludedWords, 'UniformOutput', false);

    fileInfo = cell(length(allFiles), 4);
    invalidFiles = struct('filename', {}, 'reason', {});
    validFileCount = 0;

    for i = 1:length(allFiles)
        [~, fname, ~] = fileparts(allFiles(i).name);
        fname = strrep(fname, ' ', '_');  % Replace spaces with underscores for parsing
        fprintf('Processing file: %s\n', fname);

        parts = split(fname, '_');
        parts = parts(~cellfun(@isempty, parts));
        fprintf('  Parts: %s\n', strjoin(parts, ', '));

        % Find group (look for alpha string before subject number)
        group = '';
        subjectNumStr = '';
        foundSubjectNum = false;
        for j = 1:length(parts)
            if regexp(parts{j}, '^\d+$') % Check for numeric part
                if ~foundSubjectNum
                    % Look backward for group (alpha string not in excludedWords)
                    for k = j-1:-1:1
                        part = parts{k};
                        if regexp(part, '^[a-zA-Z]+$') && ...
                           (isempty(excludedWords) || ~ismember(lower(part), excludedWords))
                            group = lower(part);
                            subjectNumStr = parts{j};
                            foundSubjectNum = true;
                            break;
                        end
                    end
                end
            end
            if foundSubjectNum
                break;
            end
        end

        if isempty(group) || isempty(subjectNumStr)
            invalidFiles(end+1).filename = allFiles(i).name;
            invalidFiles(end).reason = 'Could not locate group or subject number in filename parts';
            continue;
        end
        subjectNum = str2double(subjectNumStr);
        fprintf('  Group: %s\n', group);
        fprintf('  Subject number: %s (%d)\n', subjectNumStr, subjectNum);

        % Store valid info with the original filename
        validFileCount = validFileCount + 1;
        fileInfo{validFileCount, 1} = fullfile(app.studyFilePath, allFiles(i).name);
        fileInfo{validFileCount, 2} = group;
        fileInfo{validFileCount, 3} = subjectNum;
        fileInfo{validFileCount, 4} = sprintf('%s_%02d', group, subjectNum);
    end

    % Handle invalid files
    if ~isempty(invalidFiles)
        errorMsg = 'The following files could not be parsed:\n';
        for i = 1:length(invalidFiles)
            errorMsg = sprintf('%s  File: %s\n    Reason: %s\n', errorMsg, invalidFiles(i).filename, invalidFiles(i).reason);
        end
        errorMsg = sprintf('%sProceeding with valid files. Please remove or rename invalid files (e.g., "prefix_BHS_01.set") for full processing.', errorMsg);
        fprintf('Warning: Invalid filenames detected:\n%s\n', errorMsg);
        uialert(app.UIFigure, errorMsg, 'Invalid Filenames', 'Icon', 'warning');
        if validFileCount == 0
            app.StatusLabel.Text = 'Error: No valid filenames';
            app.ResultLabel.Text = 'Check the filenames and try again';
            fprintf('Error: No valid filenames found. Check the filenames and try again.\n');
            return;
        end
    end

    % Trim fileInfo to valid entries
    fileInfo = fileInfo(1:validFileCount, :);

    % Step 4: Create a table for sorting and validation
    fileTable = table(fileInfo(:,1), fileInfo(:,2), cell2mat(fileInfo(:,3)), fileInfo(:,4), ...
        'VariableNames', {'filename', 'Group', 'SubjectNum', 'SubjectLabel'});

    % Step 5: Sort by group and subject number
    fileTable = sortrows(fileTable, {'Group', 'SubjectNum'});

    % Step 6: Detect groups and assign group indices (for local use)
    [groupCounts, groupNames] = groupcounts(fileTable.Group);
    numGroups = length(groupNames);
    fprintf('Detected %d groups:\n', numGroups);
    for i = 1:numGroups
        fprintf('  Group %s: %d files\n', groupNames{i}, groupCounts(i));
    end

    % Enforce 1-3 groups
    if numGroups < 1 || numGroups > 3
        errorMsg = sprintf('Detected %d groups, but only 1 to 3 groups are supported. Please adjust the .set files and try again.', numGroups);
        fprintf('Error: %s\n', errorMsg);
        uialert(app.UIFigure, errorMsg, 'Group Count Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error: Invalid number of groups';
        app.ResultLabel.Text = sprintf('Detected %d groups; only 1 to 3 are supported', numGroups);
        return;
    end

    % Assign group indices
    fileTable.GroupIdx = zeros(height(fileTable), 1);
    for i = 1:numGroups
        groupMask = strcmp(fileTable.Group, groupNames{i});
        fileTable.GroupIdx(groupMask) = i;
    end

    % Step 7: Calculate group ranges based on file counts (for subjects)
    groupRanges = cell(1, numGroups);
    startIdx = 1;
    for i = 1:numGroups
        groupMask = strcmp(fileTable.Group, groupNames{i});
        groupFileCount = sum(groupMask);
        endIdx = startIdx + groupFileCount - 1;
        groupRanges{i} = sprintf('%d:%d', startIdx, endIdx);
        startIdx = endIdx + 1;
    end
    fprintf('Step 7 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug to verify

   
    % Step 8: Ensure EEGLAB (>=2025) is available (auto-detect, then fallback)
minEEGLAB = '2025.0.0';
appNamePref = 'EEGClusteringApp';
prefKey = 'EEGLABPath';

% 0) If EEGLAB already on path, just verify version
fprintf('Checking EEGLAB on path...\n');
if exist('eeglab','file') == 2 && exist('pop_loadset','file') == 2 && exist('eeg_checkset','file') == 2
    [ok, verStr, verNum] = local_readEEGLABVersion(fileparts(which('eeglab.m')));
    fprintf('Found on path: v%s, OK=%d\n', verStr, ok);
    if ok && verNum >= local_versionToNum(minEEGLAB)
        try
            eeglab('nogui');
            fprintf('EEGLAB initialized from path (v%s).\n', verStr);
        catch ME
            error('Failed to initialize EEGLAB from path: %s', char(ME.message));
        end
    else
        warning('EEGLAB on path (v%s) < %s or invalid. Searching for newer version...\n', verStr, minEEGLAB);
    end
end

eeglabFound = false;

if ~eeglabFound
    % 1) Try cached path from previous runs
    fprintf('Checking cached EEGLAB path...\n');
    if ispref(appNamePref, prefKey)
        cachedPath = getpref(appNamePref, prefKey);
        if isfolder(cachedPath) && exist(fullfile(cachedPath,'eeglab.m'),'file')
            [ok, verStr, verNum] = local_readEEGLABVersion(cachedPath);
            if ok && verNum >= local_versionToNum(minEEGLAB)
                addpath(cachedPath);
                try
                    eeglab('nogui');
                    eeglabFound = true;
                    fprintf('EEGLAB initialized from cached path: %s (v%s)\n', cachedPath, verStr);
                catch ME
                    rmpath(cachedPath);
                    warning('Cached EEGLAB failed to initialize: %s. Clearing cache.\n', char(ME.message));
                    rmpref(appNamePref, prefKey);
                end
            else
                warning('Cached EEGLAB (v%s) < %s. Ignoring cache.\n', verStr, minEEGLAB);
                rmpref(appNamePref, prefKey); % Clear invalid cache
            end
        end
    end
end

if ~eeglabFound
    % 2) Build OS-aware base search locations (shallow BFS, fast)
    bases = {};
    try bases{end+1} = strtrim(userpath); catch, end
    try bases{end+1} = pwd; catch, end

    if ispc
        home = getenv('USERPROFILE');
        bases = [bases, ...
            {home, fullfile(home,'Desktop'), fullfile(home,'Documents'), fullfile(home,'Downloads'), ...
             'C:\Program Files', 'C:\Program Files\MATLAB'}];
    elseif ismac
        home = getenv('HOME');
        bases = [bases, ...
            {home, fullfile(home,'Desktop'), fullfile(home,'Documents'), fullfile(home,'Downloads'), ...
             '/Applications'}];
    else % linux/unix
        home = getenv('HOME');
        bases = [bases, ...
            {home, fullfile(home,'Desktop'), fullfile(home,'Documents'), fullfile(home,'Downloads'), ...
             '/usr/local', '/opt'}];
    end

    % Remove empties/dupes
    bases = unique(bases(~cellfun(@(p) isempty(p) || ~isfolder(p), bases)));

    % 3) Harvest candidate eeglab folders (limit depth for speed)
    maxDepth = 6;
    candidates = struct('path',{},'verStr',{},'verNum',{});
    seen = containers.Map('KeyType','char','ValueType','logical');

    for b = 1:numel(bases)
        base = bases{b};
        q = {struct('p', base, 'd', 0)};
        while ~isempty(q)
            node = q{1}; q(1) = [];
            p = node.p; d = node.d;

            if isKey(seen, p), continue; end
            seen(p) = true;

            % Look for any folder containing 'eeglab' with eeglab.m
            [~,name] = fileparts(p);
            if contains(lower(name),'eeglab','IgnoreCase',true) && exist(fullfile(p,'eeglab.m'),'file')
                [ok, verStr, verNum] = local_readEEGLABVersion(p);
                if ok
                    candidates(end+1) = struct('path', p, 'verStr', verStr, 'verNum', verNum); %#ok<AGROW>
                    fprintf('Candidate found: %s (v%s)\n', p, verStr);
                end
            end

            % Expand children if depth allows
            if d < maxDepth
                dd = dir(p);
                dd = dd([dd.isdir]);
                for k = 1:numel(dd)
                    nm = dd(k).name;
                    if nm=="." || nm=="..", continue; end
                    % Skip huge/system folders for speed
                    if ispc && (contains(nm,'Windows') || contains(nm,'ProgramData')), continue; end
                    q{end+1} = struct('p', fullfile(p,nm), 'd', d+1); %#ok<AGROW>
                end
            end
        end
    end

    % 4) Pick best candidate: newest >= minEEGLAB; else fail
    if ~isempty(candidates)
        % Sort by verNum desc
        [~, ord] = sort([candidates.verNum], 'descend');
        candidates = candidates(ord);
        minNum = local_versionToNum(minEEGLAB);
        bestIdx = find([candidates.verNum] >= minNum, 1, 'first');

        if ~isempty(bestIdx)
            pick = candidates(bestIdx);
            addpath(pick.path);
            try
                eeglab('nogui');
                eeglabFound = true;
                fprintf('EEGLAB initialized from: %s (v%s)\n', pick.path, pick.verStr);
                setpref(appNamePref, prefKey, pick.path);
            catch ME
                rmpath(pick.path);
                error('Failed to initialize EEGLAB from %s: %s', pick.path, char(ME.message));
            end
        else
           warning('No EEGLAB version >= %s found. Candidates: %s\n', minEEGLAB, sprintf('%s (v%s), ', [candidates.path], [candidates.verStr]));
        end
    end
end

% 5) Final fallback to user selection (with min-version enforcement)
if ~eeglabFound
    app.StatusLabel.Text = 'EEGLAB not found automatically. Please select an EEGLAB folder...';
    app.ResultLabel.Text = 'EEGLAB version must be 2025 or higher. Download from https://sccn.ucsd.edu/eeglab/download.php if needed.';
    p = uigetdir('', 'Select EEGLAB directory (EEGLAB >= 2025)');
    if isequal(p,0)
        app.StatusLabel.Text = 'EEGLAB selection cancelled';
        app.ResultLabel.Text = 'Please install EEGLAB (>=2025) and try again';
        error('EEGLAB directory not selected.');
    end
    [ok, verStr, verNum] = local_readEEGLABVersion(p);
    if ~ok
        error('Selected folder does not appear to be a valid EEGLAB install: %s', p);
    end
    if verNum < local_versionToNum(minEEGLAB)
        fprintf('Selected EEGLAB version %s is below required 2025.0.0. Please install EEGLAB 2025 or higher.\n', verStr);
        error('EEGLAB version too low.');
    end
    addpath(p);
    try
        eeglab('nogui');
        setpref(appNamePref, prefKey, p);
        fprintf('EEGLAB initialized from user-selected path: %s (v%s)\n', p, verStr);
    catch ME
        rmpath(p);
        error('Failed to initialize EEGLAB from %s: %s', p, char(ME.message));
    end
    app.StatusLabel.Text = sprintf('EEGLAB initialized successfully (v%s)', verStr);
end

%% ---- Local helpers ----------------------------------------------------
            function [ok, verStr, verNum] = local_readEEGLABVersion(root)
ok = false; verStr = ''; verNum = 0;
if ~exist(fullfile(root,'eeglab.m'),'file'), return; end

% Try folder name first (e.g., eeglab2025.0.0)
[~, name] = fileparts(root);
m = regexp(lower(name), 'eeglab\s*(\d{4}(?:\.\d+)*)', 'tokens', 'once'); % Match eeglab followed by version
if ~isempty(m)
    verStr = m{1}; 
    verNum = local_versionToNum(verStr); 
    ok = true; 
    return;
end

% Parse version string from eeglab.m or Contents.m
cands = {fullfile(root,'eeglab.m'), fullfile(root,'Contents.m')};
for ii = 1:numel(cands)
    f = cands{ii};
    if exist(f,'file')
        try
            txt = fileread(f);
            pats = { ...
                '(?i)eeglab\s*(?:version|v)?\s*(\d{4}(?:\.\d+)*)', ... % Match version after eeglab/version/v
                '(?i)version\s*(\d{4}(?:\.\d+)*)', ... % Match standalone version
                '(?i)\d{4}(?:\.\d+)*' }; % Fallback to any year-based version
            for pp = 1:numel(pats)
                t = regexp(txt, pats{pp}, 'tokens', 'once');
                if ~isempty(t)
                    verStr = t{1}; 
                    verNum = local_versionToNum(verStr); 
                    ok = true; 
                    return;
                end
            end
        catch
        end
    end
end

% If version not found, mark as unknown but still valid EEGLAB
ok = true; verStr = 'unknown'; verNum = 0;
end

function n = local_versionToNum(s)
if isempty(s) || strcmpi(s,'unknown'), n = 0; return; end
parts = regexp(s,'\.','split');
nums = cellfun(@(x) str2double(x), parts);
if isnan(nums(1)), n = 0; return; end
w = [1, 1e-3, 1e-6, 1e-9];
nums(numel(nums)+1:numel(w)) = 0;
n = sum(nums(1:numel(w)).*w(1:numel(nums)));
end

% After EEGLAB is initialized
limoExternal = fullfile(fileparts(which('eeglab')), 'plugins', 'LIMO4.1.2', 'external');
addpath(genpath(limoExternal));

apPath = which('apcluster.m');
if isempty(apPath)
    warning('apcluster.m still not found. Affinity Propagation may fail.');
else
    fprintf('apcluster.m found at: %s\n', apPath);
end


    ALLEEG = [];
    numDatasets = height(fileTable);
    recomputedCount = 0;
    for i = 1:numDatasets
        try
            % Verify file existence and try alternative naming
            filename = fileTable.filename{i};
            app.StatusLabel.Text = sprintf('Processing dataset %d of %d: Checking file %s...', i, numDatasets, filename);
            if ~exist(filename, 'file')
                altFilename = strrep(filename, 'epoched data', 'epoched_data');
                if exist(altFilename, 'file')
                    fprintf('File %s not found, but found %s. Using alternative filename.\n', filename, altFilename);
                    filename = altFilename;
                    fileTable.filename{i} = altFilename;
                else
                    altFilename = strrep(filename, 'epoched_data', 'epoched data');
                    if exist(altFilename, 'file')
                        fprintf('File %s not found, but found %s. Using alternative filename.\n', filename, altFilename);
                        filename = altFilename;
                        fileTable.filename{i} = altFilename;
                    else
                        errorMsg = sprintf('Error: File %s not found. Tried alternative names but none exist. Ensure file exists and path is valid.', filename);
                        fprintf('%s\n', errorMsg);
                        app.StatusLabel.Text = sprintf('Error processing dataset %d of %d', i, numDatasets);
                        app.ResultLabel.Text = errorMsg;
                        return;
                    end
                end
            end

            app.StatusLabel.Text = sprintf('Processing dataset %d of %d: Loading %s...', i, numDatasets, filename);
            EEG = pop_loadset('filename', filename);
            EEG.subject = fileTable.SubjectLabel{i};
            EEG.group = fileTable.Group{i};
            EEG.setname = fileTable.SubjectLabel{i};

            % Validate required fields
            if ~isfield(EEG, 'data') || isempty(EEG.data) || ndims(EEG.data) ~= 3
                errorMsg = sprintf('Error: Dataset %s is missing EEG.data or it is not a 3D matrix.', filename);
                fprintf('%s\n', errorMsg);
                error(errorMsg);
            end
            nchans = size(EEG.data, 1);
            if ~isfield(EEG, 'icawinv') || isempty(EEG.icawinv) || size(EEG.icawinv, 1) ~= nchans
                errorMsg = sprintf('Error: Dataset %s is missing EEG.icawinv or has incompatible dimensions.', filename);
                fprintf('%s\n', errorMsg);
                error(errorMsg);
            end
            if ~isfield(EEG, 'icasphere') || isempty(EEG.icasphere) || size(EEG.icasphere, 1) ~= nchans || size(EEG.icasphere, 2) ~= nchans
                errorMsg = sprintf('Error: Dataset %s is missing EEG.icasphere or has incompatible dimensions.', filename);
                fprintf('%s\n', errorMsg);
                error(errorMsg);
            end
            if ~isfield(EEG, 'icaweights') || isempty(EEG.icaweights) || size(EEG.icaweights, 2) ~= nchans
                errorMsg = sprintf('Error: Dataset %s is missing EEG.icaweights or has incompatible dimensions.', filename);
                fprintf('%s\n', errorMsg);
                error(errorMsg);
            end
            if ~isfield(EEG, 'icaact') || isempty(EEG.icaact)
                app.StatusLabel.Text = sprintf('Processing dataset %d of %d: Recomputing icaact for %s...', i, numDatasets, filename);
                ncomps = size(EEG.icaweights, 1);
                [nchans_data, ntimes, nepochs] = size(EEG.data);
                if nchans_data ~= nchans
                    errorMsg = sprintf('Error: Dataset %s has mismatched channel numbers between EEG.data and ICA matrices.', filename);
                    fprintf('%s\n', errorMsg);
                    error(errorMsg);
                end
                W = EEG.icaweights * EEG.icasphere; % ncomps x nchans
                EEG_data_2D = reshape(EEG.data, nchans, ntimes * nepochs); % nchans x (ntimes * nepochs)
                icaact_2D = W * EEG_data_2D; % ncomps x (ntimes * nepochs)
                EEG.icaact = reshape(icaact_2D, ncomps, ntimes, nepochs); % ncomps x ntimes x nepochs
                recomputedCount = recomputedCount + 1;

                % Save the updated dataset using the full path in filename
                app.StatusLabel.Text = sprintf('Processing dataset %d of %d: Saving recomputed icaact for %s...', i, numDatasets, filename);
                try
                    pop_saveset(EEG, 'filename', filename);
                catch ME_save
                    errorMsg = sprintf('Error saving dataset %s after recomputing icaact: %s. Check write permissions and ensure file is not in use.', filename, ME_save.message);
                    fprintf('%s\n', errorMsg);
                    error(errorMsg);
                end

                % Reload the dataset to release file handles and confirm save
                app.StatusLabel.Text = sprintf('Processing dataset %d of %d: Reloading %s after save...', i, numDatasets, filename);
                try
                    EEG = pop_loadset('filename', filename);
                    EEG.subject = fileTable.SubjectLabel{i};
                    EEG.group = fileTable.Group{i};
                    EEG.setname = fileTable.SubjectLabel{i};
                catch ME_load
                    errorMsg = sprintf('Error reloading dataset %s after saving: %s. File may be corrupted or inaccessible.', filename, ME_load.message);
                    fprintf('%s\n', errorMsg);
                    error(errorMsg);
                end

                % Recompute icaact after reload to ensure it’s populated in EEG
                if ~isfield(EEG, 'icaact') || isempty(EEG.icaact)
                    app.StatusLabel.Text = sprintf('Processing dataset %d of %d: Recomputing icaact after reload for %s...', i, numDatasets, filename);
                    ncomps = size(EEG.icaweights, 1);
                    [nchans_data, ntimes, nepochs] = size(EEG.data);
                    if nchans_data ~= nchans
                        errorMsg = sprintf('Error: Dataset %s has mismatched channel numbers after reload.', filename);
                        fprintf('%s\n', errorMsg);
                        error(errorMsg);
                    end
                    W = EEG.icaweights * EEG.icasphere; % ncomps x nchans
                    EEG_data_2D = reshape(EEG.data, nchans, ntimes * nepochs); % nchans x (ntimes * nepochs)
                    icaact_2D = W * EEG_data_2D; % ncomps x (ntimes * nepochs)
                    EEG.icaact = reshape(icaact_2D, ncomps, ntimes, nepochs); % ncomps x ntimes x nepochs
                    fprintf('Recomputed icaact after reload for %s: size %s\n', filename, mat2str(size(EEG.icaact)));
                else
                    fprintf('icaact exists for dataset %s after reload, no recomputation needed.\n', filename);
                end
                app.StatusLabel.Text = sprintf('Processing dataset %d of %d: Saved and reloaded %s with recomputed icaact.', i, numDatasets, filename);
            else
                fprintf('icaact exists for dataset %s, no recomputation needed.\n', filename);
            end

            ALLEEG = eeg_store(ALLEEG, EEG, i);
        catch ME
            errorMsg = sprintf('Error processing dataset %d of %d: %s', i, numDatasets, ME.message);
            fprintf('%s\n', errorMsg);
            app.StatusLabel.Text = sprintf('Error processing dataset %d of %d', i, numDatasets);
            app.ResultLabel.Text = sprintf('Failed to process %s: %s', filename, ME.message);
            return;
        end
    end
    fprintf('Processed %d datasets, recomputed icaact for %d datasets.\n', numDatasets, recomputedCount);
            
    % Step 9: Create the STUDY
    try
        [STUDY, ALLEEG] = pop_study([], ALLEEG, 'name', 'non_clustered');
    catch ME
        errorMsg = sprintf('Error creating STUDY: %s', ME.message);
        fprintf('%s\n', errorMsg);
        uialert(app.UIFigure, errorMsg, 'STUDY Creation Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error creating STUDY';
        app.ResultLabel.Text = 'Failed to create STUDY';
        return;
    end

    % Debug: Check STUDY.cluster(1).sets and comps after pop_study
    if ~isfield(STUDY, 'cluster') || isempty(STUDY.cluster) || ~isfield(STUDY.cluster(1), 'sets') || ~isfield(STUDY.cluster(1), 'comps')
        errorMsg = 'Parent cluster in non-clustered STUDY is missing sets or comps after pop_study.';
        fprintf('Error: %s\n', errorMsg);
        uialert(app.UIFigure, errorMsg, 'STUDY Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error: Missing parent cluster data';
        app.ResultLabel.Text = 'Parent cluster lacks sets or comps after pop_study';
        return;
    end
    fprintf('After pop_study:\n');
    fprintf('  STUDY.cluster(1).sets (first 5 elements): %s\n', mat2str(STUDY.cluster(1).sets(1:min(5, length(STUDY.cluster(1).sets)))));
    fprintf('  STUDY.cluster(1).comps (first 5 elements): %s\n', mat2str(STUDY.cluster(1).comps(1:min(5, length(STUDY.cluster(1).comps)))));

    % Step 10: Update STUDY.datasetinfo with subject and group info
    for i = 1:length(STUDY.datasetinfo)
        STUDY.datasetinfo(i).subject = fileTable.SubjectLabel{i};
        STUDY.datasetinfo(i).group = fileTable.Group{i};
    end

    % Step 11: Create study design and precompute components
    try
        STUDY = std_makedesign(STUDY, ALLEEG, 1, 'defaultdesign', 'on');
        [STUDY, ALLEEG] = std_precomp(STUDY, ALLEEG, 'components');
    catch ME
        errorMsg = sprintf('Error in study design or precomputation: %s', ME.message);
        fprintf('%s\n', errorMsg);
        uialert(app.UIFigure, errorMsg, 'STUDY Design Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error in study design';
        app.ResultLabel.Text = 'Failed to create study design or precompute components';
        return;
    end

    % Debug: Check STUDY.cluster(1).sets and comps after std_precomp
    if ~isfield(STUDY, 'cluster') || isempty(STUDY.cluster) || ~isfield(STUDY.cluster(1), 'sets') || ~isfield(STUDY.cluster(1), 'comps')
        errorMsg = 'Parent cluster in non-clustered STUDY is missing sets or comps after std_precomp.';
        fprintf('Error: %s\n', errorMsg);
        uialert(app.UIFigure, errorMsg, 'STUDY Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error: Missing parent cluster data';
        app.ResultLabel.Text = 'Parent cluster lacks sets or comps after std_precomp';
        return;
    end
    fprintf('After std_precomp:\n');
    fprintf('  STUDY.cluster(1).sets (first 5 elements): %s\n', mat2str(STUDY.cluster(1).sets(1:min(5, length(STUDY.cluster(1).sets)))));
    fprintf('  STUDY.cluster(1).comps (first 5 elements): %s\n', mat2str(STUDY.cluster(1).comps(1:min(5, length(STUDY.cluster(1).comps)))));

    % Step 12: Extract sets and comps from the parent cluster (STUDY.cluster(1))
    non_clust_sets = STUDY.cluster(1).sets(:);
    non_clust_comps = STUDY.cluster(1).comps(:);

    % Compute and store lengths
    app.non_clust_sets_length = length(non_clust_sets);
    fprintf('Immediately after setting: app.non_clust_sets_length = %d\n', app.non_clust_sets_length);
    app.non_clust_comps_length = length(non_clust_comps);
    fprintf('Immediately after setting: app.non_clust_comps_length = %d\n', app.non_clust_comps_length);

    % Validate that sets and comps have the same length
    if app.non_clust_sets_length ~= app.non_clust_comps_length
        errorMsg = sprintf('Mismatch in non-clustered STUDY parent cluster: sets length (%d) does not match comps length (%d).', ...
            app.non_clust_sets_length, app.non_clust_comps_length);
        fprintf('Error: %s\n', errorMsg);
        uialert(app.UIFigure, errorMsg, 'STUDY Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error: Parent cluster data mismatch';
        app.ResultLabel.Text = 'Sets and comps lengths do not match in non-clustered STUDY';
        return;
    end

    % Compute number of dipoles with proper error handling
    numDipoles = 0;
    for i = 1:length(ALLEEG)
        if isfield(ALLEEG(i), 'dipfit') && isfield(ALLEEG(i).dipfit, 'model') && ~isempty(ALLEEG(i).dipfit.model)
            numDipoles = numDipoles + size(ALLEEG(i).dipfit.model, 2);
        else
            fprintf('  Warning: ALLEEG(%d) is missing dipfit.model or it is empty.\n', i);
        end
    end

    % Validate sets, comps, and number of dipoles
    if app.non_clust_sets_length ~= numDipoles || app.non_clust_comps_length ~= numDipoles
        fprintf('Warning: Mismatch in non-clustered STUDY parent cluster data:\n');
        fprintf('  Sets length: %d\n', app.non_clust_sets_length);
        fprintf('  Comps length: %d\n', app.non_clust_comps_length);
        fprintf('  Number of dipoles: %d\n', numDipoles);
        fprintf('  Proceeding without terminating, as this validation will be rechecked in SubmitParametersButtonPushed.\n');
    end

    % Store subID and icID
    app.subID = non_clust_sets;
    app.icID = non_clust_comps;

    % Log the stored information
    fprintf('Stored non-clustered STUDY parent cluster data:\n');
    fprintf('  Number of sets: %d\n', app.non_clust_sets_length);
    fprintf('  Number of comps: %d\n', app.non_clust_comps_length);
    fprintf('  Number of dipoles: %d\n', numDipoles);
    fprintf('  app.subID: %d\n', app.subID);
    fprintf('  app.icID: %d\n', app.subID);

    % Step 13: Extract and validate subject information from ALLEEG and STUDY.datasetinfo
    app.subjectNames_Section1 = cell(1, length(STUDY.datasetinfo));
    app.groupNames_Section1 = cell(1, length(STUDY.datasetinfo));
    app.subjectIDs_Section1 = zeros(1, length(STUDY.datasetinfo));

    for i = 1:length(STUDY.datasetinfo)
        alleeg_subject = ALLEEG(i).subject;
        alleeg_group = ALLEEG(i).group;
        alleeg_index = i;
        study_subject = STUDY.datasetinfo(i).subject;
        study_group = STUDY.datasetinfo(i).group;
        study_index = STUDY.datasetinfo(i).index;

        if ~strcmp(alleeg_subject, study_subject)
            errorMsg = sprintf('Error: Subject mismatch for dataset %d:\n  ALLEEG.subject: %s\n  STUDY.datasetinfo.subject: %s', ...
                i, alleeg_subject, study_subject);
            fprintf('%s\n', errorMsg);
            app.StatusLabel.Text = 'Error: Subject mismatch';
            app.ResultLabel.Text = 'ALLEEG and STUDY subjects do not match';
            return;
        end

        if ~strcmp(alleeg_group, study_group)
            errorMsg = sprintf('Error: Group mismatch for dataset %d:\n  ALLEEG.group: %s\n  STUDY.datasetinfo.group: %s', ...
                i, alleeg_group, study_group);
            fprintf('%s\n', errorMsg);
            app.StatusLabel.Text = 'Error: Group mismatch';
            app.ResultLabel.Text = 'ALLEEG and STUDY groups do not match';
            return;
        end

        if alleeg_index ~= study_index
            errorMsg = sprintf('Error: Index mismatch for dataset %d:\n  ALLEEG index: %d\n  STUDY.datasetinfo.index: %d', ...
                i, alleeg_index, study_index);
            fprintf('%s\n', errorMsg);
            app.StatusLabel.Text = 'Error: Index mismatch';
            app.ResultLabel.Text = 'ALLEEG and STUDY indices do not match';
            return;
        end

        app.subjectNames_Section1{i} = study_subject;
        app.groupNames_Section1{i} = study_group;
        app.subjectIDs_Section1(i) = study_index;
    end

    % Step 13.5: Recompute group ranges based on app.groupNames_Section1
    app.groupPrefixes = unique(app.groupNames_Section1, 'stable')';
    numGroups = length(app.groupPrefixes);
    groupRanges = cell(1, numGroups);
    startIdx = 1;
    for i = 1:numGroups
        groupMask = strcmp(app.groupNames_Section1, app.groupPrefixes{i});
        groupSubjectCount = sum(groupMask);
        endIdx = startIdx + groupSubjectCount - 1;
        groupRanges{i} = sprintf('%d:%d', startIdx, endIdx);
        startIdx = endIdx + 1;
    end

    % Step 14: Print subject information in the specified format
    fprintf('Subject Information from Non-Clustered STUDY:\n');
    for i = 1:length(STUDY.datasetinfo)
        fprintf('Group: %s Subject: %s SubjectID: %d\n', ...
            app.groupNames_Section1{i}, app.subjectNames_Section1{i}, app.subjectIDs_Section1(i));
    end

    % Step 15: Save the STUDY
    studyFilePath = fullfile(app.studyFilePath, 'non_clustered.study');
    try
        [STUDY, ALLEEG] = pop_savestudy(STUDY, ALLEEG, 'filename', studyFilePath);
    catch ME
        errorMsg = sprintf('Error saving STUDY: %s\nSave failed. Possible causes: insufficient disk space, lack of write permissions, or invalid file path (%s). Check permissions and free space.', ME.message, studyFilePath);
        fprintf('%s\n', errorMsg);
        uialert(app.UIFigure, sprintf('Error saving STUDY: %s', ME.message), 'STUDY Save Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error saving STUDY';
        app.ResultLabel.Text = 'Failed to save STUDY';
        return;
    end

    % Step 16: Store app properties
    app.STUDY = STUDY;
    app.ALLEEG = ALLEEG;
    app.detectedNumGroups = numGroups;
    app.groupPrefixes = groupNames';
    app.detectedSubjectsPerGroup = groupCounts';
    app.groupRanges = groupRanges;
    fprintf('Step 16 app.groupRanges: %s\n', strjoin(app.groupRanges, ', '));

    % Step 17: Update UI
    app.StatusLabel.Text = 'STUDY created successfully';
    app.ResultLabel.Text = sprintf('STUDY created with %d groups and %d datasets', numGroups, height(fileTable));
    fprintf('After setting StatusLabel - app.groupRanges: %s\n', strjoin(app.groupRanges, ', '));
    fprintf('After setting ResultLabel - app.groupRanges: %s\n', strjoin(app.groupRanges, ', '));

    % Print detailed confirmation
    totalSubjects = height(fileTable);
    fprintf('STUDY created successfully:\n');
    fprintf('  Number of groups: %d\n', numGroups);
    for i = 1:numGroups
        displayGroup = upper(groupNames{i});
        fprintf('  Group %s: %d subjects\n', displayGroup, groupCounts(i));
    end
    fprintf('  Total subjects: %d\n', totalSubjects);
    fprintf('  Saved in folder: %s\n', app.studyFilePath);

    % Enable the next set of UI elements
    app.NumberofGroupsDropDown.Enable = 'on';
    app.NumberofGroupsDropDownLabel.Enable = 'on';
    fprintf('After enabling NumberofGroupsDropDown - app.groupRanges: %s\n', strjoin(app.groupRanges, ', '));
    fprintf('After enabling NumberofGroupsDropDownLabel - app.groupRanges: %s\n', strjoin(app.groupRanges, ', '));

    disp('Fields in app before printing variables:');
    disp(properties(app));

    % Step 18: Print the 7 app variables for debugging
    fprintf('App variables at the end of SelectsetfilesfolderButtonPushed:\n');

    if isprop(app, 'non_clust_sets_length') && ~isempty(app.non_clust_sets_length)
        fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
    else
        fprintf('  app.non_clust_sets_length: Not set or empty\n');
    end

    if isprop(app, 'non_clust_comps_length') && ~isempty(app.non_clust_comps_length)
        fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
    else
        fprintf('  app.non_clust_comps_length: Not set or empty\n');
    end

    if isprop(app, 'subID') && ~isempty(app.subID)
        fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
    else
        fprintf('  app.subID: Not set or empty\n');
    end

    if isprop(app, 'icID') && ~isempty(app.icID)
        fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
    else
        fprintf('  app.icID: Not set or empty\n');
    end

    if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
        fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
    else
        fprintf('  app.subjectNames_Section1: Not set or empty\n');
    end

    if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
        fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
    else
        fprintf('  app.groupNames_Section1: Not set or empty\n');
    end

    if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
        fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));
    else
        fprintf('  app.subjectIDs_Section1: Not set or empty\n');
    end

    app.groupPrefixes = unique(app.groupNames_Section1, 'stable')';
    numGroups = length(app.groupPrefixes);
    groupRanges = cell(1, numGroups);
    startIdx = 1;
    for i = 1:numGroups
        groupMask = strcmp(app.groupNames_Section1, app.groupPrefixes{i});
        groupSubjectCount = sum(groupMask);
        endIdx = startIdx + groupSubjectCount - 1;
        groupRanges{i} = sprintf('%d:%d', startIdx, endIdx);
        startIdx = endIdx + 1;
    end
    app.groupRanges = groupRanges;
    fprintf('Final recomputed groupRanges before debug print: %s\n', strjoin(app.groupRanges, ', '));

    fprintf('Debug - groupNames_Section1: %s\n', strjoin(app.groupNames_Section1, ', '));
    fprintf('Debug - subjectNames_Section1: %s\n', strjoin(app.subjectNames_Section1, ', '));
    fprintf('Debug - subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
    fprintf('Debug - groupPrefixes: %s\n', strjoin(app.groupPrefixes, ', '));
    fprintf('Debug - groupRanges: %s\n', strjoin(app.groupRanges, ', '));
    fprintf('Debug - numGroups: %d\n', length(app.groupPrefixes));

    
        end

        % Value changed function: NumberofGroupsDropDown
        function NumberofGroupsDropDownValueChanged(app, event)
            
    
    % Value changed function: NumberofGroupsDropDown

    % Get the selected number of groups
    app.numGroups = str2double(app.NumberofGroupsDropDown.Value);
    fprintf('Selected Number of Groups: %d\n', app.numGroups);
    if ~ismember(app.numGroups, [1 2 3])
        app.StatusLabel.Text = 'Error: Invalid number of groups';
        app.ResultLabel.Text = 'Please select 1, 2, or 3 groups';
        uialert(app.UIFigure, 'Please select 1, 2, or 3 groups.', 'Validation Error', 'Icon', 'error');
        app.NumberofGroupsDropDown.Value = ' '; % Reset to default
        app.SubmitGroupRangesButton.Enable = 'off';
        return;
     end
    
    % Compare user-selected number of groups with app-detected number of groups
    if isprop(app, 'detectedNumGroups') && ~isempty(app.detectedNumGroups)
        if app.numGroups == app.detectedNumGroups
            fprintf('Number of groups match: User selected (%d) matches app detected (%d)\n', ...
                app.numGroups, app.detectedNumGroups);
            app.ResultLabel.Text = sprintf('Number of groups (%d) matches detected value', app.numGroups);
        else
            fprintf('Number of groups mismatch: User selected (%d) does not match app detected (%d)\n', ...
                app.numGroups, app.detectedNumGroups);
            app.StatusLabel.Text = 'Error: Group count mismatch';
            app.ResultLabel.Text = sprintf('Selected groups (%d) does not match detected (%d). Terminating.', ...
                app.numGroups, app.detectedNumGroups);
            uialert(app.UIFigure, ...
                sprintf(['The selected number of groups (%d) does not match the detected number of groups (%d).\n\n' ...
                        'Please check the .set files and ensure the correct number of groups is selected.\n' ...
                        'The app will terminate. Rectify the issue and restart.'], ...
                        app.numGroups, app.detectedNumGroups), ...
                'Group Mismatch Error', 'Icon', 'error');
            % Reset UI elements
            app.NumberofGroupsDropDown.Value = ' '; % Reset dropdown
            app.SubmitGroupRangesButton.Enable = 'off';
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Detection issue';
        app.ResultLabel.Text = 'App-detected number of groups not found. Terminating.';
        uialert(app.UIFigure, ...
            'App-detected number of groups not found. Please ensure Step 1 completed successfully. The app will terminate.', ...
            'Detection Error', 'Icon', 'error');
        % Reset UI elements
        app.NumberofGroupsDropDown.Value = ' '; % Reset dropdown
        app.SubmitGroupRangesButton.Enable = 'off';
        return;
    end

    % Update status
    app.StatusLabel.Text = sprintf('Number of groups selected: %d', app.numGroups);

    % Enable/disable subject range fields based on the number of groups
    switch app.numGroups
        case 1
            app.Group1SubjectRange.Enable = 'on';
            app.Group1SubjectRange.Editable = 'on';
            app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'on';
            app.Group2SubjectRange.Enable = 'off';
            app.Group2SubjectRange.Editable = 'off';
            app.Group2SubjectRangeEg1630Label.Enable = 'off';
            app.Group3SubjectRange.Enable = 'off';
            app.Group3SubjectRange.Editable = 'off';
            app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
        case 2
            app.Group1SubjectRange.Enable = 'on';
            app.Group1SubjectRange.Editable = 'on';
            app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'on';
            app.Group2SubjectRange.Enable = 'on';
            app.Group2SubjectRange.Editable = 'on';
            app.Group2SubjectRangeEg1630Label.Enable = 'on';
            app.Group3SubjectRange.Enable = 'off';
            app.Group3SubjectRange.Editable = 'off';
            app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
        case 3
            app.Group1SubjectRange.Enable = 'on';
            app.Group1SubjectRange.Editable = 'on';
            app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'on';
            app.Group2SubjectRange.Enable = 'on';
            app.Group2SubjectRange.Editable = 'on';
            app.Group2SubjectRangeEg1630Label.Enable = 'on';
            app.Group3SubjectRange.Enable = 'on';
            app.Group3SubjectRange.Editable = 'on';
            app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'on';
    end

    % Enable the submit button
    app.SubmitGroupRangesButton.Enable = 'on';

        end

        % Button pushed function: SubmitGroupRangesButton
        function SubmitGroupRangesButtonPushed(app, event)
 
            fprintf('Start of SubmitGroupRangesButtonPushed - app.groupRanges: ');
        for g = 1:app.numGroups
             fprintf('%s ', mat2str(app.groupRanges{g}));
        end
        fprintf('\n');
    
    % Button pushed function: SubmitGroupRangesButton

    % Update status
    app.StatusLabel.Text = 'Processing ranges...';

    % Validate ranges from UI input
    [isValid, groupRanges] = app.validateSubjectRanges();
    if ~isValid
        app.StatusLabel.Text = 'Error in range validation';
        app.ResultLabel.Text = 'Check the ranges and try again';
        return;
    end

    % Ensure groupRanges is a cell array of strings or numeric ranges
    if ~iscell(groupRanges) || isempty(groupRanges)
        app.StatusLabel.Text = 'Error: Invalid range format';
        app.ResultLabel.Text = 'Ranges must be provided as a cell array';
        uialert(app.UIFigure, ...
            'Ranges must be provided as a cell array (e.g., {"1:15", "16:30"}).', ...
            'Range Format Error', 'Icon', 'error');
        return;
    end

    % Convert string ranges to numeric arrays
    app.groupRanges = cell(1, app.numGroups); % Initialize as cell array for numeric ranges
    for g = 1:app.numGroups
        rangeInput = groupRanges{g}; % e.g., '1:15' or '1:13'
        
        % Handle string input
        if ischar(rangeInput) || isstring(rangeInput)
            try
                rangeTokens = strsplit(strtrim(rangeInput));
                if length(rangeTokens) > 1
                    warning('Multiple ranges detected in Group %d input: %s. Using the first range only.', g, rangeInput);
                    rangeInput = rangeTokens{1};
                end
                
                rangeParts = str2double(strsplit(rangeInput, ':'));
                if length(rangeParts) ~= 2 || any(isnan(rangeParts)) || rangeParts(1) > rangeParts(2)
                    error('Invalid range format');
                end
                startIdx = rangeParts(1);
                endIdx = rangeParts(2);
                app.groupRanges{g} = startIdx:endIdx; % Convert to numeric array
            catch ME
                app.StatusLabel.Text = 'Error: Invalid range format';
                app.ResultLabel.Text = sprintf('Range for Group %d is invalid: %s', g, ME.message);
                uialert(app.UIFigure, ...
                    sprintf('Invalid range format for Group %d: "%s". Please use the format "start:end" (e.g., "1:15").', ...
                    g, rangeInput), ...
                    'Range Format Error', 'Icon', 'error');
                return;
            end
        elseif isnumeric(rangeInput)
            if length(rangeInput) < 2 || rangeInput(1) > rangeInput(end)
                app.StatusLabel.Text = 'Error: Invalid range format';
                app.ResultLabel.Text = sprintf('Range for Group %d must be a valid numeric sequence', g);
                uialert(app.UIFigure, ...
                    sprintf('Range for Group %d must be a valid numeric sequence (e.g., 1:15).', g), ...
                    'Range Format Error', 'Icon', 'error');
                return;
            end
            app.groupRanges{g} = rangeInput;
        else
            app.StatusLabel.Text = 'Error: Invalid range format';
            app.ResultLabel.Text = sprintf('Range for Group %d must be a string or numeric array', g);
            uialert(app.UIFigure, ...
                sprintf('Range for Group %d must be a string (e.g., "1:15") or numeric array.', g), ...
                'Range Format Error', 'Icon', 'error');
            return;
        end

        % Validate range bounds against detected subjects
        maxSubjectID = max(app.subjectIDs_Section1); % e.g., 30
        if min(app.groupRanges{g}) < 1 || max(app.groupRanges{g}) > maxSubjectID
            app.StatusLabel.Text = 'Error: Range out of bounds';
            app.ResultLabel.Text = sprintf('Range for Group %d (%d:%d) exceeds available subjects (1:%d)', ...
                g, min(app.groupRanges{g}), max(app.groupRanges{g}), maxSubjectID);
            uialert(app.UIFigure, ...
                sprintf('Range for Group %d (%d:%d) is out of bounds (1:%d).', ...
                    g, min(app.groupRanges{g}), max(app.groupRanges{g}), maxSubjectID), ...
                'Range Bounds Error', 'Icon', 'error');
            return;
        end
    end

    % Debug the parsed ranges
    for g = 1:app.numGroups
        fprintf('app.groupRanges{%d} type: %s, value: %s\n', g, class(app.groupRanges{g}), mat2str(app.groupRanges{g}));
    end

    % Calculate the number of subjects per group from the user-entered ranges
    userSubjectsPerGroup = zeros(1, app.numGroups);
    for g = 1:app.numGroups
        range = app.groupRanges{g};
        userSubjectsPerGroup(g) = length(range); % Number of subjects = length of the range
    end

    % Compare user-entered number of subjects per group with app-detected values
    if isprop(app, 'detectedSubjectsPerGroup') && ~isempty(app.detectedSubjectsPerGroup)
        if length(userSubjectsPerGroup) == length(app.detectedSubjectsPerGroup)
            allMatch = true;
            for g = 1:app.numGroups
                userCount = userSubjectsPerGroup(g);
                detectedCount = app.detectedSubjectsPerGroup(g);
                % Use app.groupPrefixes for group name
                if isprop(app, 'groupPrefixes') && ~isempty(app.groupPrefixes)
                    groupName = upper(app.groupPrefixes{g});
                else
                    groupName = sprintf('Group %d', g);
                end
                fprintf('Group %d is named "%s" with %d subjects (detected by app).\n', ...
                    g, groupName, detectedCount);
                fprintf('User entered range for Group %d: %d:%d (%d subjects).\n', ...
                    g, min(app.groupRanges{g}), max(app.groupRanges{g}), userCount);
                if userCount == detectedCount
                    fprintf('Group %d: Subject count matches (%d subjects).\n', g, userCount);
                else
                    fprintf('Group %d: Subject count mismatch - User: %d, Detected: %d.\n', ...
                        g, userCount, detectedCount);
                    allMatch = false;
                end
           end
            if allMatch
                app.ResultLabel.Text = 'All subject counts match detected values';
            else
                app.StatusLabel.Text = 'Error: Subject count mismatch';
                app.ResultLabel.Text = 'Subject counts do not match detected values. Terminating.';
                uialert(app.UIFigure, ...
                    ['Some user-entered subject counts do not match the app-detected values.\n\n' ...
                     'Please check the .set files and ensure the entered ranges match the detected subject counts.\n' ...
                     'The app will terminate. Rectify the issue and restart.'], ...
                    'Subject Count Mismatch Error', 'Icon', 'error');
                app.NumberofGroupsDropDown.Value = '1';
                app.SubmitGroupRangesButton.Enable = 'off';
                app.Group1SubjectRange.Enable = 'off';
                app.Group1SubjectRange.Editable = 'off';
                app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';
                app.Group2SubjectRange.Enable = 'off';
                app.Group2SubjectRange.Editable = 'off';
                app.Group2SubjectRangeEg1630Label.Enable = 'off';
                app.Group3SubjectRange.Enable = 'off';
                app.Group3SubjectRange.Editable = 'off';
                app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
                return;
            end
        else
            fprintf('Mismatch in number of groups: User has %d groups, app detected %d groups.\n', ...
                length(userSubjectsPerGroup), length(app.detectedSubjectsPerGroup));
            app.StatusLabel.Text = 'Error: Group count mismatch';
            app.ResultLabel.Text = 'Number of groups does not match detected value. Terminating.';
            uialert(app.UIFigure, ...
                sprintf(['The number of groups (%d) does not match the detected number of groups (%d).\n\n' ...
                        'Please check the .set files and ensure the correct number of groups is selected.\n' ...
                        'The app will terminate. Rectify the issue and restart.'], ...
                        length(userSubjectsPerGroup), length(app.detectedSubjectsPerGroup)), ...
                'Group Mismatch Error', 'Icon', 'error');
            app.NumberofGroupsDropDown.Value = '1';
            app.SubmitGroupRangesButton.Enable = 'off';
            app.Group1SubjectRange.Enable = 'off';
            app.Group1SubjectRange.Editable = 'off';
            app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';
            app.Group2SubjectRange.Enable = 'off';
            app.Group2SubjectRange.Editable = 'off';
            app.Group2SubjectRangeEg1630Label.Enable = 'off';
            app.Group3SubjectRange.Enable = 'off';
            app.Group3SubjectRange.Editable = 'off';
            app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Detection issue';
        app.ResultLabel.Text = 'App-detected subject counts not found. Terminating.';
        uialert(app.UIFigure, ...
            'App-detected subject counts not found. Please ensure Step 1 completed successfully. The app will terminate.', ...
            'Detection Error', 'Icon', 'error');
        app.NumberofGroupsDropDown.Value = '1';
        app.SubmitGroupRangesButton.Enable = 'off';
        app.Group1SubjectRange.Enable = 'off';
        app.Group1SubjectRange.Editable = 'off';
        app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';
        app.Group2SubjectRange.Enable = 'off';
        app.Group2SubjectRange.Editable = 'off';
        app.Group2SubjectRangeEg1630Label.Enable = 'off';
        app.Group3SubjectRange.Enable = 'off';
        app.Group3SubjectRange.Editable = 'off';
        app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
        return;
    end

    % Update UI
    app.StatusLabel.Text = 'Subject ranges validated successfully';

    % Enable the next set of dropdowns
    app.FilteringusingResidualVarianceDropDown.Enable = 'on';
    app.FilteringusingResidualVarianceDropDownLabel.Enable = 'on';
    app.ClusteringMethodDropDown.Enable = 'on';
    app.ClusteringMethodDropDownLabel.Enable = 'on';
    app.StandardDeviationremoveoutliersDropDown.Enable = 'on';
    app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'on';

    % Debug final state of groupRanges
    fprintf('Final app.groupRanges: ');
    for g = 1:app.numGroups
        fprintf('%s ', mat2str(app.groupRanges{g}));
    end
    fprintf('\n');

    % Optional: Debug in string format
    fprintf('Final app.groupRanges (string format): ');
    for g = 1:app.numGroups
        fprintf('%d:%d ', min(app.groupRanges{g}), max(app.groupRanges{g}));
    end
    fprintf('\n');

   
        end

        % Value changed function: FilteringusingResidualVarianceDropDown
        function FilteringusingResidualVarianceDropDownValueChanged(app, event)
    
    % Store the selected residual variance as a decimal (e.g., 15% -> 0.15)
    value = str2double(app.FilteringusingResidualVarianceDropDown.Value);
if isnan(value) || value < 0 || value > 100
    app.StatusLabel.Text = 'Error: Invalid residual variance';
    app.ResultLabel.Text = 'Please select a value between 0 and 100%';
    uialert(app.UIFigure, 'Please select a value between 0 and 100%.', 'Validation Error', 'Icon', 'error');
    return;
end
app.residualVariance = value / 100;

    fprintf('Selected Residual Variance: %.0f%%\n', app.residualVariance * 100); 
    % Update status
    app.StatusLabel.Text = sprintf('Residual Variance selected: %.0f%%', app.residualVariance * 100);
 
        end

        % Value changed function: ClusteringMethodDropDown
        function ClusteringMethodDropDownValueChanged(app, event)


    % Store the selected clustering method
    app.clusteringMethod = app.ClusteringMethodDropDown.Value;
    fprintf('Selected Clustering Method: %s\n', app.clusteringMethod);      

    % Update status
    app.StatusLabel.Text = sprintf('Clustering Method selected: %s', app.clusteringMethod);

    % Default: enable Std. Dev. dropdown & label
    app.StandardDeviationremoveoutliersDropDown.Enable = 'on';
    app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'on';

    % By default, disable Submit button until Std. Dev is selected
    app.SubmitParametersButton.Enable = 'off';

    % Special Case: Neural Network or Affinity Propagation
    if strcmpi(app.clusteringMethod, 'Neural Network (stat. toolbox)') || ...
       strcmpi(app.clusteringMethod, 'Affinity Propagation')

        msg = sprintf(['⚠️ The selected clustering algorithm (%s) does NOT support outlier removal.\n\n', ...
                       'If you want to handle outliers, please choose one of these algorithms instead:\n', ...
                       '- Kmeans (stat. toolbox)\n- Kmeanscluster (no toolbox)\n- Optimal Kmeans'], ...
                       app.clusteringMethod);

        choice = uiconfirm(app.UIFigure, msg, 'Outlier Handling Restriction', ...
                           'Options', {'Continue with Current Algorithm','Select Different Algorithm'}, ...
                           'DefaultOption', 2, 'Icon', 'warning');

        if strcmp(choice, 'Continue with Current Algorithm')
            % Disable Std. Dev. dropdown since outliers not supported
            app.StandardDeviationremoveoutliersDropDown.Enable = 'off';
            app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'off';

            % Allow user to continue
            app.SubmitParametersButton.Enable = 'on';

        elseif strcmp(choice, 'Select Different Algorithm')
            % Reset both dropdowns to default values
            app.ClusteringMethodDropDown.Value = app.ClusteringMethodDropDown.Items{1};  % default method
            app.StandardDeviationremoveoutliersDropDown.Value = app.StandardDeviationremoveoutliersDropDown.Items{1}; % default Std. Dev.

            % Ensure both dropdowns are enabled
            app.ClusteringMethodDropDown.Enable = 'on';
            app.ClusteringMethodDropDownLabel.Enable = 'on';
            app.StandardDeviationremoveoutliersDropDown.Enable = 'on';
            app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'on';

            % Disable Submit until Std. Dev is selected
            app.SubmitParametersButton.Enable = 'off';
        end
    end

    % Additional case: user manually switches algorithm after choosing Continue
    % For k-means family algorithms, ensure Std. Dev. is enabled
    if any(strcmpi(app.clusteringMethod, {'Kmeans (stat. toolbox)', 'Kmeanscluster (no toolbox)', 'Optimal Kmeans'}))
        app.StandardDeviationremoveoutliersDropDown.Enable = 'on';
        app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'on';

        % Disable Submit until user picks Std. Dev
        app.SubmitParametersButton.Enable = 'off';
    end
  

        end

        % Value changed function: StandardDeviationremoveoutliersDropDown
        function StandardDeviationremoveoutliersDropDownValueChanged(app, event)

% Step 1: Get the dropdown value (string)
    selectedValue = app.StandardDeviationremoveoutliersDropDown.Value;
    
    % Step 2: Check if it's the string option or numeric
    if strcmp(selectedValue, 'No outlier removal')
        app.stdDevThreshold = 0;  % Use 0 internally to mean no removal
        fprintf('Selected: NO OUTLIER REMOVAL\n');
        app.StatusLabel.Text = 'Standard Deviation Threshold: No outlier removal';
    else
        % Convert string to number for numeric options
        app.stdDevThreshold = str2double(selectedValue);
        
        % Highlight if default value is selected
        if app.stdDevThreshold == 3
            fprintf('Selected Standard Deviation Threshold: %.0f (Default/Recommended)\n', app.stdDevThreshold);
            app.StatusLabel.Text = sprintf('Standard Deviation Threshold: %.0f (Default)', app.stdDevThreshold);
        else
            fprintf('Selected Standard Deviation Threshold: %.0f\n', app.stdDevThreshold);
            app.StatusLabel.Text = sprintf('Standard Deviation Threshold: %.0f', app.stdDevThreshold);
        end
    end
    
    app.SubmitParametersButton.Enable = 'on';            
        end

        % Button pushed function: SubmitParametersButton
        function SubmitParametersButtonPushed(app, event)

fprintf('Debug - groupPrefixes: %s\n', strjoin(app.groupPrefixes, ', '));
% Debug final state of groupRanges
    fprintf('Final app.groupRanges: ');
    for g = 1:app.numGroups
        fprintf('%s ', mat2str(app.groupRanges{g}));
    end
    fprintf('\n');

    % Optional: Debug in string format
    fprintf('Final app.groupRanges (string format): ');
    for g = 1:app.numGroups
        fprintf('%d:%d ', min(app.groupRanges{g}), max(app.groupRanges{g}));
    end
    fprintf('\n');
fprintf('Debug - numGroups: %d\n', length(app.groupPrefixes));  

eeglabPath = fileparts(which('eeglab'));
addpath(fullfile(eeglabPath, 'plugins', 'dipfit', 'standard_BEM'));

% Step 1: Update status and check if STUDY and ALLEEG are initialized
app.StatusLabel.Text = 'Processing parameters...';
if isempty(app.STUDY) || isempty(app.ALLEEG)
    uialert(app.UIFigure, 'STUDY or ALLEEG data is not loaded. Please load a STUDY file first.', 'Error', 'Icon', 'error');
    app.StatusLabel.Text = 'Error: Data not loaded';
    app.ResultLabel.Text = 'Load a STUDY file first';
    return;
end

% Step 2: Use the stored user inputs and validate parameters
rvThreshold = app.residualVariance; % Already in decimal form (e.g., 0.15 for 15%)
clusteringMethod = app.clusteringMethod;
stdDevThreshold = app.stdDevThreshold;

% Validate rvThreshold (should be between 0 and 1)
if rvThreshold <= 0 || rvThreshold >= 1
    uialert(app.UIFigure, ...
        sprintf('Invalid residual variance threshold (%.2f). Must be between 0 and 1. Please select a valid value and retry.', rvThreshold), ...
        'Invalid Parameter', 'Icon', 'error');
    app.StatusLabel.Text = 'Error: Invalid RV threshold';
    app.ResultLabel.Text = 'Residual variance must be between 0 and 1';
    return;
end

% Validate clustering method
validMethods = {'Kmeans (stat. toolbox)', 'Neural Network (stat. toolbox)', 'Kmeanscluster (no toolbox)', ...
                'Affinity Propagation', 'Optimal Kmeans'};
if ~ismember(clusteringMethod, validMethods)
    uialert(app.UIFigure, ...
        sprintf('Invalid clustering method: %s. Expected one of: %s.', clusteringMethod, strjoin(validMethods, ', ')), ...
        'Invalid Parameter', 'Icon', 'error');
    app.StatusLabel.Text = 'Error: Invalid clustering method';
    app.ResultLabel.Text = 'Select a valid clustering method';
    return;
end

% Map the clustering method to the format expected by pop_clust
switch clusteringMethod
    case 'Kmeans (stat. toolbox)'
        clustAlgo = 'kmeans';
    case 'Neural Network (stat. toolbox)'
        clustAlgo = 'neural network';
    case 'Kmeanscluster (no toolbox)'
        clustAlgo = 'kmeanscluster';
    case 'Affinity Propagation'
        clustAlgo = 'Affinity Propagation';
    case 'Optimal Kmeans'
        clustAlgo = 'optimal_kmeans'; % Corrected to use optimal_kmeans algorithm
    otherwise
        uialert(app.UIFigure, 'Unsupported clustering method selected.', 'Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error in clustering method';
        app.ResultLabel.Text = 'Unsupported clustering method';
        return;
end

clustAlgo = string(clustAlgo);  % <-- ADD THIS LINE
app.ClustAlgo = clustAlgo;

% Step 3: Count information before RV filtering
fprintf('Before RV filtering:\n');

% Number of datasets (four methods)
numDatasetsSets = length(unique(app.STUDY.cluster(1).sets));
numDatasetsALLEEG = length(app.ALLEEG);
numDatasetsInfo = length(app.STUDY.datasetinfo);
numValidDatasetsBefore = length(unique(app.STUDY.cluster(1).sets));
fprintf('  Number of datasets (unique sets): %d\n', numDatasetsSets);
fprintf('  Number of datasets (ALLEEG): %d\n', numDatasetsALLEEG);
fprintf('  Number of datasets (STUDY.datasetinfo): %d\n', numDatasetsInfo);
fprintf('  Number of valid datasets: %d\n', numValidDatasetsBefore);

% Check if all dataset counts match
if numDatasetsSets == numDatasetsALLEEG && numDatasetsALLEEG == numDatasetsInfo && numDatasetsInfo == numValidDatasetsBefore
    fprintf('  All dataset counts match.\n');
else
    fprintf('  Error: Dataset counts do not match before RV filtering: unique sets (%d), ALLEEG (%d), datasetinfo (%d), valid datasets (%d)\n', ...
        numDatasetsSets, numDatasetsALLEEG, numDatasetsInfo, numValidDatasetsBefore);
    app.StatusLabel.Text = 'Error: Dataset count mismatch before RV filtering';
    app.ResultLabel.Text = 'Dataset counts do not match before RV filtering. Terminating.';
    return;
end

% Number of dipoles (three methods)
numDipolesALLEEG = 0;
for i = 1:length(app.ALLEEG)
    if isfield(app.ALLEEG(i), 'dipfit') && isfield(app.ALLEEG(i).dipfit, 'model') && ~isempty(app.ALLEEG(i).dipfit.model)
        numDipolesALLEEG = numDipolesALLEEG + size(app.ALLEEG(i).dipfit.model, 2);
    end
end
numDipolesComps = size(app.STUDY.cluster(1).comps, 2);
numDipolesSets = size(app.STUDY.cluster(1).sets, 2);
fprintf('  Number of dipoles (via ALLEEG.dipfit.model): %d\n', numDipolesALLEEG);
fprintf('  Number of dipoles (comps): %d\n', numDipolesComps);
fprintf('  Number of dipoles (sets): %d\n', numDipolesSets);

% Check if all dipole counts match
if numDipolesALLEEG == numDipolesComps && numDipolesComps == numDipolesSets
    fprintf('  All dipole counts match.\n');
else
    fprintf('  Error: Dipole counts do not match before RV filtering: ALLEEG.dipfit.model (%d), comps (%d), sets (%d)\n', ...
        numDipolesALLEEG, numDipolesComps, numDipolesSets);
    app.StatusLabel.Text = 'Error: Dipole count mismatch before RV filtering';
    app.ResultLabel.Text = 'Dipole counts do not match before RV filtering. Terminating.';
    return;
end

non_clust_sets = app.STUDY.cluster(1).sets(:);
non_clust_comps = app.STUDY.cluster(1).comps(:);
app.non_clust_sets_length = length(non_clust_sets);
fprintf('  Updated app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
app.non_clust_comps_length = length(non_clust_comps);
fprintf('  Updated app.non_clust_comps_length: %d\n', app.non_clust_comps_length);

app.subID = non_clust_sets;
fprintf('  Updated app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
app.icID = non_clust_comps;
fprintf('  Updated app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));

fprintf('Updated non_clustered_updated STUDY parent cluster data:\n');
fprintf('  Number of sets: %d\n', app.non_clust_sets_length);
fprintf('  Number of comps: %d\n', app.non_clust_comps_length);


% Step 4: Apply RV filtering using pop_study with 'commands'
try
    [app.STUDY, app.ALLEEG] = pop_study(app.STUDY, app.ALLEEG, ...
        'commands', {'inbrain', 'on', 'dipselect', rvThreshold, 'return'});
    app.ResultLabel.Text = sprintf('RV filtering applied with threshold %.0f%%', rvThreshold * 100);
catch ME
    app.StatusLabel.Text = 'Error in RV filtering';
    app.ResultLabel.Text = ME.message;
    return;
end

% Step 5: Count information after RV filtering
fprintf('After RV filtering (RV <= %.0f%%):\n', rvThreshold*100);

% Number of datasets (four methods)
numDatasetsSets = length(unique(app.STUDY.cluster(1).sets));
numDatasetsALLEEG = length(app.ALLEEG);
numDatasetsInfo = length(app.STUDY.datasetinfo);
numValidDatasetsAfter = 0;
invalidDatasets = struct('index', {}, 'subject', {});
for i = 1:length(app.STUDY.datasetinfo)
    if isempty(app.STUDY.datasetinfo(i).comps)
        invalidEntry.index = i;
        invalidEntry.subject = app.STUDY.datasetinfo(i).subject;
        invalidDatasets(end+1) = invalidEntry;
    end
    if size(app.STUDY.datasetinfo(i).comps, 2) > 0
        numValidDatasetsAfter = numValidDatasetsAfter + 1;
    end
end
fprintf('  Number of datasets (unique sets): %d\n', numDatasetsSets);
fprintf('  Number of datasets (ALLEEG): %d\n', numDatasetsALLEEG);
fprintf('  Number of datasets (STUDY.datasetinfo): %d\n', numDatasetsInfo);
fprintf('  Number of valid datasets: %d\n', numValidDatasetsAfter);

% Check dataset counts and handle invalid datasets
datasetMismatch = ~(numDatasetsSets == numDatasetsALLEEG && numDatasetsALLEEG == numDatasetsInfo && numDatasetsInfo == numValidDatasetsAfter);

% Number of dipoles (three methods)
numDipolesDatasetInfo = 0;
invalidDipoleDatasets = struct('index', {}, 'subject', {});
for i = 1:length(app.STUDY.datasetinfo)
    numComps = size(app.STUDY.datasetinfo(i).comps, 2);
    numDipolesDatasetInfo = numDipolesDatasetInfo + numComps;
    if numComps == 0
        invalidEntry.index = i;
        invalidEntry.subject = app.STUDY.datasetinfo(i).subject;
        invalidDipoleDatasets(end+1) = invalidEntry;
    end
end
numDipolesComps = size(app.STUDY.cluster(1).comps, 2);
numDipolesSets = size(app.STUDY.cluster(1).sets, 2);
fprintf('  Number of dipoles (via STUDY.datasetinfo.comps): %d\n', numDipolesDatasetInfo);
fprintf('  Number of dipoles (comps): %d\n', numDipolesComps);
fprintf('  Number of dipoles (sets): %d\n', numDipolesSets);

% Check dipole counts
dipoleMismatch = ~(numDipolesDatasetInfo == numDipolesComps && numDipolesComps == numDipolesSets);

% Handle mismatches
if datasetMismatch || dipoleMismatch
    % Verify that invalid datasets match between dataset and dipole checks
    if length(invalidDatasets) == length(invalidDipoleDatasets)
        for i = 1:length(invalidDatasets)
            if invalidDatasets(i).index ~= invalidDipoleDatasets(i).index || ~strcmp(invalidDatasets(i).subject, invalidDipoleDatasets(i).subject)
                fprintf('  Error: Mismatch between invalid datasets and dipoles after RV filtering.\n');
                app.StatusLabel.Text = 'Error: Mismatch in invalid dataset detection';
                app.ResultLabel.Text = 'Inconsistent invalid dataset detection after RV filtering. Terminating.';
                return;
            end
        end
    else
        fprintf('  Error: Mismatch in number of invalid datasets after RV filtering: %d (datasets) vs %d (dipoles)\n', ...
            length(invalidDatasets), length(invalidDipoleDatasets));
        app.StatusLabel.Text = 'Error: Mismatch in invalid dataset count';
        app.ResultLabel.Text = 'Inconsistent number of invalid datasets after RV filtering. Terminating.';
        return;
    end

    % Print invalid datasets
    if ~isempty(invalidDatasets)
        fprintf('  Invalid datasets after RV filtering:\n');
        for i = 1:length(invalidDatasets)
            fprintf('    Dataset %d (Subject: %s)\n', invalidDatasets(i).index, invalidDatasets(i).subject);
        end
        fprintf('  Total invalid datasets: %d\n', length(invalidDatasets));
    end

    % Display user options modal
invalidList = '';
for i = 1:length(invalidDatasets)
    % Capitalize the group part of the subject label for display
    subjectLabel = invalidDatasets(i).subject; % e.g., bhs_01
    parts = split(subjectLabel, '_');
    if length(parts) >= 2
        group = upper(parts{1}); % Capitalize group (e.g., BHS)
        subjectNum = parts{2};
        displaySubject = sprintf('%s_%s', group, subjectNum); % e.g., BHS_01
    else
        displaySubject = subjectLabel; % Fallback if format is unexpected
    end
    invalidList = sprintf('%sDataset %d (Subject: %s)\n', invalidList, invalidDatasets(i).index, displaySubject);
end
msg = sprintf(['🚫 The following datasets are INVALID after RV filtering (empty components):\n\n' ...
               '%s\n' ...
               'Please choose how you want to proceed:'], invalidList);

choice = uiconfirm(app.UIFigure, msg, '⚠️ Invalid Datasets Detected', ...
    'Options', {'🔁 Increase RV Value', '🧹 Reconstruct STUDY'}, ...
    'DefaultOption', 1, ...
    'Icon', 'warning');  % Optional: 'warning', 'error', or 'success'

% 🔍 Debug output
disp(['User selected: ', choice]);

if strcmp(choice, '🔁 Increase RV Value')
    % Store the current RV value for the instruction
    currentRVPercent = rvThreshold * 100; % e.g., 15

    % Confirm with the user before proceeding, with updated instructions
    confirmChoice = uiconfirm(app.UIFigure, ...
        sprintf(['If you click Yes, the app will restart and then select RV > %.0f%% when the RV option is enabled this time.\n\n' ...
                 'Proceed?'], currentRVPercent), ...
        'Confirm Reset', ...
        'Options', {'Yes', 'No'}, ...
        'DefaultOption', 1, ...
        'Icon', 'info');
    
    % Debug output to confirm the choice
    disp(['Confirm choice selected: ', confirmChoice]);

    if strcmp(confirmChoice, 'Yes')
        % Clear app state (reset all properties to initial state)
        app.STUDY = [];
        app.ALLEEG = [];
        app.studyFilePath = ''; % Clear the folder path to prompt user selection
        % Reset UI elements to initial state and disable all options
        app.FilteringusingResidualVarianceDropDown.Value = app.FilteringusingResidualVarianceDropDown.Items{1};
        app.FilteringusingResidualVarianceDropDown.Enable = 'off';
        app.FilteringusingResidualVarianceDropDownLabel.Enable = 'off';
        app.ClusteringMethodDropDown.Value = app.ClusteringMethodDropDown.Items{1};
        app.ClusteringMethodDropDown.Enable = 'off';
        app.ClusteringMethodDropDownLabel.Enable = 'off';
        app.StandardDeviationremoveoutliersDropDown.Value = app.StandardDeviationremoveoutliersDropDown.Items{1};
        app.StandardDeviationremoveoutliersDropDown.Enable = 'off';
        app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'off';
        app.NumberofGroupsDropDown.Value = app.NumberofGroupsDropDown.Items{1};
        app.NumberofGroupsDropDown.Enable = 'off';
        app.NumberofGroupsDropDownLabel.Enable = 'off';
        app.Group1SubjectRange.Value = '';
        app.Group1SubjectRange.Enable = 'off';
        app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';
        app.Group2SubjectRange.Value = '';
        app.Group2SubjectRange.Enable = 'off';
        app.Group2SubjectRangeEg1630Label.Enable = 'off';
        app.Group3SubjectRange.Value = '';
        app.Group3SubjectRange.Enable = 'off';
        app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
        app.SubmitParametersButton.Enable = 'off';
        app.SubmitGroupRangesButton.Enable = 'off';
        app.StatusLabel.Text = 'Resetting...';
        app.ResultLabel.Text = 'App state cleared. Preparing to restart...';
        
        % Force UI update
        drawnow;

        % Display final alert to guide the user
        uialert(app.UIFigure, ...
            'The app has been reset. Please start by selecting a folder.', ...
            'App Reset', 'Icon', 'info');

        % Update status labels for the next step
        app.StatusLabel.Text = 'Action Required';
        app.ResultLabel.Text = 'Please start by selecting a folder to proceed.';
    else
        app.StatusLabel.Text = 'Action Cancelled';
        app.ResultLabel.Text = 'User cancelled the reset operation.';
    end

    return;
else
    % Reconstruct STUDY (existing code remains unchanged)

        try
            % Step 5.1: Identify and exclude invalid datasets
            invalidFiles = {};
            for i = 1:length(invalidDatasets)
                invalidFiles{end+1} = app.STUDY.datasetinfo(invalidDatasets(i).index).filename;
            end

            % Re-scan the folder and exclude invalid files
            allFiles = dir(fullfile(app.studyFilePath, '*.set'));
            validFiles = {};
            for i = 1:length(allFiles)
                if ~ismember(allFiles(i).name, invalidFiles)
                    validFiles{end+1} = fullfile(app.studyFilePath, allFiles(i).name);
                end
            end

            % Inform user about excluded datasets
            fprintf('Reconstructing STUDY by excluding invalid datasets:\n');
            for i = 1:length(invalidFiles)
                fprintf('  Excluded dataset: %s (invalid after RV filtering)\n', invalidFiles{i});
            end

            % Step 5.2: Sort valid files to match the initial study creation order
            % Extract group and subject number from filenames for sorting
            fileInfo = cell(length(validFiles), 3);
            excludedWords = {'data', 'epoched', 'set', 'subject', 'sub'};
            for i = 1:length(validFiles)
                [~, fname, ~] = fileparts(validFiles{i});
                fname = strrep(fname, ' ', '_');
                parts = split(fname, '_');
                parts = parts(~cellfun(@isempty, parts)); % Remove empty parts

                % Extract subject number
                subjectNumStr = regexp(fname, '\d+', 'match', 'once');
                if isempty(subjectNumStr)
                    error('Filename %s does not contain a subject number', fname);
                end
                subjectNum = str2double(subjectNumStr);

                % Find the subject number's position in parts
                subjectNumIdx = find(cellfun(@(x) strcmp(x, subjectNumStr), parts), 1);
                if isempty(subjectNumIdx)
                    error('Could not locate subject number in filename parts for %s', fname);
                end

                % Extract group
                group = '';
                for j = 1:(subjectNumIdx-1)
                    part = parts{j};
                    if regexp(part, '^[a-zA-Z]+$') && ~ismember(lower(part), excludedWords)
                        group = lower(part);
                        break;
                    end
                end
                if isempty(group)
                    error('Filename %s does not contain a valid group identifier', fname);
                end

                fileInfo{i, 1} = validFiles{i};
                fileInfo{i, 2} = group;
                fileInfo{i, 3} = subjectNum;
            end

            % Create a table for sorting
            fileSortTable = table(fileInfo(:,1), fileInfo(:,2), cell2mat(fileInfo(:,3)), ...
                'VariableNames', {'filename', 'group', 'subjectNum'});
            fileSortTable = sortrows(fileSortTable, {'group', 'subjectNum'});
            validFiles = fileSortTable.filename;

            % Step 5.3: Load valid datasets into ALLEEG
            ALLEEG = [];
            fileTable = table('Size', [length(validFiles), 5], ...
                'VariableTypes', {'string', 'string', 'double', 'string', 'double'}, ...
                'VariableNames', {'filename', 'group', 'groupIdx', 'subject', 'index'});
            groupPrefixes = {};
            for i = 1:length(validFiles)
                [~, fname, ~] = fileparts(validFiles{i});
                fname = strrep(fname, ' ', '_');
                parts = split(fname, '_');
                subjectNumStr = regexp(fname, '\d+', 'match', 'once');
                subjectNum = str2double(subjectNumStr);
                subjectNumIdx = find(cellfun(@(x) strcmp(x, subjectNumStr), parts), 1);
                group = '';
                for j = 1:(subjectNumIdx-1)
                    part = parts{j};
                    if regexp(part, '^[a-zA-Z]+$') && ~ismember(lower(part), excludedWords)
                        group = lower(part);
                        break;
                    end
                end
                subjectLabel = sprintf('%s_%02d', group, subjectNum);
                groupNum = find(strcmp(groupPrefixes, group));
                if isempty(groupNum)
                    groupPrefixes{end+1} = group;
                    groupNum = length(groupPrefixes);
                end

                % Load EEG dataset
                EEG = pop_loadset('filename', validFiles{i});
                EEG.subject = subjectLabel;
                EEG.group = group;
                EEG.setname = subjectLabel;
                ALLEEG = eeg_store(ALLEEG, EEG, i);

                % Populate fileTable
                fileTable.filename(i) = validFiles{i};
                fileTable.group(i) = group;
                fileTable.groupIdx(i) = groupNum;
                fileTable.subject(i) = subjectLabel;
                fileTable.index(i) = i;
            end

            % Step 5.4: Create the STUDY
            [STUDY, ALLEEG] = pop_study([], ALLEEG, 'name', 'non_clustered_updated');
            fprintf('After pop_study:\n');
            fprintf('  STUDY.cluster(1).sets (first 5 elements): %s\n', mat2str(STUDY.cluster(1).sets(1:min(5, length(STUDY.cluster(1).sets)))));
            fprintf('  STUDY.cluster(1).comps (first 5 elements): %s\n', mat2str(STUDY.cluster(1).comps(1:min(5, length(STUDY.cluster(1).comps)))));

            % Step 5.5: Update STUDY.datasetinfo.subject
            for i = 1:height(fileTable)
                STUDY.datasetinfo(i).subject = fileTable.subject{i};
                STUDY.datasetinfo(i).group = fileTable.group{i};
            end

            % Step 5.6: Create study design and precompute components
            STUDY = std_makedesign(STUDY, ALLEEG, 1, 'defaultdesign', 'on');
            [STUDY, ALLEEG] = std_precomp(STUDY, ALLEEG, 'components');
            fprintf('After std_precomp:\n');
            fprintf('  STUDY.cluster(1).sets (first 5 elements): %s\n', mat2str(STUDY.cluster(1).sets(1:min(5, length(STUDY.cluster(1).sets)))));
            fprintf('  STUDY.cluster(1).comps (first 5 elements): %s\n', mat2str(STUDY.cluster(1).comps(1:min(5, length(STUDY.cluster(1).comps)))));

            % Step 5.7: Update app variables (sets, comps, subID, icID)
            if ~isfield(STUDY, 'cluster') || isempty(STUDY.cluster) || ~isfield(STUDY.cluster(1), 'sets') || ~isfield(STUDY.cluster(1), 'comps')
                uialert(app.UIFigure, ...
                    'Parent cluster in non_clustered_updated STUDY is missing sets or comps after std_precomp.', ...
                    'STUDY Error', 'Icon', 'error');
                app.StatusLabel.Text = 'Error: Missing parent cluster data';
                app.ResultLabel.Text = 'Parent cluster lacks sets or comps in non_clustered_updated STUDY';
                return;
            end

            non_clust_sets = STUDY.cluster(1).sets(:);
            non_clust_comps = STUDY.cluster(1).comps(:);
            
            app.non_clust_sets_length = length(non_clust_sets);
            fprintf('  Updated app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
            app.non_clust_comps_length = length(non_clust_comps);
            fprintf('  Updated app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
            
            if app.non_clust_sets_length ~= app.non_clust_comps_length
                uialert(app.UIFigure, ...
                    sprintf('Mismatch in non_clustered_updated STUDY parent cluster: sets length (%d) does not match comps length (%d).', ...
                        app.non_clust_sets_length, app.non_clust_comps_length), ...
                    'STUDY Error', 'Icon', 'error');
                app.StatusLabel.Text = 'Error: Parent cluster data mismatch';
                app.ResultLabel.Text = 'Sets and comps lengths do not match in non_clustered_updated STUDY';
                return;
            end

            numDipoles = 0;
            for i = 1:length(ALLEEG)
                if isfield(ALLEEG(i), 'dipfit') && isfield(ALLEEG(i).dipfit, 'model') && ~isempty(ALLEEG(i).dipfit.model)
                    numDipoles = numDipoles + size(ALLEEG(i).dipfit.model, 2);
                else
                    fprintf('  Warning: ALLEEG(%d) is missing dipfit.model or it is empty.\n', i);
                end
            end

            if app.non_clust_sets_length ~= numDipoles || app.non_clust_comps_length ~= numDipoles
                fprintf('Warning: Mismatch in non_clustered_updated STUDY parent cluster data:\n');
                fprintf('  Sets length: %d\n', app.non_clust_sets_length);
                fprintf('  Comps length: %d\n', app.non_clust_comps_length);
                fprintf('  Number of dipoles: %d\n', numDipoles);
                fprintf('  Proceeding without terminating, as this validation may be rechecked later.\n');
            end

            app.subID = non_clust_sets;
            fprintf('  Updated app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
            app.icID = non_clust_comps;
            fprintf('  Updated app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));

            fprintf('Updated non_clustered_updated STUDY parent cluster data:\n');
            fprintf('  Number of sets: %d\n', app.non_clust_sets_length);
            fprintf('  Number of comps: %d\n', app.non_clust_comps_length);
            fprintf('  Number of dipoles: %d\n', numDipoles);

            % Step 5.8: Extract and validate subject information (local variables only)
            subjectNames_Section2 = cell(1, length(STUDY.datasetinfo));
            groupNames_Section2 = cell(1, length(STUDY.datasetinfo));
            subjectIDs_Section2 = zeros(1, length(STUDY.datasetinfo));

            for i = 1:length(STUDY.datasetinfo)
                alleeg_subject = ALLEEG(i).subject;
                alleeg_group = ALLEEG(i).group;
                alleeg_index = i;
                study_subject = STUDY.datasetinfo(i).subject;
                study_group = STUDY.datasetinfo(i).group;
                study_index = STUDY.datasetinfo(i).index;

                if ~strcmp(alleeg_subject, study_subject)
                    fprintf('Error: Subject mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
                    fprintf('  ALLEEG.subject: %s\n', alleeg_subject);
                    fprintf('  STUDY.datasetinfo.subject: %s\n', study_subject);
                    app.StatusLabel.Text = 'Error: Subject mismatch';
                    app.ResultLabel.Text = 'ALLEEG and STUDY subjects do not match in non_clustered_updated STUDY';
                    return;
                end

                if ~strcmp(alleeg_group, study_group)
                    fprintf('Error: Group mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
                    fprintf('  ALLEEG.group: %s\n', alleeg_group);
                    fprintf('  STUDY.datasetinfo.group: %s\n', study_group);
                    app.StatusLabel.Text = 'Error: Group mismatch';
                    app.ResultLabel.Text = 'ALLEEG and STUDY groups do not match in non_clustered_updated STUDY';
                    return;
                end

                if alleeg_index ~= study_index
                    fprintf('Error: Index mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
                    fprintf('  ALLEEG index: %d\n', alleeg_index);
                    fprintf('  STUDY.datasetinfo.index: %d\n', study_index);
                    app.StatusLabel.Text = 'Error: Index mismatch';
                    app.ResultLabel.Text = 'ALLEEG and STUDY indices do not match in non_clustered_updated STUDY';
                    return;
                end

                subjectNames_Section2{i} = study_subject;
                groupNames_Section2{i} = study_group;
                subjectIDs_Section2(i) = study_index;
            end

            % Step 5.9: Calculate group ranges and collect subject names
            groupCounts = groupcounts(fileTable, 'group');
            groupRanges = cell(1, length(groupPrefixes));
            groupSubjects = cell(1, length(groupPrefixes));
            startIdx = 1;
            for i = 1:length(groupPrefixes)
                groupMask = strcmp(fileTable.group, groupPrefixes{i});
                groupFileCount = sum(groupMask);
                endIdx = startIdx + groupFileCount - 1;
                groupRanges{i} = sprintf('%d:%d', startIdx, endIdx);
                groupSubjects{i} = fileTable.subject(groupMask);
                startIdx = endIdx + 1;
            end
fprintf('Step 5.9 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 5.10: Save the new STUDY
            studyFilePath = fullfile(app.studyFilePath, 'non_clustered_updated.study');
            [STUDY, ALLEEG] = pop_savestudy(STUDY, ALLEEG, 'filename', studyFilePath);
fprintf('Step 5.10 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 5.11: Update app properties
            app.STUDY = STUDY;
            app.ALLEEG = ALLEEG;
            app.detectedNumGroups = length(groupPrefixes);
            app.groupPrefixes = groupPrefixes;
            app.detectedSubjectsPerGroup = groupCounts.GroupCount';
            app.groupRanges = groupRanges;
fprintf('Step 5.11 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 5.12: Print detailed information after reconstruction
            fprintf('STUDY reconstructed successfully (non_clustered_updated.study):\n');
            fprintf('  Number of groups: %d\n', length(groupPrefixes));
            totalSubjects = height(fileTable);
            for i = 1:length(groupPrefixes)
                displayGroup = upper(groupPrefixes{i});
                fprintf('  Group %s: %d subjects\n', displayGroup, groupCounts.GroupCount(i));
            end
            fprintf('  Total subjects: %d\n', totalSubjects);
            fprintf('  Saved in folder: %s\n', app.studyFilePath);

            fprintf('Subject Information from Non-Clustered Updated STUDY:\n');
            for i = 1:length(STUDY.datasetinfo)
                fprintf('Group: %s Subject: %s SubjectID: %d\n', ...
                    groupNames_Section2{i}, subjectNames_Section2{i}, subjectIDs_Section2(i));
            end

            fprintf('App variables after STUDY reconstruction:\n');
            if isprop(app, 'non_clust_sets_length') && ~isempty(app.non_clust_sets_length)
                fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
            else
                fprintf('  app.non_clust_sets_length: Not set or empty\n');
            end
            if isprop(app, 'non_clust_comps_length') && ~isempty(app.non_clust_comps_length)
                fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
            else
                fprintf('  app.non_clust_comps_length: Not set or empty\n');
            end
            if isprop(app, 'subID') && ~isempty(app.subID)
                fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
            else
                fprintf('  app.subID: Not set or empty\n');
            end
            if isprop(app, 'icID') && ~isempty(app.icID)
                fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
            else
                fprintf('  app.icID: Not set or empty\n');
            end
            if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
                fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
            else
                fprintf('  app.subjectNames_Section1: Not set or empty\n');
            end
            if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
                fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
            else
                fprintf('  app.groupNames_Section1: Not set or empty\n');
            end
            if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
                fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));
            else
                fprintf('  app.subjectIDs_Section1: Not set or empty\n');
            end
fprintf('Step 5.12 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 5.13: Automatically re-run SubmitParametersButtonPushed
            app.SubmitParametersButtonPushed();
            return;
        catch ME
            fprintf('  Error reconstructing STUDY: %s\n', ME.message);
            app.StatusLabel.Text = 'Error reconstructing STUDY';
            app.ResultLabel.Text = ME.message;
            return;
        end
    end
else
    fprintf('  All dataset counts match.\n');
    fprintf('  All dipole counts match.\n');
end

% non_clust_sets = app.STUDY.cluster(1).sets(:);
% non_clust_comps = app.STUDY.cluster(1).comps(:);
% 
% app.non_clust_sets_length = length(non_clust_sets);
% fprintf('  Updated app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
% app.non_clust_comps_length = length(non_clust_comps);
% fprintf('  Updated app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
% 
% app.subID = non_clust_sets;
% fprintf('  Updated app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
% app.icID = non_clust_comps;
% fprintf('  Updated app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));

fprintf('Updated non_clustered_updated STUDY parent cluster data:\n');
fprintf('  Number of sets: %d\n', app.non_clust_sets_length);
fprintf('  Number of comps: %d\n', app.non_clust_comps_length);
fprintf('  Number of dipoles: %d\n', numDipolesDatasetInfo);


% Step 6: Pre-clustering with std_preclust
fprintf('Performing pre-clustering...\n');
if isempty(app.STUDY.cluster(1).comps)
    fprintf('  Warning: app.STUDY.cluster(1).comps is empty. Proceeding with pre-clustering, but results may be invalid.\n');
end
try
    [app.STUDY, app.ALLEEG] = std_preclust(app.STUDY, app.ALLEEG, 1, {'dipoles', 'norm', 1, 'weight', 1});
    app.ResultLabel.Text = 'Pre-clustering completed successfully';
catch ME
    app.StatusLabel.Text = 'Error in pre-clustering';
    app.ResultLabel.Text = ME.message;
    return;
end

% Step 7: Verify pre-clustering
fprintf('Verifying pre-clustering:\n');
% Check number of components being pre-clustered
numComponentsPreclust = size(app.STUDY.cluster(1).comps, 2);
fprintf('  Number of components pre-clustered: %d\n', numComponentsPreclust);

% Check pre-clustering data in the correct field
if isfield(app.STUDY, 'etc') && isfield(app.STUDY.etc, 'preclust') && isfield(app.STUDY.etc.preclust, 'preclustdata')
    preclustDataSize = size(app.STUDY.etc.preclust.preclustdata);
    fprintf('  Size of etc.preclust.preclustdata: %d x %d\n', preclustDataSize(1), preclustDataSize(2));
else
    fprintf('  Warning: etc.preclust.preclustdata not found.\n');
    if isfield(app.STUDY, 'etc') && isfield(app.STUDY.etc, 'preclust')
        fprintf('  Contents of app.STUDY.etc.preclust:\n');
        disp(app.STUDY.etc.preclust);
    else
        fprintf('  Warning: app.STUDY.etc.preclust not found.\n');
    end
end

% Check dipole locations for validity
fprintf('  Checking dipole locations for validity:\n');
uniqueSets = unique(app.STUDY.cluster(1).sets);
for i = 1:length(uniqueSets)
    setIdx = uniqueSets(i);
    compIndices = app.STUDY.cluster(1).comps(app.STUDY.cluster(1).sets == setIdx);
    for j = 1:length(compIndices)
        compIdx = compIndices(j);
        try
            if ~isfield(app.ALLEEG(setIdx).dipfit, 'model') || length(app.ALLEEG(setIdx).dipfit.model) < compIdx
                fprintf('    Warning: dipfit.model missing or insufficient for Dataset %d, Component %d.\n', setIdx, compIdx);
                continue;
            end
            posxyz = app.ALLEEG(setIdx).dipfit.model(compIdx).posxyz;
            fprintf('    Dataset %d, Component %d: posxyz = %s\n', setIdx, compIdx, mat2str(posxyz));
            if any(isnan(posxyz)) || any(isinf(posxyz))
                fprintf('    Warning: NaN or Inf in posxyz for Dataset %d, Component %d\n', setIdx, compIdx);
            end
        catch err
            fprintf('    Error accessing posxyz for Dataset %d, Component %d: %s\n', setIdx, compIdx, err.message);
        end
    end
end

% Step 8: Count information after pre-clustering
fprintf('After pre-clustering:\n');

% Number of datasets (four methods)
numDatasetsSets = length(unique(app.STUDY.cluster(1).sets));
numDatasetsALLEEG = length(app.ALLEEG);
numDatasetsInfo = length(app.STUDY.datasetinfo);
numValidDatasetsAfterPreclust = 0;
invalidDatasets = struct('index', {}, 'subject', {});
for i = 1:length(app.STUDY.datasetinfo)
    if isempty(app.STUDY.datasetinfo(i).comps)
        invalidEntry.index = i;
        invalidEntry.subject = app.STUDY.datasetinfo(i).subject;
        invalidDatasets(end+1) = invalidEntry;
    end
    if size(app.STUDY.datasetinfo(i).comps, 2) > 0
        numValidDatasetsAfterPreclust = numValidDatasetsAfterPreclust + 1;
    end
end
fprintf('  Number of datasets (unique sets): %d\n', numDatasetsSets);
fprintf('  Number of datasets (ALLEEG): %d\n', numDatasetsALLEEG);
fprintf('  Number of datasets (STUDY.datasetinfo): %d\n', numDatasetsInfo);
fprintf('  Number of valid datasets: %d\n', numValidDatasetsAfterPreclust);

% Check dataset counts
datasetMismatch = ~(numDatasetsSets == numDatasetsALLEEG && numDatasetsALLEEG == numDatasetsInfo && numDatasetsInfo == numValidDatasetsAfterPreclust);

% Number of dipoles (three methods)
numDipolesDatasetInfo = 0;
invalidDipoleDatasets = struct('index', {}, 'subject', {});
for i = 1:length(app.STUDY.datasetinfo)
    numComps = size(app.STUDY.datasetinfo(i).comps, 2);
    numDipolesDatasetInfo = numDipolesDatasetInfo + numComps;
    if numComps == 0
        invalidEntry.index = i;
        invalidEntry.subject = app.STUDY.datasetinfo(i).subject;
        invalidDipoleDatasets(end+1) = invalidEntry;
    end
end
numDipolesComps = size(app.STUDY.cluster(1).comps, 2);
numDipolesSets = size(app.STUDY.cluster(1).sets, 2);
fprintf('  Number of dipoles (via STUDY.datasetinfo.comps): %d\n', numDipolesDatasetInfo);
fprintf('  Number of dipoles (comps): %d\n', numDipolesComps);
fprintf('  Number of dipoles (sets): %d\n', numDipolesSets);

% Check dipole counts
dipoleMismatch = ~(numDipolesDatasetInfo == numDipolesComps && numDipolesComps == numDipolesSets);

% Extract and validate subject information after pre-clustering
subjectNames_Section2 = cell(1, length(app.STUDY.datasetinfo));
groupNames_Section2 = cell(1, length(app.STUDY.datasetinfo));
subjectIDs_Section2 = zeros(1, length(app.STUDY.datasetinfo));

for i = 1:length(app.STUDY.datasetinfo)
    alleeg_subject = app.ALLEEG(i).subject;
    alleeg_group = app.ALLEEG(i).group;
    alleeg_index = i;
    study_subject = app.STUDY.datasetinfo(i).subject;
    study_group = app.STUDY.datasetinfo(i).group;
    study_index = app.STUDY.datasetinfo(i).index;

    if ~strcmp(alleeg_subject, study_subject)
        fprintf('Error: Subject mismatch for dataset %d after pre-clustering:\n', i);
        fprintf('  ALLEEG.subject: %s\n', alleeg_subject);
        fprintf('  STUDY.datasetinfo.subject: %s\n', study_subject);
        app.StatusLabel.Text = 'Error: Subject mismatch';
        app.ResultLabel.Text = 'ALLEEG and STUDY subjects do not match after pre-clustering';
        return;
    end

    if ~strcmp(alleeg_group, study_group)
        fprintf('Error: Group mismatch for dataset %d after pre-clustering:\n', i);
        fprintf('  ALLEEG.group: %s\n', alleeg_group);
        fprintf('  STUDY.datasetinfo.group: %s\n', study_group);
        app.StatusLabel.Text = 'Error: Group mismatch';
        app.ResultLabel.Text = 'ALLEEG and STUDY groups do not match after pre-clustering';
        return;
    end

    if alleeg_index ~= study_index
        fprintf('Error: Index mismatch for dataset %d after pre-clustering:\n', i);
        fprintf('  ALLEEG index: %d\n', alleeg_index);
        fprintf('  STUDY.datasetinfo.index: %d\n', study_index);
        app.StatusLabel.Text = 'Error: Index mismatch';
        app.ResultLabel.Text = 'ALLEEG and STUDY indices do not match after pre-clustering';
        return;
    end

    subjectNames_Section2{i} = study_subject;
    groupNames_Section2{i} = study_group;
    subjectIDs_Section2(i) = study_index;
end

% Handle mismatches
if datasetMismatch || dipoleMismatch
    % Verify that invalid datasets match between dataset and dipole checks
    if length(invalidDatasets) == length(invalidDipoleDatasets)
        for i = 1:length(invalidDatasets)
            if invalidDatasets(i).index ~= invalidDipoleDatasets(i).index || ~strcmp(invalidDatasets(i).subject, invalidDipoleDatasets(i).subject)
                fprintf('  Error: Mismatch between invalid datasets and dipoles after pre-clustering.\n');
                app.StatusLabel.Text = 'Error: Mismatch in invalid dataset detection';
                app.ResultLabel.Text = 'Inconsistent invalid dataset detection after pre-clustering. Terminating.';
                return;
            end
        end
    else
        fprintf('  Error: Mismatch in number of invalid datasets after pre-clustering: %d (datasets) vs %d (dipoles)\n', ...
            length(invalidDatasets), length(invalidDipoleDatasets));
        app.StatusLabel.Text = 'Error: Mismatch in invalid dataset count';
        app.ResultLabel.Text = 'Inconsistent number of invalid datasets after pre-clustering. Terminating.';
        return;
    end

    % Print invalid datasets
    if ~isempty(invalidDatasets)
        fprintf('  Invalid datasets after pre-clustering:\n');
        for i = 1:length(invalidDatasets)
            fprintf('    Dataset %d (Subject: %s)\n', invalidDatasets(i).index, invalidDatasets(i).subject);
        end
        fprintf('  Total invalid datasets: %d\n', length(invalidDatasets));
    end

     % Display user options modal
invalidList = '';
for i = 1:length(invalidDatasets)
    % Capitalize the group part of the subject label for display
    subjectLabel = invalidDatasets(i).subject; % e.g., bhs_01
    parts = split(subjectLabel, '_');
    if length(parts) >= 2
        group = upper(parts{1}); % Capitalize group (e.g., BHS)
        subjectNum = parts{2};
        displaySubject = sprintf('%s_%s', group, subjectNum); % e.g., BHS_01
    else
        displaySubject = subjectLabel; % Fallback if format is unexpected
    end
    invalidList = sprintf('%sDataset %d (Subject: %s)\n', invalidList, invalidDatasets(i).index, displaySubject);
end
msg = sprintf(['🚫 The following datasets are INVALID after RV filtering (empty components):\n\n' ...
               '%s\n' ...
               'Please choose how you want to proceed:'], invalidList);

choice = uiconfirm(app.UIFigure, msg, '⚠️ Invalid Datasets Detected', ...
    'Options', {'🔁 Increase RV Value', '🧹 Reconstruct STUDY'}, ...
    'DefaultOption', 1, ...
    'Icon', 'warning');  % Optional: 'warning', 'error', or 'success'

% 🔍 Debug output
disp(['User selected: ', choice]);

if strcmp(choice, '🔁 Increase RV Value')
    % Store the current RV value for the instruction
    currentRVPercent = rvThreshold * 100; % e.g., 15

    % Confirm with the user before proceeding, with updated instructions
    confirmChoice = uiconfirm(app.UIFigure, ...
        sprintf(['If you click Yes, the app will restart and then select RV > %.0f%% when the RV option is enabled this time.\n\n' ...
                 'Proceed?'], currentRVPercent), ...
        'Confirm Reset', ...
        'Options', {'Yes', 'No'}, ...
        'DefaultOption', 1, ...
        'Icon', 'info');
    
    % Debug output to confirm the choice
    disp(['Confirm choice selected: ', confirmChoice]);

    if strcmp(confirmChoice, 'Yes')
        % Clear app state (reset all properties to initial state)
        app.STUDY = [];
        app.ALLEEG = [];
        app.studyFilePath = ''; % Clear the folder path to prompt user selection
        % Reset UI elements to initial state and disable all options
        app.FilteringusingResidualVarianceDropDown.Value = app.FilteringusingResidualVarianceDropDown.Items{1};
        app.FilteringusingResidualVarianceDropDown.Enable = 'off';
        app.FilteringusingResidualVarianceDropDownLabel.Enable = 'off';
        app.ClusteringMethodDropDown.Value = app.ClusteringMethodDropDown.Items{1};
        app.ClusteringMethodDropDown.Enable = 'off';
        app.ClusteringMethodDropDownLabel.Enable = 'off';
        app.StandardDeviationremoveoutliersDropDown.Value = app.StandardDeviationremoveoutliersDropDown.Items{1};
        app.StandardDeviationremoveoutliersDropDown.Enable = 'off';
        app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'off';
        app.NumberofGroupsDropDown.Value = app.NumberofGroupsDropDown.Items{1};
        app.NumberofGroupsDropDown.Enable = 'off';
        app.NumberofGroupsDropDownLabel.Enable = 'off';
        app.Group1SubjectRange.Value = '';
        app.Group1SubjectRange.Enable = 'off';
        app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';
        app.Group2SubjectRange.Value = '';
        app.Group2SubjectRange.Enable = 'off';
        app.Group2SubjectRangeEg1630Label.Enable = 'off';
        app.Group3SubjectRange.Value = '';
        app.Group3SubjectRange.Enable = 'off';
        app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
        app.SubmitParametersButton.Enable = 'off';
        app.SubmitGroupRangesButton.Enable = 'off';
        app.StatusLabel.Text = 'Resetting...';
        app.ResultLabel.Text = 'App state cleared. Preparing to restart...';
        
        % Force UI update
        drawnow;

        % Display final alert to guide the user
        uialert(app.UIFigure, ...
            'The app has been reset. Please start by selecting a folder.', ...
            'App Reset', 'Icon', 'info');

        % Update status labels for the next step
        app.StatusLabel.Text = 'Action Required';
        app.ResultLabel.Text = 'Please start by selecting a folder to proceed.';
    else
        app.StatusLabel.Text = 'Action Cancelled';
        app.ResultLabel.Text = 'User cancelled the reset operation.';
    end

    return;
else
    % Reconstruct STUDY (existing code remains unchanged)
        try
            % Step 8.1: Identify and exclude invalid datasets
            invalidFiles = {};
            for i = 1:length(invalidDatasets)
                invalidFiles{end+1} = app.STUDY.datasetinfo(invalidDatasets(i).index).filename;
            end

            % Re-scan the folder and exclude invalid files
            allFiles = dir(fullfile(app.studyFilePath, '*.set'));
            validFiles = {};
            for i = 1:length(allFiles)
                if ~ismember(allFiles(i).name, invalidFiles)
                    validFiles{end+1} = fullfile(app.studyFilePath, allFiles(i).name);
                end
            end

            % Inform user about excluded datasets
            fprintf('Reconstructing STUDY by excluding invalid datasets:\n');
            for i = 1:length(invalidFiles)
                fprintf('  Excluded dataset: %s (invalid after pre-clustering)\n', invalidFiles{i});
            end

            % Step 8.2: Sort valid files to match the initial study creation order
            fileInfo = cell(length(validFiles), 3);
            excludedWords = {'data', 'epoched', 'set', 'subject', 'sub'};
            for i = 1:length(validFiles)
                [~, fname, ~] = fileparts(validFiles{i});
                fname = strrep(fname, ' ', '_');
                parts = split(fname, '_');
                parts = parts(~cellfun(@isempty, parts));

                subjectNumStr = regexp(fname, '\d+', 'match', 'once');
                if isempty(subjectNumStr)
                    error('Filename %s does not contain a subject number', fname);
                end
                subjectNum = str2double(subjectNumStr);

                subjectNumIdx = find(cellfun(@(x) strcmp(x, subjectNumStr), parts), 1);
                if isempty(subjectNumIdx)
                    error('Could not locate subject number in filename parts for %s', fname);
                end

                group = '';
                for j = 1:(subjectNumIdx-1)
                    part = parts{j};
                    if regexp(part, '^[a-zA-Z]+$') && ~ismember(lower(part), excludedWords)
                        group = lower(part);
                        break;
                    end
                end
                if isempty(group)
                    error('Filename %s does not contain a valid group identifier', fname);
                end

                fileInfo{i, 1} = validFiles{i};
                fileInfo{i, 2} = group;
                fileInfo{i, 3} = subjectNum;
            end

            fileSortTable = table(fileInfo(:,1), fileInfo(:,2), cell2mat(fileInfo(:,3)), ...
                'VariableNames', {'filename', 'group', 'subjectNum'});
            fileSortTable = sortrows(fileSortTable, {'group', 'subjectNum'});
            validFiles = fileSortTable.filename;

            % Step 8.3: Load valid datasets into ALLEEG
            ALLEEG = [];
            fileTable = table('Size', [length(validFiles), 5], ...
                'VariableTypes', {'string', 'string', 'double', 'string', 'double'}, ...
                'VariableNames', {'filename', 'group', 'groupIdx', 'subject', 'index'});
            groupPrefixes = {};
            for i = 1:length(validFiles)
                [~, fname, ~] = fileparts(validFiles{i});
                fname = strrep(fname, ' ', '_');
                parts = split(fname, '_');
                subjectNumStr = regexp(fname, '\d+', 'match', 'once');
                subjectNum = str2double(subjectNumStr);
                subjectNumIdx = find(cellfun(@(x) strcmp(x, subjectNumStr), parts), 1);
                group = '';
                for j = 1:(subjectNumIdx-1)
                    part = parts{j};
                    if regexp(part, '^[a-zA-Z]+$') && ~ismember(lower(part), excludedWords)
                        group = lower(part);
                        break;
                    end
                end
                subjectLabel = sprintf('%s_%02d', group, subjectNum);
                groupNum = find(strcmp(groupPrefixes, group));
                if isempty(groupNum)
                    groupPrefixes{end+1} = group;
                    groupNum = length(groupPrefixes);
                end

                EEG = pop_loadset('filename', validFiles{i});
                EEG.subject = subjectLabel;
                EEG.group = group;
                EEG.setname = subjectLabel;
                ALLEEG = eeg_store(ALLEEG, EEG, i);

                fileTable.filename(i) = validFiles{i};
                fileTable.group(i) = group;
                fileTable.groupIdx(i) = groupNum;
                fileTable.subject(i) = subjectLabel;
                fileTable.index(i) = i;
            end

            % Step 8.4: Create the STUDY
            [STUDY, ALLEEG] = pop_study([], ALLEEG, 'name', 'non_clustered_updated');
            fprintf('After pop_study in reconstruction:\n');
            fprintf('  STUDY.cluster(1).sets (first 5 elements): %s\n', mat2str(STUDY.cluster(1).sets(1:min(5, length(STUDY.cluster(1).sets)))));
            fprintf('  STUDY.cluster(1).comps (first 5 elements): %s\n', mat2str(STUDY.cluster(1).comps(1:min(5, length(STUDY.cluster(1).comps)))));

            % Step 8.5: Update STUDY.datasetinfo.subject
            for i = 1:height(fileTable)
                STUDY.datasetinfo(i).subject = fileTable.subject{i};
                STUDY.datasetinfo(i).group = fileTable.group{i};
            end

            % Step 8.6: Create study design and precompute components
            STUDY = std_makedesign(STUDY, ALLEEG, 1, 'defaultdesign', 'on');
            [STUDY, ALLEEG] = std_precomp(STUDY, ALLEEG, 'components');
            fprintf('After std_precomp in reconstruction:\n');
            fprintf('  STUDY.cluster(1).sets (first 5 elements): %s\n', mat2str(STUDY.cluster(1).sets(1:min(5, length(STUDY.cluster(1).sets)))));
            fprintf('  STUDY.cluster(1).comps (first 5 elements): %s\n', mat2str(STUDY.cluster(1).comps(1:min(5, length(STUDY.cluster(1).comps)))));

            % Step 8.7: Update app variables (sets, comps, subID, icID)
            if ~isfield(STUDY, 'cluster') || isempty(STUDY.cluster) || ~isfield(STUDY.cluster(1), 'sets') || ~isfield(STUDY.cluster(1), 'comps')
                uialert(app.UIFigure, ...
                    'Parent cluster in non_clustered_updated STUDY is missing sets or comps after std_precomp.', ...
                    'STUDY Error', 'Icon', 'error');
                app.StatusLabel.Text = 'Error: Missing parent cluster data';
                app.ResultLabel.Text = 'Parent cluster lacks sets or comps in non_clustered_updated STUDY';
                return;
            end

            non_clust_sets = STUDY.cluster(1).sets(:);
            non_clust_comps = STUDY.cluster(1).comps(:);
            
            app.non_clust_sets_length = length(non_clust_sets);
            fprintf('  Updated app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
            app.non_clust_comps_length = length(non_clust_comps);
            fprintf('  Updated app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
            
            if app.non_clust_sets_length ~= app.non_clust_comps_length
                uialert(app.UIFigure, ...
                    sprintf('Mismatch in non_clustered_updated STUDY parent cluster: sets length (%d) does not match comps length (%d).', ...
                        app.non_clust_sets_length, app.non_clust_comps_length), ...
                    'STUDY Error', 'Icon', 'error');
                app.StatusLabel.Text = 'Error: Parent cluster data mismatch';
                app.ResultLabel.Text = 'Sets and comps lengths do not match in non_clustered_updated STUDY';
                return;
            end

            numDipoles = 0;
            for i = 1:length(ALLEEG)
                if isfield(ALLEEG(i), 'dipfit') && isfield(ALLEEG(i).dipfit, 'model') && ~isempty(ALLEEG(i).dipfit.model)
                    numDipoles = numDipoles + size(ALLEEG(i).dipfit.model, 2);
                else
                    fprintf('  Warning: ALLEEG(%d) is missing dipfit.model or it is empty.\n', i);
                end
            end

            if app.non_clust_sets_length ~= numDipoles || app.non_clust_comps_length ~= numDipoles
                fprintf('Warning: Mismatch in non_clustered_updated STUDY parent cluster data:\n');
                fprintf('  Sets length: %d\n', app.non_clust_sets_length);
                fprintf('  Comps length: %d\n', app.non_clust_comps_length);
                fprintf('  Number of dipoles: %d\n', numDipoles);
                fprintf('  Proceeding without terminating, as this validation may be rechecked later.\n');
            end

            app.subID = non_clust_sets;
            fprintf('  Updated app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
            app.icID = non_clust_comps;
            fprintf('  Updated app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));

            fprintf('Updated non_clustered_updated STUDY parent cluster data:\n');
            fprintf('  Number of sets: %d\n', app.non_clust_sets_length);
            fprintf('  Number of comps: %d\n', app.non_clust_comps_length);
            fprintf('  Number of dipoles: %d\n', numDipoles);

            % Step 8.8: Extract and validate subject information (local variables only)
            subjectNames_Section2 = cell(1, length(STUDY.datasetinfo));
            groupNames_Section2 = cell(1, length(STUDY.datasetinfo));
            subjectIDs_Section2 = zeros(1, length(STUDY.datasetinfo));

            for i = 1:length(STUDY.datasetinfo)
                alleeg_subject = ALLEEG(i).subject;
                alleeg_group = ALLEEG(i).group;
                alleeg_index = i;
                study_subject = STUDY.datasetinfo(i).subject;
                study_group = STUDY.datasetinfo(i).group;
                study_index = STUDY.datasetinfo(i).index;

                if ~strcmp(alleeg_subject, study_subject)
                    fprintf('Error: Subject mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
                    fprintf('  ALLEEG.subject: %s\n', alleeg_subject);
                    fprintf('  STUDY.datasetinfo.subject: %s\n', study_subject);
                    app.StatusLabel.Text = 'Error: Subject mismatch';
                    app.ResultLabel.Text = 'ALLEEG and STUDY subjects do not match in non_clustered_updated STUDY';
                    return;
                end

                if ~strcmp(alleeg_group, study_group)
                    fprintf('Error: Group mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
                    fprintf('  ALLEEG.group: %s\n', alleeg_group);
                    fprintf('  STUDY.datasetinfo.group: %s\n', study_group);
                    app.StatusLabel.Text = 'Error: Group mismatch';
                    app.ResultLabel.Text = 'ALLEEG and STUDY groups do not match in non_clustered_updated STUDY';
                    return;
                end

                if alleeg_index ~= study_index
                    fprintf('Error: Index mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
                    fprintf('  ALLEEG index: %d\n', alleeg_index);
                    fprintf('  STUDY.datasetinfo.index: %d\n', study_index);
                    app.StatusLabel.Text = 'Error: Index mismatch';
                    app.ResultLabel.Text = 'ALLEEG and STUDY indices do not match in non_clustered_updated STUDY';
                    return;
                end

                subjectNames_Section2{i} = study_subject;
                groupNames_Section2{i} = study_group;
                subjectIDs_Section2(i) = study_index;
            end

            % Step 8.9: Calculate group ranges and collect subject names
            groupCounts = groupcounts(fileTable, 'group');
            groupRanges = cell(1, length(groupPrefixes));
            groupSubjects = cell(1, length(groupPrefixes));
            startIdx = 1;
            for i = 1:length(groupPrefixes)
                groupMask = strcmp(fileTable.group, groupPrefixes{i});
                groupFileCount = sum(groupMask);
                endIdx = startIdx + groupFileCount - 1;
                groupRanges{i} = sprintf('%d:%d', startIdx, endIdx);
                groupSubjects{i} = fileTable.subject(groupMask);
                startIdx = endIdx + 1;
            end
            fprintf('Step 8.9 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 8.10: Save the new STUDY
            studyFilePath = fullfile(app.studyFilePath, 'non_clustered_updated.study');
            [STUDY, ALLEEG] = pop_savestudy(STUDY, ALLEEG, 'filename', studyFilePath);
            fprintf('Step 8.10 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 8.11: Update app properties
            app.STUDY = STUDY;
            app.ALLEEG = ALLEEG;
            app.detectedNumGroups = length(groupPrefixes);
            app.groupPrefixes = groupPrefixes;
            app.detectedSubjectsPerGroup = groupCounts.GroupCount';
            app.groupRanges = groupRanges;
            fprintf('Step 8.11 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 8.12: Print detailed information after reconstruction
            fprintf('STUDY reconstructed successfully (non_clustered_updated.study):\n');
            fprintf('  Number of groups: %d\n', length(groupPrefixes));
            totalSubjects = height(fileTable);
            for i = 1:length(groupPrefixes)
                displayGroup = upper(groupPrefixes{i});
                fprintf('  Group %s: %d subjects\n', displayGroup, groupCounts.GroupCount(i));
            end
            fprintf('  Total subjects: %d\n', totalSubjects);
            fprintf('  Saved in folder: %s\n', app.studyFilePath);

            fprintf('Subject Information from Non-Clustered Updated STUDY:\n');
            for i = 1:length(STUDY.datasetinfo)
                fprintf('Group: %s Subject: %s SubjectID: %d\n', ...
                    groupNames_Section2{i}, subjectNames_Section2{i}, subjectIDs_Section2(i));
            end

            fprintf('App variables after STUDY reconstruction (post-pre-clustering):\n');
            if isprop(app, 'non_clust_sets_length') && ~isempty(app.non_clust_sets_length)
                fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
            else
                fprintf('  app.non_clust_sets_length: Not set or empty\n');
            end
            if isprop(app, 'non_clust_comps_length') && ~isempty(app.non_clust_comps_length)
                fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
            else
                fprintf('  app.non_clust_comps_length: Not set or empty\n');
            end
            if isprop(app, 'subID') && ~isempty(app.subID)
                fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
            else
                fprintf('  app.subID: Not set or empty\n');
            end
            if isprop(app, 'icID') && ~isempty(app.icID)
                fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
            else
                fprintf('  app.icID: Not set or empty\n');
            end
            if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
                fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
            else
                fprintf('  app.subjectNames_Section1: Not set or empty\n');
            end
            if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
                fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
            else
                fprintf('  app.groupNames_Section1: Not set or empty\n');
            end
            if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
                fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));
            else
                fprintf('  app.subjectIDs_Section1: Not set or empty\n');
            end
            fprintf('Step 8.12 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug

            % Step 8.13: Automatically re-run SubmitParametersButtonPushed
            app.SubmitParametersButtonPushed();
            return;
        catch ME
            fprintf('  Error reconstructing STUDY: %s\n', ME.message);
            app.StatusLabel.Text = 'Error reconstructing STUDY';
            app.ResultLabel.Text = ME.message;
            return;
        end
    end
else
    fprintf('  All dataset counts match.\n');
    fprintf('  All dipole counts match.\n');
    fprintf('  Preclustering done successfully!\n');

    % Set app variables in the normal path to reflect the current state of STUDY
    if ~isfield(app.STUDY, 'cluster') || isempty(app.STUDY.cluster) || ~isfield(app.STUDY.cluster(1), 'sets') || ~isfield(app.STUDY.cluster(1), 'comps')
        uialert(app.UIFigure, ...
            'Parent cluster in STUDY is missing sets or comps after pre-clustering.', ...
            'STUDY Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error: Missing parent cluster data';
        app.ResultLabel.Text = 'Parent cluster lacks sets or comps after pre-clustering';
        return;
    end

    % % Extract sets and comps from the parent cluster
    % non_clust_sets = app.STUDY.cluster(1).sets(:);
    % non_clust_comps = app.STUDY.cluster(1).comps(:);
    % 
    % % Update lengths
    % app.non_clust_sets_length = length(non_clust_sets);
    % app.non_clust_comps_length = length(non_clust_comps);

    % Validate that sets and comps have the same length
    if app.non_clust_sets_length ~= app.non_clust_comps_length
        uialert(app.UIFigure, ...
            sprintf('Mismatch in STUDY parent cluster after pre-clustering: sets length (%d) does not match comps length (%d).', ...
                app.non_clust_sets_length, app.non_clust_comps_length), ...
            'STUDY Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error: Parent cluster data mismatch';
        app.ResultLabel.Text = 'Sets and comps lengths do not match after pre-clustering';
        return;
    end

    % Compute number of dipoles
    numDipoles = 0;
    for i = 1:length(app.STUDY.datasetinfo)
            numComps = size(app.STUDY.datasetinfo(i).comps, 2);
            numDipoles = numDipoles + numComps;
        if numComps == 0
            invalidEntry.index = i;
            invalidEntry.subject = app.STUDY.datasetinfo(i).subject;
            invalidDipoleDatasets(end+1) = invalidEntry;
        end
    end


    % % Validate sets, comps, and number of dipoles
    % if app.non_clust_sets_length ~= numDipoles || app.non_clust_comps_length ~= numDipoles
    %     fprintf('Warning: Mismatch in STUDY parent cluster data after pre-clustering:\n');
    %     fprintf('  Sets length: %d\n', app.non_clust_sets_length);
    %     fprintf('  Comps length: %d\n', app.non_clust_comps_length);
    %     fprintf('  Number of dipoles: %d\n', numDipoles);
    %     fprintf('  Proceeding without terminating, as this validation may be rechecked later.\n');
    % end

    % Update subID and icID
    % app.subID = non_clust_sets;
    % app.icID = non_clust_comps;

    subjectNames_Section2 = cell(1, length(app.STUDY.datasetinfo));
    groupNames_Section2 = cell(1, length(app.STUDY.datasetinfo));
    subjectIDs_Section2 = zeros(1, length(app.STUDY.datasetinfo));

    for i = 1:length(app.STUDY.datasetinfo)
        alleeg_subject = app.ALLEEG(i).subject;
        alleeg_group = app.ALLEEG(i).group;
        alleeg_index = i;
        study_subject = app.STUDY.datasetinfo(i).subject;
        study_group = app.STUDY.datasetinfo(i).group;
        study_index = app.STUDY.datasetinfo(i).index;

        if ~strcmp(alleeg_subject, study_subject)
            fprintf('Error: Subject mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
            fprintf('  ALLEEG.subject: %s\n', alleeg_subject);
            fprintf('  STUDY.datasetinfo.subject: %s\n', study_subject);
            app.StatusLabel.Text = 'Error: Subject mismatch';
            app.ResultLabel.Text = 'ALLEEG and STUDY subjects do not match in non_clustered_updated STUDY';
            return;
        end

        if ~strcmp(alleeg_group, study_group)
            fprintf('Error: Group mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
            fprintf('  ALLEEG.group: %s\n', alleeg_group);
            fprintf('  STUDY.datasetinfo.group: %s\n', study_group);
            app.StatusLabel.Text = 'Error: Group mismatch';
            app.ResultLabel.Text = 'ALLEEG and STUDY groups do not match in non_clustered_updated STUDY';
            return;
        end

        if alleeg_index ~= study_index
            fprintf('Error: Index mismatch for dataset %d in non_clustered_updated STUDY:\n', i);
            fprintf('  ALLEEG index: %d\n', alleeg_index);
            fprintf('  STUDY.datasetinfo.index: %d\n', study_index);
            app.StatusLabel.Text = 'Error: Index mismatch';
            app.ResultLabel.Text = 'ALLEEG and STUDY indices do not match in non_clustered_updated STUDY';
            return;
        end

        subjectNames_Section2{i} = study_subject;
        groupNames_Section2{i} = study_group;
        subjectIDs_Section2(i) = study_index;
    end


    % Calculate group counts for printing
    groupPrefixes = unique(groupNames_Section2);
    groupCounts = groupcounts(table(groupNames_Section2', 'VariableNames', {'groupNames_Section2'}), 'groupNames_Section2');
    
    % Calculate group counts for printing
    groupPrefixes = unique(groupNames_Section2);
    groupCounts = groupcounts(table(groupNames_Section2', 'VariableNames', {'groupNames_Section2'}), 'groupNames_Section2');
    
    % Print detailed information after all updates
    fprintf('STUDY state after pre-clustering (no reconstruction needed):\n');
    fprintf('  Number of groups: %d\n', length(groupPrefixes));
    totalSubjects = length(subjectNames_Section2);
    for i = 1:length(groupPrefixes)
        displayGroup = upper(groupPrefixes{i});
        groupCount = groupCounts.GroupCount(strcmp(groupCounts.groupNames_Section2, groupPrefixes{i}));
        fprintf('  Group %s: %d subjects\n', displayGroup, groupCount);
    end
    fprintf('  Total subjects: %d\n', totalSubjects);
    fprintf('  Saved in folder: %s\n', app.studyFilePath);

    fprintf('Subject Information after Pre-Clustering:\n');
    for i = 1:length(app.STUDY.datasetinfo)
        fprintf('Group: %s Subject: %s SubjectID: %d\n', ...
            groupNames_Section2{i}, subjectNames_Section2{i}, subjectIDs_Section2(i));
    end

    fprintf('App variables after pre-clustering (normal path):\n');
    if isprop(app, 'non_clust_sets_length') && ~isempty(app.non_clust_sets_length)
        fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
    else
        fprintf('  app.non_clust_sets_length: Not set or empty\n');
    end
    if isprop(app, 'non_clust_comps_length') && ~isempty(app.non_clust_comps_length)
        fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
    else
        fprintf('  app.non_clust_comps_length: Not set or empty\n');
    end
    if isprop(app, 'subID') && ~isempty(app.subID)
        fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
    else
        fprintf('  app.subID: Not set or empty\n');
    end
    if isprop(app, 'icID') && ~isempty(app.icID)
        fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
    else
        fprintf('  app.icID: Not set or empty\n');
    end
    if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
        fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
    else
        fprintf('  app.subjectNames_Section1: Not set or empty\n');
    end
    if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
        fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
    else
        fprintf('  app.groupNames_Section1: Not set or empty\n');
    end
    if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
        fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));
    else
        fprintf('  app.subjectIDs_Section1: Not set or empty\n');
    end
    %fprintf('Step 8.13 groupRanges: %s\n', strjoin(groupRanges, ', ')); % Debug
    fprintf('Step 8.13 groupRanges: %s\n', mat2str(app.groupRanges{g}));
end

% Step 9.0a: Compute silhouette scores for different numbers of clusters (2 to 20)
fprintf('Computing silhouette scores for different numbers of clusters (2 to 20)...\n');

% Extract the pre-clustering data (dipole locations)
preclustData = app.STUDY.etc.preclust.preclustdata;
numComponents = size(preclustData, 1);
fprintf('Total number of components in preclustData: %d\n', numComponents);

% Get the component indices from the parent cluster
parentComps = app.STUDY.cluster(1).comps;
parentSets = app.STUDY.cluster(1).sets;

% Debug: Inspect the size of parentSets
fprintf('Size of parentSets: %d x %d\n', size(parentSets, 1), size(parentSets, 2));

% Handle parentSets dimensions
if size(parentSets, 2) == length(parentComps)
    parentSets = parentSets(1, :);
elseif size(parentSets, 1) == length(parentComps)
    parentSets = parentSets(:, 1);
else
    fprintf('Warning: parentSets size (%d x %d) does not match parentComps size (%d). Reconstructing parentSets...\n', ...
        size(parentSets, 1), size(parentSets, 2), length(parentComps));
    parentSets = zeros(length(parentComps), 1);
    for idx = 1:length(parentComps)
        compIdx = parentComps(idx);
        found = false;
        for datasetIdx = 1:length(app.STUDY.datasetinfo)
            if isfield(app.STUDY.datasetinfo(datasetIdx), 'index')
                datasetNum = app.STUDY.datasetinfo(datasetIdx).index;
                if datasetNum <= length(app.ALLEEG)
                    eegComps = 1:size(app.ALLEEG(datasetNum).icaweights, 1);
                    if compIdx <= length(eegComps)
                        parentSets(idx) = datasetNum;
                        found = true;
                        break;
                    end
                end
            end
        end
        if ~found
            fprintf('Warning: Could not find dataset for component %d. Assigning to dataset 1.\n', compIdx);
            parentSets(idx) = 1;
        end
    end
end

if length(parentComps) ~= numComponents
    error('Mismatch between parent cluster components (%d) and preclustData (%d)', length(parentComps), numComponents);
end

% Debug: Print the first few parentSets and parentComps to verify
fprintf('First 5 parentSets: %s\n', mat2str(parentSets(1:min(5, length(parentSets)))));
fprintf('First 5 parentComps: %s\n', mat2str(parentComps(1:min(5, length(parentComps)))));

% Build a mapping from (dataset, component) to preclustData index
compMap = containers.Map('KeyType', 'char', 'ValueType', 'double');
for idx = 1:length(parentComps)
    key = sprintf('%d,%d', parentSets(idx), parentComps(idx));
    compMap(key) = idx;
end


% Initialize variables to store silhouette scores and problematic clusters
clusterNumbers = 2:20;
meanSilhouetteScores = zeros(size(clusterNumbers));
medianSilhouetteScores = zeros(size(clusterNumbers));
allSilhouetteScores = cell(size(clusterNumbers));
allClusterAssignments = cell(size(clusterNumbers));
app.allClusterAssignments = allClusterAssignments;
incompleteClusterCounts = []; % Store cluster counts with incomplete assignments

if ~strcmpi(clustAlgo, 'Affinity Propagation') && ...
   ~(strcmpi(clustAlgo, 'optimal_kmeans') && stdDevThreshold < 1)

for k = 1:length(clusterNumbers)
    numClusters = clusterNumbers(k);
    fprintf('Clustering with %d clusters...\n', numClusters);
    
    % Set random seed for reproducibility
    rng(42, 'twister');

    % Perform temporary clustering to compute silhouette scores
    
    % Modified logic for kmeans, kmeanscluster, neural network, optimal_kmeans
try
    if strcmpi(clustAlgo, 'kmeans')
        if stdDevThreshold >= 1
            % Use robust_kmeans
            outliersParam = stdDevThreshold;
            fprintf('Using algorithm: kmeans (robust_kmeans with stdDevThreshold = %.1f)\n', stdDevThreshold);
            [tempSTUDY, tempALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...
                'clus_num', numClusters, ...
                'outliers', outliersParam);
        else
            % Standard kmeans
            fprintf('Using algorithm: kmeans (standard kmeans)\n');
            [tempSTUDY, tempALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...
                'clus_num', numClusters);
        end

    elseif strcmpi(clustAlgo, 'kmeanscluster')
        if stdDevThreshold >= 1
            % Force robust_kmeans via pop_clust using 'kmeans' format
            outliersParam = stdDevThreshold;
            fprintf('Using algorithm: kmeanscluster (robust_kmeans with stdDevThreshold = %.1f)\n', stdDevThreshold);
            [tempSTUDY, tempALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...
                'clus_num', numClusters, ...
                'outliers', outliersParam);
        else
            fprintf('Using algorithm: kmeanscluster (kmeans_st, standard)\n');
            [tempSTUDY, tempALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...
                'clus_num', numClusters);
        end

    elseif strcmpi(clustAlgo, 'neural network')
        % Neural network: no outlier handling
        fprintf('Using algorithm: neural network (no outlier handling)\n');
        [tempSTUDY, tempALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
            'algorithm', clustAlgo, ...
            'clus_num', numClusters);

    elseif strcmpi(clustAlgo, 'optimal_kmeans') && stdDevThreshold >= 1
        % Optimal kmeans with outlier handling
        outliersParam = stdDevThreshold;
        fprintf('Using algorithm: optimal_kmeans (robust_kmeans with stdDevThreshold = %.1f)\n', stdDevThreshold);

        % Ensure clus_num is 1x2 vector
        %clusNumVector = [2 numClusters];
        % if ~isequal(size(clusNumVector), [1 2])
        %     error('clus_num must be a 1x2 vector for optimal_kmeans. Current size: [%d %d]', size(clusNumVector));
        % else
        %     fprintf('clus_num is a 1x2 vector: [%d %d]\n', clusNumVector);
        % end

        [tempSTUDY, tempALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
            'algorithm', 'kmeans', ...   % route through robust_kmeans
            'clus_num', numClusters, ...
            'outliers', outliersParam);

    else
        % This should not happen, catch unsupported algorithms
        error('Unsupported clustering algorithm in silhouette loop: %s', clustAlgo);
    end

catch ME
    fprintf('Error in pop_clust for %s with %d clusters: %s\n', clustAlgo, numClusters, ME.message);
    incompleteClusterCounts(end+1) = numClusters;
    continue;
end


    % Initialize hasOutlier and check for outlier cluster
    hasOutlier = false;
    totalCompsAssigned = 0;
    if length(tempSTUDY.cluster) >= 2
        if contains(lower(tempSTUDY.cluster(2).name), 'outlier')
            hasOutlier = true;
            numComps = length(tempSTUDY.cluster(2).comps);
            fprintf('pop_clust - Outlier Cluster: %d components\n', numComps);
            totalCompsAssigned = totalCompsAssigned + numComps;
        else
            fprintf('pop_clust - No Outlier Cluster detected.\n');
        end
    end

    % Check for empty or invalid clusters
    actualNumClusters = length(tempSTUDY.cluster) - 1; % Exclude parent cluster
    if hasOutlier
        actualNumClusters = actualNumClusters - 1; % Exclude outlier cluster
    end
    if actualNumClusters < numClusters || actualNumClusters == 0
        fprintf('Warning: Only %d clusters formed for requested %d clusters or no valid clusters.\n', actualNumClusters, numClusters);
        incompleteClusterCounts(end+1) = numClusters;
        continue; % Skip silhouette computation for this k
    end

    % Debug: Check the number of components in each cluster after pop_clust
    if hasOutlier
        startIdx = 3;
    else
        startIdx = 2;
    end
    for i = startIdx:length(tempSTUDY.cluster)
        numComps = length(tempSTUDY.cluster(i).comps);
        fprintf('pop_clust - Cluster %d: %d components\n', i, numComps);
        totalCompsAssigned = totalCompsAssigned + numComps;
    end
    fprintf('Total components assigned by pop_clust: %d\n', totalCompsAssigned);
    
    % Check for incomplete assignments
    if totalCompsAssigned < numComponents
        fprintf('Warning: Only %d of %d components assigned for %d clusters. Continuing with silhouette computation.\n', ...
            totalCompsAssigned, numComponents, numClusters);
        incompleteClusterCounts(end+1) = numClusters;
    end
    
    % Extract cluster assignments directly from tempSTUDY.cluster
    clusterAssignments = -1 * ones(numComponents, 1);
    outlierIndices = [];
    realClusterIndices = cell(1, numClusters); % Store indices for each real cluster
    
    % Check for duplicate components across clusters
    allComps = [];
    for i = 2:length(tempSTUDY.cluster)
        allComps = [allComps, tempSTUDY.cluster(i).comps];
    end
    duplicates = setdiff(allComps, unique(allComps));
    if ~isempty(duplicates)
        fprintf('Warning: Duplicate components found across clusters: %s\n', mat2str(duplicates));
    end
    
    fprintf('pop_clust - Cluster 1: Parent Cluster - size of sets: %d x %d, size of comps: %d\n', ...
        size(app.STUDY.cluster(1).sets, 1), size(app.STUDY.cluster(1).sets, 2), length(app.STUDY.cluster(1).comps));
    
    % Wrap cluster assignment and silhouette computation in try-catch
    try
        for i = 2:length(tempSTUDY.cluster)
            comps = tempSTUDY.cluster(i).comps;
            sets = tempSTUDY.cluster(i).sets;
            clusterLabel = tempSTUDY.cluster(i).name;
            
            if i == 2 && hasOutlier
                fprintf('Cluster %d (Outlier Cluster) - Size of sets: %d x %d, Size of comps: %d\n', i, size(sets, 1), size(sets, 2), length(comps));
            else
                fprintf('Cluster %d - Size of sets: %d x %d, Size of comps: %d\n', i, size(sets, 1), size(sets, 2), length(comps));
            end
            
            if size(sets, 2) == length(comps)
                sets = sets(1, :);
            elseif size(sets, 1) == length(comps)
                sets = sets(:, 1);
            else
                fprintf('Error: In Cluster %d, sets length (%d x %d) does not match comps length (%d). Reconstructing sets...\n', ...
                    i, size(sets, 1), size(sets, 2), length(comps));
                sets = zeros(length(comps), 1);
                for j = 1:length(comps)
                    compIdx = comps(j);
                    parentIdx = find(parentComps == compIdx, 1);
                    if ~isempty(parentIdx)
                        sets(j) = parentSets(parentIdx);
                    else
                        fprintf('Warning: Could not find parent index for component %d in Cluster %d. Assigning to dataset 1.\n', compIdx, i);
                        sets(j) = 1;
                    end
                end
            end
            
            if ~isempty(comps)
                for j = 1:length(comps)
                    compIdx = comps(j);
                    datasetIdx = sets(j);
                    key = sprintf('%d,%d', datasetIdx, compIdx);
                    
                    if compMap.isKey(key)
                        matchIdx = compMap(key);
                        if clusterAssignments(matchIdx) >= 0
                            fprintf('Component %d (dataset %d) already assigned to cluster %d, skipping in Cluster %d\n', ...
                                compIdx, datasetIdx, clusterAssignments(matchIdx), i - 1);
                            continue;
                        end
                        if i == 2 && hasOutlier
                            clusterAssignments(matchIdx) = 0; % Outlier cluster
                            outlierIndices = [outlierIndices, matchIdx];
                        else
                            if hasOutlier
                                realClusterIdx = i-2;
                            else
                                realClusterIdx = i-1;
                            end
                            clusterAssignments(matchIdx) = realClusterIdx;
                            realClusterIndices{realClusterIdx} = [realClusterIndices{realClusterIdx}, matchIdx];
                        end
                    else
                        fprintf('Warning: Key %s not found in compMap for Cluster %d, component index %d\n', key, i, j);
                        parentIdx = find(parentComps == compIdx, 1);
                        if ~isempty(parentIdx)
                            matchIdx = parentIdx;
                            if clusterAssignments(matchIdx) >= 0
                                fprintf('Component %d (dataset %d) already assigned to cluster %d, skipping in Cluster %d\n', ...
                                    compIdx, datasetIdx, clusterAssignments(matchIdx), i - 1);
                                continue;
                            end
                            if i == 2 && hasOutlier
                                clusterAssignments(matchIdx) = 0;
                                outlierIndices = [outlierIndices, matchIdx];
                            else
                                if hasOutlier
                                    realClusterIdx = i-2;
                                else
                                    realClusterIdx = i-1;
                                end
                                clusterAssignments(matchIdx) = realClusterIdx;
                                realClusterIndices{realClusterIdx} = [realClusterIndices{realClusterIdx}, matchIdx];
                            end
                        else
                            fprintf('Warning: Component %d (dataset %d) not found in parentComps or compMap for Cluster %d. Skipping.\n', compIdx, datasetIdx, i);
                        end
                    end
                end
            end
        end
        
        % Report assignment summary
        assignedComponents = sum(clusterAssignments >= 0);
        outlierCount = sum(clusterAssignments == 0);
        realClusterCount = sum(clusterAssignments > 0);
        fprintf('Outlier components identified: %d\n', outlierCount);
        if hasOutlier
            fprintf('Indices assigned to outlier cluster: %s\n', mat2str(outlierIndices));
        end
        for c = 1:numClusters
            fprintf('Indices assigned to Cluster %d: %s\n', c + 2, mat2str(realClusterIndices{c}));
        end
        fprintf('Number of components assigned to real clusters: %d\n', realClusterCount);
        fprintf('Total components (including outliers): %d\n', assignedComponents);
        
        % Compute silhouette scores for pop_clust (exclude parent and outlier clusters)
        fprintf('Cluster 1 is the parent cluster, so no silhouette scores are computed for it.\n');
        validIndices = clusterAssignments > 0;
        silhouetteScores = zeros(numComponents, 1);
        if any(validIndices)
            silhouetteScores(validIndices) = silhouette(preclustData(validIndices, :), clusterAssignments(validIndices), 'Euclidean');
            allSilhouetteScores{k} = silhouetteScores(validIndices);
            meanSilhouetteScores(k) = mean(silhouetteScores(validIndices));
            medianSilhouetteScores(k) = median(silhouetteScores(validIndices));
        else
            allSilhouetteScores{k} = [];
            meanSilhouetteScores(k) = NaN;
            medianSilhouetteScores(k) = NaN;
        end
        
        fprintf('pop_clust - Silhouette Scores for %d clusters (excluding outlier):\n', numClusters);
        for cluster = 1:numClusters
            clusterComps = find(clusterAssignments == cluster);
            if ~isempty(clusterComps)
                clusterScores = silhouetteScores(clusterComps);
                fprintf('  Cluster %d: Mean Silhouette = %.3f, Median Silhouette = %.3f (n = %d components)\n', ...
                    cluster + 2, mean(clusterScores), median(clusterScores), length(clusterComps));  % <-- UPDATED
            else
                fprintf('  Cluster %d: No components\n', cluster + 2);
            end
        end
        fprintf('  Mean Silhouette Score for %d clusters (pop_clust): %.3f\n', numClusters, meanSilhouetteScores(k));
        fprintf('  Median Silhouette Score for %d clusters (pop_clust): %.3f\n\n', numClusters, medianSilhouetteScores(k));  % <-- ADD THIS
                
        allClusterAssignments{k} = clusterAssignments;
    catch ME
        fprintf('Error during cluster assignment or silhouette computation for %d clusters: %s\n', numClusters, ME.message);
        incompleteClusterCounts(end+1) = numClusters;
        continue; % Skip to next k
    end
end
end
 
% Step 9.0b: Store results in app properties for verification and visualization
fprintf('Storing silhouette computation results in app properties...\n');
app.allSilhouetteScores = allSilhouetteScores;
app.allClusterAssignments = allClusterAssignments;
app.meanSilhouetteScores = meanSilhouetteScores;
app.medianSilhouetteScores = medianSilhouetteScores;

fprintf('Silhouette computation completed successfully for algorithms requiring comparison.\n\n');


function printClusterSummary(numClustersMean, clusterAssignmentsMean, silhouetteValsMean, clustAlgo, clusterRange, allClusterAssignments, allSilhouetteScores, numClustersMedian, clusterAssignmentsMedian, silhouetteValsMedian)
% Helper to print cluster results for all algorithms
% Inputs:
% numClustersMean: Number of clusters for the best K based on mean silhouette
% clusterAssignmentsMean: Cluster indices for each component (best K mean)
% silhouetteValsMean: Silhouette scores for each component (best K mean)
% clustAlgo: Selected clustering algorithm (string)
% clusterRange: Range of clusters evaluated (e.g., 2:20)
% allClusterAssignments: Cell array of cluster assignments for each K
% allSilhouetteScores: Cell array of silhouette scores for each K
% numClustersMedian: Number of clusters for the best K based on median silhouette
% clusterAssignmentsMedian: Cluster indices for each component (best K median)
% silhouetteValsMedian: Silhouette scores for each component (best K median)

fprintf('\n=== Cluster Summary Report ===\n');

if strcmpi(clustAlgo, 'optimal_kmeans')
    fprintf('Selected Algorithm: optimal_kmeans (no outlier)\n');
elseif strcmpi(clustAlgo, 'Affinity Propagation')
    fprintf('Selected Algorithm: Affinity Propagation\n');
else
    fprintf('Selected Algorithm: %s\n', clustAlgo);
end

fprintf('Best Number of Clusters (Mean): %d\n', numClustersMean);
fprintf('Best Number of Clusters (Median): %d\n', numClustersMedian);

% Print detailed summary for all valid K in clusterRange (only for optimal_kmeans)
if strcmpi(clustAlgo, 'optimal_kmeans') && nargin > 4 && ~isempty(clusterRange) && ~isempty(allClusterAssignments) && ~isempty(allSilhouetteScores)
    for k = 1:length(clusterRange)
        currentK = clusterRange(k);
        assignments = allClusterAssignments{k};
        silScores = allSilhouetteScores{k};
        
        if ~isempty(assignments) && ~isempty(silScores)
            % Determine algorithm display name
            if strcmpi(clustAlgo, 'optimal_kmeans')
                algoDisplay = 'Optimal_kmeans (no outlier)';
            elseif strcmpi(clustAlgo, 'Affinity Propagation')
                algoDisplay = 'Affinity Propagation (no outlier)';
            else
                algoDisplay = clustAlgo;
            end
            
            fprintf('\n-- Clusters for K = %d (%s) --\n', currentK, algoDisplay);
            uniqueClusters = unique(assignments(assignments > 0));
            for c = 1:length(uniqueClusters)
                clusterIdx = uniqueClusters(c);
                clusterComps = find(assignments == clusterIdx);
                clusterScores = silScores(assignments == clusterIdx);
                fprintf('Cluster %d - Size: %d components\n', clusterIdx + 1, length(clusterComps));
                fprintf(' Indices: %s\n', mat2str(clusterComps));
                fprintf(' Mean Silhouette: %.3f\n', mean(clusterScores));
                fprintf(' Median Silhouette: %.3f\n', median(clusterScores));
            end
            fprintf('Overall Mean Silhouette for K=%d: %.3f\n', currentK, mean(silScores));
            fprintf('Overall Median Silhouette for K=%d: %.3f\n', currentK, median(silScores));
        else
            % Determine algorithm display name
            if strcmpi(clustAlgo, 'optimal_kmeans')
                algoDisplay = 'Optimal_kmeans (no outlier)';
            elseif strcmpi(clustAlgo, 'Affinity Propagation')
                algoDisplay = 'Affinity Propagation (no outlier)';
            else
                algoDisplay = clustAlgo;
            end
            fprintf('\n-- Clusters for K = %d (%s) --\n', currentK, algoDisplay);
            fprintf('No valid clusters formed.\n');
        end
    end
end

% Helper function to print overall summary for a given K
function printOverallSummary(numClusters, clusterAssignments, silhouetteVals, summaryType)
    % Reindex clusters for Affinity Propagation
    if strcmpi(clustAlgo, 'Affinity Propagation')
        uniqueClusters = unique(clusterAssignments(clusterAssignments > 0));
        if ~isempty(uniqueClusters)
            reindexMap = containers.Map(uniqueClusters, 1:length(uniqueClusters));
            reindexedAssignments = zeros(size(clusterAssignments));
            for c = 1:length(uniqueClusters)
                reindexedAssignments(clusterAssignments == uniqueClusters(c)) = c;
            end
            clusterAssignments = reindexedAssignments;
        else
            clusterAssignments = zeros(size(clusterAssignments)); % Handle empty case
        end
    end
    
    % Determine algorithm display name
    if strcmpi(clustAlgo, 'optimal_kmeans')
        algoDisplay = 'Optimal_kmeans (no outlier)';
    elseif strcmpi(clustAlgo, 'Affinity Propagation')
        algoDisplay = 'Affinity Propagation (no outlier)';
    else
        algoDisplay = clustAlgo;
    end
    
    fprintf('\n-- Overall Summary for Best K = %d (%s: %s) --\n', numClusters, summaryType, algoDisplay);
    
    if ~isempty(clusterAssignments) && ~isempty(silhouetteVals)
        uniqueClusters = unique(clusterAssignments(clusterAssignments > 0));
        for c = 1:numClusters
            clusterIdx = c;
            clusterComps = find(clusterAssignments == clusterIdx);
            fprintf('Cluster %d - Size: %d components\n', c + 1, numel(clusterComps));
            fprintf(' Indices: %s\n', mat2str(clusterComps));
            if ~isempty(clusterComps)
                clusterScores = silhouetteVals(clusterAssignments == clusterIdx);
                fprintf(' Mean Silhouette: %.3f\n', mean(clusterScores));
                fprintf(' Median Silhouette: %.3f\n', median(clusterScores));
            else
                fprintf(' Mean Silhouette: NaN\n');
                fprintf(' Median Silhouette: NaN\n');
            end
        end
        fprintf('Overall Mean Silhouette for Best K=%d: %.3f\n', numClusters, mean(silhouetteVals));
        fprintf('Overall Median Silhouette for Best K=%d: %.3f\n', numClusters, median(silhouetteVals));
    else
        % Determine algorithm display name
        if strcmpi(clustAlgo, 'optimal_kmeans')
            algoDisplay = 'Optimal_kmeans (no outlier)';
        elseif strcmpi(clustAlgo, 'Affinity Propagation')
            algoDisplay = 'Affinity Propagation (no outlier)';
        else
            algoDisplay = clustAlgo;
        end
        fprintf('\n-- Overall Summary for Best K = %d (%s: %s) --\n', numClusters, summaryType, algoDisplay);
    end
end

% Print overall summaries for best K based on mean and median
printOverallSummary(numClustersMean, clusterAssignmentsMean, silhouetteValsMean, 'Mean');

if nargin > 7 && ~isempty(numClustersMedian) && ~isempty(clusterAssignmentsMedian) && ~isempty(silhouetteValsMedian)
    printOverallSummary(numClustersMedian, clusterAssignmentsMedian, silhouetteValsMedian, 'Median');
end

fprintf('===============================\n\n');
% === Store results in app for later access (used by submitNumberOfClusters) ===
% === Store results in app for later access (used by submitNumberOfClusters) ===
try
    % Store basic clustering info
    app.ClustAlgo = clustAlgo;
    app.NumClustersMean = numClustersMean;
    app.NumClustersMedian = numClustersMedian;

    % For optimal_kmeans, store per-K overall mean and median silhouettes
    if strcmpi(clustAlgo, 'optimal_kmeans') && ~isempty(clusterRange) ...
            && ~isempty(allClusterAssignments) && ~isempty(allSilhouetteScores)
        
        kOverallStats = nan(length(clusterRange), 3); % Columns: K | OverallMean | OverallMedian
        for k = 1:length(clusterRange)
            currentK = clusterRange(k);
            silScores = allSilhouetteScores{k};
            if ~isempty(silScores)
                overallMean = mean(silScores);
                overallMedian = median(silScores);
            else
                overallMean = NaN;
                overallMedian = NaN;
            end
            kOverallStats(k, :) = [currentK, overallMean, overallMedian];
        end
        app.KOverallStats = kOverallStats; % Save in app
    else
        app.KOverallStats = [];
    end

    % Timestamp for debugging / version tracking
    app.LastClusterSummaryUpdate = datestr(now);

    fprintf('\n[INFO] Cluster summary variables saved to app for later use.\n');
catch ME
    fprintf(2, '\n[WARNING] Could not save cluster summary variables to app: %s\n', ME.message);
end


end


% Step 9.0b: Handle optimal_kmeans separately
if (strcmpi(clustAlgo, 'optimal_kmeans') && stdDevThreshold < 1)
    fprintf('Running optimal_kmeans (evaluating 2–20 clusters)...\n');

    % --- Extract preclustering data ---
    preclustData = app.STUDY.etc.preclust.preclustdata;

    % --- Debug statements ---
    fprintf('Debug: app.studyFilePath = %s\n', string(app.studyFilePath));
    fprintf('Debug: isprop(app, ''studyFilePath'') = %d\n', isprop(app, 'studyFilePath'));
    if isfield(app.STUDY, 'filepath')
        fprintf('Debug: app.STUDY.filepath = %s\n', string(app.STUDY.filepath));
    else
        fprintf('Debug: app.STUDY.filepath does not exist\n');
    end
    
    % --- Correct Save Path ---
    if isprop(app, 'studyFilePath') && ~isempty(app.studyFilePath) && isfolder(app.studyFilePath)
        saveDir = app.studyFilePath; % Use the folder directly
        [~, studyName, ~] = fileparts(app.studyFilePath); % Still extract studyName for filename
        if isempty(studyName)
            studyName = 'non_clustered';
        end
    elseif isfield(app.STUDY, 'filepath') && ~isempty(app.STUDY.filepath)
        % Fallback to STUDY.filepath
        if isfolder(app.STUDY.filepath)
            saveDir = app.STUDY.filepath;
            studyName = 'non_clustered';
        else
            [saveDir, studyName, ~] = fileparts(app.STUDY.filepath);
            if isempty(studyName)
                studyName = 'non_clustered';
            end
        end
    else
        % Last fallback
        fprintf('Warning: No valid save directory found. Using pwd.\n');
        saveDir = pwd;
        studyName = 'non_clustered';
    end

    % --- Evaluate across cluster range (2–20) ---
    clusterRange = 2:20;
    meanSilScores = zeros(size(clusterRange));
    medianSilScores = zeros(size(clusterRange));
    allSilhouetteScores = cell(1, length(clusterRange));
    allClusterAssignments = cell(1, length(clusterRange));

    for i = 1:numel(clusterRange)
        rng(42, 'twister');
        numClusters = clusterRange(i);
        [idx, ~, ~, ~] = kmeans(preclustData, numClusters, 'Replicates', 5, 'Distance', 'sqeuclidean');
        if numel(unique(idx)) > 1
            silVals = silhouette(preclustData, idx, 'Euclidean');
            meanSilScores(i) = mean(silVals);
            medianSilScores(i) = median(silVals);
            allSilhouetteScores{i} = silVals;
            allClusterAssignments{i} = idx;
        else
            meanSilScores(i) = NaN;
            medianSilScores(i) = NaN;
            allSilhouetteScores{i} = [];
            allClusterAssignments{i} = [];
        end
    end

    % --- Best K based on mean and median silhouette ---
    validIndices = find(~isnan(meanSilScores));
    if any(validIndices)
        [bestScore_mean, bestIdx_mean] = max(meanSilScores(validIndices));
        bestNumClusters_mean = clusterRange(validIndices(bestIdx_mean));
        [bestScore_median, bestIdx_median] = max(medianSilScores(validIndices));
        bestNumClusters_median = clusterRange(validIndices(bestIdx_median));
    else
        error('No valid silhouette scores computed for any K.');
    end

    % --- Line plot: Mean & Median Silhouette vs K ---
    fig1 = figure('Name', 'Optimal_kmeans Silhouette Scores vs Number of Clusters', 'NumberTitle', 'off');
    plot(clusterRange, meanSilScores, '-o', 'LineWidth', 2, 'MarkerSize', 8, 'Color', [0 0.4470 0.7410]);
    hold on;
    plot(clusterRange, medianSilScores, '-s', 'LineWidth', 2, 'MarkerSize', 8, 'Color', [0.4940 0.1840 0.5560]);
    plot(bestNumClusters_mean, bestScore_mean, 'r*', 'MarkerSize', 12, 'LineWidth', 2);
    plot(bestNumClusters_median, bestScore_median, 'r+', 'MarkerSize', 12, 'LineWidth', 2);
    xlabel('Number of Clusters ', 'FontSize', 12, 'FontWeight', 'bold');
    ylabel('Silhouette Score', 'FontSize', 12, 'FontWeight', 'bold');
    title('Optimal\_kmeans Mean & Median Silhouette Score vs Number of Clusters (pop\_clust)','FontSize',14,'FontWeight','bold');
    grid on;
    set(gca, 'FontSize', 10, 'XLim', [2 20], 'XTick', 2:20);
    legend({'Mean', 'Median', 'Best Mean', 'Best Median'}, 'Location', 'best');
    hold off;

   % --- Save line plot ---
tiffFileName1 = sprintf('%s_Optimal_kmeans_silhouette_scores_vs_clusters.tif', studyName);
tiffFilePath1 = app.getUniqueFilePath(saveDir, tiffFileName1); % Use getUniqueFilePath for consistency
savefig(fig1, fullfile(saveDir, sprintf('%s_Optimal_kmeans_silhouette_scores_vs_clusters.fig', studyName)));
print(fig1, '-dtiff', tiffFilePath1, '-r600');

    % --- Bar plot: Silhouette Means per Cluster for Valid K ---
    validClusterNumbers = clusterRange(validIndices);
    numValidClusters = length(validClusterNumbers);
    rows = ceil(sqrt(numValidClusters));
    cols = ceil(numValidClusters / rows);

    fig2 = figure('Name', 'Optimal_kmeans Silhouette Plots for Valid Clusters', 'NumberTitle', 'off', 'Position', [100, 100, 1200, 800]);
    movegui(fig2, 'center');
    for k = 1:numValidClusters
        numClusters = validClusterNumbers(k);
        clusterAssignments = allClusterAssignments{find(clusterRange == numClusters)};
        silVals = allSilhouetteScores{find(clusterRange == numClusters)};
        validIdx = clusterAssignments > 0;
        if any(validIdx)
            subplot(rows, cols, k);
            uniqueClusters = unique(clusterAssignments(validIdx));
            means = zeros(length(uniqueClusters), 1);
            for c = 1:length(uniqueClusters)
                clusterIdx = uniqueClusters(c);
                means(c) = mean(silVals(clusterAssignments == clusterIdx));
            end
            bar(1:length(uniqueClusters), means, 'FaceColor', [0 0.4470 0.7410]);
            set(gca, 'XTick', 1:length(uniqueClusters));
            set(gca, 'XTickLabel', arrayfun(@(x) num2str(x), 1:length(uniqueClusters), 'UniformOutput', false));
            ylim([0 max(0.5, max(means))]);
            if any(validClusterNumbers(k) == bestNumClusters_mean)
                titleStr = sprintf('%d Clusters (Mean = %.3f)^{\\fontsize{14}\\bf\\color{red}*}', ...
                    numClusters, meanSilScores(find(clusterRange == numClusters)));
            else
                titleStr = sprintf('%d Clusters (Mean = %.3f)', ...
                    numClusters, meanSilScores(find(clusterRange == numClusters)));
            end
            title(titleStr, 'FontSize', 10, 'FontWeight', 'bold', 'Interpreter', 'tex');
            xlabel('Cluster Number', 'FontSize', 6, 'FontWeight', 'bold');
            ylabel('Silhouette Score', 'FontSize', 6, 'FontWeight', 'bold');
            set(gca, 'FontSize', 8);
            grid on;
            hold on;
            yline(meanSilScores(find(clusterRange == numClusters)), '--r', 'LineWidth', 1);
            yl = ylim;
            xl = xlim;
            text(mean(xl), yl(2), sprintf('-- Mean = %.3f', meanSilScores(find(clusterRange == numClusters))), ...
                'Color', 'r', 'FontSize', 8, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'top');
            hold off;
        else
            subplot(rows, cols, k);
            title(sprintf('%d Clusters (No Real Clusters)', numClusters), 'FontSize', 10, 'FontWeight', 'bold');
            set(gca, 'FontSize', 8);
        end
    end
    sgtitle(sprintf('Optimal\\_kmeans Silhouette Mean Plots for %d Valid Clusters (Best Highlighted with red *)', numValidClusters), ...
        'FontSize', 12, 'FontWeight', 'bold');
    set(gcf, 'Color', 'white');

    % --- Save bar plot ---
tiffFileName2 = sprintf('%s_Optimal_kmeans_silhouette_plots_valid_clusters.tif', studyName);
tiffFilePath2 = app.getUniqueFilePath(saveDir, tiffFileName2);
savefig(fig2, fullfile(saveDir, sprintf('%s_Optimal_kmeans_silhouette_plots_valid_clusters.fig', studyName)));
print(fig2, '-dtiff', tiffFilePath2, '-r600');

    % --- Box plot: Median Silhouette Across Valid K ---
    allSil = [];
    allGroup = [];
    medianValuesValid = zeros(numValidClusters, 1);
    for k = 1:numValidClusters
        numClusters = validClusterNumbers(k);
        clusterAssign_full = allClusterAssignments{find(clusterRange == numClusters)};
        silPerCluster_full = allSilhouetteScores{find(clusterRange == numClusters)};
        validIdx = clusterAssign_full > 0;
        if ~isempty(silPerCluster_full) && any(validIdx)
            silPerCluster = silPerCluster_full;
            allSil = [allSil; silPerCluster];
            allGroup = [allGroup; repmat(numClusters, length(silPerCluster), 1)];
            medianValuesValid(k) = median(silPerCluster);
        else
            app.ResultLabel.Text = sprintf('%s\nWarning: No valid components for %d clusters, skipping median calculation.', ...
                app.ResultLabel.Text, numClusters);
            medianValuesValid(k) = NaN;
        end
    end

    validMedians = ~isnan(medianValuesValid);
    if any(validMedians)
        [~, bestIdx] = max(medianValuesValid(validMedians));
        bestClusterNum = validClusterNumbers(bestIdx);

        fig3 = figure('Name', 'Optimal_kmeans Silhouette Median Box Plots', 'NumberTitle', 'off', 'Position', [100, 100, 1200, 600]);
        movegui(fig3, 'center');
        hBox = boxplot(allSil, allGroup, 'Whisker', 1.5, 'Colors', 'k', 'Symbol', 'r+');
        hold on;
        cmap = parula(numValidClusters);
        hBoxes = flipud(findobj(hBox, 'Tag', 'Box'));
        for j = 1:numValidClusters
            if ~isnan(medianValuesValid(j))
                patch(get(hBoxes(j), 'XData'), get(hBoxes(j), 'YData'), cmap(j, :), 'FaceAlpha', 0.5, 'EdgeColor', 'k');
            end
        end
        bestClusterIdx = find(validClusterNumbers == bestClusterNum);
        medianY = median(allSil(allGroup == bestClusterNum));
        plot(bestClusterIdx, medianY, 'r+', 'MarkerSize', 12, 'LineWidth', 2);
        xlabel('Number of Clusters', 'FontSize', 12, 'FontWeight', 'bold');
        ylabel('Silhouette Value', 'FontSize', 12, 'FontWeight', 'bold');
        sgtitle(sprintf('Optimal\\_kmeans Silhouette Median Plots for %d Valid Clusters (Best Highlighted with red +)', sum(validMedians)), ...
            'FontSize', 12, 'FontWeight', 'bold');
        grid on;
        hOut = findobj(hBox, 'Tag', 'Outliers');
        set(hOut, 'MarkerEdgeColor', 'r', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
        set(gcf, 'Color', 'white');
        hold off;

% --- Save box plot ---
tiffFileName3 = sprintf('%s_Optimal_kmeans_median_boxplot.tif', studyName);
tiffFilePath3 = app.getUniqueFilePath(saveDir, tiffFileName3);
savefig(fig3, fullfile(saveDir, sprintf('%s_Optimal_kmeans_median_boxplot.fig', studyName)));
print(fig3, '-dtiff', tiffFilePath3, '-r600');

    else
        app.ResultLabel.Text = sprintf('%s\nWarning: No valid median values for any cluster counts, skipping boxplot.', ...
            app.ResultLabel.Text);
    end

    fprintf('Optimal_kmeans plots saved successfully.\n');

    % --- Call helper summary with best mean K ---
    idx_best = allClusterAssignments{find(clusterRange == bestNumClusters_mean)};
    sil_best = allSilhouetteScores{find(clusterRange == bestNumClusters_mean)};
    printClusterSummary(bestNumClusters_mean, idx_best, sil_best, clustAlgo, clusterRange, allClusterAssignments, allSilhouetteScores, ...
                   bestNumClusters_median, allClusterAssignments{find(clusterRange == bestNumClusters_median)}, ...
                   allSilhouetteScores{find(clusterRange == bestNumClusters_median)});

    % --- Print best mean and median ---
    fprintf('Best cluster by mean: %d (Mean Silhouette = %.3f)\n', bestNumClusters_mean, bestScore_mean);
    fprintf('Best cluster by median: %d (Median Silhouette = %.3f)\n', bestNumClusters_median, bestScore_median);

    % --- Store results in app ---
    app.ResultLabel.Text = sprintf('%s\nBest cluster by mean: %d (Mean Silhouette = %.3f)', ...
        app.ResultLabel.Text, bestNumClusters_mean, bestScore_mean);
    app.ResultLabel.Text = sprintf('%s\nBest cluster by median: %d (Median Silhouette = %.3f)', ...
        app.ResultLabel.Text, bestNumClusters_median, bestScore_median);
end


% Helper: Find AP preference to target desired number of clusters
function p_opt = findAPPreference(preclustData, desiredK)
    % Convert to similarity matrix (negative squared Euclidean distance)
    S = -pdist2(preclustData, preclustData, 'euclidean').^2;

    p_min = min(S(:));
    p_max = max(S(:));
    tol = 1e-5;
    maxIter = 20;

    for iter = 1:maxIter
        p_try = (p_min + p_max)/2;
        [idx, ~, ~, ~] = apcluster(S, p_try);
        K_found = numel(unique(idx));

        if K_found == desiredK
            p_opt = p_try;
            return;
        elseif K_found < desiredK
            p_max = p_try;  % lower preference → fewer clusters
        else
            p_min = p_try;  % higher preference → more clusters
        end

        if abs(p_max - p_min) < tol
            break;
        end
    end
    p_opt = p_try;  % closest found if exact match not reached
end


% Step 9.0c: Handle Affinity Propagation separately
if strcmpi(clustAlgo, 'Affinity Propagation')
    fprintf('Running Affinity Propagation (evaluating 2–20 clusters)...\n');

    % --- Extract preclustering data ---
    preclustData = app.STUDY.etc.preclust.preclustdata;

    % --- Debug statements to verify paths ---
    fprintf('Debug: app.studyFilePath = %s\n', string(app.studyFilePath));
    fprintf('Debug: isprop(app, ''studyFilePath'') = %d\n', isprop(app, 'studyFilePath'));
    if isfield(app.STUDY, 'filepath')
        fprintf('Debug: app.STUDY.filepath = %s\n', string(app.STUDY.filepath));
    else
        fprintf('Debug: app.STUDY.filepath does not exist\n');
    end

    % --- Correct Save Path ---
    if isprop(app, 'studyFilePath') && ~isempty(app.studyFilePath) && isfolder(app.studyFilePath)
        saveDir = app.studyFilePath; % Use the selected folder directly
        [~, studyName, ~] = fileparts(app.studyFilePath); % Extract studyName for filenames
        if isempty(studyName)
            studyName = 'non_clustered';
        end
    elseif isfield(app.STUDY, 'filepath') && ~isempty(app.STUDY.filepath)
        % Fallback to STUDY.filepath
        if isfolder(app.STUDY.filepath)
            saveDir = app.STUDY.filepath;
            studyName = 'non_clustered'; % Default if STUDY.filepath is a folder
        else
            [saveDir, studyName, ~] = fileparts(app.STUDY.filepath);
            if isempty(studyName)
                studyName = 'non_clustered';
            end
        end
    else
        % Last fallback
        fprintf('Warning: No valid save directory found. Using pwd.\n');
        saveDir = pwd;
        studyName = 'non_clustered';
    end

    % --- Evaluate across cluster range (2–20) ---
    clusterRange = 2:20;
    numClustersFound = zeros(size(clusterRange));
    preferencesTried = zeros(size(clusterRange));
    meanSilScores = zeros(size(clusterRange));
    medianSilScores = zeros(size(clusterRange));
    allSilhouetteScores = cell(1, length(clusterRange));
    allClusterAssignments = cell(1, length(clusterRange));

    for i = 1:numel(clusterRange)
        rng(42, 'twister');
        desiredK = clusterRange(i);
        p_opt = findAPPreference(preclustData, desiredK);
        preferencesTried(i) = p_opt;

        S = squareform(pdist(preclustData, 'euclidean'));
        [idx, ~, ~, ~] = apcluster(S, p_opt, 'maxits', 200, 'convits', 100, 'dampfact', 0.9);
        numClustersFound(i) = numel(unique(idx));

        if numClustersFound(i) > 1
            silVals = silhouette(preclustData, idx, 'Euclidean');
            meanSilScores(i) = mean(silVals);
            medianSilScores(i) = median(silVals);
            allSilhouetteScores{i} = silVals;
            allClusterAssignments{i} = idx;
        else
            meanSilScores(i) = NaN;
            medianSilScores(i) = NaN;
            allSilhouetteScores{i} = [];
            allClusterAssignments{i} = [];
        end
    end

    % --- Best K based on mean and median silhouette ---
    validIndices = find(~isnan(meanSilScores));
    if any(validIndices)
        [bestScore_mean, bestIdx_mean] = max(meanSilScores(validIndices));
        bestNumClusters_mean = clusterRange(validIndices(bestIdx_mean));
        [bestScore_median, bestIdx_median] = max(medianSilScores(validIndices));
        bestNumClusters_median = clusterRange(validIndices(bestIdx_median));
    else
        error('No valid silhouette scores computed for any K.');
    end

    % --- Line plot: Mean & Median Silhouette vs Desired K ---
    fig1 = figure('Name', 'AP Silhouette Scores vs Number of Clusters', 'NumberTitle', 'off');
    plot(clusterRange, meanSilScores, '-o', 'LineWidth', 2, 'MarkerSize', 8, 'Color', [0 0.4470 0.7410]);
    hold on;
    plot(clusterRange, medianSilScores, '-s', 'LineWidth', 2, 'MarkerSize', 8, 'Color', [0.4940 0.1840 0.5560]);
    plot(bestNumClusters_mean, bestScore_mean, 'r*', 'MarkerSize', 12, 'LineWidth', 2);
    plot(bestNumClusters_median, bestScore_median, 'r+', 'MarkerSize', 12, 'LineWidth', 2);
    xlabel('Number of Clusters', 'FontSize', 12, 'FontWeight', 'bold');
    ylabel('Silhouette Score', 'FontSize', 12, 'FontWeight', 'bold');
    title('AP Mean & Median Silhouette Score vs Number of Clusters (pop_clust)', 'FontSize', 14, 'FontWeight', 'bold');
    grid on;
    set(gca, 'FontSize', 10, 'XLim', [2 20], 'XTick', 2:20);
    legend({'Mean', 'Median', 'Best Mean', 'Best Median'}, 'Location', 'best');
    hold off;

    % --- Save line plot in correct folder ---
    tiffFileName1 = sprintf('%s_AP_silhouette_scores_vs_clusters.tif', studyName);
    tiffFilePath1 = app.getUniqueFilePath(saveDir, tiffFileName1);
    savefig(fig1, fullfile(saveDir, sprintf('%s_AP_silhouette_scores_vs_clusters.fig', studyName)));
    print(fig1, '-dtiff', tiffFilePath1, '-r600');

    % --- Bar plot: Silhouette Means per Cluster for Valid K ---
    validClusterNumbers = clusterRange(validIndices);
    numValidClusters = length(validClusterNumbers);
    rows = ceil(sqrt(numValidClusters));
    cols = ceil(numValidClusters / rows);

    fig2 = figure('Name', 'AP Silhouette Plots for Valid Clusters', 'NumberTitle', 'off', 'Position', [100, 100, 1200, 800]);
    movegui(fig2, 'center');
    for k = 1:numValidClusters
        numClusters = validClusterNumbers(k);
        clusterAssignments = allClusterAssignments{find(clusterRange == numClusters)};
        silVals = allSilhouetteScores{find(clusterRange == numClusters)};
        validIdx = clusterAssignments > 0;
        if any(validIdx)
            subplot(rows, cols, k);
            uniqueClusters = unique(clusterAssignments(validIdx));
            means = zeros(length(uniqueClusters), 1);
            for c = 1:length(uniqueClusters)
                clusterIdx = uniqueClusters(c);
                means(c) = mean(silVals(clusterAssignments == clusterIdx));
            end
            bar(1:length(uniqueClusters), means, 'FaceColor', [0 0.4470 0.7410]);
            set(gca, 'XTick', 1:length(uniqueClusters));
            set(gca, 'XTickLabel', arrayfun(@(x) num2str(x), 1:length(uniqueClusters), 'UniformOutput', false));
            ylim([0 max(0.5, max(means))]);
            if any(validClusterNumbers(k) == bestNumClusters_mean)
                titleStr = sprintf('%d Clusters (Mean = %.3f)^{\\fontsize{14}\\bf\\color{red}*}', ...
                    numClusters, meanSilScores(find(clusterRange == numClusters)));
            else
                titleStr = sprintf('%d Clusters (Mean = %.3f)', ...
                    numClusters, meanSilScores(find(clusterRange == numClusters)));
            end
            title(titleStr, 'FontSize', 10, 'FontWeight', 'bold', 'Interpreter', 'tex');
            xlabel('Cluster Number', 'FontSize', 6, 'FontWeight', 'bold');
            ylabel('Silhouette Score', 'FontSize', 6, 'FontWeight', 'bold');
            set(gca, 'FontSize', 8);
            grid on;
            hold on;
            yline(meanSilScores(find(clusterRange == numClusters)), '--r', 'LineWidth', 1);
            yl = ylim;
            xl = xlim;
            text(mean(xl), yl(2), sprintf('-- Mean = %.3f', meanSilScores(find(clusterRange == numClusters))), ...
                'Color', 'r', 'FontSize', 8, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'top');
            hold off;
        else
            subplot(rows, cols, k);
            title(sprintf('%d Clusters (No Real Clusters)', numClusters), 'FontSize', 10, 'FontWeight', 'bold');
            set(gca, 'FontSize', 8);
        end
    end
    sgtitle(sprintf('AP Silhouette Mean Plots for %d Valid Clusters (Best Highlighted with red *)', numValidClusters), ...
        'FontSize', 12, 'FontWeight', 'bold');
    set(gcf, 'Color', 'white');

    % --- Save bar plot in correct folder ---
    tiffFileName2 = sprintf('%s_AP_silhouette_plots_valid_clusters.tif', studyName);
    tiffFilePath2 = app.getUniqueFilePath(saveDir, tiffFileName2);
    savefig(fig2, fullfile(saveDir, sprintf('%s_AP_silhouette_plots_valid_clusters.fig', studyName)));
    print(fig2, '-dtiff', tiffFilePath2, '-r600');

    % --- Box plot: Median Silhouette Across Valid K ---
    allSil = [];
    allGroup = [];
    medianValuesValid = zeros(numValidClusters, 1);
    for k = 1:numValidClusters
        numClusters = validClusterNumbers(k);
        clusterAssign_full = allClusterAssignments{find(clusterRange == numClusters)};
        silPerCluster_full = allSilhouetteScores{find(clusterRange == numClusters)};
        validIdx = clusterAssign_full > 0;
        if ~isempty(silPerCluster_full) && any(validIdx)
            silPerCluster = silPerCluster_full;
            allSil = [allSil; silPerCluster];
            allGroup = [allGroup; repmat(numClusters, length(silPerCluster), 1)];
            medianValuesValid(k) = median(silPerCluster);
        else
            app.ResultLabel.Text = sprintf('%s\nWarning: No valid components for %d clusters, skipping median calculation.', ...
                app.ResultLabel.Text, numClusters);
            medianValuesValid(k) = NaN;
        end
    end

    validMedians = ~isnan(medianValuesValid);
    if any(validMedians)
        [~, bestIdx] = max(medianValuesValid(validMedians));
        bestClusterNum = validClusterNumbers(bestIdx);

        fig3 = figure('Name', 'AP Silhouette Median Plots for Valid Cluster', ...
            'NumberTitle', 'off', 'Position', [100, 100, 1200, 600]);
        movegui(fig3, 'center');
        hBox = boxplot(allSil, allGroup, 'Whisker', 1.5, 'Colors', 'k', 'Symbol', 'r+');
        hold on;
        cmap = parula(numValidClusters);
        hBoxes = flipud(findobj(hBox, 'Tag', 'Box'));
        for j = 1:numValidClusters
            if ~isnan(medianValuesValid(j))
                patch(get(hBoxes(j), 'XData'), get(hBoxes(j), 'YData'), cmap(j, :), 'FaceAlpha', 0.5, 'EdgeColor', 'k');
            end
        end
        bestClusterIdx = find(validClusterNumbers == bestClusterNum);
        medianY = median(allSil(allGroup == bestClusterNum));
        plot(bestClusterIdx, medianY, 'r+', 'MarkerSize', 12, 'LineWidth', 2);
        xlabel('Desired Cluster Number', 'FontSize', 12, 'FontWeight', 'bold');
        ylabel('Silhouette Value', 'FontSize', 12, 'FontWeight', 'bold');
        sgtitle(sprintf('AP Silhouette Median Plots for %d Valid Clusters (Best Highlighted with red +)', sum(validMedians)), ...
            'FontSize', 12, 'FontWeight', 'bold');
        grid on;
        hOut = findobj(hBox, 'Tag', 'Outliers');
        set(hOut, 'MarkerEdgeColor', 'r', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
        set(gcf, 'Color', 'white');
        hold off;

        % --- Save box plot in correct folder ---
        tiffFileName3 = sprintf('%s_AP_median_boxplot.tif', studyName);
        tiffFilePath3 = app.getUniqueFilePath(saveDir, tiffFileName3);
        savefig(fig3, fullfile(saveDir, sprintf('%s_AP_median_boxplot.fig', studyName)));
        print(fig3, '-dtiff', tiffFilePath3, '-r600');
    else
        app.ResultLabel.Text = sprintf('%s\nWarning: No valid median values for any cluster counts, skipping boxplot.', ...
            app.ResultLabel.Text);
    end

    fprintf('Affinity Propagation plots saved successfully.\n');

    % --- Call helper summary with best mean K ---
    idx_best = allClusterAssignments{find(clusterRange == bestNumClusters_mean)};
    sil_best = allSilhouetteScores{find(clusterRange == bestNumClusters_mean)};
    printClusterSummary(bestNumClusters_mean, idx_best, sil_best, clustAlgo, clusterRange, allClusterAssignments, allSilhouetteScores, ...
        bestNumClusters_median, allClusterAssignments{find(clusterRange == bestNumClusters_median)}, ...
        allSilhouetteScores{find(clusterRange == bestNumClusters_median)});

    % --- Print best mean and median ---
    fprintf('Best cluster by mean: %d (Mean Silhouette = %.3f)\n', bestNumClusters_mean, bestScore_mean);
    fprintf('Best cluster by median: %d (Median Silhouette = %.3f)\n', bestNumClusters_median, bestScore_median);

    % --- Store results in app ---
    app.ResultLabel.Text = sprintf('%s\nBest cluster by mean: %d (Mean Silhouette = %.3f)', ...
        app.ResultLabel.Text, bestNumClusters_mean, bestScore_mean);
    app.ResultLabel.Text = sprintf('%s\nBest cluster by median: %d (Median Silhouette = %.3f)', ...
        app.ResultLabel.Text, bestNumClusters_median, bestScore_median);
end


% Step 9.1: Alert user about incomplete cluster assignments
if ~isempty(incompleteClusterCounts)
    % Format list of problematic cluster counts
    incompleteList = sprintf('%d, ', incompleteClusterCounts);
    incompleteList = incompleteList(1:end-2); % Remove trailing comma
    
    % Warning message with emoticons
    msg = sprintf(['⚠️ Some cluster counts had issues (e.g., fewer clusters formed or not all %d dipoles assigned):\n\n' ...
                   '❌ Problematic cluster counts: %s\n\n' ...
                   '💡 Recommendation: Avoid selecting these cluster counts for final clustering, or consider changing the clustering algorithm.'], ...
                   numComponents, incompleteList);
    
    % Ask user how to proceed
    choice = uiconfirm(app.UIFigure, msg, 'Clustering Issues Detected', ...
        'Options', {'✅ Continue with Current Algorithm', '🔄 Change Algorithm'}, ...
        'DefaultOption', 1, 'Icon', 'warning');
    
    fprintf('User selected: %s\n', choice);
    
    % If user chooses to reset
    if strcmp(choice, '🔄 Change Algorithm')
        % Reset app state
        app.STUDY = [];
        app.ALLEEG = [];
        app.studyFilePath = '';
        
        % Reset dropdowns and UI fields
        app.FilteringusingResidualVarianceDropDown.Value = app.FilteringusingResidualVarianceDropDown.Items{1};
        app.FilteringusingResidualVarianceDropDown.Enable = 'off';
        app.FilteringusingResidualVarianceDropDownLabel.Enable = 'off';
        
        app.ClusteringMethodDropDown.Value = app.ClusteringMethodDropDown.Items{1};
        app.ClusteringMethodDropDown.Enable = 'off';
        app.ClusteringMethodDropDownLabel.Enable = 'off';
        
        app.StandardDeviationremoveoutliersDropDown.Value = app.StandardDeviationremoveoutliersDropDown.Items{1};
        app.StandardDeviationremoveoutliersDropDown.Enable = 'off';
        app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'off';
        
        app.NumberofGroupsDropDown.Value = app.NumberofGroupsDropDown.Items{1};
        app.NumberofGroupsDropDown.Enable = 'off';
        app.NumberofGroupsDropDownLabel.Enable = 'off';
        
        app.Group1SubjectRange.Value = '';
        app.Group1SubjectRange.Enable = 'off';
        app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';
        
        app.Group2SubjectRange.Value = '';
        app.Group2SubjectRange.Enable = 'off';
        app.Group2SubjectRangeEg1630Label.Enable = 'off';
        
        app.Group3SubjectRange.Value = '';
        app.Group3SubjectRange.Enable = 'off';
        app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
        
        % Reset buttons and status
        app.SubmitParametersButton.Enable = 'off';
        app.SubmitGroupRangesButton.Enable = 'off';
        app.StatusLabel.Text = '🔄 Resetting...';
        app.ResultLabel.Text = '⚡ App state cleared. Preparing to restart...';
        
        drawnow;
        
        % Alert user
        uialert(app.UIFigure, '✅ The app has been reset. Please start by selecting a folder.', ...
            'App Reset', 'Icon', 'info');
        
        app.StatusLabel.Text = '📂 Action Required';
        app.ResultLabel.Text = '👉 Please start by selecting a folder to proceed.';
        return;
    end
end


% Step 10: Line plot of Mean & Median 
if ~strcmpi(clustAlgo, 'Affinity Propagation') && ...
   ~(strcmpi(clustAlgo, 'optimal_kmeans') && stdDevThreshold < 1)

% Check save directory
if isempty(app.studyFilePath)
    fprintf('Warning: studyFilePath is empty. Saving plots to current working directory.\n');
    saveDir = pwd;
else
    saveDir = app.studyFilePath;
end

fig1 = figure('Name', sprintf('%s - Silhouette Scores vs Number of Clusters', clustAlgo), 'NumberTitle', 'off');

% Plot mean silhouette
plot(clusterNumbers, meanSilhouetteScores, '-o', 'LineWidth', 2, 'MarkerSize', 8, 'Color', [0 0.4470 0.7410]);
hold on;

% Plot median silhouette
plot(clusterNumbers, medianSilhouetteScores, '-s', 'LineWidth', 2, 'MarkerSize', 8, 'Color', [0.4940 0.1840 0.5560]);

% Best clusters
[bestScore_mean, bestIdx_mean] = max(meanSilhouetteScores);
bestNumClusters_mean = clusterNumbers(bestIdx_mean);

[bestScore_median, bestIdx_median] = max(medianSilhouetteScores);
bestNumClusters_median = clusterNumbers(bestIdx_median);

plot(bestNumClusters_mean, bestScore_mean, 'r*', 'MarkerSize', 12, 'LineWidth', 2);
plot(bestNumClusters_median, bestScore_median, 'r+', 'MarkerSize', 12, 'LineWidth', 2);

xlabel('Number of Clusters','FontSize',12,'FontWeight','bold');
ylabel('Silhouette Score','FontSize',12,'FontWeight','bold');
title(sprintf('%s - Mean & Median Silhouette Score vs Number of Clusters (pop\\_clust)', ...
    strrep(clustAlgo, '_', '\_')), ...
    'FontSize', 14, 'FontWeight', 'bold');
grid on;
set(gca,'FontSize',10,'XLim',[2 20],'XTick',2:20);
legend({'Mean','Median','Best Mean','Best Median'}, 'Location','best');

% Save figure
[~, studyName, ~] = fileparts(app.studyFilePath);
if isempty(studyName), studyName = 'non_clustered'; end
tiffFileName1 = sprintf('%s_%s_silhouette_scores_vs_clusters_lineplot.tiff', clustAlgo, studyName);
tiffFilePath1 = app.getUniqueFilePath(saveDir, tiffFileName1);
savefig(fig1, fullfile(saveDir, sprintf('%s_%s_silhouette_scores_vs_clusters_lineplot.fig', clustAlgo, studyName)));
print(fig1, '-dtiff', tiffFilePath1, '-r600');
hold off;
end

% Step 11: Plot silhouette scores for each number of valid clusters
if ~strcmpi(clustAlgo, 'Affinity Propagation') && ...
   ~(strcmpi(clustAlgo, 'optimal_kmeans') && stdDevThreshold < 1)
% Filter out incomplete cluster counts
validClusterNumbers = setdiff(clusterNumbers, incompleteClusterCounts);
numValidClusters = length(validClusterNumbers);

% Determine subplot grid size dynamically
rows = ceil(sqrt(numValidClusters));
cols = ceil(numValidClusters / rows);

fig2 = figure('Name', sprintf('%s - Silhouette boxplots for Valid Clusters', clustAlgo), 'NumberTitle', 'off', 'Position', [100, 100, 1200, 800]);
movegui(fig2, 'center'); % Center the figure on screen
for k = 1:numValidClusters
    numClusters = validClusterNumbers(k);
    clusterAssignments = allClusterAssignments{find(clusterNumbers == numClusters)};
    
    % Create subplot based on dynamic grid
    subplot(rows, cols, k);
    validIndices = clusterAssignments > 0;
    if any(validIndices)
        s = silhouette(preclustData(validIndices, :), clusterAssignments(validIndices), 'Euclidean');
        uniqueClusters = unique(clusterAssignments(validIndices));
        means = zeros(length(uniqueClusters), 1);
        for c = 1:length(uniqueClusters)
            clusterIdx = uniqueClusters(c);
            means(c) = mean(s(clusterAssignments(validIndices) == clusterIdx));
        end
        bar(1:length(uniqueClusters), means, 'FaceColor', [0 0.4470 0.7410]);
        % Highlight best mean cluster with red *
        % if any(validClusterNumbers(k) == bestNumClusters_mean)
        %     yMax = max(means);
        %     hold on;
        %     plot(length(uniqueClusters)/2, yMax + 0.05, 'r*', 'MarkerSize',12, 'LineWidth',2); % Place above bar
        %     hold off;
        % end
        set(gca, 'XTick', 1:length(uniqueClusters));
        set(gca, 'XTickLabel', arrayfun(@(x) num2str(x + 2), 1:length(uniqueClusters), 'UniformOutput', false));
        %ylim([0 0.5]); % Set y-axis limits
        ylim([0 max(0.5, max(s))]); % auto-scale but min=0.5
        %ylim([0 1]); % Set y-axis limits from 0 to 1 for consistency
        % ----- NEW: red superscript asterisk in title for best cluster -----
            if any(validClusterNumbers(k) == bestNumClusters_mean)
                titleStr = sprintf('%d Clusters (Mean = %.3f)^{\\fontsize{14}\\bf\\color{red}*}', ...
                    numClusters, ...
                    meanSilhouetteScores(find(clusterNumbers == numClusters)));
            else
                titleStr = sprintf('%d Clusters (Mean = %.3f)', ...
                    numClusters, ...
                    meanSilhouetteScores(find(clusterNumbers == numClusters)));
            end
            title(titleStr, 'FontSize', 10, 'FontWeight', 'bold', ...
                  'Interpreter', 'tex');
            % -------------------------------------------------------------------

            xlabel('Cluster Number', 'FontSize', 6, 'FontWeight', 'bold');
            ylabel('Silhouette Score', 'FontSize', 6, 'FontWeight', 'bold');
            set(gca, 'FontSize', 8);
            grid on;

            % Draw red dashed line
            hold on;
            yline(meanSilhouetteScores(find(clusterNumbers == numClusters)), ...
                  '--r', 'LineWidth', 1);
            
            % Add red text at the middle-top of the axes
            yl = ylim;                      % current y-axis limits
            xl = xlim;                      % current x-axis limits
            text(mean(xl), yl(2), ...
                 sprintf('-- Mean = %.3f', meanSilhouetteScores(find(clusterNumbers == numClusters))), ...
                 'Color', 'r', ...
                 'FontSize', 8, ...
                 'FontWeight', 'bold', ...
                 'HorizontalAlignment', 'center', ...
                 'VerticalAlignment', 'top');
            
            hold off;
        else
            title(sprintf('%d Clusters (No Real Clusters)', numClusters), ...
                'FontSize', 10, 'FontWeight', 'bold');
            set(gca, 'FontSize', 8);
        end
    end

% Adjust layout and add overall title
sgtitle(sprintf('%s - Silhouette Mean Plots for %d Valid Clusters (Best Highlighted with red *)', ...
    strrep(clustAlgo, '_', '\_'), numValidClusters), ...
    'FontSize', 12, 'FontWeight', 'bold');
%sgtitle(sprintf('%s - Silhouette Mean Plots for %d Valid Clusters (Best Highlighted with red *)', ...
 %   clustAlgo, numValidClusters), ...
  %  'FontSize', 12, 'FontWeight', 'bold', 'Interpreter', 'none');

set(gcf, 'Color', 'white');

% Save the combined plot as TIFF at 600 DPI
tiffFileName2 = sprintf('%s_%s_silhouette_barplots_valid_clusters.tiff', clustAlgo, studyName);
tiffFilePath2 = app.getUniqueFilePath(saveDir, tiffFileName2);
savefig(fig2, fullfile(saveDir, sprintf('%s_%s_silhouette_barplots_valid_clusters.fig', clustAlgo, studyName)));
print(fig2, '-dtiff', tiffFilePath2, '-r600'); 
end

% Step 12: Combined Boxplot for Median per Valid Cluster 
if ~strcmpi(clustAlgo, 'Affinity Propagation') && ...
   ~(strcmpi(clustAlgo, 'optimal_kmeans') && stdDevThreshold < 1)

    % Filter out incomplete cluster counts
    validClusterNumbers = setdiff(clusterNumbers, incompleteClusterCounts);
    numValidClusters    = length(validClusterNumbers);

    % Prepare combined data for boxplot
    allSil   = [];
    allGroup = [];
    medianValuesValid = zeros(numValidClusters,1);

    for k = 1:numValidClusters
        numClusters = validClusterNumbers(k);
        clusterAssign_full = allClusterAssignments{find(clusterNumbers == numClusters)};
        silPerCluster_full = allSilhouetteScores{find(clusterNumbers == numClusters)};
        validIdx = clusterAssign_full > 0;
        if ~isempty(silPerCluster_full) && any(validIdx)
            silPerCluster = silPerCluster_full;
            allSil   = [allSil; silPerCluster];
            allGroup = [allGroup; repmat(numClusters, length(silPerCluster), 1)];
            medianValuesValid(k) = median(silPerCluster);
        else
            app.ResultLabel.Text = sprintf('%s\nWarning: No valid components for %d clusters, skipping median calculation.', ...
                app.ResultLabel.Text, numClusters);
            medianValuesValid(k) = NaN;
        end
    end

    % Remove NaN medians to avoid issues in finding best cluster
    validMedians = ~isnan(medianValuesValid);
    if any(validMedians)
        % Determine best cluster based on max median
        [~, bestIdx] = max(medianValuesValid(validMedians));
        bestClusterNum = validClusterNumbers(bestIdx);

        % Debugging output to ResultLabel
        app.ResultLabel.Text = sprintf('%s\nmedianValuesValid: %s', ...
            app.ResultLabel.Text, mat2str(medianValuesValid, 3));
        app.ResultLabel.Text = sprintf('%s\nvalidClusterNumbers: %s', ...
            app.ResultLabel.Text, mat2str(validClusterNumbers));
        app.ResultLabel.Text = sprintf('%s\nbestIdx: %d, bestClusterNum: %d', ...
            app.ResultLabel.Text, bestIdx, bestClusterNum);

        % Create figure
        figMedian = figure('Name',sprintf('%s - Silhouette Median Plots for Valid Cluster', clustAlgo), ...
                           'NumberTitle','off','Position',[100,100,1200,600]);
        movegui(figMedian,'center');

        % --- Boxplot with colored boxes ---
        hBox = boxplot(allSil, allGroup, 'Whisker',1.5, 'Colors','k', 'Symbol','r+'); % black edges, red outliers
        hold on;

        % Fill boxes with gradient colors
        cmap = parula(numValidClusters);
        hBoxes = flipud(findobj(hBox,'Tag','Box'));  % flip to match cluster order
        for j = 1:numValidClusters
            if ~isnan(medianValuesValid(j))  % Only plot valid boxes
                patch(get(hBoxes(j),'XData'), get(hBoxes(j),'YData'), cmap(j,:), ...
                      'FaceAlpha',0.5, 'EdgeColor','k');
            end
        end

        % Highlight best cluster median with red square
        bestClusterIdx = find(validClusterNumbers == bestClusterNum);
        medianY = median(allSil(allGroup == bestClusterNum));
        plot(bestClusterIdx, medianY, 'r+', 'MarkerSize',12, 'LineWidth',2);

        % Labels and title
        xlabel('Cluster Number','FontSize',12,'FontWeight','bold');
        ylabel('Silhouette Value','FontSize',12,'FontWeight','bold');
        sgtitle(sprintf('%s - Silhouette Median Plots for %d Valid Clusters (Best Highlighted with red +)', ...
        strrep(clustAlgo, '_', '\_'), sum(validMedians)), ...
        'FontSize', 12, 'FontWeight', 'bold');
        %sgtitle(sprintf('%s - Silhouette Median Plots for %d Valid Clusters (Best Highlighted with red +)', ...
        %strrep(clustAlgo, '_', '\_'), sum(validMedians)), ...
        %'FontSize', 12, 'FontWeight', 'bold', 'Interpreter', 'none');

        grid on;

        % Make outliers red
        hOut = findobj(hBox,'Tag','Outliers');
        set(hOut, 'MarkerEdgeColor','r','MarkerFaceColor','r','MarkerSize',6);

        set(gcf,'Color','white');

        % Save figure
        tiffFileNameMedian = sprintf('%s_%s_silhouette_boxplots_valid_clusters.tif', clustAlgo, studyName);
        tiffFilePathMedian  = app.getUniqueFilePath(saveDir, tiffFileNameMedian);
        savefig(figMedian, fullfile(saveDir, sprintf('%s_%s_silhouette_boxplots_valid_clusters.fig', clustAlgo, studyName)));
        print(figMedian, '-dtiff', tiffFilePathMedian, '-r600');

        % Print both best mean and median
        fprintf('Best cluster by mean: %d (Mean Silhouette = %.3f)\n', bestNumClusters_mean, bestScore_mean);
        fprintf('Best cluster by median: %d (Median Silhouette = %.3f)\n', bestNumClusters_median, bestScore_median);

        % Console output
        app.ResultLabel.Text = sprintf('%s\nBest cluster by mean: %d (Mean Silhouette = %.3f)', ...
            app.ResultLabel.Text, bestNumClusters_mean, bestScore_mean);
        app.ResultLabel.Text = sprintf('%s\nBest cluster by median: %d (Median Silhouette = %.3f)', ...
            app.ResultLabel.Text, bestClusterNum, medianValuesValid(bestIdx));
    else
        app.ResultLabel.Text = sprintf('%s\nWarning: No valid median values for any cluster counts, skipping boxplot.', ...
            app.ResultLabel.Text);
    end
end

% Step 13: Print the 7 app variables for debugging
    fprintf('App variables at the end of SelectsetfilesfolderButtonPushed:\n');
    
    % Note: Using isprop instead of isfield because isfield may not reliably detect
    % declared properties on App Designer objects.

    % app.non_clust_sets_length
    if isprop(app, 'non_clust_sets_length') && ~isempty(app.non_clust_sets_length)
        fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
    else
        fprintf('  app.non_clust_sets_length: Not set or empty\n');
    end
    
    % app.non_clust_comps_length
    if isprop(app, 'non_clust_comps_length') && ~isempty(app.non_clust_comps_length)
        fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
    else
        fprintf('  app.non_clust_comps_length: Not set or empty\n');
    end
    
    % app.subID
    if isprop(app, 'subID') && ~isempty(app.subID)
        fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
    else
        fprintf('  app.subID: Not set or empty\n');
    end
    
    % app.icID
    if isprop(app, 'icID') && ~isempty(app.icID)
        fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
    else
        fprintf('  app.icID: Not set or empty\n');
    end

    % Length of app.subID
    if isprop(app, 'subID') && ~isempty(app.subID)
        fprintf('  Length of app.subID: %d\n', length(app.subID));
    else
        fprintf('  Length of app.subID: Not set or empty\n');
    end
    
    % Length of app.icID
    if isprop(app, 'icID') && ~isempty(app.icID)
        fprintf('  Length of app.icID: %d\n', length(app.icID));
    else
        fprintf('  Length of app.icID: Not set or empty\n');
    end
    
    % app.subjectNames_Section1
    if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
        fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
    else
        fprintf('  app.subjectNames_Section1: Not set or empty\n');
    end
    
    % app.groupNames_Section1
    if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
        fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
    else
        fprintf('  app.groupNames_Section1: Not set or empty\n');
    end
    
    % app.subjectIDs_Section1
    if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
        fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));
    else
        fprintf('  app.subjectIDs_Section1: Not set or empty\n');
    end

    % Length of app.subjectNames_Section1
    if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
        fprintf('  Length of app.subjectNames_Section1: %d\n', length(app.subjectNames_Section1));
    else
        fprintf('  Length of app.subjectNames_Section1: Not set or empty\n');
    end
    
    % Length of app.groupNames_Section1
    if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
        fprintf('  Length of app.groupNames_Section1: %d\n', length(app.groupNames_Section1));
    else
        fprintf('  Length of app.groupNames_Section1: Not set or empty\n');
    end
    
    % Length of app.subjectIDs_Section1
    if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
        fprintf('  Length of app.subjectIDs_Section1: %d\n', length(app.subjectIDs_Section1));
    else
        fprintf('  Length of app.subjectIDs_Section1: Not set or empty\n');
    end


fprintf('Debug - groupNames_Section1: %s\n', strjoin(app.groupNames_Section1, ', '));
fprintf('Debug - subjectNames_Section1: %s\n', strjoin(app.subjectNames_Section1, ', '));
fprintf('Debug - subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
fprintf('Debug - groupPrefixes: %s\n', strjoin(app.groupPrefixes, ', '));
if iscell(app.groupRanges)
    fprintf('Debug - groupRanges: %s\n', strjoin(cellfun(@mat2str, app.groupRanges, 'UniformOutput', false), ', '));
else
    fprintf('Debug - groupRanges: %s\n', mat2str(app.groupRanges));
end

fprintf('Debug - numGroups: %d\n', length(app.groupPrefixes));

% Debug / print
% Print both best mean and median
fprintf('Best cluster by mean: %d (Mean Silhouette = %.3f)\n', bestNumClusters_mean, bestScore_mean);
fprintf('Best cluster by median: %d (Median Silhouette = %.3f)\n', bestNumClusters_median, bestScore_median);


% Step 14: Prepare valid cluster numbers
allClusterNumbers = 2:20;

% Remove duplicates and sort incompleteClusterCounts if it exists
if exist('incompleteClusterCounts', 'var') && ~isempty(incompleteClusterCounts)
    incompleteClusterCounts = unique(incompleteClusterCounts);
else
    incompleteClusterCounts = [];
end

% Compute valid cluster numbers
validClusterNumbers = setdiff(allClusterNumbers, incompleteClusterCounts);

% ✅ Print valid cluster numbers if not empty
if ~isempty(validClusterNumbers)
    fprintf('✅ Valid cluster numbers: %s\n', ...
        strjoin(arrayfun(@num2str, validClusterNumbers, 'UniformOutput', false), ', '));
end

% --- Update dropdown: preserve default and remove invalid numeric items ---
items  = string(app.NumberofClustersDropDown.Items);
curVal = string(app.NumberofClustersDropDown.Value);

% Identify/preserve the default item: first non-numeric, else first item
isNumItem = ~isnan(str2double(items));
if any(~isNumItem)
    defaultItem = items(find(~isNumItem, 1, 'first'));
else
    defaultItem = items(1);
end

% Keep only numeric items that are valid cluster numbers
numericItems = items(isNumItem);
numericVals  = str2double(numericItems);
keepMask     = ismember(numericVals, validClusterNumbers);
validNumericItems = numericItems(keepMask);

% --- Normalize types and concatenate safely ---
toCellStr = @(x) cellstr(string(x(:)));   % string -> cellstr column
defaultItemC       = toCellStr(defaultItem);
validNumericItemsC = toCellStr(validNumericItems);

newItems = unique([defaultItemC; validNumericItemsC], 'stable');

% Assign new items (App Designer expects cellstr or string)
app.NumberofClustersDropDown.Items = newItems;

% Preserve current value if still valid; otherwise set to default
curValChar = char(curVal);
if any(strcmp(curValChar, newItems))
    app.NumberofClustersDropDown.Value = curValChar;
else
    app.NumberofClustersDropDown.Value = defaultItemC{1};
end

% Step 15: Update UI
app.StatusLabel.Text = 'Clustering parameters set';
app.ResultLabel.Text = sprintf('Silhouette analysis completed. Valid clusters: %s', mat2str(validClusterNumbers));
app.NumberofClustersDropDown.Enable = 'on';
app.NumberofClustersDropDownLabel.Enable = 'on';


        end

        % Value changed function: NumberofClustersDropDown
        function NumberofClustersDropDownValueChanged(app, event)


    % --- Debug prints at the beginning ---
    fprintf('\n--- Number of Clusters Dropdown Triggered ---\n');
    if isprop(app, 'clusteringMethod')
        fprintf('app.clusteringMethod = %s\n', app.clusteringMethod);
    else
        fprintf('app.clusteringMethod does NOT exist!\n');
    end

    if isprop(app, 'stdDevThreshold')
        fprintf('app.stdDevThreshold = %.2f\n', app.stdDevThreshold);
    else
        fprintf('app.stdDevThreshold does NOT exist!\n');
    end

    fprintf('Selected number of clusters (dropdown) = %s\n', app.NumberofClustersDropDown.Value);

    % --- Convert selected value to numeric ---
    selectedNum = str2double(app.NumberofClustersDropDown.Value);

    % Validate selection
    if isnan(selectedNum) || selectedNum < 2 || selectedNum > 20
        uialert(app.UIFigure, ...
            '⚠️ Invalid number of clusters selected. Please choose a number between 2 and 20.', ...
            'Invalid Selection', 'Icon', 'error');
        app.SubmitNumberofClustersButton.Enable = 'off';
        return;
    end

% --- Warning for algorithms that may not produce exact clusters/assignments ---
if strcmpi(app.clusteringMethod,'Optimal Kmeans') || ...
   strcmpi(app.clusteringMethod,'Affinity Propagation') || ...
   strcmpi(app.clusteringMethod,'Neural Network (stat. toolbox)')

    % Determine display name for the algorithm
    switch app.clusteringMethod
        case 'Optimal Kmeans'
            algoDisplay = 'optimal_kmeans';
        otherwise
            algoDisplay = app.clusteringMethod;
    end

    % --- Build custom warning messages per algorithm ---
    if strcmpi(app.clusteringMethod,'Neural Network (stat. toolbox)') || ...
       strcmpi(app.clusteringMethod,'Optimal Kmeans')
        % Neural net and optimal_kmeans (no outlier)
        msg = sprintf(['⚠️ The selected algorithm (%s) may not reproduce identical component assignments as during silhouette analysis:\n\n' ...
                       '❗ While the final clustering will use the same number of clusters (%d),\n' ...
                       'the number of datasets (sets) and/or IC components per cluster may differ.\n\n' ...
                       '💡 Recommended Options: Continue with the current algorithm or choose a deterministic one (e.g., kmeans or kmeanscluster) for exact reproducibility.'], ...
                       algoDisplay, selectedNum);

    elseif strcmpi(app.clusteringMethod,'Affinity Propagation')
        % Affinity Propagation (no outlier)
        msg = sprintf(['⚠️ The selected algorithm (%s) may not produce the same number of clusters or component assignments as in silhouette analysis:\n\n' ...
                       '❗ The final clustering may yield a different total number of clusters,\n' ...
                       'and the number of datasets (sets) and IC components per cluster may also differ.\n\n' ...
                       '💡 Recommended Options: Continue with the current algorithm or select a different one for fixed cluster control.'], ...
                       algoDisplay);
    end

    % --- Ask user how to proceed ---
    choice = uiconfirm(app.UIFigure, msg, 'Cluster Consistency Warning', ...
        'Options', {'✅ Continue with Current Algorithm', '🔄 Change Algorithm'}, ...
        'DefaultOption', 1, 'Icon', 'warning');

    fprintf('User selected: %s\n', choice);

    if strcmp(choice, '✅ Continue with Current Algorithm')
        app.selectedNumClusters = selectedNum;
        fprintf('Proceeding with %s and %d clusters.\n', algoDisplay, selectedNum);
    else
        confirmMsg = ['⚠️ Changing the algorithm will reset the app.\n\n' ...
                      '✅ Click OK to restart the app and select a new algorithm.'];
        choiceConfirm = uiconfirm(app.UIFigure, confirmMsg, 'Confirm App Reset', ...
            'Options', {'✅ OK', '❌ Cancel'}, 'DefaultOption', 1, 'Icon', 'info');

        fprintf('User selected for reset: %s\n', choiceConfirm);

        if strcmp(choiceConfirm, '✅ OK')
            resetAppState(app);
            return;
        else
            app.selectedNumClusters = selectedNum;
            fprintf('User cancelled reset. Proceeding with %s and %d clusters.\n', algoDisplay, selectedNum);
        end
    end

else
    % Normal deterministic algorithms
    app.selectedNumClusters = selectedNum;
    fprintf('User selected number of clusters: %d\n', selectedNum);
end
    

% Enable Submit button
app.SubmitNumberofClustersButton.Enable = 'on';


% --- Helper function to reset the app state ---
function resetAppState(app)
    % Clear core data
    app.STUDY = [];
    app.ALLEEG = [];
    app.studyFilePath = '';

    % Reset dropdowns and UI fields
    app.FilteringusingResidualVarianceDropDown.Value = app.FilteringusingResidualVarianceDropDown.Items{1};
    app.FilteringusingResidualVarianceDropDown.Enable = 'off';
    app.FilteringusingResidualVarianceDropDownLabel.Enable = 'off';

    app.ClusteringMethodDropDown.Value = app.ClusteringMethodDropDown.Items{1};
    app.ClusteringMethodDropDown.Enable = 'off';
    app.ClusteringMethodDropDownLabel.Enable = 'off';

    app.StandardDeviationremoveoutliersDropDown.Value = app.StandardDeviationremoveoutliersDropDown.Items{1};
    app.StandardDeviationremoveoutliersDropDown.Enable = 'off';
    app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'off';

    app.NumberofGroupsDropDown.Value = app.NumberofGroupsDropDown.Items{1};
    app.NumberofGroupsDropDown.Enable = 'off';
    app.NumberofGroupsDropDownLabel.Enable = 'off';

    app.Group1SubjectRange.Value = '';
    app.Group1SubjectRange.Enable = 'off';
    app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';

    app.Group2SubjectRange.Value = '';
    app.Group2SubjectRange.Enable = 'off';
    app.Group2SubjectRangeEg1630Label.Enable = 'off';

    app.Group3SubjectRange.Value = '';
    app.Group3SubjectRange.Enable = 'off';
    app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';

    % Reset buttons and status
    app.SubmitParametersButton.Enable = 'off';
    app.SubmitGroupRangesButton.Enable = 'off';
    app.SubmitNumberofClustersButton.Enable = 'off';

    app.StatusLabel.Text = '🔄 Resetting...';
    app.ResultLabel.Text = '⚡ App state cleared. Preparing to restart...';
    drawnow;

    % Alert user
    uialert(app.UIFigure, '✅ The app has been reset. Please start by selecting a folder.', ...
        'App Reset', 'Icon', 'info');

    app.StatusLabel.Text = '📂 Action Required';
    app.ResultLabel.Text = '👉 Please start by selecting a folder to proceed.';
end


        end

        % Button pushed function: SubmitNumberofClustersButton
        function SubmitNumberofClustersButtonPushed(app, event)

    % Button pushed function: SubmitNumberofClustersButton

    disp(app.selectedNumClusters);
    disp(length(app.allClusterAssignments));

    % Step 1: Update status and map clustering method
    app.StatusLabel.Text = 'Performing final clustering...';


    % Map clustering method to the format expected by pop_clust
    clustAlgo = '';
    switch app.clusteringMethod
    case 'Kmeans (stat. toolbox)'
        clustAlgo = 'kmeans';
    case 'Neural Network (stat. toolbox)'
        clustAlgo = 'neural network';
    case 'Kmeanscluster (no toolbox)'
        clustAlgo = 'kmeanscluster';
    case 'Affinity Propagation'
        clustAlgo = 'Affinity Propagation';
    case 'Optimal Kmeans'
        clustAlgo = 'optimal_kmeans'; % Corrected to use optimal_kmeans algorithm
    otherwise
        uialert(app.UIFigure, 'Unsupported clustering method selected.', 'Error', 'Icon', 'error');
        app.StatusLabel.Text = 'Error in clustering method';
        app.ResultLabel.Text = 'Unsupported clustering method';
        return;
    end
    
% Step 2: Perform final clustering with the user-selected number of clusters
fprintf('Performing final clustering with %d clusters (pop_clust)...\n', app.selectedNumClusters);
rng(42, 'twister');

% ---------------- DEBUG INFO BEFORE FINAL CLUSTERING ----------------
fprintf('\n=== FINAL CLUSTERING DEBUG INFO ===\n');
fprintf('Selected algorithm: %s\n', clustAlgo);
fprintf('Selected # of clusters: %d\n', app.selectedNumClusters);
fprintf('stdDevThreshold: %.3f\n', app.stdDevThreshold);
numComps = length([app.STUDY.cluster(1).comps]);  % count total components
fprintf('Number of components before clustering: %d\n', numComps);
fprintf('----------------------------------------------------------\n');

% ------------------- FIX: Ensure K <= numComps ----------------------
K = app.selectedNumClusters;
if K >= numComps
    warning('Selected clusters (%d) >= number of components (%d). Reducing K to %d.', ...
        K, numComps, numComps - 1);
    K = max(numComps - 1, 1); % at least 1 cluster
end

try
    fprintf('Calling pop_clust with algorithm: %s ...\n', clustAlgo);
    if strcmp(clustAlgo, 'optimal_kmeans')
    % Optimal Kmeans: handle outliers only if stdDevThreshold >= 1
        clusVec = reshape([K K], 1, 2);  % Ensure 1x2 row vector for optimal_kmeans
        fprintf('clus_num vector for optimal_kmeans: [%d %d] (size: %dx%d)\n', clusVec(1), clusVec(2), size(clusVec,1), size(clusVec,2));
    
        if app.stdDevThreshold >= 1
            fprintf('Routing optimal_kmeans -> robust_kmeans (outliers = %.2f)\n', app.stdDevThreshold);
            [app.STUDY, app.ALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...   % route through robust_kmeans internally
                'clus_num', clusVec, ...       % 1x2 vector as required
                'outliers', app.stdDevThreshold);
        else
            fprintf('Running optimal_kmeans standard\n');
            [app.STUDY, app.ALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...
                'clus_num', clusVec);        % 1x2 vector, no outliers
        end

    elseif strcmp(clustAlgo, 'Affinity Propagation')
        fprintf('Affinity Propagation run (cluster number ignored)\n');
        [app.STUDY, app.ALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
            'algorithm', clustAlgo);

    else
        % kmeans, kmeanscluster, neural network
        if app.stdDevThreshold >= 1
            fprintf('Using outlier handling (robust_kmeans likely invoked)\n');
            [app.STUDY, app.ALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...
                'clus_num', K, ...
                'outliers', app.stdDevThreshold);
        else
            fprintf('Standard clustering without outliers\n');
            [app.STUDY, app.ALLEEG] = pop_clust(app.STUDY, app.ALLEEG, ...
                'algorithm', clustAlgo, ...
                'clus_num', K);
        end
    end
catch ME
    fprintf(2, '\n❌ ERROR DURING FINAL CLUSTERING ❌\n');
    fprintf(2, 'Algorithm: %s | stdDevThreshold: %.2f\n', clustAlgo, app.stdDevThreshold);
    fprintf(2, 'Error Message: %s\n', ME.message);
    fprintf(2, 'Full Trace:\n%s\n', getReport(ME, 'extended'));
    if exist('app','var') && isfield(app, 'STUDY')
        fprintf('Current STUDY has %d clusters\n', length(app.STUDY.cluster));
        for c = 1:length(app.STUDY.cluster)
            fprintf('  Cluster %d: %s | comps = %d\n', ...
                c, app.STUDY.cluster(c).name, length(app.STUDY.cluster(c).comps));
        end
    end
    app.StatusLabel.Text = 'Error in final clustering';
    app.ResultLabel.Text = ME.message;
    return;
end

% Debug: Print cluster names and number of components
fprintf('Debug: STUDY has %d clusters after pop_clust.\n', length(app.STUDY.cluster));
for idx = 1:length(app.STUDY.cluster)
    fprintf('  Cluster %d name: %s (num comps = %d)\n', ...
        idx, app.STUDY.cluster(idx).name, length(app.STUDY.cluster(idx).comps));
end

% --- Check if Outlier cluster exists ---
hasOutlier = length(app.STUDY.cluster) >= 2 && contains(lower(app.STUDY.cluster(2).name), 'outlier');
if hasOutlier
    startIdx = 3; % Real clusters start index
else
    startIdx = 2; % Real clusters start index
end

numClusters = length(app.STUDY.cluster) - (startIdx - 1);  % Number of real clusters

% Step 3: Verify number of components in each cluster (debug)
totalCompsAssigned = 0;
for i = 2:length(app.STUDY.cluster)
    numComps = length(app.STUDY.cluster(i).comps);
    if i == 2 && contains(lower(app.STUDY.cluster(i).name), 'outlier')
        fprintf('After pop_clust - Cluster %d (Outlier Cluster): %d components\n', i, numComps);
    else
        fprintf('After pop_clust - Cluster %d: %d components\n', i, numComps);
    end
    totalCompsAssigned = totalCompsAssigned + numComps;
end
fprintf('Total components assigned by pop_clust: %d\n', totalCompsAssigned);

% Step 4: Silhouette computation & detailed reporting
if ~strcmp(clustAlgo, 'Affinity Propagation') && ...
   ~strcmp(clustAlgo, 'optimal_kmeans') && ...
   ~strcmp(clustAlgo, 'neural network')
    
    % --- Find k corresponding to user-selected number of clusters ---
    clusterNumbers = 2:20;
    kIdx = find(clusterNumbers == app.selectedNumClusters, 1);
    
    if isempty(kIdx)
        fprintf('Error: Selected number of clusters (%d) not found in silhouette computation.\n', app.selectedNumClusters);
        return;
    end
    
    if isempty(app.allClusterAssignments) || isempty(app.allClusterAssignments{kIdx})
        fprintf('No silhouette data found for K=%d. Skipping silhouette comparison.\n', app.selectedNumClusters);
        return;
    end
    
    % --- Get silhouette assignments and scores ---
    silhouetteAssignments = app.allClusterAssignments{kIdx};
    silhouetteScores = app.allSilhouetteScores{kIdx};
    
    % --- Check if Outlier cluster exists ---
    hasOutlier = length(app.STUDY.cluster) >= 2 && contains(lower(app.STUDY.cluster(2).name), 'outlier');
    if hasOutlier
        startIdx = 3; % Real clusters start index
    else
        startIdx = 2; % Real clusters start index
    end
    
    numClusters = length(app.STUDY.cluster) - (startIdx - 1);  % Number of real clusters
    
    fprintf('===== SILHOUETTE SUMMARY (K = %d) =====\n', numClusters);
    
    % --- Parent cluster ---
    parentClusterComps = app.STUDY.cluster(1).comps;
    numParentComponents = length(parentClusterComps);
    numParentDatasets = length(unique(app.STUDY.cluster(1).sets));
    fprintf('Parent Cluster:\n');
    fprintf('  Number of datasets: %d\n', numParentDatasets);
    fprintf('  Number of components: %d\n', numParentComponents);
    fprintf('  List of components: %s\n', mat2str(parentClusterComps));
    fprintf('  Components match silhouette computation (%d components)\n', numParentComponents);
    fprintf('  Mean Silhouette = NaN, Median Silhouette = NaN (n = %d components)\n', numParentComponents);
    
    % --- Outlier cluster (if exists) ---
    if hasOutlier
        outlierIndex = 2;
        outlierComps = app.STUDY.cluster(outlierIndex).comps;
        numOutlierComponents = length(outlierComps);
        numOutlierDatasets = length(unique(app.STUDY.cluster(outlierIndex).sets));
        fprintf('Outlier Cluster:\n');
        fprintf('  Number of datasets: %d\n', numOutlierDatasets);
        fprintf('  Number of components: %d\n', numOutlierComponents);
        fprintf('  List of components: %s\n', mat2str(outlierComps));
        fprintf('  Components match silhouette computation (%d components)\n', numOutlierComponents);
        fprintf('  Mean Silhouette = NaN, Median Silhouette = NaN (n = %d components)\n', numOutlierComponents);
    end
    
    % --- Real clusters ---
    for c = 1:numClusters
        clusterIdx = startIdx + c - 1;
        clusterComps = find(silhouetteAssignments == c);
        
        if isempty(clusterComps)
            fprintf('Cluster %d: No components\n', c);
            continue;
        end
        
        clusterDatasets = length(unique(app.STUDY.cluster(clusterIdx).sets));
        
        % Map global indices to silhouetteScores
        realCompIndices = find(silhouetteAssignments > 0);
        clusterScoresIdx = find(ismember(realCompIndices, clusterComps));
        clusterSilScores = silhouetteScores(clusterScoresIdx);
        
        fprintf('Cluster %d:\n', c);
        fprintf('  Number of datasets: %d\n', clusterDatasets);
        fprintf('  Number of components: %d\n', length(clusterComps));
        fprintf('  List of components: %s\n', mat2str(clusterComps));
        fprintf('  Components match silhouette computation (%d components)\n', length(clusterComps));
        fprintf('  Mean Silhouette = %.3f, Median Silhouette = %.3f (n = %d components)\n', ...
            mean(clusterSilScores), median(clusterSilScores), length(clusterComps));
    end
    
    fprintf('Overall Mean Silhouette for K = %d: %.3f\n', numClusters, mean(silhouetteScores));
    fprintf('Overall Median Silhouette for K = %d: %.3f\n', numClusters, median(silhouetteScores));
    fprintf('===== END OF SILHOUETTE SUMMARY =====\n\n');
    
end

% ===============================
% Step 5: Print final cluster summary (unified across all algorithms)
% ===============================
fprintf('\n=== FINAL CLUSTER SUMMARY REPORT ===\n');

STUDY = app.STUDY;
nClusters = numel(STUDY.cluster);

if nClusters < 1
    fprintf('No clusters found in STUDY.\n');
    return;
end

% --- 1. Parent cluster (always first row) ---
fprintf('\n--- Parent Cluster ---\n');
i = 1;
clusterName = STUDY.cluster(i).name;
sets = STUDY.cluster(i).sets;
comps = STUDY.cluster(i).comps;

uniqueSets = unique(sets);
nDatasets = numel(uniqueSets);
nComps = numel(comps);

fprintf('Cluster Name: %s\n', clusterName);
fprintf(' - Number of unique datasets: %d\n', nDatasets);
fprintf(' - Unique datasets: %s\n', mat2str(uniqueSets));
fprintf(' - Number of components: %d\n', nComps);
fprintf(' - Component indices: %s\n', mat2str(comps));

% --- 2. Detect outlier cluster (2nd row) ---
if nClusters >= 2 && contains(lower(STUDY.cluster(2).name), 'outlier')
    hasOutlier = true;
    fprintf('\n--- Outlier Cluster ---\n');
    i = 2;
    clusterName = STUDY.cluster(i).name;
    sets = STUDY.cluster(i).sets;
    comps = STUDY.cluster(i).comps;

    uniqueSets = unique(sets);
    nDatasets = numel(uniqueSets);
    nComps = numel(comps);

    fprintf('Cluster Name: %s\n', clusterName);
    fprintf(' - Number of unique datasets: %d\n', nDatasets);
    fprintf(' - Unique datasets: %s\n', mat2str(uniqueSets));
    fprintf(' - Number of components: %d\n', nComps);
    fprintf(' - Component indices: %s\n', mat2str(comps));

    startIdx = 3;
else
    hasOutlier = false;
    startIdx = 2;
end

% --- 3. Real clusters ---
nRealClusters = nClusters - (startIdx - 1);
fprintf('\nNumber of real clusters: %d\n', nRealClusters);

realClusterCounter = 1;  % Counts real clusters (1st, 2nd, etc.)

for i = startIdx:nClusters
    clusterName = STUDY.cluster(i).name;
    sets = STUDY.cluster(i).sets;
    comps = STUDY.cluster(i).comps;

    if isempty(sets)
        fprintf('\nCluster %s: [empty cluster]\n', clusterName);
        continue;
    end

    uniqueSets = unique(sets);
    nDatasets = numel(uniqueSets);
    nComps = numel(comps);

    % --- Ordinal-aware label (1st, 2nd, 3rd, 11th, 21st, etc.) ---
    switch mod(realClusterCounter, 100)
        case {11, 12, 13}
            suffix = 'th';
        otherwise
            switch mod(realClusterCounter, 10)
                case 1, suffix = 'st';
                case 2, suffix = 'nd';
                case 3, suffix = 'rd';
                otherwise, suffix = 'th';
            end
    end
    ordinalLabel = sprintf('%d%s', realClusterCounter, suffix);

    % --- Print cluster info ---
    fprintf('\n--- %s Real Cluster ---\n', ordinalLabel);
    fprintf('Cluster Name: %s\n', clusterName);
    fprintf(' - Number of unique datasets: %d\n', nDatasets);
    fprintf(' - Unique datasets: %s\n', mat2str(uniqueSets));
    fprintf(' - Number of components: %d\n', nComps);
    fprintf(' - Component indices: %s\n', mat2str(comps));

    realClusterCounter = realClusterCounter + 1;
end

fprintf('\n=== END OF REPORT ===\n');

% --- Preserve Optimal KMeans section (unchanged) ---
if strcmpi(app.ClustAlgo, 'optimal_kmeans') && ~isempty(app.KOverallStats) && app.stdDevThreshold < 1
    matchIdx = find(app.KOverallStats(:, 1) == nRealClusters, 1);
    if ~isempty(matchIdx)
        overallMean = app.KOverallStats(matchIdx, 2);
        overallMedian = app.KOverallStats(matchIdx, 3);
        fprintf('\nOverall Mean Silhouette for K = %d: %.3f\n', nRealClusters, overallMean);
        fprintf('Overall Median Silhouette for K = %d: %.3f\n', nRealClusters, overallMedian);
    else
        fprintf('\nNo matching K found in KOverallStats for %d clusters.\n', nRealClusters);
    end
else 
   if strcmpi(app.ClustAlgo, 'optimal_kmeans') && app.stdDevThreshold >= 1
        % Find the index corresponding to the user-selected number of clusters
        clusterNumbers = 2:20;
        kIdx = find(clusterNumbers == app.selectedNumClusters, 1);
    
        if isempty(kIdx) || isempty(app.allSilhouetteScores{kIdx})
            fprintf('No silhouette scores available for K = %d.\n', app.selectedNumClusters);
        else
            silhouetteScores = app.allSilhouetteScores{kIdx};
            fprintf('Overall Mean Silhouette for K = %d: %.3f\n', app.selectedNumClusters, mean(silhouetteScores));
            fprintf('Overall Median Silhouette for K = %d: %.3f\n', app.selectedNumClusters, median(silhouetteScores));
        end
    end

end


    % Step 6: Save the clustered STUDY file
    clusteredFilename = 'clustered.study'; % Simple name for the clustered file
    savePath = fullfile(app.studyFilePath, clusteredFilename); % Use app.studyFilePath directly as the directory
    fprintf('Saving clustered STUDY to %s...\n', savePath);
    try
        [app.STUDY, app.ALLEEG] = pop_savestudy(app.STUDY, app.ALLEEG, 'filename', clusteredFilename, 'filepath', app.studyFilePath);
    catch ME
        app.StatusLabel.Text = 'Error saving STUDY file';
        app.ResultLabel.Text = ME.message;
        return;
    end
    fprintf('-study saved successfully to %s\n', savePath);


    % Step 7: Print the 7 app variables to ensure they exist
    fprintf('Verifying app variables before loading clustered STUDY file:\n');
    
    % Check and print app.non_clust_sets_length
    if isprop(app, 'non_clust_sets_length') && ~isempty(app.non_clust_sets_length)
        fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.non_clust_sets_length is missing or empty';
        fprintf('Error: app.non_clust_sets_length is missing or empty\n');
        return;
    end

    % Check and print app.non_clust_comps_length
    if isprop(app, 'non_clust_comps_length') && ~isempty(app.non_clust_comps_length)
        fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.non_clust_comps_length is missing or empty';
        fprintf('Error: app.non_clust_comps_length is missing or empty\n');
        return;
    end

    % Validate that sets and comps lengths match
    if app.non_clust_sets_length ~= app.non_clust_comps_length
        app.StatusLabel.Text = 'Error: App data mismatch';
        app.ResultLabel.Text = sprintf('app.non_clust_sets_length (%d) does not match app.non_clust_comps_length (%d)', ...
            app.non_clust_sets_length, app.non_clust_comps_length);
        fprintf('Error: app.non_clust_sets_length (%d) does not match app.non_clust_comps_length (%d)\n', ...
            app.non_clust_sets_length, app.non_clust_comps_length);
        return;
    end

    % Check and print app.subID
    if isprop(app, 'subID') && ~isempty(app.subID)
        fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
        % Validate that subID is numeric
        if ~isnumeric(app.subID)
            app.StatusLabel.Text = 'Error: Invalid app variable';
            app.ResultLabel.Text = 'app.subID contains non-numeric data';
            fprintf('Error: app.subID contains non-numeric data\n');
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.subID is missing or empty';
        fprintf('Error: app.subID is missing or empty\n');
        return;
    end

    % Check and print app.icID
    if isprop(app, 'icID') && ~isempty(app.icID)
        fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
        % Validate that icID is numeric
        if ~isnumeric(app.icID)
            app.StatusLabel.Text = 'Error: Invalid app variable';
            app.ResultLabel.Text = 'app.icID contains non-numeric data';
            fprintf('Error: app.icID contains non-numeric data\n');
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.icID is missing or empty';
        fprintf('Error: app.icID is missing or empty\n');
        return;
    end

    % Check and print app.subjectNames_Section1
    if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
        fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.subjectNames_Section1 is missing or empty';
        fprintf('Error: app.subjectNames_Section1 is missing or empty\n');
        return;
    end

    % Check and print app.groupNames_Section1
    if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
        fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.groupNames_Section1 is missing or empty';
        fprintf('Error: app.groupNames_Section1 is missing or empty\n');
        return;
    end

    % Check and print app.subjectIDs_Section1
    if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
        fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));
        % Validate that subjectIDs_Section1 is numeric
        if ~isnumeric(app.subjectIDs_Section1)
            app.StatusLabel.Text = 'Error: Invalid app variable';
            app.ResultLabel.Text = 'app.subjectIDs_Section1 contains non-numeric data';
            fprintf('Error: app.subjectIDs_Section1 contains non-numeric data\n');
            return;
        end
        % Validate that subID aligns with subjectIDs_Section1
        if ~all(ismember(app.subID, app.subjectIDs_Section1))
            fprintf('Error: app.subID contains values not found in app.subjectIDs_Section1\n');
            fprintf('  app.subID (unique values): %s\n', mat2str(unique(app.subID)));
            fprintf('  app.subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
            app.StatusLabel.Text = 'Error: Invalid subID values';
            app.ResultLabel.Text = 'app.subID does not match Section 1 subject IDs';
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.subjectIDs_Section1 is missing or empty';
        fprintf('Error: app.subjectIDs_Section1 is missing or empty\n');
        return;
    end

fprintf('Debug - groupNames_Section1: %s\n', strjoin(app.groupNames_Section1, ', '));
fprintf('Debug - subjectNames_Section1: %s\n', strjoin(app.subjectNames_Section1, ', '));
fprintf('Debug - subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
fprintf('Debug - groupPrefixes: %s\n', strjoin(app.groupPrefixes, ', '));
if iscell(app.groupRanges)
    fprintf('Debug - groupRanges: %s\n', strjoin(cellfun(@mat2str, app.groupRanges, 'UniformOutput', false), ', '));
else
    fprintf('Debug - groupRanges: %s\n', mat2str(app.groupRanges));
end
fprintf('Debug - numGroups: %d\n', length(app.groupPrefixes));

 
 % Step 8: Update UI
    app.StatusLabel.Text = 'Final clustering completed';
    app.ResultLabel.Text = 'Clustered STUDY file saved successfully';

    close all;

    % Enable the "Load clustered EEG STUDY file" button
    app.LoadclusteredEEGSTUDYfileButton.Enable = 'on';

        end

        % Button pushed function: LoadclusteredEEGSTUDYfileButton
        function LoadclusteredEEGSTUDYfileButtonPushed(app, event)


    % Button pushed function: LoadclusteredEEGSTUDYfileButton

    % Step 1.1: Print the 7 app variables to ensure they exist
    fprintf('Verifying app variables before loading clustered STUDY file:\n');
    
    % Check and print app.non_clust_sets_length
    if isprop(app, 'non_clust_sets_length') && ~isempty(app.non_clust_sets_length)
        fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.non_clust_sets_length is missing or empty';
        fprintf('Error: app.non_clust_sets_length is missing or empty\n');
        return;
    end

    % Check and print app.non_clust_comps_length
    if isprop(app, 'non_clust_comps_length') && ~isempty(app.non_clust_comps_length)
        fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.non_clust_comps_length is missing or empty';
        fprintf('Error: app.non_clust_comps_length is missing or empty\n');
        return;
    end

    % Validate that sets and comps lengths match
    if app.non_clust_sets_length ~= app.non_clust_comps_length
        app.StatusLabel.Text = 'Error: App data mismatch';
        app.ResultLabel.Text = sprintf('app.non_clust_sets_length (%d) does not match app.non_clust_comps_length (%d)', ...
            app.non_clust_sets_length, app.non_clust_comps_length);
        fprintf('Error: app.non_clust_sets_length (%d) does not match app.non_clust_comps_length (%d)\n', ...
            app.non_clust_sets_length, app.non_clust_comps_length);
        return;
    end

    % Check and print app.subID
    if isprop(app, 'subID') && ~isempty(app.subID)
        fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
        % Validate that subID is numeric
        if ~isnumeric(app.subID)
            app.StatusLabel.Text = 'Error: Invalid app variable';
            app.ResultLabel.Text = 'app.subID contains non-numeric data';
            fprintf('Error: app.subID contains non-numeric data\n');
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.subID is missing or empty';
        fprintf('Error: app.subID is missing or empty\n');
        return;
    end

    % Check and print app.icID
    if isprop(app, 'icID') && ~isempty(app.icID)
        fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
        % Validate that icID is numeric
        if ~isnumeric(app.icID)
            app.StatusLabel.Text = 'Error: Invalid app variable';
            app.ResultLabel.Text = 'app.icID contains non-numeric data';
            fprintf('Error: app.icID contains non-numeric data\n');
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.icID is missing or empty';
        fprintf('Error: app.icID is missing or empty\n');
        return;
    end

    % Check and print app.subjectNames_Section1
    if isprop(app, 'subjectNames_Section1') && ~isempty(app.subjectNames_Section1)
        fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.subjectNames_Section1 is missing or empty';
        fprintf('Error: app.subjectNames_Section1 is missing or empty\n');
        return;
    end

    % Check and print app.groupNames_Section1
    if isprop(app, 'groupNames_Section1') && ~isempty(app.groupNames_Section1)
        fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.groupNames_Section1 is missing or empty';
        fprintf('Error: app.groupNames_Section1 is missing or empty\n');
        return;
    end

    % Check and print app.subjectIDs_Section1
    if isprop(app, 'subjectIDs_Section1') && ~isempty(app.subjectIDs_Section1)
        fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));
        % Validate that subjectIDs_Section1 is numeric
        if ~isnumeric(app.subjectIDs_Section1)
            app.StatusLabel.Text = 'Error: Invalid app variable';
            app.ResultLabel.Text = 'app.subjectIDs_Section1 contains non-numeric data';
            fprintf('Error: app.subjectIDs_Section1 contains non-numeric data\n');
            return;
        end
        % Validate that subID aligns with subjectIDs_Section1
        if ~all(ismember(app.subID, app.subjectIDs_Section1))
            fprintf('Error: app.subID contains values not found in app.subjectIDs_Section1\n');
            fprintf('  app.subID (unique values): %s\n', mat2str(unique(app.subID)));
            fprintf('  app.subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
            app.StatusLabel.Text = 'Error: Invalid subID values';
            app.ResultLabel.Text = 'app.subID does not match Section 1 subject IDs';
            return;
        end
    else
        app.StatusLabel.Text = 'Error: Missing app variable';
        app.ResultLabel.Text = 'app.subjectIDs_Section1 is missing or empty';
        fprintf('Error: app.subjectIDs_Section1 is missing or empty\n');
        return;
    end
 
    fprintf('Debug - groupNames_Section1: %s\n', strjoin(app.groupNames_Section1, ', '));
fprintf('Debug - subjectNames_Section1: %s\n', strjoin(app.subjectNames_Section1, ', '));
fprintf('Debug - subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
fprintf('Debug - groupPrefixes: %s\n', strjoin(app.groupPrefixes, ', '));
if iscell(app.groupRanges)
    fprintf('Debug - groupRanges: %s\n', strjoin(cellfun(@mat2str, app.groupRanges, 'UniformOutput', false), ', '));
else
    fprintf('Debug - groupRanges: %s\n', mat2str(app.groupRanges));
end
fprintf('Debug - numGroups: %d\n', length(app.groupPrefixes));

    % Step 1.2: Initialize and Load Clustered Study File
    % Update status label
    app.StatusLabel.Text = 'Status: Loading clustered study file...';

    % Construct the path to the clustered .study file
    clusteredFilename = 'clustered.study';
    fullFilePath = fullfile(app.studyFilePath, clusteredFilename);
    
    % Check if the clustered file exists
    if ~exist(fullFilePath, 'file')
        app.StatusLabel.Text = 'Error: Clustered STUDY file not found';
        app.ResultLabel.Text = sprintf('Could not find %s in %s', clusteredFilename, app.studyFilePath);
        fprintf('Error: Clustered STUDY file not found at %s\n', fullFilePath);
        return;
    end

    % Suppress name conflict warnings to reduce clutter
    warning('off', 'MATLAB:dispatcher:nameConflict');
 
    % Dynamically locate the latest EEGLAB version
eeglabPaths = which('eeglab', '-all');
if isempty(eeglabPaths)
    app.StatusLabel.Text = 'Error: EEGLAB not found';
    app.ResultLabel.Text = 'EEGLAB not found in the MATLAB path';
    fprintf('Error: EEGLAB not found in the MATLAB path\n');
    return;
end

% Extract version numbers and select the latest
eeglabVersionParts = cell(length(eeglabPaths), 1); % Store version parts as cell arrays
for i = 1:length(eeglabPaths)
    pathStr = eeglabPaths{i};
    % Extract the folder name containing the version (e.g., eeglab2024.1)
    [parentPath, versionFolder] = fileparts(fileparts(pathStr));
    % Parse version number (e.g., 'eeglab2024.1' -> 2024.1)
    versionStr = strrep(versionFolder, 'eeglab', ''); % Remove 'eeglab' prefix
    % Split version into parts (e.g., '2024.1' -> [2024, 1], '2025.0.0' -> [2025, 0, 0])
    versionParts = str2double(strsplit(versionStr, '.'));
    if any(isnan(versionParts))
        warning('Invalid version number for path %s, skipping.', pathStr);
        eeglabVersionParts{i} = [0]; % Default to 0 if parsing fails
    else
        eeglabVersionParts{i} = versionParts;
    end
    fprintf('Debug - Path %s parsed to version parts: %s\n', pathStr, mat2str(eeglabVersionParts{i}));
end

% Compare versions part by part to find the latest
latestIdx = 1;
for i = 2:length(eeglabPaths)
    parts1 = eeglabVersionParts{latestIdx};
    parts2 = eeglabVersionParts{i};
    % Pad shorter version with zeros for consistent comparison
    maxLen = max(length(parts1), length(parts2));
    parts1 = [parts1, zeros(1, maxLen - length(parts1))];
    parts2 = [parts2, zeros(1, maxLen - length(parts2))];
    % Compare parts
    for p = 1:maxLen
        if parts1(p) > parts2(p)
            break; % parts1 (current latest) is larger
        elseif parts2(p) > parts1(p)
            latestIdx = i; % parts2 is larger, update latest
            break;
        end
        % If equal, continue to next part
    end
    fprintf('Debug - Comparing versions: %s vs %s, latestIdx = %d\n', mat2str(parts1), mat2str(parts2), latestIdx);
end

eeglabPath = eeglabPaths{latestIdx};
eeglabRoot = fileparts(eeglabPath); % Go up one level from eeglab.m to get the version-specific root (e.g., eeglab2025.0.0)
fprintf('Using EEGLAB version from %s\n', eeglabRoot);

% Initialize EEGLAB (no GUI) with retry mechanism
maxRetries = 2;
retryCount = 0;
eeglabInitialized = false;
while retryCount < maxRetries && ~eeglabInitialized
    try
        [app.ALLEEG, ~, ~, ~] = eeglab('nogui');
        eeglabInitialized = true;
    catch ME
        retryCount = retryCount + 1;
        if retryCount == maxRetries
            app.StatusLabel.Text = 'Error: Failed to initialize EEGLAB';
            app.ResultLabel.Text = sprintf('EEGLAB initialization failed after %d attempts: %s', maxRetries, ME.message);
            fprintf('Error: EEGLAB initialization failed after %d attempts: %s\n', maxRetries, ME.message);
            warning('on', 'MATLAB:dispatcher:nameConflict');
            return;
        end
        fprintf('EEGLAB initialization failed (attempt %d/%d): %s. Retrying...\n', retryCount, maxRetries, ME.message);
        pause(1); % Wait before retrying
    end
end

% Locate standard_mri.mat within the selected version's plugins directory
mriFile = fullfile(eeglabRoot, 'plugins', 'dipfit', 'standard_BEM', 'standard_mri.mat');
if ~exist(mriFile, 'file')
    % Use dir to search within the version-specific root
    mriFiles = dir(fullfile(eeglabRoot, 'plugins', '**', 'standard_mri.mat'));
    if ~isempty(mriFiles)
        mriFile = fullfile(mriFiles(1).folder, mriFiles(1).name);
    else
        warning('standard_mri.mat not found in EEGLAB %s directory. Proceeding without MRI background.', eeglabRoot);
        app.mriData = [];
        app.mriFilePath = '';
    end
end

% Load the MRI data into app.mriData if the file was found
if exist('mriFile', 'var') && ~isempty(mriFile)
    app.mriFilePath = mriFile;
    try
        mriDataLoaded = load(app.mriFilePath);
        if isfield(mriDataLoaded, 'mri')
            app.mriData = mriDataLoaded.mri;
        else
            warning('Could not find MRI structure in standard_mri.mat. Plotting without MRI background.');
            app.mriData = [];
        end
    catch ME
        warning('Error loading MRI file: %s. Proceeding without MRI background.', char(ME.message));
        app.mriData = [];
    end
else
    app.mriData = [];
    app.mriFilePath = '';
end

disp(['app.mriFilePath: ', app.mriFilePath]); % Print app.mriFilePath to command window

    % Load the clustered STUDY file
    try
        [app.STUDY, app.ALLEEG] = pop_loadstudy('filename', clusteredFilename, 'filepath', app.studyFilePath);
        fprintf('Successfully loaded clustered STUDY file from %s\n', fullFilePath);
    catch ME
        app.StatusLabel.Text = 'Error loading clustered STUDY file';
        app.ResultLabel.Text = sprintf('Failed to load %s: %s', clusteredFilename, ME.message);
        fprintf('Error: Failed to load clustered STUDY file %s: %s\n', clusteredFilename, ME.message);
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    % Step 1.3: Print the 7 app variables again before proceeding to Step 2
    fprintf('App variables after loading clustered STUDY file (before proceeding to Step 2):\n');
    fprintf('  app.non_clust_sets_length: %d\n', app.non_clust_sets_length);
    fprintf('  app.non_clust_comps_length: %d\n', app.non_clust_comps_length);
    fprintf('  app.subID (first 5 elements): %s\n', mat2str(app.subID(1:min(5, length(app.subID)))));
    fprintf('  app.icID (first 5 elements): %s\n', mat2str(app.icID(1:min(5, length(app.icID)))));
    fprintf('  app.subjectNames_Section1 (first 5 elements): %s\n', strjoin(app.subjectNames_Section1(1:min(5, length(app.subjectNames_Section1))), ', '));
    fprintf('  app.groupNames_Section1 (first 5 elements): %s\n', strjoin(app.groupNames_Section1(1:min(5, length(app.groupNames_Section1))), ', '));
    fprintf('  app.subjectIDs_Section1 (first 5 elements): %s\n', mat2str(app.subjectIDs_Section1(1:min(5, length(app.subjectIDs_Section1)))));

    % Update status label
    app.StatusLabel.Text = 'Status: Processing data...';


% Step 2: Verify Cluster Details and Compare with Silhouette Computation
fprintf('Loaded clustered STUDY file. Verifying cluster details:\n');

% Determine the number of clusters in the loaded STUDY
totalClusters = length(app.STUDY.cluster);
hasOutlier = false;
if totalClusters >= 2 && contains(lower(app.STUDY.cluster(2).name), 'outlier')
    hasOutlier = true;
    startIdx = 3;  % Real clusters start after parent + outlier
else
    startIdx = 2;  % Real clusters start after parent
end
app.hasOutlier = hasOutlier;  % Store in app

numClusters = totalClusters - (startIdx - 1);  % Number of real clusters
fprintf('  Number of real clusters (excluding parent): %d\n', numClusters);

% Validate number of clusters
if numClusters < 2 || numClusters > 20
    app.StatusLabel.Text = sprintf('Error: Number of clusters (%d) must be between 2 and 20', numClusters);
    app.ResultLabel.Text = 'Invalid number of clusters';
    fprintf('Error: Number of clusters (%d) must be between 2 and 20\n', numClusters);
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end
app.numClusters = numClusters;

% Build cluster assignments from STUDY.cluster
totalComps = 0;
for c = startIdx:totalClusters
    totalComps = totalComps + length(app.STUDY.cluster(c).comps);
end
clusterAssignmentsActual = zeros(1, totalComps);
compCounter = 1;
for i = startIdx:totalClusters
    comps = app.STUDY.cluster(i).comps;
    clusterID = i - (startIdx - 1); % Reindex real clusters to 1..numClusters
    for j = 1:length(comps)
        clusterAssignmentsActual(compCounter) = clusterID;
        compCounter = compCounter + 1;
    end
end

% Continuation of Step 2: Silhouette computation & detailed reporting
if ~strcmp(app.ClustAlgo, 'Affinity Propagation') && ...
   ~strcmp(app.ClustAlgo, 'optimal_kmeans') && ...
   ~strcmp(app.ClustAlgo, 'neural network')
   
    % Find k corresponding to number of real clusters
    clusterNumbers = 2:20;
    kIdx = find(clusterNumbers == numClusters, 1);
    
    if isempty(kIdx)
        fprintf('Error: Number of clusters (%d) not found in silhouette computation.\n', numClusters);
        app.StatusLabel.Text = 'Error: Cluster mismatch';
        app.ResultLabel.Text = sprintf('Number of clusters (%d) not found in silhouette computation', numClusters);
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end
    
    if isempty(app.allClusterAssignments) || isempty(app.allClusterAssignments{kIdx})
        fprintf('No silhouette data found for K=%d. Skipping silhouette comparison.\n', numClusters);
        return;
    end
    
    % Get silhouette assignments for this k
    silhouetteAssignments = app.allClusterAssignments{kIdx};
    
    % Check if outlier exists
    if hasOutlier
        startIdx = 3;
    else
        startIdx = 2;
    end

    silhouetteScores = app.allSilhouetteScores{kIdx};

    fprintf('===== SILHOUETTE SUMMARY (K = %d) =====\n', numClusters);

    % --- Parent cluster ---
    parentClusterComps = app.STUDY.cluster(1).comps;
    numParentComponents = length(parentClusterComps);
    numParentDatasets = length(unique(app.STUDY.cluster(1).sets));
    fprintf('Parent Cluster:\n');
    fprintf('  Number of datasets: %d\n', numParentDatasets);
    fprintf('  Number of components: %d\n', numParentComponents);
    fprintf('  List of components: %s\n', mat2str(parentClusterComps));
    fprintf('  Components match silhouette computation (%d components)\n', numParentComponents);
    fprintf('  Mean Silhouette = NaN, Median Silhouette = NaN (n = %d components)\n', numParentComponents);

    % --- Outlier cluster ---
    if hasOutlier
        outlierIndex = 2;
        outlierComps = app.STUDY.cluster(outlierIndex).comps;
        numOutlierComponents = length(outlierComps);
        numOutlierDatasets = length(unique(app.STUDY.cluster(outlierIndex).sets));
        fprintf('Outlier Cluster:\n');
        fprintf('  Number of datasets: %d\n', numOutlierDatasets);
        fprintf('  Number of components: %d\n', numOutlierComponents);
        fprintf('  List of components: %s\n', mat2str(outlierComps));
        fprintf('  Components match silhouette computation (%d components)\n', numOutlierComponents);
        fprintf('  Mean Silhouette = NaN, Median Silhouette = NaN (n = %d components)\n', numOutlierComponents);
    end

    % --- Real clusters ---
    for c = 1:numClusters
        clusterIdx = startIdx + c - 1;
        clusterComps = find(silhouetteAssignments == c);
        if isempty(clusterComps)
            fprintf('Cluster %d: No components\n', c);
            continue;
        end
        clusterDatasets = length(unique(app.STUDY.cluster(clusterIdx).sets));

        realCompIndices = find(silhouetteAssignments > 0);
        clusterScoresIdx = find(ismember(realCompIndices, clusterComps));
        clusterSilScores = silhouetteScores(clusterScoresIdx);

        fprintf('Cluster %d:\n', c);
        fprintf('  Number of datasets: %d\n', clusterDatasets);
        fprintf('  Number of components: %d\n', length(clusterComps));
        fprintf('  List of components: %s\n', mat2str(clusterComps));
        fprintf('  Components match silhouette computation (%d components)\n', length(clusterComps));
        fprintf('  Mean Silhouette = %.3f, Median Silhouette = %.3f (n = %d components)\n', ...
                mean(clusterSilScores), median(clusterSilScores), length(clusterComps));
    end

    fprintf('Overall Mean Silhouette for K = %d: %.3f\n', numClusters, mean(silhouetteScores));
    fprintf('Overall Median Silhouette for K = %d: %.3f\n', numClusters, median(silhouetteScores));
    fprintf('===== END OF SILHOUETTE SUMMARY =====\n\n');
end

% Continuation of Step 2: Print final cluster summary (unified across all algorithms)
fprintf('\n=== FINAL CLUSTER SUMMARY REPORT ===\n');

STUDY = app.STUDY;
nClusters = numel(STUDY.cluster);

if nClusters < 1
    fprintf('No clusters found in STUDY.\n');
    return;
end

% --- Parent cluster ---
fprintf('\n--- Parent Cluster ---\n');
i = 1;
clusterName = STUDY.cluster(i).name;
sets = STUDY.cluster(i).sets;
comps = STUDY.cluster(i).comps;

uniqueSets = unique(sets);
nDatasets = numel(uniqueSets);
nComps = numel(comps);

fprintf('Cluster Name: %s\n', clusterName);
fprintf(' - Number of unique datasets: %d\n', nDatasets);
fprintf(' - Unique datasets: %s\n', mat2str(uniqueSets));
fprintf(' - Number of components: %d\n', nComps);
fprintf(' - Component indices: %s\n', mat2str(comps));

% --- Detect outlier cluster ---
if nClusters >= 2 && contains(lower(STUDY.cluster(2).name), 'outlier')
    hasOutlier = true;
    fprintf('\n--- Outlier Cluster ---\n');
    i = 2;
    clusterName = STUDY.cluster(i).name;
    sets = STUDY.cluster(i).sets;
    comps = STUDY.cluster(i).comps;

    uniqueSets = unique(sets);
    nDatasets = numel(uniqueSets);
    nComps = numel(comps);

    fprintf('Cluster Name: %s\n', clusterName);
    fprintf(' - Number of unique datasets: %d\n', nDatasets);
    fprintf(' - Unique datasets: %s\n', mat2str(uniqueSets));
    fprintf(' - Number of components: %d\n', nComps);
    fprintf(' - Component indices: %s\n', mat2str(comps));

    startIdx = 3;
else
    hasOutlier = false;
    startIdx = 2;
end

% --- Real clusters ---
nRealClusters = nClusters - (startIdx - 1);
fprintf('\nNumber of real clusters: %d\n', nRealClusters);

realClusterCounter = 1;

for i = startIdx:nClusters
    clusterName = STUDY.cluster(i).name;
    sets = STUDY.cluster(i).sets;
    comps = STUDY.cluster(i).comps;

    if isempty(sets)
        fprintf('\nCluster %s: [empty cluster]\n', clusterName);
        continue;
    end

    uniqueSets = unique(sets);
    nDatasets = numel(uniqueSets);
    nComps = numel(comps);

    % Ordinal label
    switch mod(realClusterCounter, 100)
        case {11, 12, 13}, suffix = 'th';
        otherwise
            switch mod(realClusterCounter, 10)
                case 1, suffix = 'st';
                case 2, suffix = 'nd';
                case 3, suffix = 'rd';
                otherwise, suffix = 'th';
            end
    end
    ordinalLabel = sprintf('%d%s', realClusterCounter, suffix);

    % Print cluster info
    fprintf('\n--- %s Real Cluster ---\n', ordinalLabel);
    fprintf('Cluster Name: %s\n', clusterName);
    fprintf(' - Number of unique datasets: %d\n', nDatasets);
    fprintf(' - Unique datasets: %s\n', mat2str(uniqueSets));
    fprintf(' - Number of components: %d\n', nComps);
    fprintf(' - Component indices: %s\n', mat2str(comps));

    realClusterCounter = realClusterCounter + 1;
end

fprintf('\n=== END OF REPORT ===\n');

% --- Preserve Optimal KMeans section (unchanged) ---
if strcmpi(app.ClustAlgo, 'optimal_kmeans') && ~isempty(app.KOverallStats) && app.stdDevThreshold < 1
    matchIdx = find(app.KOverallStats(:, 1) == nRealClusters, 1);
    if ~isempty(matchIdx)
        overallMean = app.KOverallStats(matchIdx, 2);
        overallMedian = app.KOverallStats(matchIdx, 3);
        fprintf('\nOverall Mean Silhouette for K = %d: %.3f\n', nRealClusters, overallMean);
        fprintf('Overall Median Silhouette for K = %d: %.3f\n', nRealClusters, overallMedian);
    else
        fprintf('\nNo matching K found in KOverallStats for %d clusters.\n', nRealClusters);
    end
else  
   if strcmpi(app.ClustAlgo, 'optimal_kmeans') && app.stdDevThreshold >= 1
        % Find the index corresponding to the user-selected number of clusters
        clusterNumbers = 2:20;
        kIdx = find(clusterNumbers == app.selectedNumClusters, 1);
    
        if isempty(kIdx) || isempty(app.allSilhouetteScores{kIdx})
            fprintf('No silhouette scores available for K = %d.\n', app.selectedNumClusters);
        else
            silhouetteScores = app.allSilhouetteScores{kIdx};
            fprintf('Overall Mean Silhouette for K = %d: %.3f\n', app.selectedNumClusters, mean(silhouetteScores));
            fprintf('Overall Median Silhouette for K = %d: %.3f\n', app.selectedNumClusters, median(silhouetteScores));
        end
   end
end


    % Step 3: Validate Data Availability for Scalp, Spectra, ERP, ERSP, and ITC Plots
    fprintf('Verifying data availability for plots...\n');
    dataIssues = false; % Flag to track if there are any data issues
    allClustersMissingData = true; % Flag to check if all clusters are missing critical data

    for i = 1:length(app.STUDY.cluster)
        if i == 1
            clusterLabel = 'Parent Cluster';
        elseif i == 2 && hasOutlier
            clusterLabel = 'Outlier Cluster';
        else
            if hasOutlier
                clusterLabel = sprintf('Cluster %d', i);
            else
                clusterLabel = sprintf('Cluster %d', i + 1);
            end
        end

        % Check for components in the cluster
        if ~isfield(app.STUDY.cluster(i), 'comps') || isempty(app.STUDY.cluster(i).comps)
            fprintf('  Warning: %s has no components. Scalp, spectra, ERP, ERSP, and ITC plots will fail for this cluster.\n', clusterLabel);
            dataIssues = true;
            continue; % Skip further checks for this cluster
        else
            allClustersMissingData = false; % At least one cluster has components
        end

        % Check for dataset assignments
        if ~isfield(app.STUDY.cluster(i), 'sets') || isempty(app.STUDY.cluster(i).sets) || ...
           length(app.STUDY.cluster(i).sets) ~= length(app.STUDY.cluster(i).comps)
            fprintf('  Warning: %s has invalid dataset assignments. Scalp, spectra, ERP, ERSP, and ITC plots may fail.\n', clusterLabel);
            dataIssues = true;
            continue;
        end

        % Get unique dataset indices for this cluster
        uniqueSets = unique(app.STUDY.cluster(i).sets(:));

        % Check data for each dataset
        for d = 1:length(uniqueSets)
            datasetIdx = uniqueSets(d);
            if datasetIdx > length(app.ALLEEG) || isempty(app.ALLEEG(datasetIdx).data)
                fprintf('  Warning: Dataset %d for %s lacks EEG data. Spectra, ERP, ERSP, and ITC plots will fail.\n', datasetIdx, clusterLabel);
                dataIssues = true;
                continue;
            end

            % Check for ICA weights (for scalp maps)
            if ~isfield(app.ALLEEG(datasetIdx), 'icaweights') || isempty(app.ALLEEG(datasetIdx).icaweights) || ...
               ~isfield(app.ALLEEG(datasetIdx), 'icawinv') || isempty(app.ALLEEG(datasetIdx).icawinv)
                fprintf('  Warning: Dataset %d for %s lacks ICA weights. Scalp maps will fail.\n', datasetIdx, clusterLabel);
                dataIssues = true;
            end

            % Check for EEG data (for spectra)
            if ~isfield(app.ALLEEG(datasetIdx), 'data') || isempty(app.ALLEEG(datasetIdx).data)
                fprintf('  Warning: Dataset %d for %s lacks EEG data. Spectra plots will fail.\n', datasetIdx, clusterLabel);
                dataIssues = true;
            end

            % Check for event data (for ERP, ERSP, ITC)
            if ~isfield(app.ALLEEG(datasetIdx), 'event') || isempty(app.ALLEEG(datasetIdx).event)
                fprintf('  Warning: Dataset %d for %s lacks event data. ERP, ERSP, and ITC plots will fail.\n', datasetIdx, clusterLabel);
                dataIssues = true;
            else
                % Check for required event fields (e.g., type, latency)
                hasEvents = false;
                for e = 1:length(app.ALLEEG(datasetIdx).event)
                    if isfield(app.ALLEEG(datasetIdx).event(e), 'type') && ...
                       isfield(app.ALLEEG(datasetIdx).event(e), 'latency')
                        hasEvents = true;
                        break;
                    end
                end
                if ~hasEvents
                    fprintf('  Warning: Dataset %d for %s has event data but lacks required fields (type, latency). ERP, ERSP, and ITC plots may fail.\n', datasetIdx, clusterLabel);
                    dataIssues = true;
                end
            end
        end
    end

    % Summarize data verification
    if allClustersMissingData
        app.StatusLabel.Text = 'Error: No clusters have components';
        app.ResultLabel.Text = 'All clusters lack components. Cannot proceed with plotting.';
        fprintf('Error: All clusters lack components. Cannot proceed with plotting.\n');
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    elseif dataIssues
        fprintf('Data verification complete with issues. Some plots may fail for certain clusters (see warnings above).\n');
    else
        fprintf('Data verification complete. All clusters have necessary data for scalp, spectra, ERP, ERSP, and ITC plots.\n');
    end

    % Step 4: Generate clust_table (including outlier cluster if present)
    if isempty(app.STUDY.cluster)
        app.StatusLabel.Text = 'Error: STUDY.cluster is empty';
        app.ResultLabel.Text = 'No clusters found in the STUDY file';
        fprintf('Error: STUDY.cluster is empty\n');
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    clust_table = table([], [], [], ...
        'VariableNames', {'ClustersID', 'SubjectID', 'icID'});

    % Process outlier cluster if it exists
    if hasOutlier
        clusterIdx = 2; % Outlier cluster
        if ~isfield(app.STUDY.cluster(clusterIdx), 'sets') || isempty(app.STUDY.cluster(clusterIdx).sets) || ...
           ~isfield(app.STUDY.cluster(clusterIdx), 'comps') || isempty(app.STUDY.cluster(clusterIdx).comps)
            fprintf('Warning: Outlier cluster has no valid data. Skipping.\n');
        else
            ClustersID = 0; % Outlier cluster ID
            SubjectID = app.STUDY.cluster(clusterIdx).sets(:);
            icID = app.STUDY.cluster(clusterIdx).comps(:);
            if length(SubjectID) ~= length(icID)
                fprintf('Warning: Outlier cluster sets (%d) and comps (%d) lengths do not match. Skipping.\n', length(SubjectID), length(icID));
            else
                currentTable = table(repmat(ClustersID, length(SubjectID), 1), SubjectID, icID, ...
                    'VariableNames', {'ClustersID', 'SubjectID', 'icID'});
                clust_table = [clust_table; currentTable];
            end
        end
    end

    % Process real clusters
    for i = 1:numClusters
        clusterIdx = startIdx + i - 1;
        if clusterIdx > totalClusters || ~isfield(app.STUDY.cluster(clusterIdx), 'name') || isempty(app.STUDY.cluster(clusterIdx).name)
            fprintf('Warning: Cluster index %d is invalid or missing name. Skipping.\n', clusterIdx);
            continue;
        end
        clusterName = app.STUDY.cluster(clusterIdx).name;
        % Dynamically extract ClustersID from cluster name (e.g., 'Cls 5' -> ClustersID = 3)
        tokens = regexp(clusterName, 'Cls (\d+)', 'tokens');
        if ~isempty(tokens)
            clusterNum = str2double(tokens{1}{1});
            % Dynamically adjust ClustersID depending on outlier presence
            if hasOutlier
                % When outlier exists, real clusters start from Cls 3 → ID 1
                ClustersID = clusterNum - 2;
            else
                % When no outlier, real clusters start from Cls 2 → ID 1
                ClustersID = clusterNum - 1;
            end
        else
            fprintf('Warning: Could not parse cluster number from name "%s". Skipping cluster %d.\n', clusterName, clusterIdx);
            continue;
        end

        if ~isfield(app.STUDY.cluster(clusterIdx), 'sets') || isempty(app.STUDY.cluster(clusterIdx).sets) || ...
           ~isfield(app.STUDY.cluster(clusterIdx), 'comps') || isempty(app.STUDY.cluster(clusterIdx).comps)
            fprintf('Warning: Cluster %d has no valid sets or comps data. Skipping.\n', clusterIdx);
            continue;
        end
        SubjectID = app.STUDY.cluster(clusterIdx).sets(:);
        icID = app.STUDY.cluster(clusterIdx).comps(:);
        if length(SubjectID) ~= length(icID)
            fprintf('Warning: Cluster %d sets (%d) and comps (%d) lengths do not match.\n', clusterIdx, length(SubjectID), length(icID));
            continue;
        end
        currentTable = table(repmat(ClustersID, length(SubjectID), 1), SubjectID, icID, ...
            'VariableNames', {'ClustersID', 'SubjectID', 'icID'});
        clust_table = [clust_table; currentTable];
    end

    % Sort and validate clust_table with Section 1 subject data
    if ~isempty(clust_table)
        clust_table = sortrows(clust_table, 'ClustersID');
        
        % Validate SubjectID against app.subjectIDs_Section1
        uniqueSubjectIDs = unique(clust_table.SubjectID);
        validSubjectIDs = app.subjectIDs_Section1;
        invalidSubjects = ~ismember(uniqueSubjectIDs, validSubjectIDs);
        if any(invalidSubjects)
            invalidIDs = uniqueSubjectIDs(invalidSubjects);
            fprintf('Error: The following SubjectIDs in clust_table are not found in Section 1 data: %s\n', mat2str(invalidIDs));
            app.StatusLabel.Text = 'Error: Invalid SubjectIDs in clust_table';
            app.ResultLabel.Text = 'SubjectIDs in clust_table do not match Section 1 data';
            warning('on', 'MATLAB:dispatcher:nameConflict');
            return;
        end
    else
        app.StatusLabel.Text = 'Error: clust_table is empty';
        app.ResultLabel.Text = 'No valid cluster data found';
        fprintf('Error: clust_table is empty\n');
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end
    app.clust_table = clust_table;

    % Debug: Print size of clust_table
    fprintf('Size of clust_table: %d rows, %d columns\n', size(app.clust_table, 1), size(app.clust_table, 2));

    % Save clust_table
    clustFileName = 'clust_table.mat';
    clustFullFilePath = app.getUniqueFilePath(app.studyFilePath, clustFileName);
    clust_table = app.clust_table;
    save(clustFullFilePath, 'clust_table', '-v7.3');
    fprintf('Saved clust_table to %s\n', clustFullFilePath);

    % Step 5: Calculate Total Subjects and Validate Group Ranges
    % Calculate total subjects
    app.totalSubjects = length(unique(app.clust_table.SubjectID));
    if app.totalSubjects == 0
        fprintf('Error: No subjects found in clust_table\n');
        app.StatusLabel.Text = 'Error: No subjects in clust_table';
        app.ResultLabel.Text = 'No subjects found in the clustered STUDY';
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    % Debug: Print totalSubjects
    fprintf('Total subjects (app.totalSubjects): %d\n', app.totalSubjects);

    % Convert group ranges from strings to numeric arrays and validate
    try
        % Convert subject_range_1
        if ischar(app.groupRanges{1}) || isstring(app.groupRanges{1})
            subject_range_1 = eval(app.groupRanges{1});
        else
            subject_range_1 = app.groupRanges{1};
        end
        if ~isnumeric(subject_range_1) || isempty(subject_range_1)
            error('Invalid format for group range 1: %s', app.groupRanges{1});
        end

        % Convert subject_range_2 if it exists
        if app.numGroups >= 2
            if ischar(app.groupRanges{2}) || isstring(app.groupRanges{2})
                subject_range_2 = eval(app.groupRanges{2});
            else
                subject_range_2 = app.groupRanges{2};
            end
            if ~isnumeric(subject_range_2) || isempty(subject_range_2)
                error('Invalid format for group range 2: %s', app.groupRanges{2});
            end
        else
            subject_range_2 = [];
        end

        % Convert subject_range_3 if it exists
        if app.numGroups == 3
            if ischar(app.groupRanges{3}) || isstring(app.groupRanges{3})
                subject_range_3 = eval(app.groupRanges{3});
            else
                subject_range_3 = app.groupRanges{3};
            end
            if ~isnumeric(subject_range_3) || isempty(subject_range_3)
                error('Invalid format for group range 3: %s', app.groupRanges{3});
            end
        else
            subject_range_3 = [];
        end
    catch ME
        fprintf('Error: Failed to parse group ranges: %s\n', ME.message);
        app.StatusLabel.Text = 'Error: Invalid group range format';
        app.ResultLabel.Text = sprintf('Failed to parse group ranges: %s', ME.message);
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    % Debug: Print subject ranges (now numeric)
    fprintf('subject_range_1: %s\n', mat2str(subject_range_1));
    if app.numGroups >= 2
        fprintf('subject_range_2: %s\n', mat2str(subject_range_2));
    end
    if app.numGroups == 3
        fprintf('subject_range_3: %s\n', mat2str(subject_range_3));
    end

    % Validate ranges against Section 1 subject data
    if ~all(ismember(subject_range_1, app.subjectIDs_Section1)) || ...
       (app.numGroups >= 2 && ~all(ismember(subject_range_2, app.subjectIDs_Section1))) || ...
       (app.numGroups == 3 && ~all(ismember(subject_range_3, app.subjectIDs_Section1)))
        fprintf('Error: Subject ranges contain IDs not found in Section 1 data\n');
        app.StatusLabel.Text = 'Error: Invalid subject ranges';
        app.ResultLabel.Text = 'Subject ranges do not match Section 1 subject IDs';
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    % Step 6: Process dipXyz Data and Create dipXyz_table2
% Validate that non-clustered study data exists
if ~isprop(app, 'subID') || ~isprop(app, 'icID') || ...
   ~isprop(app, 'non_clust_sets_length') || ~isprop(app, 'non_clust_comps_length')
    app.StatusLabel.Text = 'Error: Non-clustered study data missing';
    app.ResultLabel.Text = 'Non-clustered sets and comps data not found';
    fprintf('Error: Non-clustered study data missing, non-clustered sets and comps data not found\n');
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Validate stored sets and comps lengths
if app.non_clust_sets_length ~= app.non_clust_comps_length
    app.StatusLabel.Text = 'Error: Non-clustered study data mismatch';
    app.ResultLabel.Text = sprintf('Non-clustered sets length (%d) does not match comps length (%d)', ...
        app.non_clust_sets_length, app.non_clust_comps_length);
    fprintf('Error: Non-clustered sets length (%d) does not match comps length (%d)\n', ...
        app.non_clust_sets_length, app.non_clust_comps_length);
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

if isempty(app.subID) || isempty(app.icID)
    app.StatusLabel.Text = 'Error: Non-clustered study data empty';
    app.ResultLabel.Text = 'Non-clustered sets or comps are empty';
    fprintf('Error: Non-clustered sets or comps are empty\n');
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

fprintf('Processing dipXyz data using non-clustered study parent cluster:\n');
fprintf('  Number of sets: %d\n', app.non_clust_sets_length);
fprintf('  Number of comps: %d\n', app.non_clust_comps_length);

% Initialize dipXyz array
dipXyz = [];
for subjIdx = 1:length(app.ALLEEG)
    % Skip if no dipfit model exists
    if ~isfield(app.ALLEEG(subjIdx), 'dipfit') || isempty(app.ALLEEG(subjIdx).dipfit.model)
        fprintf('Warning: No dipfit model for subject %d. Skipping.\n', subjIdx);
        continue;
    end

    % Obtain xyz, dip moment, maxProj channel xyz
    xyz = zeros(length(app.ALLEEG(subjIdx).dipfit.model), 5);
    for modelIdx = 1:length(app.ALLEEG(subjIdx).dipfit.model)
        % Choose the larger dipole if symmetrical
        currentSubjectID = subjIdx;
        currentXyz = app.ALLEEG(subjIdx).dipfit.model(modelIdx).posxyz;
        currentMom = app.ALLEEG(subjIdx).dipfit.model(modelIdx).momxyz; % nAmm
        currentrv = app.ALLEEG(subjIdx).dipfit.model(modelIdx).rv;

        if size(currentMom, 1) > 1
            [~, largerOneIdx] = max([norm(currentMom(1,:)) norm(currentMom(2,:))]);
            currentXyz = app.ALLEEG(subjIdx).dipfit.model(modelIdx).posxyz(largerOneIdx, :);
        end
        xyz(modelIdx, :) = [currentSubjectID currentXyz currentrv];
    end
    dipXyz = [dipXyz; xyz];
end

% Check if any dipole data was extracted
if isempty(dipXyz)
    app.StatusLabel.Text = 'Error: No dipole data extracted';
    app.ResultLabel.Text = 'Failed to extract any dipole data from ALLEEG';
    fprintf('Error: No dipole data extracted from ALLEEG\n');
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Remove rows with NaN in posx
dipXyz = dipXyz(~isnan(dipXyz(:, 2)), :);
if isempty(dipXyz)
    app.StatusLabel.Text = 'Error: dipXyz is empty after NaN removal';
    app.ResultLabel.Text = 'No valid dipole data after removing NaN positions';
    fprintf('Error: dipXyz is empty after removing NaN positions\n');
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Convert to table
dipXyz_table = array2table(dipXyz, 'VariableNames', {'SubjectID', 'posx', 'posy', 'posz', 'rv'});

% Create dipXyz_table2 with Subjects column
numRows = size(dipXyz_table, 1);
subjectColumn = cell(numRows, 1); % Using cell array to store string values

% Create the new table with the 'Subjects' column added
dipXyz_table2 = [table(subjectColumn, 'VariableNames', {'Subjects'}), dipXyz_table];

% Populate the Subjects column
for i = 1:numRows
    subjID = dipXyz_table2.SubjectID(i);
    if subjID > length(app.ALLEEG) || ~isfield(app.ALLEEG(subjID), 'subject') || isempty(app.ALLEEG(subjID).subject)
        fprintf('Warning: Invalid or missing subject data for SubjectID %d at row %d. Skipping.\n', subjID, i);
        dipXyz_table2.Subjects{i} = '';
    else
        dipXyz_table2.Subjects{i} = app.ALLEEG(subjID).subject;
    end
end

% Reorder columns to [Subjects, SubjectID, posx, posy, posz, rv]
dipXyz_table2 = dipXyz_table2(:, {'Subjects', 'SubjectID', 'posx', 'posy', 'posz', 'rv'});

% Validate dipXyz_table2 against Section 1 data
invalidSubjects = ~ismember(dipXyz_table2.SubjectID, app.subjectIDs_Section1);
if any(invalidSubjects)
    fprintf('Error: The following SubjectIDs in dipXyz_table2 are not found in Section 1 data: %s\n', mat2str(dipXyz_table2.SubjectID(invalidSubjects)));
    app.StatusLabel.Text = 'Error: Invalid SubjectIDs in dipXyz_table2';
    app.ResultLabel.Text = 'SubjectIDs in dipXyz_table2 do not match Section 1 data';
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Validate row count against non_clust_sets_length and non_clust_comps_length
if height(dipXyz_table2) ~= app.non_clust_sets_length || height(dipXyz_table2) ~= app.non_clust_comps_length
    fprintf('Error: dipXyz_table2 has %d rows, but non_clust_sets_length is %d and non_clust_comps_length is %d\n', ...
        height(dipXyz_table2), app.non_clust_sets_length, app.non_clust_comps_length);
    app.StatusLabel.Text = 'Error: dipXyz_table2 row count mismatch';
    app.ResultLabel.Text = 'dipXyz_table2 rows do not match non-clustered study data lengths';
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Add subID and icID columns to dipXyz_table2
dipXyz_table2.subID = app.subID;
dipXyz_table2.icID = app.icID;

% Match SubjectID with subID row by row
for i = 1:height(dipXyz_table2)
    if dipXyz_table2.SubjectID(i) ~= dipXyz_table2.subID(i)
        fprintf('Error: SubjectID %d does not match subID %d at row %d in dipXyz_table2\n', ...
            dipXyz_table2.SubjectID(i), dipXyz_table2.subID(i), i);
        app.StatusLabel.Text = 'Error: SubjectID and subID mismatch';
        app.ResultLabel.Text = 'SubjectID does not match subID in dipXyz_table2';
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end
end

% Remove subID column, keeping icID (resulting in 7 columns)
dipXyz_table2.subID = [];

% --- Updated Code: Validate and Add icaweights, icawinv, icaact, chanlocs, and srate Columns ---
% Step 6.1: Validate dipXyz_table2 rows against total dipoles in ALLEEG
totalDipoles = 0;
for i = 1:length(app.ALLEEG)
    if isfield(app.ALLEEG(i), 'dipfit') && ~isempty(app.ALLEEG(i).dipfit.model)
        totalDipoles = totalDipoles + size(app.ALLEEG(i).dipfit.model, 2);
    end
end
if height(dipXyz_table2) ~= totalDipoles
    fprintf('Error: dipXyz_table2 has %d rows, but total dipoles in ALLEEG is %d\n', height(dipXyz_table2), totalDipoles);
    app.StatusLabel.Text = 'Error: dipXyz_table2 row count mismatch with ALLEEG';
    app.ResultLabel.Text = 'dipXyz_table2 rows do not match total dipoles in ALLEEG';
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Step 6.2: Validate unique subjects in dipXyz_table2 against ALLEEG.subject
uniqueSubjectsTable = unique(dipXyz_table2.Subjects);
alleegSubjects = cell(1, length(app.ALLEEG));
for i = 1:length(app.ALLEEG)
    if isfield(app.ALLEEG(i), 'subject') && ~isempty(app.ALLEEG(i).subject)
        alleegSubjects{i} = app.ALLEEG(i).subject;
    else
        alleegSubjects{i} = '';
    end
end
uniqueAlleegSubjects = unique(alleegSubjects);
invalidSubjects = ~ismember(uniqueSubjectsTable, uniqueAlleegSubjects);
if any(invalidSubjects)
    invalidSubjectNames = uniqueSubjectsTable(invalidSubjects);
    fprintf('Error: The following subjects in dipXyz_table2 are not found in ALLEEG: %s\n', strjoin(invalidSubjectNames, ', '));
    app.StatusLabel.Text = 'Error: Invalid subjects in dipXyz_table2';
    app.ResultLabel.Text = 'Subjects in dipXyz_table2 do not match ALLEEG subjects';
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Step 6.3: Add icaweights, icawinv, icaact, chanlocs, and srate columns
icaweightsColumn = cell(height(dipXyz_table2), 1);
icawinvColumn = cell(height(dipXyz_table2), 1);
icaactColumn = cell(height(dipXyz_table2), 1);
chanlocsColumn = cell(height(dipXyz_table2), 1);
srateColumn = nan(height(dipXyz_table2), 1);

% Find standard chanlocs (first subject with 60 channels)
standardChanlocs = [];
for idx = 1:length(app.ALLEEG)
    if length(app.ALLEEG(idx).chanlocs) == 60
        standardChanlocs = app.ALLEEG(idx).chanlocs;
        break;
    end
end
if isempty(standardChanlocs)
    fprintf('Error: No subject with 60 channels found in app.ALLEEG.\n');
    app.StatusLabel.Text = 'Error: No standard chanlocs found';
    app.ResultLabel.Text = 'No subject with 60 channels found in app.ALLEEG';
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Group dipXyz_table2 by SubjectID to process each subject
uniqueSubjectIDs = unique(dipXyz_table2.SubjectID);
for s = 1:length(uniqueSubjectIDs)
    subjID = uniqueSubjectIDs(s);
    subjectRows = dipXyz_table2.SubjectID == subjID;
    subjectIcIDs = dipXyz_table2.icID(subjectRows);
    numIcIDs = length(subjectIcIDs);

    % Find corresponding ALLEEG index
    alleegIdx = subjID; % Since SubjectID corresponds to ALLEEG index

    % Validate icaweights
    if ~isfield(app.ALLEEG(alleegIdx), 'icaweights') || isempty(app.ALLEEG(alleegIdx).icaweights)
        fprintf('Error: ALLEEG(%d) lacks icaweights for subject %s\n', alleegIdx, dipXyz_table2.Subjects{find(subjectRows, 1)});
        app.StatusLabel.Text = 'Error: Missing icaweights';
        app.ResultLabel.Text = sprintf('ALLEEG(%d) lacks icaweights', alleegIdx);
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end
    icaweights = app.ALLEEG(alleegIdx).icaweights;
    if size(icaweights, 1) ~= numIcIDs
        fprintf('Error: Number of icIDs (%d) does not match icaweights size (%d) for subject %s\n', ...
            numIcIDs, size(icaweights, 1), dipXyz_table2.Subjects{find(subjectRows, 1)});
        app.StatusLabel.Text = 'Error: icaweights size mismatch';
        app.ResultLabel.Text = sprintf('icaweights size mismatch for subject %s', dipXyz_table2.Subjects{find(subjectRows, 1)});
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    % Validate icawinv
    if ~isfield(app.ALLEEG(alleegIdx), 'icawinv') || isempty(app.ALLEEG(alleegIdx).icawinv)
        fprintf('Error: ALLEEG(%d) lacks icawinv for subject %s\n', alleegIdx, dipXyz_table2.Subjects{find(subjectRows, 1)});
        app.StatusLabel.Text = 'Error: Missing icawinv';
        app.ResultLabel.Text = sprintf('ALLEEG(%d) lacks icawinv', alleegIdx);
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end
    icawinv = app.ALLEEG(alleegIdx).icawinv;
    if size(icawinv, 2) ~= numIcIDs
        fprintf('Error: Number of icIDs (%d) does not match icawinv size (%d) for subject %s\n', ...
            numIcIDs, size(icawinv, 2), dipXyz_table2.Subjects{find(subjectRows, 1)});
        app.StatusLabel.Text = 'Error: icawinv size mismatch';
        app.ResultLabel.Text = sprintf('icawinv size mismatch for subject %s', dipXyz_table2.Subjects{find(subjectRows, 1)});
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    % Validate icaact
    if ~isfield(app.ALLEEG(alleegIdx), 'icaact') || isempty(app.ALLEEG(alleegIdx).icaact)
        fprintf('Error: ALLEEG(%d) lacks icaact for subject %s\n', alleegIdx, dipXyz_table2.Subjects{find(subjectRows, 1)});
        app.StatusLabel.Text = 'Error: Missing icaact';
        app.ResultLabel.Text = sprintf('ALLEEG(%d) lacks icaact', alleegIdx);
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end
    icaact = app.ALLEEG(alleegIdx).icaact;
    icaactDims = ndims(icaact);
    if (icaactDims == 2 && size(icaact, 1) ~= numIcIDs) || (icaactDims == 3 && size(icaact, 1) ~= numIcIDs)
        fprintf('Error: Number of icIDs (%d) does not match icaact size (%d) for subject %s\n', ...
            numIcIDs, size(icaact, 1), dipXyz_table2.Subjects{find(subjectRows, 1)});
        app.StatusLabel.Text = 'Error: icaact size mismatch';
        app.ResultLabel.Text = sprintf('icaact size mismatch for subject %s', dipXyz_table2.Subjects{find(subjectRows, 1)});
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end

    % Extract chanlocs and srate
    EEG = app.ALLEEG(alleegIdx);
    chanlocs = EEG.chanlocs;
    srate = EEG.srate;
    subjectName = dipXyz_table2.Subjects{find(subjectRows, 1)};
    fprintf('  Before Interpolation - Chanlocs for SubjectID %d (1x%d struct):\n', subjID, length(chanlocs));
    disp(chanlocs);

    % Perform interpolation if needed
    expectedChannels = 60;
    if length(chanlocs) ~= expectedChannels
        fprintf('  Interpolating %s (SubjectID %d) from %d to %d channels...\n', ...
             subjectName, subjID, length(chanlocs), expectedChannels);
        try
            EEG = eeg_interp(EEG, standardChanlocs, 'spherical');
            chanlocs = EEG.chanlocs;
            srate = EEG.srate;
        catch ME
            fprintf('Warning: Interpolation failed for %s (SubjectID %d): %s. Skipping.\n', subjectName, subjID, ME.message);
            app.StatusLabel.Text = sprintf('Warning: Interpolation failed for SubjectID %d', subjID);
            app.ResultLabel.Text = sprintf('Interpolation failed: %s', ME.message);
            continue;
        end
    else
        fprintf('  No interpolation needed for %s (SubjectID %d), already %d channels.\n', ...
              subjectName, subjID, length(chanlocs));
    end

    % Print after interpolation
    fprintf('  After Interpolation - Chanlocs for SubjectID %d (%s) (1x%d struct):\n', subjID, subjectName, length(chanlocs));
    disp(chanlocs);
    fprintf('  Sampling Rate for SubjectID %d (%s): %.2f Hz\n', subjID, subjectName, srate);

    % Assign icaweights, icawinv, icaact, chanlocs, and srate to each icID
    rowIndices = find(subjectRows);
    for ic = 1:numIcIDs
        rowIdx = rowIndices(ic);
        icID = subjectIcIDs(ic);

        % icaweights: row corresponding to icID
        icaweightsColumn{rowIdx} = icaweights(icID, :);

        % icawinv: column corresponding to icID
        icawinvColumn{rowIdx} = icawinv(:, icID);

        % icaact: row (2D) or slice (3D) corresponding to icID
        if icaactDims == 2
            icaactColumn{rowIdx} = icaact(icID, :);
        else % 3D
            icaactColumn{rowIdx} = squeeze(icaact(icID, :, :));
        end

        % chanlocs and srate: same for all components of this subject
        chanlocsColumn{rowIdx} = chanlocs;
        srateColumn(rowIdx) = srate;
    end
end

% Add the new columns to dipXyz_table2
dipXyz_table2.icaweights = icaweightsColumn;
dipXyz_table2.icawinv = icawinvColumn;
dipXyz_table2.icaact = icaactColumn;
dipXyz_table2.chanlocs = chanlocsColumn;
dipXyz_table2.srate = srateColumn;

% --- End of Updated Code ---

% Store dipXyz_table2 in app
app.dipXyz_table2 = dipXyz_table2;

% Debug: Print size of dipXyz_table2
fprintf('Size of dipXyz_table2: %d rows, %d columns\n', size(app.dipXyz_table2, 1), size(app.dipXyz_table2, 2));

% Save dipXyz_table2
dipXyzFileName = 'dipxyz_table2.mat';
dipXyzFullFilePath = app.getUniqueFilePath(app.studyFilePath, dipXyzFileName);
dipXyz_table2 = app.dipXyz_table2;
save(dipXyzFullFilePath, 'dipXyz_table2', '-v7.3');
fprintf('Saved dipXyz_table2 to %s\n', dipXyzFullFilePath);

% Step 7: Generate dipxyz_clust_table
% Initialize dipxyz_clust_table with 12 columns (including new icaweights, icawinv, icaact, chanlocs, and srate)
dipxyz_clust_table = table([], [], [], cell(0,1), [], [], [], [], cell(0,1), cell(0,1), cell(0,1), cell(0,1), [], ...
    'VariableNames', {'ClustersID', 'SubjectID', 'icID', 'Subjects', 'posx', 'posy', 'posz', 'rv', 'icaweights', 'icawinv', 'icaact', 'chanlocs', 'srate'});

% Match clust_table with dipXyz_table2 on SubjectID and icID
for i = 1:height(app.clust_table)
    subjectID = app.clust_table.SubjectID(i);
    icID = app.clust_table.icID(i);
    clusterID = app.clust_table.ClustersID(i);

    % Find matching row in dipXyz_table2
    matchIdx = find(app.dipXyz_table2.SubjectID == subjectID & app.dipXyz_table2.icID == icID, 1);
    if isempty(matchIdx)
        fprintf('Warning: No dipole data for SubjectID %d, icID %d in dipXyz_table2. Skipping.\n', subjectID, icID);
        continue;
    end

    % Create new row for dipxyz_clust_table
    newRow = table(clusterID, subjectID, icID, app.dipXyz_table2.Subjects(matchIdx), ...
        app.dipXyz_table2.posx(matchIdx), app.dipXyz_table2.posy(matchIdx), app.dipXyz_table2.posz(matchIdx), app.dipXyz_table2.rv(matchIdx), ...
        app.dipXyz_table2.icaweights(matchIdx), app.dipXyz_table2.icawinv(matchIdx), app.dipXyz_table2.icaact(matchIdx), ...
        app.dipXyz_table2.chanlocs(matchIdx), app.dipXyz_table2.srate(matchIdx), ...
        'VariableNames', {'ClustersID', 'SubjectID', 'icID', 'Subjects', 'posx', 'posy', 'posz', 'rv', 'icaweights', 'icawinv', 'icaact', 'chanlocs', 'srate'});
    dipxyz_clust_table = [dipxyz_clust_table; newRow];
end

% Check for empty table
if isempty(dipxyz_clust_table)
    app.StatusLabel.Text = 'Error: dipxyz_clust_table is empty';
    app.ResultLabel.Text = 'No data in dipxyz_clust_table after processing';
    fprintf('Error: dipxyz_clust_table is empty after processing\n');
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Validate row count
if height(dipxyz_clust_table) ~= height(app.clust_table)
    fprintf('Error: dipxyz_clust_table has %d rows, but clust_table has %d rows\n', ...
        height(dipxyz_clust_table), height(app.clust_table));
    app.StatusLabel.Text = 'Error: dipxyz_clust_table row count mismatch';
    app.ResultLabel.Text = 'dipxyz_clust_table rows do not match clust_table';
    warning('on', 'MATLAB:dispatcher:nameConflict');
    return;
end

% Update SubjectID in dipxyz_clust_table based on Section 1 data
for i = 1:height(dipxyz_clust_table)
    subjectName = dipxyz_clust_table.Subjects{i};
    subjectIdx = find(strcmp(app.subjectNames_Section1, subjectName), 1);
    if isempty(subjectIdx)
        fprintf('Error: Subject %s not found in Section 1 data at row %d of dipxyz_clust_table\n', subjectName, i);
        app.StatusLabel.Text = 'Error: Subject not found in Section 1';
        app.ResultLabel.Text = 'Subject in dipxyz_clust_table not found in Section 1 data';
        warning('on', 'MATLAB:dispatcher:nameConflict');
        return;
    end
    correctSubjectID = app.subjectIDs_Section1(subjectIdx);
    if dipxyz_clust_table.SubjectID(i) ~= correctSubjectID
        fprintf('Updating SubjectID for subject %s: %d -> %d\n', subjectName, dipxyz_clust_table.SubjectID(i), correctSubjectID);
        dipxyz_clust_table.SubjectID(i) = correctSubjectID;
    end
end

% Store dipxyz_clust_table in app
app.dipxyz_clust_table = dipxyz_clust_table;

% Debug: Print size of dipxyz_clust_table
fprintf('Size of dipxyz_clust_table: %d rows, %d columns\n', size(app.dipxyz_clust_table, 1), size(app.dipxyz_clust_table, 2));

% Save dipxyz_clust_table
dipFileName = 'dipxyz_clust_table.mat';
dipFullFilePath = app.getUniqueFilePath(app.studyFilePath, dipFileName);
dipxyz_clust_table = app.dipxyz_clust_table;
save(dipFullFilePath, 'dipxyz_clust_table', '-v7.3');
fprintf('Saved dipxyz_clust_table to %s\n', dipFullFilePath);

% Step 8: Validate dipxyz_clust_table Row Count
% Compute total number of components (excluding parent cluster)
totalComps = 0;
for i = 2:length(app.STUDY.cluster)
    if isfield(app.STUDY.cluster(i), 'comps')
        totalComps = totalComps + length(app.STUDY.cluster(i).comps);
    end
end
fprintf('Total components in STUDY.cluster (excluding parent): %d\n', totalComps);
fprintf('dipxyz_clust_table created with %d rows.\n', height(app.dipxyz_clust_table));
if height(app.dipxyz_clust_table) ~= totalComps
    fprintf('Warning: dipxyz_clust_table has %d rows, but expected %d components. Some dipole data may be missing.\n', ...
        height(app.dipxyz_clust_table), totalComps);
end

% Step 9: Update UI
fprintf('Debug - groupNames_Section1: %s\n', strjoin(app.groupNames_Section1, ', '));
fprintf('Debug - subjectNames_Section1: %s\n', strjoin(app.subjectNames_Section1, ', '));
fprintf('Debug - subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
fprintf('Debug - groupPrefixes: %s\n', strjoin(app.groupPrefixes, ', '));
if iscell(app.groupRanges)
    fprintf('Debug - groupRanges: %s\n', strjoin(cellfun(@mat2str, app.groupRanges, 'UniformOutput', false), ', '));
else
    fprintf('Debug - groupRanges: %s\n', mat2str(app.groupRanges));
end
fprintf('Debug - numGroups: %d\n', length(app.groupPrefixes));

if isempty(app.dipxyz_clust_table)
    app.StatusLabel.Text = 'Error: dipxyz_clust_table is empty';
    app.ResultLabel.Text = 'No data in dipxyz_clust_table';
    fprintf('Error: dipxyz_clust_table is empty\n');
else
    app.StatusLabel.Text = 'Status: Clustered data processed successfully';
    app.ResultLabel.Text = 'Ready to generate plots and statistics';
    app.GenerateplotsandstatisticsButton.Enable = 'on'; % Enable the "Generate plots and statistics" button
end


% Restore warning state
warning('on', 'MATLAB:dispatcher:nameConflict');
    

        end

        % Button pushed function: GenerateplotsandstatisticsButton
        function GenerateplotsandstatisticsButtonPushed(app, event)
% Step 0: Update Status Label
app.StatusLabel.Text = 'Status: Generating cluster plots...';

fprintf('Debug - groupNames_Section1: %s\n', strjoin(app.groupNames_Section1, ', '));
fprintf('Debug - subjectNames_Section1: %s\n', strjoin(app.subjectNames_Section1, ', '));
fprintf('Debug - subjectIDs_Section1: %s\n', mat2str(app.subjectIDs_Section1));
fprintf('Debug - groupPrefixes: %s\n', strjoin(app.groupPrefixes, ', '));
if iscell(app.groupRanges)
    fprintf('Debug - groupRanges: %s\n', strjoin(cellfun(@mat2str, app.groupRanges, 'UniformOutput', false), ', '));
else
    fprintf('Debug - groupRanges: %s\n', mat2str(app.groupRanges));
end
fprintf('Debug - numGroups: %d\n', length(app.groupPrefixes));

% Dynamically locate the latest EEGLAB version
eeglabPaths = which('eeglab', '-all');
if isempty(eeglabPaths)
    error('EEGLAB not found in MATLAB path. Please add EEGLAB and try again.');
end

% Extract version numbers and select the latest
eeglabVersionParts = cell(length(eeglabPaths), 1);
for i = 1:length(eeglabPaths)
    pathStr = eeglabPaths{i};
    [parentPath, versionFolder] = fileparts(fileparts(pathStr));
    versionStr = strrep(versionFolder, 'eeglab', '');
    versionParts = str2double(strsplit(versionStr, '.'));
    if any(isnan(versionParts))
        warning('Invalid version number for path %s, skipping.', pathStr);
        eeglabVersionParts{i} = [0];
    else
        eeglabVersionParts{i} = versionParts;
    end
    fprintf('Debug - Path %s parsed to version parts: %s\n', pathStr, mat2str(eeglabVersionParts{i}));
end

latestIdx = 1;
for i = 2:length(eeglabPaths)
    parts1 = eeglabVersionParts{latestIdx};
    parts2 = eeglabVersionParts{i};
    maxLen = max(length(parts1), length(parts2));
    parts1 = [parts1, zeros(1, maxLen - length(parts1))];
    parts2 = [parts2, zeros(1, maxLen - length(parts2))];
    for p = 1:maxLen
        if parts1(p) > parts2(p)
            break;
        elseif parts2(p) > parts1(p)
            latestIdx = i;
            break;
        end
    end
    fprintf('Debug - Comparing versions: %s vs %s, latestIdx = %d\n', mat2str(parts1), mat2str(parts2), latestIdx);
end

eeglabPath = eeglabPaths{latestIdx};
eeglabRoot = fileparts(eeglabPath);
fprintf('Using EEGLAB version from %s\n', eeglabRoot);

addpath(eeglabRoot);
eeglab nogui;

% Load MRI data
mriFile = fullfile(eeglabRoot, 'plugins', 'dipfit', 'standard_BEM', 'standard_mri.mat');
if ~exist(mriFile, 'file')
    mriFiles = dir(fullfile(eeglabRoot, 'plugins', '**', 'standard_mri.mat'));
    if ~isempty(mriFiles)
        mriFile = fullfile(mriFiles(1).folder, mriFiles(1).name);
    else
        error('standard_mri.mat not found in EEGLAB %s directory. Please ensure the dipfit plugin is installed.', eeglabRoot);
    end
end

try
    mriDataLoaded = load(mriFile);
    if isfield(mriDataLoaded, 'mri')
        app.mriData = mriDataLoaded.mri;
        app.mriFilePath = mriFile;
        fprintf('MRI data loaded successfully from %s.\n', app.mriFilePath);
    else
        error('MRI structure not found in %s. Please provide a valid MRI file.', mriFile);
    end
catch ME
    error('Error loading MRI file %s: %s. Cannot proceed without MRI data.', mriFile, ME.message);
end

disp(['app.mriFilePath: ', app.mriFilePath]);
try ft_read_mri(app.mriFilePath); fprintf('Successfully loaded MRI file using ft_read_mri from %s.\n', app.mriFilePath); catch ME; fprintf('Failed to load MRI file using ft_read_mri: %s\n', ME.message); end

% Step 1: Determine Cluster Range (Including Outlier)
clusterIDs = unique(app.dipxyz_clust_table.ClustersID);
hasOutlier = ismember(0, clusterIDs);
if hasOutlier
    startIdx = 0;
else
    startIdx = 1;
end
clusterIndices = clusterIDs(clusterIDs >= startIdx);

if isempty(clusterIndices)
    warning('No clusters to process (clusterIDs: %d). Aborting plot generation.', length(clusterIDs));
    app.StatusLabel.Text = 'Status: No clusters to process.';
    return;
end

color1 = [0, 255, 0]; color2 = [255, 0, 0]; color3 = [255, 255, 0]; centroid_color = [0, 0, 255];

remainingSubjectIDs = unique(app.dipxyz_clust_table.SubjectID);
totalUniqueSubjects = length(remainingSubjectIDs);
fprintf('Total unique SubjectIDs in dipxyz_clust_table: %d\n', totalUniqueSubjects);
fprintf('Remaining SubjectIDs: %s\n', mat2str(remainingSubjectIDs));

numericGroupRanges = cell(1, app.numGroups);
for g = 1:app.numGroups
    rangeInput = app.groupRanges{g};
    fprintf('Parsing %s range input (raw):\n', app.groupPrefixes{g});
    disp(rangeInput);   % safer than fprintf, shows type & content
    disp(class(rangeInput));

    try
        % --- Case 1: Already numeric array
        if isnumeric(rangeInput)
            numericRange = rangeInput(:)';  % force row vector

        % --- Case 2: String/char like "1:18"
        elseif ischar(rangeInput) || isstring(rangeInput)
            strVal = strtrim(string(rangeInput));
            if contains(strVal, ":")
                parts = str2double(strsplit(strVal, ":"));
                if numel(parts) == 2 && all(~isnan(parts))
                    numericRange = parts(1):parts(2);
                else
                    error('Invalid "start:end" string format');
                end
            else
                % maybe space/comma-separated numbers
                parts = str2double(strsplit(strVal, {',',' ',';'}));
                numericRange = parts(~isnan(parts));
            end

        % --- Case 3: Weird binary garbage (like your boxes)
        elseif iscell(rangeInput) || isa(rangeInput,'uint8') || isa(rangeInput,'char')
            try
                % convert to string then parse numbers
                strVal = string(rangeInput(:)');
                parts = str2double(regexp(strVal, '\d+', 'match'));
                numericRange = parts(~isnan(parts));
            catch
                error('Unsupported binary/encoded range format');
            end

        else
            error('Unsupported range input type: %s', class(rangeInput));
        end

        % --- Validate bounds
        if max(numericRange) > totalUniqueSubjects
            warning('%s range exceeds subject count (%d). Truncating.', ...
                app.groupPrefixes{g}, totalUniqueSubjects);
            numericRange = numericRange(numericRange <= totalUniqueSubjects);
        end
        numericGroupRanges{g} = unique(numericRange);

    catch ME
        warning('%s range parsing failed: %s. Using 1:%d', ...
            app.groupPrefixes{g}, ME.message, totalUniqueSubjects);
        numericGroupRanges{g} = 1:totalUniqueSubjects;
    end
end


groupSubjectIDs = cell(1, app.numGroups);
totalAssignedSubjects = 0;
for g = 1:app.numGroups
    range = numericGroupRanges{g};
    fprintf('Before processing - %s range: %s\n', app.groupPrefixes{g}, mat2str(range));
    if max(range) > totalUniqueSubjects
        warning('%s range (%d:%d) exceeds number of unique SubjectIDs (%d). Truncating range.', app.groupPrefixes{g}, min(range), max(range), totalUniqueSubjects);
        range = range(range <= totalUniqueSubjects);
    end
    if isempty(range)
        warning('%s has an empty range after truncation. No subjects will be assigned.', app.groupNames_Section1{g});
        groupSubjectIDs{g} = [];
    else
        groupSubjectIDs{g} = remainingSubjectIDs(range);
    end
    totalAssignedSubjects = totalAssignedSubjects + length(groupSubjectIDs{g});
    fprintf('%s SubjectIDs (%d subjects): %s\n', app.groupPrefixes{g}, length(groupSubjectIDs{g}), mat2str(groupSubjectIDs{g}));
end

if totalAssignedSubjects ~= totalUniqueSubjects
    warning('Mismatch in assigned subjects: Total unique SubjectIDs = %d, Total assigned = %d', totalUniqueSubjects, totalAssignedSubjects);
end

% Step 2: Data Preparation for All Group Together and Group-wise Plots
allDipoles = struct('posxyz', cell(1, length(clusterIndices)));
icaactAll = cell(1, length(clusterIndices));
spectraAll = cell(1, length(clusterIndices));
erpAll = cell(1, length(clusterIndices)); % For ERP
erspAll = cell(1, length(clusterIndices)); % For ERSP
itcAll = cell(1, length(clusterIndices)); % For ITC

% Step 2.1: Define Subject IDs for Each Group
% Extract unique subject IDs and sort them to handle discontinuities
uniqueSubjectIDs = sort(unique(app.dipxyz_clust_table.SubjectID));
totalUniqueSubjects = length(uniqueSubjectIDs);
fprintf('Total unique SubjectIDs in dipxyz_clust_table (sorted): %d\n', totalUniqueSubjects);
fprintf('Unique SubjectIDs: %s\n', mat2str(uniqueSubjectIDs));
% Print number of groups and group prefixes
fprintf('app.numGroups = %d, app.groupPrefixes = %s\n', app.numGroups, sprintf('%s ', app.groupPrefixes{:}));


% Recompute groupSubjectIDs based on uniqueSubjectIDs and groupRanges
groupSubjectIDs = cell(1, app.numGroups);

% ✅ Ensure groupRanges is always a cell array (even if only 1 group)
if ~iscell(app.groupRanges)
    app.groupRanges = {app.groupRanges};
end

for g = 1:app.numGroups
    rangeVal = app.groupRanges{g};
    fprintf('Parsing %s range for subject assignment: %s\n', ...
        app.groupPrefixes{g}, mat2str(rangeVal));

    try
        % Handle three possible formats:
        if ischar(rangeVal) || isstring(rangeVal)   % "1:100"
            rangeParts = str2double(strsplit(rangeVal, ':'));
            if length(rangeParts) == 2
                subjectIdx = rangeParts(1):rangeParts(2);
            else
                error('Invalid string format: must be "start:end"');
            end
        elseif isnumeric(rangeVal) && numel(rangeVal) == 2   % [1 100]
            subjectIdx = rangeVal(1):rangeVal(2);
        elseif isnumeric(rangeVal) && numel(rangeVal) >= 1   % [1 2 3 ... N]
            subjectIdx = rangeVal;
        else
            error('Range must be "start:end", [start end], or explicit numeric list');
        end

        % ✅ Clip to available subjects (in case input overshoots)
        subjectIdx = subjectIdx(subjectIdx >= 1 & subjectIdx <= totalUniqueSubjects);

        if isempty(subjectIdx)
            warning('%s has no valid subjects after adjustment.', app.groupPrefixes{g});
            groupSubjectIDs{g} = [];
        else
            groupSubjectIDs{g} = uniqueSubjectIDs(subjectIdx);
        end

        fprintf('%s SubjectIDs (%d subjects): %s\n', ...
            app.groupPrefixes{g}, length(groupSubjectIDs{g}), mat2str(groupSubjectIDs{g}));

    catch ME
        error('Failed to parse %s range: %s. Aborting.', app.groupPrefixes{g}, ME.message);
    end
end

% Step 2.2: Cluster Plot for Outlier Cluster and Save Outlier Data
fprintf('Unique Cluster IDs in app.dipxyz_clust_table: %s\n', mat2str(unique(app.dipxyz_clust_table.ClustersID)));
fprintf('Current clusterIndices: %s\n', mat2str(clusterIndices));
hasOutlier = any(app.dipxyz_clust_table.ClustersID == 0);
if hasOutlier
    clusterLabel = 'Outlier Cluster';
    clusterIDForPlots = 0;

    % Extract and save outlier table (ClustersID == 0)
    outlierTable = app.dipxyz_clust_table(app.dipxyz_clust_table.ClustersID == clusterIDForPlots, :);
    if isempty(outlierTable)
        fprintf('Warning: Outlier cluster table is empty.\n');
    else
        outlierFilePath = fullfile(app.studyFilePath, 'outlier_cluster.mat');
        save(outlierFilePath, 'outlierTable', '-v7.3');
        fprintf('Outlier cluster table saved to %s with %d rows.\n', outlierFilePath, height(outlierTable));
    end

    % Proceed with dipole plot generation
    app.StatusLabel.Text = sprintf('Status: Generating dipole plots for %s...', clusterLabel);
    fig = [];
    try
        cluster_data = app.dipxyz_clust_table(app.dipxyz_clust_table.ClustersID == clusterIDForPlots, :);
        if isempty(cluster_data)
            error('No data for %s. Cannot proceed with dipole plot.', clusterLabel);
        end

        cluster_data_pos = cluster_data(:, {'posx', 'posy', 'posz'});
        group_data_1 = cluster_data(ismember(cluster_data.SubjectID, groupSubjectIDs{1}), {'posx', 'posy', 'posz'});
        if app.numGroups >= 2
            group_data_2 = cluster_data(ismember(cluster_data.SubjectID, groupSubjectIDs{2}), {'posx', 'posy', 'posz'});
        else
            group_data_2 = table([], [], [], 'VariableNames', {'posx', 'posy', 'posz'});
        end

        cluster_mean = mean(table2array(cluster_data_pos), 1);
        cluster_mean_table = array2table(cluster_mean, 'VariableNames', {'posx', 'posy', 'posz'});
        cluster_data_pos = [cluster_data_pos; cluster_mean_table];
        cluster_data_array = table2array(cluster_data_pos);

        n = size(cluster_data_array, 1);
        n_dipoles = n - 1;
        rgbMatrix = zeros(n, 3);
        n_group1 = height(group_data_1);
        n_group2 = height(group_data_2);
        total_rows = n_group1 + n_group2;

        if total_rows ~= n_dipoles
            error('Mismatch in dipole counts for %s: expected %d dipoles, but found %d (%s: %d, %s: %d).', ...
                clusterLabel, n_dipoles, total_rows, app.groupPrefixes{1}, n_group1, app.groupPrefixes{2}, n_group2);
        end

        if total_rows > 0
            if n_group1 > 0
                rgbMatrix(1:n_group1, :) = repmat([0 1 0], n_group1, 1); % Green for group 1
            end
            if n_group2 > 0
                rgbMatrix((n_group1 + 1):(n_group1 + n_group2), :) = repmat([1 0 0], n_group2, 1); % Red for group 2
            end
            rgbMatrix(n, :) = [0 0 1]; % Blue for centroid
        else
            error('No dipoles to color for %s.', clusterLabel);
        end

        max_value = max(rgbMatrix(:));
        if max_value == 0
            error('No valid color values for %s.', clusterLabel);
        else
            rgbMatrix = rgbMatrix / max_value;
        end

        if ~isprop(app, 'mriData') || isempty(app.mriData) || (~isstruct(app.mriData) && ~ischar(app.mriData))
            errorMsg = sprintf('Invalid or missing MRI data for dipole plot of %s.', clusterLabel);
            fprintf('ERROR: %s\n', errorMsg);
            app.StatusLabel.Text = sprintf('Status: Error - %s', errorMsg);
            app.ResultLabel.Text = 'Result: Failed - Check error message.';
            error(errorMsg);
        end

        % Define 4 views
        viewAngles = {[45 35], [0 90], [90 0], [0 0]};
        viewLabels = {'Oblique', 'Top', 'Sagittal', 'Frontal'};
        
        % Store file paths for composite plot
        % tiffFilePaths = cell(4, 1);
        
        % Loop through each view
        for viewIdx = 1:length(viewAngles)
            fig = figure('Name', sprintf('%s Dipoles - %s View', clusterLabel, viewLabels{viewIdx}), 'NumberTitle', 'off');
            dipPlotHandle = plotDipoles(cluster_data_array, rgbMatrix, app.mriData, viewAngles{viewIdx});
            if isempty(dipPlotHandle)
                error('Failed to generate dipole plot for %s - %s View.', clusterLabel, viewLabels{viewIdx});
            end
            drawnow;
            %title(sprintf('%s Dipoles - %s View', clusterLabel, viewLabels{viewIdx}));
            xlabel('X (MNI)');
            ylabel('Y (MNI)');
            zlabel('Z (MNI)');
            grid on;
            
            % Save with view label in filename
            tiffFileName = sprintf('%s_Dipoles_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), viewLabels{viewIdx}, datestr(now, 'HH-MM'));
            tiffFilePath = app.getUniqueFilePath(app.studyFilePath, tiffFileName);
            savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            print(fig, tiffFilePath, '-dtiff', '-r600');
            
            % Store the file path for composite plot
            tiffFilePaths{viewIdx} = tiffFilePath;
            
            close(fig);
        end
        
    catch ME
        errorMsg = sprintf('Error generating dipole plot for %s: %s', clusterLabel, ME.message);
        fprintf('ERROR: %s\n', errorMsg);
        app.StatusLabel.Text = sprintf('Status: Error - %s', errorMsg);
        app.ResultLabel.Text = 'Result: Failed - Check error message.';
        if ~isempty(fig) && ishandle(fig)
            close(fig);
        end
        error(errorMsg);
    end
else
    fprintf('No outlier cluster present.\n');
end

% Validate total assigned subjects matches total unique subjects
totalAssigned = sum(cellfun(@length, groupSubjectIDs));
if totalAssigned ~= totalUniqueSubjects
    error('Mismatch in assigned subjects: Total unique SubjectIDs = %d, Total assigned = %d. Aborting.', totalUniqueSubjects, totalAssigned);
end


% ================================================================
% 🧠 Step 2.2.x: Group Analysis Selection (Before Step 2.3)
% ================================================================

% Default flags
runStep23 = false;
runStep24 = false;

if app.numGroups > 1
    % 🧩 Multiple groups detected — single confirm dialog
    selection = uiconfirm(app.UIFigure, ...
        sprintf(['🧩 Detected %d groups in your dataset!\n\n' ...
                 'You can choose which analysis to perform:\n\n' ...
             '• 🟢 GROUP-COMBINED ANALYSIS (Step 2.3): Combine all groups for joint clustering and stats.\n' ...
             '• 🔵 GROUP-WISE ANALYSIS (Step 2.4): Run clustering separately for each group.\n' ...
             '• 🟣 BOTH ANALYSES: Perform both types for comparison.\n\n' ...
                 'Please select your preferred option below.'], app.numGroups), ...
        'Multiple Groups Detected ⚙️', ...
        'Options', {'🟢 Group-Combined (Default)', '🔵 Group-Wise', '🟣 Both'}, ...
        'DefaultOption', '🟢 Group-Combined (Default)', ...
        'CancelOption', '🟢 Group-Combined (Default)', ...
        'Icon', 'info');

    % Handle user choice
    switch selection
        case '🟢 Group-Combined (Default)'
            fprintf('✅ User selected: Group-Combined analysis only.\n');
            runStep23 = true;

        case '🔵 Group-Wise'
            fprintf('✅ User selected: Group-Wise analysis only.\n');
            runStep24 = true;

        case '🟣 Both'
            fprintf('✅ User selected: Perform both analyses.\n');
            runStep23 = true;
            runStep24 = true;
    end
else
    % 👤 Single group case
    fprintf('👤 Only one group detected — skipping Step 2.3 and running Step 2.4 only.\n');
    runStep24 = true;
end


% Step 2.3: All Clusters, All Groups Combined Data Preparation, Saving, and Plotting
if runStep23
    realClusterIndices = clusterIndices(clusterIndices > 0);
    fprintf('Number of real clusters for further analysis: %d\n', length(realClusterIndices));
    fprintf('Real cluster indices: %s\n', mat2str(realClusterIndices));

    if ~isempty(realClusterIndices)

    % Create 'group-combined' folder
    combinedFolder = fullfile(app.studyFilePath, 'group-combined');
    if ~exist(combinedFolder, 'dir')
        mkdir(combinedFolder);
    end

    % Create subfolders for each real cluster
    for i = 1:length(realClusterIndices)
        clusterSubfolder = fullfile(combinedFolder, sprintf('Cluster_%d', realClusterIndices(i)));
        if ~exist(clusterSubfolder, 'dir')
            mkdir(clusterSubfolder);
        end
    end

    % Prepare combined .mat file path
    combinedDataFile = fullfile(combinedFolder, 'group-combined_data.mat');

    % Pre-extract and validate all data
    numClusters = length(realClusterIndices);
    allClusterData = struct();
    allCentroidData = struct();
    tempAllDipoles = repmat(struct('posxyz', [], 'srate', []), 1, numClusters);
    icaactAll = cell(1, numClusters);
    spectraAll = cell(1, numClusters);
    erpAll = cell(1, numClusters);
    erspAll = cell(1, numClusters);
    itcAll = cell(1, numClusters);
    timesOutAll = cell(1, numClusters);
    freqsOutAll = cell(1, numClusters);

    % Validate numClusters (must be between 2 and 20 inclusive)
    if numClusters < 2 || numClusters > 20
        error('Number of clusters must be between 2 and 20 inclusive.');
    end

    % Pre-extract data for all clusters
    for clusterIdx = 1:numClusters
        i = realClusterIndices(clusterIdx);
        clusterLabel = sprintf('Cluster %d', i);
        app.StatusLabel.Text = sprintf('Status: Extracting data for %s (all groups)...', clusterLabel);
        drawnow;

        % Extract cluster data
        cluster_data = app.dipxyz_clust_table(app.dipxyz_clust_table.ClustersID == i, :);
        fprintf('Cluster %s: Number of dipoles extracted: %d\n', clusterLabel, height(cluster_data));
        if isempty(cluster_data)
            error('No dipoles found for %s. All clusters must have dipoles.', clusterLabel);
        end

        % Save cluster data
        varName = sprintf('cluster_%d', i);
        allClusterData.(varName) = cluster_data;

        % Store dipole positions and srate
        cluster_data_pos = cluster_data(:, {'posx', 'posy', 'posz'});
        tempAllDipoles(clusterIdx).posxyz = table2array(cluster_data_pos);
        tempAllDipoles(clusterIdx).srate = mode(cluster_data.srate(cluster_data.srate > 0));

        % Prepare centroid data
        if ~isempty(tempAllDipoles(clusterIdx).posxyz)
            cluster_mean = mean(tempAllDipoles(clusterIdx).posxyz, 1);
            mni_coord = cluster_mean;
            try
                tal_coord = mni2tal(mni_coord);
            catch ME
                warning('Failed to convert MNI to Talairach for %s: %s', clusterLabel, ME.message);
                tal_coord = [NaN NaN NaN];
            end
            varNameCentroid = sprintf('centroid_%d', i);
            allCentroidData.(varNameCentroid) = struct('clusterID', i, 'mni_x', mni_coord(1), 'mni_y', mni_coord(2), 'mni_z', mni_coord(3), ...
                'tal_x', tal_coord(1), 'tal_y', tal_coord(2), 'tal_z', tal_coord(3));
        end
    end

    % Assign validated data to final structures
    allDipoles = tempAllDipoles;

    baseColors = [0.5 0 0.5; 1 0.5 0];
    densityBaseColors = [0.1 0.4 0.7; 0.9 0.4 0.3];
    if numClusters > 2
        additionalColors = hsv(numClusters - 2);
        colorList = [baseColors; additionalColors(1:(numClusters-2), :)];
        additionalDensityColors = hsv(numClusters - 2);
        densityColorList = [densityBaseColors; additionalDensityColors(1:(numClusters - 2), :)];
        if numClusters > 6
            additionalDensityColors = hsv(numClusters - 6);
            densityColorList = [densityBaseColors; additionalDensityColors(1:(numClusters-6), :)];
        end
        colorList = colorList(1:numClusters, :);
        densityColorList = densityColorList(1:numClusters, :);
    else
        colorList = baseColors(1:numClusters, :);
        densityColorList = densityBaseColors(1:numClusters, :);
    end
    colorListCell = num2cell(colorList, 2);
    densityColorListCell = num2cell(densityColorList, 2);

    % Prepare centroid and all dipoles data for plotting
    dipModelCentroids = repmat(struct('posxyz', [], 'momxyz', [], 'rv', []), numClusters, 1);
    all_dipoles = repmat(struct('posxyz', [], 'momxyz', [], 'rv', []), 0, 1); % Dynamic growth
    all_colors = cell(0, 1);
    dipole_count = 1;
    for clusterIdx = 1:numClusters
        if isfield(allDipoles(clusterIdx), 'posxyz') && ~isempty(allDipoles(clusterIdx).posxyz)
            cluster_mean = mean(allDipoles(clusterIdx).posxyz, 1);
            dipModelCentroids(clusterIdx).posxyz = cluster_mean;
            dipModelCentroids(clusterIdx).momxyz = [0 0 0];
            dipModelCentroids(clusterIdx).rv = 0;
            cluster_data = allDipoles(clusterIdx).posxyz;
            numDipoles = size(cluster_data, 1);
            for j = 1:numDipoles
                all_dipoles(dipole_count).posxyz = cluster_data(j, :);
                all_dipoles(dipole_count).momxyz = [0 0 0];
                all_dipoles(dipole_count).rv = 0;
                all_colors{dipole_count} = colorListCell{clusterIdx};
                dipole_count = dipole_count + 1;
            end
        end
    end

    % Loop over real clusters for plotting
    for clusterIdx = 1:numClusters
        i = realClusterIndices(clusterIdx);
        clusterLabel = sprintf('Cluster %d', i);
        clusterSubfolder = fullfile(combinedFolder, sprintf('Cluster_%d', i));
        app.StatusLabel.Text = sprintf('Status: Processing %s (all groups)...', clusterLabel);
        drawnow;

        % 1. Cluster Plot - Generate 4 views
app.StatusLabel.Text = sprintf('Status: Generating cluster plots for %s...', clusterLabel);
cluster_data = allClusterData.(sprintf('cluster_%d', i));
cluster_data_pos = array2table(allDipoles(clusterIdx).posxyz, 'VariableNames', {'posx', 'posy', 'posz'});
indices = ismember(cluster_data.SubjectID, groupSubjectIDs{1});
group_data_1 = cluster_data_pos(indices, :);
if app.numGroups >= 2
    indices = ismember(cluster_data.SubjectID, groupSubjectIDs{2});
    group_data_2 = cluster_data_pos(indices, :);
else
    group_data_2 = [];
end
if app.numGroups == 3
    indices = ismember(cluster_data.SubjectID, groupSubjectIDs{3});
    group_data_3 = cluster_data_pos(indices, :);
else
    group_data_3 = [];
end
cluster_mean = mean(allDipoles(clusterIdx).posxyz, 1);
cluster_mean_table = array2table(cluster_mean, 'VariableNames', {'posx', 'posy', 'posz'});
cluster_data_pos = [cluster_data_pos; cluster_mean_table];
cluster_data_array = table2array(cluster_data_pos);
n = size(cluster_data_array, 1);
n_dipoles = n - 1;
rgbMatrix = zeros(n, 3);
n_group1 = height(group_data_1);
n_group2 = height(group_data_2);
n_group3 = height(group_data_3);
total_rows = n_group1 + n_group2 + n_group3;
if total_rows ~= n_dipoles
    error('Mismatch in dipole counts for %s.', clusterLabel);
end
if total_rows > 0
    if n_group1 > 0
        rgbMatrix(1:n_group1, :) = repmat(color1 / 255, n_group1, 1);
    end
    if app.numGroups >= 2 && n_group2 > 0
        rgbMatrix((n_group1 + 1):(n_group1 + n_group2), :) = repmat(color2 / 255, n_group2, 1);
    end
    if app.numGroups == 3 && n_group3 > 0
        rgbMatrix((n_group1 + n_group2 + 1):total_rows, :) = repmat(color3 / 255, n_group3, 1);
    end
    rgbMatrix(n, :) = centroid_color / 255;
end
max_value = max(rgbMatrix(:));
if max_value == 0
    error('No valid color values for %s.', clusterLabel);
else
    rgbMatrix = rgbMatrix / max_value;
end

% Define 4 views
viewAngles = {[45 35], [0 90], [90 0], [0 0]};
viewLabels = {'Oblique', 'Top', 'Sagittal', 'Frontal'};

% Store file paths for composite plot
tiffFilePaths = cell(4, 1);

% Loop through each view
for viewIdx = 1:length(viewAngles)
    fig = figure('Name', sprintf('%s Dipoles - %s View', clusterLabel, viewLabels{viewIdx}), 'NumberTitle', 'off');
    plotDipoles(cluster_data_array, rgbMatrix, app.mriData, viewAngles{viewIdx});
    drawnow;
    %title(sprintf('%s Dipoles - %s View', clusterLabel, viewLabels{viewIdx}));
    xlabel('X (MNI)');
    ylabel('Y (MNI)');
    zlabel('Z (MNI)');
    grid on;
    
    % Save with view label in filename
    tiffFileName = sprintf('%s_Dipoles_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), viewLabels{viewIdx}, datestr(now, 'HH-MM'));
    tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
    savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
    print(fig, tiffFilePath, '-dtiff', '-r600');
    
    % Store the file path for composite plot
    tiffFilePaths{viewIdx} = tiffFilePath;
    
    close(fig);
end


% 2. High-Quality Density Plot with 3 Views (Axial, Sagittal, Coronal)
app.StatusLabel.Text = sprintf('Status: Generating density plots for %s...', clusterLabel);
drawnow;

% ========================================================================
% STEP 1: Add FieldTrip to path (required for dipoledensity_hq)
% ========================================================================
eeglabRoot = fileparts(which('eeglab'));
fieldtripPath = fullfile(eeglabRoot, 'plugins', 'Fieldtrip-lite250523');

if ~exist(fieldtripPath, 'dir')
    ftPaths = dir(fullfile(eeglabRoot, 'plugins', 'Fieldtrip*'));
    if ~isempty(ftPaths)
        fieldtripPath = fullfile(ftPaths(1).folder, ftPaths(1).name);
    end
end

if exist(fieldtripPath, 'dir')
    % Suppress path warnings temporarily
    warning('off', 'MATLAB:rmpath:DirNotFound');
    
    % Get all subfolders but exclude compatibility folders
    ftSubfolders = genpath(fieldtripPath);
    
    % Remove all 'compat' folders from path (they cause function name conflicts)
    ftSubfolders = regexprep(ftSubfolders, '[^;]*compat[^;]*;', '');
    
    % Add the cleaned path
    addpath(ftSubfolders);
    fprintf('Debug - FieldTrip path added (excluding compat folders): %s\n', fieldtripPath);
    
    warning('on', 'MATLAB:rmpath:DirNotFound');
else
    warning('FieldTrip not found. Density plotting may fail.');
end

% ========================================================================
% STEP 2: Verify cluster data exists
% ========================================================================
cluster_data_array = allDipoles(clusterIdx).posxyz;
n_dipoles_all = size(cluster_data_array, 1);

if n_dipoles_all == 0
    warning('No dipoles found for cluster %d. Skipping density plot.', clusterIdx);
    app.StatusLabel.Text = sprintf('Status: No dipoles found for %s. Skipped.', clusterLabel);
    return;
end

fprintf('Debug - Processing %d dipoles for density plot.\n', n_dipoles_all);

% ========================================================================
% STEP 3: Build dipole model structure
% ========================================================================
dipModelAll = struct('posxyz', [], 'momxyz', [], 'rv', []);
for dipIdx = 1:n_dipoles_all
    dipModelAll(dipIdx).posxyz = cluster_data_array(dipIdx, :);
    dipModelAll(dipIdx).momxyz = [0 0 0];  % Not used for density
    dipModelAll(dipIdx).rv = 0;             % Not used for density
end

fprintf('Debug - Dipole model built with %d dipoles.\n', n_dipoles_all);

% Display coordinate ranges for verification
all_coords = cluster_data_array;
fprintf('Debug - Dipole coordinate ranges:\n');
fprintf('  X: [%.2f, %.2f] mm\n', min(all_coords(:,1)), max(all_coords(:,1)));
fprintf('  Y: [%.2f, %.2f] mm\n', min(all_coords(:,2)), max(all_coords(:,2)));
fprintf('  Z: [%.2f, %.2f] mm\n', min(all_coords(:,3)), max(all_coords(:,3)));

% ========================================================================
% STEP 4: Compute density data using dipoledensity_hq
% ========================================================================
app.StatusLabel.Text = sprintf('Status: Computing density data for %s...', clusterLabel);
drawnow;

try
    fprintf('Debug - Calling dipoledensity_hq to compute density...\n');
    
    % Call high-quality density computation (no plotting yet)
    [dens3d, mri_output] = dipoledensity_hq(dipModelAll, ...
        'method', 'alldistance', ...
        'methodparam', 20, ...
        'plot', 'off', ...
        'subsample', 2, ...
        'normalization', 'on', ...
        'smooth', 0);
    
    fprintf('Debug - Density computation successful.\n');
    fprintf('  - Density size: %s\n', mat2str(size(dens3d{1})));
    fprintf('  - Max density: %.6f dipoles/cc\n', max(dens3d{1}(:)));
    fprintf('  - MRI dimensions: %s\n', mat2str(mri_output.dim));
    
catch ME
    fprintf('ERROR - dipoledensity_hq failed: %s\n', ME.message);
    fprintf('Error stack:\n');
    for k = 1:length(ME.stack)
        fprintf('  File: %s, Line: %d, Function: %s\n', ...
            ME.stack(k).file, ME.stack(k).line, ME.stack(k).name);
    end
    app.StatusLabel.Text = sprintf('Status: Error computing density for %s', clusterLabel);
    rethrow(ME);
end

% ========================================================================
% STEP 5: Generate and save 3 views (Axial, Sagittal, Coronal)
% ========================================================================
views = {'top', 'side', 'rear'};
viewNames = {'Axial', 'Sagittal', 'Coronal'};
viewDescriptions = {'Top', 'Side', 'Front'};

fprintf('Debug - Generating 3 view plots...\n');

for viewIdx = 1:length(views)
    try
        % Update status
        app.StatusLabel.Text = sprintf('Status: Generating %s view for %s...', ...
            viewNames{viewIdx}, clusterLabel);
        drawnow;
        
        fprintf('Debug - Creating %s view (%s)...\n', viewNames{viewIdx}, views{viewIdx});
        
        % Create figure with proper settings
        hFig = figure('Name', sprintf('%s - %s View (%s)', clusterLabel, viewNames{viewIdx}, viewDescriptions{viewIdx}), ...
                      'NumberTitle', 'off', ...
                      'Color', 'k', ...
                      'Position', [100 100 1200 900]);
        
        % Plot density on MRI slices
        mri3dplot(dens3d, mri_output, 'mriview', views{viewIdx}, 'fighandle', hFig);
        
        fprintf('Debug - %s view plot generated successfully.\n', viewNames{viewIdx});
        
        % Force rendering
        drawnow;
        pause(0.5);  % Small pause to ensure rendering completes
        
        % ================================================================
        % STEP 6: Save figure and high-resolution TIFF
        % ================================================================
        
        % Generate filenames
        timestamp = datestr(now, 'HH-MM-SS');
        baseFileName = sprintf('%s_Density_%s_%s', ...
            strrep(clusterLabel, ' ', '_'), viewNames{viewIdx}, timestamp);
        
        figFileName = [baseFileName, '.fig'];
        tiffFileName = [baseFileName, '.tiff'];
        
        figFilePath = app.getUniqueFilePath(clusterSubfolder, figFileName);
        tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        
        % Save MATLAB figure
        try
            savefig(hFig, figFilePath);
            fprintf('Debug - Saved .fig file: %s\n', figFilePath);
        catch saveError
            fprintf('Warning - Could not save .fig file: %s\n', saveError.message);
        end
        
        % Save high-resolution TIFF
        try
            print(hFig, tiffFilePath, '-dtiff', '-r600');
            fprintf('Debug - Saved .tiff file (600 DPI): %s\n', tiffFilePath);
        catch saveError
            fprintf('Warning - Could not save .tiff file: %s\n', saveError.message);
        end
        
        % Close figure
        close(hFig);
        
    catch viewError
        fprintf('ERROR - Failed to generate %s view: %s\n', viewNames{viewIdx}, viewError.message);
        fprintf('Error stack:\n');
        for k = 1:length(viewError.stack)
            fprintf('  File: %s, Line: %d, Function: %s\n', ...
                viewError.stack(k).file, viewError.stack(k).line, viewError.stack(k).name);
        end
        
        % Close figure if it exists
        if exist('hFig', 'var') && ishandle(hFig)
            close(hFig);
        end
        
        % Continue to next view instead of stopping
        fprintf('Warning - Skipping %s view and continuing...\n', viewNames{viewIdx});
    end
end

% ========================================================================
% STEP 7: Final cleanup and status update
% ========================================================================
close all;  % Ensure all figures are closed

fprintf('Debug - All density plots completed for %s.\n', clusterLabel);
app.StatusLabel.Text = sprintf('Status: Density plots saved for %s (3 views)', clusterLabel);
drawnow;

% Optional: Display summary
fprintf('\n=== DENSITY PLOT SUMMARY for %s ===\n', clusterLabel);
fprintf('Dipoles processed: %d\n', n_dipoles_all);
fprintf('Max density: %.6f dipoles/cc\n', max(dens3d{1}(:)));
fprintf('Views generated: Axial, Sagittal, Coronal\n');
fprintf('Files saved to: %s\n', clusterSubfolder);
fprintf('==========================================\n\n');


% 


        % 3. Density Stats
        app.StatusLabel.Text = sprintf('Status: Computing density stats for %s...', clusterLabel);
        cluster_data_array = allDipoles(clusterIdx).posxyz;
        cluster_mean = mean(cluster_data_array, 1);
        coordinates = cluster_data_array;
        standardDeviation = std(coordinates);
        standardError = standardDeviation / sqrt(size(coordinates, 1));
        try
            centroidTal = mni2tal(cluster_mean);
        catch ME
            warning('Failed to convert MNI to Talairach for %s: %s', clusterLabel, ME.message);
            centroidTal = [NaN NaN NaN];
        end
        dipoles1 = struct('posxyz', []);
        for j = 1:size(cluster_data_array, 1)
            dipoles1(j).posxyz = cluster_data_array(j, :);
        end
        dipoles2 = struct('posxyz', []);
        dip2_idx = 1;
        for other_cluster_idx = 1:numClusters
            if other_cluster_idx ~= clusterIdx
                other_cluster_dipoles = allDipoles(other_cluster_idx).posxyz;
                for dipIdx = 1:size(other_cluster_dipoles, 1)
                    dipoles2(dip2_idx).posxyz = other_cluster_dipoles(dipIdx, :);
                    dip2_idx = dip2_idx + 1;
                end
            end
        end
        gridDims = [91 109 91];
        voxelSize = 2;
        [X, Y, Z] = ndgrid(1:gridDims(1), 1:gridDims(2), 1:gridDims(3));
        mniX = (X - gridDims(1)/2) * voxelSize;
        mniY = (Y - gridDims(2)/2) * voxelSize;
        mniZ = (Z - gridDims(3)/2) * voxelSize;
        radius = 20;
        distances = sqrt((mniX - cluster_mean(1)).^2 + (mniY - cluster_mean(2)).^2 + (mniZ - cluster_mean(3)).^2);
        roiMask = distances <= radius;
        pos1 = zeros(numel(dipoles1), 3);
        for j = 1:numel(dipoles1)
            pos1(j, :) = dipoles1(j).posxyz;
        end
        if ~isempty(dipoles2)
            pos2 = zeros(numel(dipoles2), 3);
            for j = 1:numel(dipoles2)
                pos2(j, :) = dipoles2(j).posxyz;
            end
        else
            pos2 = zeros(0, 3); % Handle empty dipoles2 case
        end
        sigma = 5 / voxelSize;
        kernelSize = ceil(3 * sigma);
        density1 = zeros(gridDims);
        density2 = zeros(gridDims);
        [dx, dy, dz] = ndgrid(-kernelSize:kernelSize, -kernelSize:kernelSize, -kernelSize:kernelSize);
        gaussKernel = exp(-(dx.^2 + dy.^2 + dz.^2) / (2 * sigma^2)) / (sqrt(2 * pi) * sigma)^3;
        for j = 1:size(pos1, 1)
            voxel = round((pos1(j, :) / voxelSize) + (gridDims / 2));
            voxel = max(1, min(voxel, gridDims));
            xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
            yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
            zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
            kxRange = xRange - voxel(1) + kernelSize + 1;
            kyRange = yRange - voxel(2) + kernelSize + 1;
            kzRange = zRange - voxel(3) + kernelSize + 1;
            density1(xRange, yRange, zRange) = density1(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
        end
        if ~isempty(pos2)
            for j = 1:size(pos2, 1)
                voxel = round((pos2(j, :) / voxelSize) + (gridDims / 2));
                voxel = max(1, min(voxel, gridDims));
                xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
                yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
                zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
                kxRange = xRange - voxel(1) + kernelSize + 1;
                kyRange = yRange - voxel(2) + kernelSize + 1;
                kzRange = zRange - voxel(3) + kernelSize + 1;
                density2(xRange, yRange, zRange) = density2(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
            end
        end
        voxelVolume = voxelSize^3;
        density1 = density1 / voxelVolume / size(pos1, 1);
        density2 = density2 / voxelVolume / size(pos2, 1);
        [maxDensity, maxIdx] = max(density1(:));
        [peakX, peakY, peakZ] = ind2sub(gridDims, maxIdx);
        densityPeakMNI = [(peakX - gridDims(1)/2) * voxelSize, (peakY - gridDims(2)/2) * voxelSize, (peakZ - gridDims(3)/2) * voxelSize];
        try
            densityPeakTal = mni2tal(densityPeakMNI);
            meanSD = mean(standardDeviation);
            labels = simulated_talairach_lookup(densityPeakTal, meanSD/2);
        catch ME
            warning('Failed to retrieve labels for %s: %s', clusterLabel, ME.message);
            labels = struct('Unknown', 1.0);
        end
        trueDifference = density1 - density2;
        numPermutations = 5000;
        allPos = [pos1; pos2];
        n1 = size(pos1, 1);
        n2 = size(pos2, 1);
        permutedMaxDiff = zeros(numPermutations, 1);
        roiIndices = find(roiMask);
        numVoxelsInROI = nnz(roiMask);
        trueDiffROI = trueDifference(roiIndices);
        permutedDiffROI = zeros(numVoxelsInROI, numPermutations);
        % Initialize for cluster-wise correction
        maxClusterSizes = zeros(numPermutations, 1);
        for p = 1:numPermutations
            if mod(p, 1000) == 0
                fprintf('%s: Permutation %d of %d completed...\n', clusterLabel, p, numPermutations);
            end
            permIdx = randperm(n1 + n2);
            permPos1 = allPos(permIdx(1:n1), :);
            permPos2 = allPos(permIdx(n1+1:end), :);
            permDensity1 = zeros(gridDims);
            permDensity2 = zeros(gridDims);
            for j = 1:size(permPos1, 1)
                voxel = round((permPos1(j, :) / voxelSize) + (gridDims / 2));
                voxel = max(1, min(voxel, gridDims));
                xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
                yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
                zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
                kxRange = xRange - voxel(1) + kernelSize + 1;
                kyRange = yRange - voxel(2) + kernelSize + 1;
                kzRange = zRange - voxel(3) + kernelSize + 1;
                permDensity1(xRange, yRange, zRange) = permDensity1(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
            end
            for j = 1:size(permPos2, 1)
                voxel = round((permPos2(j, :) / voxelSize) + (gridDims / 2));
                voxel = max(1, min(voxel, gridDims));
                xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
                yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
                zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
                kxRange = xRange - voxel(1) + kernelSize + 1;
                kyRange = yRange - voxel(2) + kernelSize + 1;
                kzRange = zRange - voxel(3) + kernelSize + 1;
                permDensity2(xRange, yRange, zRange) = permDensity2(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
            end
            permDensity1 = permDensity1 / voxelVolume / size(permPos1, 1);
            permDensity2 = permDensity2 / voxelVolume / size(permPos2, 1);
            permDiff = permDensity1 - permDensity2;
            permutedDiffROI(:, p) = permDiff(roiIndices);
            permutedMaxDiff(p) = max(abs(permDiff(roiIndices)));
            % Cluster-wise correction: Identify clusters in permuted data
            permPvaluesROI = zeros(numVoxelsInROI, 1);
            for v = 1:numVoxelsInROI
                permPvaluesROI(v) = mean(abs(permutedDiffROI(v, 1:p)) >= abs(trueDiffROI(v)));
            end
            permPvalues3D = zeros(gridDims);
            permPvalues3D(roiIndices) = permPvaluesROI;
            permSigMask = permPvalues3D < 0.05 & roiMask;
            [permLabeled, permNumClusters] = bwlabeln(permSigMask, 26);
            if permNumClusters > 0
                clusterSizes = arrayfun(@(x) nnz(permLabeled == x), 1:permNumClusters);
                maxClusterSizes(p) = max(clusterSizes, [], 'all', 'omitnan');
            else
                maxClusterSizes(p) = 0;
            end
        end
        % Compute uncorrected and FDR-corrected p-values
        uncorrectedPvalues3D = zeros(gridDims);
        uncorrectedPvaluesROI = zeros(numVoxelsInROI, 1);
        for v = 1:numVoxelsInROI
            uncorrectedPvaluesROI(v) = mean(abs(permutedDiffROI(v, :)) >= abs(trueDiffROI(v)));
        end
        uncorrectedPvalues3D(roiIndices) = uncorrectedPvaluesROI;
        fdrPvaluesROI = mafdr(uncorrectedPvaluesROI, 'BHFDR', true);
        correctionMask3D = zeros(gridDims);
        correctionMaskROI = zeros(numVoxelsInROI, 1);
        significantVoxelsROI = fdrPvaluesROI < 0.1;
        correctionMaskROI(significantVoxelsROI) = 1;
        correctionMask3D(roiIndices) = correctionMaskROI;
        % Compute Cohen's d
        cohensD3D = zeros(gridDims);
        cohensDROI = zeros(numVoxelsInROI, 1);
        for v = 1:numVoxelsInROI
            stdPermDiff = std(permutedDiffROI(v, :), 0, 2, 'omitnan');
            if stdPermDiff > 1e-6 % Avoid division by zero
                cohensDROI(v) = trueDiffROI(v) / stdPermDiff;
            else
                cohensDROI(v) = 0;
            end
        end
        cohensD3D(roiIndices) = cohensDROI;
        meanCohensD = mean(cohensDROI(significantVoxelsROI), 'omitnan');
        if isnan(meanCohensD) || isempty(significantVoxelsROI)
            meanCohensD = mean(cohensDROI, 'omitnan');
        end
        % Cluster-wise correction: Identify significant clusters
        sigMask = uncorrectedPvalues3D < 0.05 & roiMask;
        [labeledClusters, numClusters] = bwlabeln(sigMask, 26);
        clusterSizes = zeros(numClusters, 1);
        clusterPvalues = zeros(numClusters, 1);
        for c = 1:numClusters
            clusterSizes(c) = nnz(labeledClusters == c);
            clusterPvalues(c) = mean(maxClusterSizes >= clusterSizes(c));
        end
        clusterCorrectionMask3D = zeros(gridDims);
        significantClustersIdx = find(clusterPvalues < 0.05);
        if ~isempty(significantClustersIdx)
            for c = significantClustersIdx'
                clusterCorrectionMask3D(labeledClusters == c) = 1;
            end
        end
        propSignificantClusterCorrected = nnz(clusterCorrectionMask3D & roiMask) / numVoxelsInROI;
        % Spatial extent analysis
        [labeledFDRClusters, numSignificantClusters] = bwlabeln(correctionMask3D & roiMask, 26);
        totalSignificantVolume = nnz(correctionMask3D & roiMask) * (voxelSize^3) / 1000; % Convert to cc
        significantClusters = struct();
        if numSignificantClusters > 0
            clusterSizesFDR = zeros(numSignificantClusters, 1);
            clusterPeaksMNI = zeros(numSignificantClusters, 3);
            clusterPeaksTal = zeros(numSignificantClusters, 3);
            clusterLabels = cell(numSignificantClusters, 1);
            for c = 1:numSignificantClusters
                clusterVoxels = find(labeledFDRClusters == c);
                clusterSizesFDR(c) = length(clusterVoxels) * (voxelSize^3) / 1000; % Volume in cc
                clusterDiff = trueDifference(clusterVoxels);
                [~, peakIdx] = max(abs(clusterDiff));
                [peakX, peakY, peakZ] = ind2sub(gridDims, clusterVoxels(peakIdx));
                clusterPeaksMNI(c, :) = [(peakX - gridDims(1)/2) * voxelSize, (peakY - gridDims(2)/2) * voxelSize, (peakZ - gridDims(3)/2) * voxelSize];
                try
                    clusterPeaksTal(c, :) = mni2tal(clusterPeaksMNI(c, :));
                    clusterLabels{c} = simulated_talairach_lookup(clusterPeaksTal(c, :), meanSD/2);
                catch ME
                    warning('Failed to retrieve labels for cluster %d in %s: %s', c, clusterLabel, ME.message);
                    clusterLabels{c} = struct('Unknown', 1.0);
                end
            end
            % Sort clusters by size
            [~, sortIdx] = sort(clusterSizesFDR, 'descend');
            clusterSizesFDR = clusterSizesFDR(sortIdx);
            clusterPeaksMNI = clusterPeaksMNI(sortIdx, :);
            clusterPeaksTal = clusterPeaksTal(sortIdx, :);
            clusterLabels = clusterLabels(sortIdx);
            for c = 1:numSignificantClusters
                significantClusters(c).volume = clusterSizesFDR(c);
                significantClusters(c).peakMNI = clusterPeaksMNI(c, :);
                significantClusters(c).peakTal = clusterPeaksTal(c, :);
                significantClusters(c).labels = clusterLabels{c};
            end
        else
            significantClusters = struct('volume', [], 'peakMNI', [], 'peakTal', [], 'labels', []);
        end
        % Compute standard metrics
        scaleFactor = 1000;
        trueDifferenceROI = trueDifference(roiMask) * scaleFactor;
        meanDifference = mean(trueDifferenceROI(:), 'omitnan');
        maxDifference = max(abs(trueDifferenceROI(:)), [], 'omitnan');
        if maxDifference < 0.001
            warning('Density differences for %s are too small (Max Difference: %.4f dipoles/cc). Skipping significance.', clusterLabel, maxDifference);
            propSignificantUncorrected = NaN;
            propSignificantCorrected = NaN;
            propSignificantClusterCorrected = NaN;
            meanCohensD = NaN;
            centroidSignificant = [NaN NaN NaN];
            numSignificantClusters = 0;
            totalSignificantVolume = 0;
            significantClusters = struct('volume', [], 'peakMNI', [], 'peakTal', [], 'labels', []);
        else
            propSignificantUncorrected = mean(uncorrectedPvaluesROI < 0.05, 'omitnan');
            propSignificantCorrected = mean(correctionMaskROI > 0, 'omitnan');
            if nnz(correctionMaskROI) >= 3
                significantVoxels = find(roiMask & correctionMask3D);
                [sigX, sigY, sigZ] = ind2sub(gridDims, significantVoxels);
                sigMniX = (sigX - gridDims(1)/2) * voxelSize;
                sigMniY = (sigY - gridDims(2)/2) * voxelSize;
                sigMniZ = (sigZ - gridDims(3)/2) * voxelSize;
                centroidSignificant = mean([sigMniX(:), sigMniY(:), sigMniZ(:)], 1, 'omitnan');
            else
                centroidSignificant = [NaN NaN NaN];
            end
        end
        % Generate report
        clusterReport = sprintf('\n%s Density Statistics (All Groups):\nCentroid in MNI: [%.1f %.1f %.1f]\nCentroid in Talairach: [%.1f %.1f %.1f]\nStandard Deviation: [%.1f %.1f %.1f]\nStandard Error: [%.1f %.1f %.1f]\nMean Density Difference: %.4f dipoles/cc\nMax Density Difference: %.4f dipoles/cc\nMean Cohen''s d (ROI): %.3f\nProportion Significant (Uncorrected, p<0.05): %.5f\nProportion Significant (FDR-corrected, p<0.1): %.5f\nProportion Significant (Cluster-corrected, p<0.05): %.5f\nCentroid of Significant Differences (MNI): [%.1f %.1f %.1f]\nDipole Density Peak (MNI): [%.1f %.1f %.1f]\nNumber of Significant Clusters (FDR-corrected): %d\nTotal Significant Volume (FDR-corrected): %.3f cc\n\nProbabilistic Labels (confusion sphere diameter = %.1f mm):\n', ...
            clusterLabel, cluster_mean(1), cluster_mean(2), cluster_mean(3), centroidTal(1), centroidTal(2), centroidTal(3), ...
            standardDeviation(1), standardDeviation(2), standardDeviation(3), standardError(1), standardError(2), standardError(3), ...
            meanDifference, maxDifference, meanCohensD, propSignificantUncorrected, propSignificantCorrected, propSignificantClusterCorrected, ...
            centroidSignificant(1), centroidSignificant(2), centroidSignificant(3), ...
            densityPeakMNI(1), densityPeakMNI(2), densityPeakMNI(3), numSignificantClusters, totalSignificantVolume, meanSD);
        labelNames = fieldnames(labels);
        for l = 1:length(labelNames)
            labelName = labelNames{l};
            labelProb = labels.(labelName);
            labelName = strrep(labelName, '_', ' ');
            clusterReport = [clusterReport sprintf('%-30s %.3f\n', labelName, labelProb)];
        end
        if numSignificantClusters > 0
            clusterReport = [clusterReport sprintf('\nSignificant Clusters (FDR-corrected):\n')];
            for c = 1:numSignificantClusters
                clusterReport = [clusterReport sprintf('Cluster %d: Volume = %.3f cc, Peak MNI = [%.1f %.1f %.1f], Peak Talairach = [%.1f %.1f %.1f]\nProbabilistic Labels:\n', ...
                    c, significantClusters(c).volume, significantClusters(c).peakMNI(1), significantClusters(c).peakMNI(2), significantClusters(c).peakMNI(3), ...
                    significantClusters(c).peakTal(1), significantClusters(c).peakTal(2), significantClusters(c).peakTal(3))];
                labelNames = fieldnames(significantClusters(c).labels);
                for l = 1:length(labelNames)
                    labelName = labelNames{l};
                    labelProb = significantClusters(c).labels.(labelName);
                    labelName = strrep(labelName, '_', ' ');
                    clusterReport = [clusterReport sprintf('  %-28s %.3f\n', labelName, labelProb)];
                end
            end
        end
        disp(clusterReport);
        clusterFileName = sprintf('%s_Statistics_All_Groups_%s.mat', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        clusterFilePath = app.getUniqueFilePath(clusterSubfolder, clusterFileName);
        save(clusterFilePath, 'clusterReport', 'uncorrectedPvalues3D', 'correctionMask3D', 'clusterCorrectionMask3D', 'propSignificantClusterCorrected', ...
            'meanCohensD', 'cohensD3D', 'numSignificantClusters', 'totalSignificantVolume', 'significantClusters', 'trueDifference', 'density1', 'density2');


% 
        % 4.=== Scalp Topoplot (Mean ICA Weights Only) ===
app.StatusLabel.Text = sprintf('Status: Generating scalp topoplot for %s...', clusterLabel);

cluster_data = allClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
n_channels = 60;

% Collect ICA weights from each dipole
icawinv_all = zeros(n_channels, num_dipoles);
for d = 1:num_dipoles
    icawinv = cluster_data.icawinv{d};
    if length(icawinv) < n_channels
        icawinv(end+1:n_channels) = mean(icawinv); % pad with mean if missing
    end
    icawinv_all(:, d) = icawinv;
end

% Compute mean scalp map
mean_map = mean(icawinv_all, 2);

% Plot topography
fig = figure('Name', [clusterLabel ' Mean Scalp Map'], 'Color', 'w', 'Position', [100 100 600 500]);
movegui(fig, 'center');
topoplot(mean_map, cluster_data.chanlocs{1});
title(['Mean ICA Weights - ' clusterLabel], 'FontSize', 12, 'FontWeight', 'bold');
colorbar('Position', [0.92 0.11 0.02 0.8]);
drawnow;

% Save figure
tiffFileName = sprintf('%s_Mean_ScalpMap_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);


        % % 4. Scalp Topoplot
        % app.StatusLabel.Text = sprintf('Status: Generating scalp topoplot for %s...', clusterLabel);
        % cluster_data = allClusterData.(sprintf('cluster_%d', i));
        % num_dipoles = height(cluster_data);
        % num_conditions = size(cluster_data.icaact{1}, 2);
        % n_channels = 60;
        % icawinv_all = zeros(n_channels, num_dipoles);
        % for d = 1:num_dipoles
        %     icawinv = cluster_data.icawinv{d};
        %     if length(icawinv) < n_channels
        %         icawinv(end+1:n_channels) = mean(icawinv); % pad with mean
        %     end
        %     icawinv_all(:, d) = icawinv;
        % end
        % mean_map = mean(icawinv_all, 2);
        % avg_map_all = zeros(n_channels, num_dipoles);
        % for d = 1:num_dipoles
        %     icawinv = cluster_data.icawinv{d};
        %     if length(icawinv) < n_channels
        %         icawinv(end+1:n_channels) = mean(icawinv);
        %     end
        %     avg_act = mean(cluster_data.icaact{d}(:)); % across all time and conds
        %     avg_map_all(:, d) = icawinv * avg_act;
        % end
        % avg_map = mean(avg_map_all, 2);
        % fig = figure('Name', [clusterLabel ' Comparison of Maps']);
        % subplot(1, 2, 1);
        % topoplot(mean_map, cluster_data.chanlocs{1});
        % title('Mean ICA Weights (Unweighted)');
        % subplot(1, 2, 2);
        % topoplot(avg_map, cluster_data.chanlocs{1});
        % title('Activation-Weighted Mean ICA');
        % colorbar('Position', [0.93 0.11 0.02 0.8]); % Right side colorbar
        % drawnow;
        % tiffFileName = sprintf('%s_Mean_vs_ActivationWeighted_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);

        % % Cluster Figure B: Condition-wise Topoplots
        % cond_maps = zeros(n_channels, num_conditions);
        % for cond = 1:num_conditions
        %     cond_map_all = zeros(n_channels, num_dipoles);
        %     for d = 1:num_dipoles
        %         icawinv = cluster_data.icawinv{d};
        %         if length(icawinv) < n_channels
        %             icawinv(end+1:n_channels) = mean(icawinv);
        %         end
        %         cond_mean = mean(cluster_data.icaact{d}(:, cond)); % avg over time for this cond
        %         cond_map_all(:, d) = icawinv * cond_mean;
        %     end
        %     cond_maps(:, cond) = mean(cond_map_all, 2);
        % end
        % fig = figure('Name', [clusterLabel ' Condition-wise Scalp Maps']);
        % for cond = 1:num_conditions
        %     subplot(3, 4, cond);
        %     topoplot(cond_maps(:, cond), cluster_data.chanlocs{1});
        %     title(['Condition ' num2str(cond)]);
        % end
        % subplot(3, 4, 12);
        % axis off;
        % c = colorbar('southoutside');
        % c.Position = [0.363 0.05 0.3 0.03];
        % drawnow;
        % tiffFileName = sprintf('%s_ConditionWise_ScalpTopoplots_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);


         %5. === Mean Spectra Plot ===
app.StatusLabel.Text = sprintf('Status: Generating mean spectra plot for %s...', clusterLabel);

cluster_data = allClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
epoch_len = size(cluster_data.icaact{1}, 1); % Samples per epoch
srate = cluster_data.srate(1);               % Sampling rate

% Concatenate all dipole data across epochs
all_data_concat = [];
for d = 1:num_dipoles
    ica = cluster_data.icaact{d}; % [epoch_len x num_epochs]
    all_data_concat = [all_data_concat, reshape(ica, 1, [])]; % flatten and append
end

% Compute overall power spectrum (no plotting)
[spec_all_matrix, freqs, ~, ~] = spectopo(all_data_concat, epoch_len, srate, 'plot', 'off');
spec_all = mean(spec_all_matrix, 1); % Average across all epochs

% Plot mean spectrum (1–50 Hz)
freq_range = freqs >= 1 & freqs <= 50;
fig = figure('Name', [clusterLabel '_Mean_Spectrum'], 'Color', 'w', 'Position', [100 100 800 500]);
movegui(fig, 'center');

plot(freqs(freq_range), spec_all(freq_range), 'LineWidth', 2);
xlabel('Frequency (Hz)');
ylabel('Power (dB)');
title(['Overall Mean Spectrum - ' clusterLabel]);
grid on;
drawnow;

% Save figure as .fig and high-res .tiff
tiffFileName = sprintf('%s_Mean_Spectrum_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);

        % % 5. Spectra Plot
        % app.StatusLabel.Text = sprintf('Status: Generating spectra plot for %s...', clusterLabel);
        % cluster_data = allClusterData.(sprintf('cluster_%d', i));
        % num_dipoles = height(cluster_data);
        % num_conditions = size(cluster_data.icaact{1}, 2); % Number of epochs
        % epoch_len = size(cluster_data.icaact{1}, 1); % Samples per epoch
        % srate = cluster_data.srate(1); % Sampling rate
        % all_data_concat = [];
        % for d = 1:num_dipoles
        %     ica = cluster_data.icaact{d}; % [epoch_len x num_conditions]
        %     all_data_concat = [all_data_concat, reshape(ica, 1, [])]; % [1 x (N*epoch_len)]
        % end
        % [spec_all_matrix, freqs, ~, ~] = spectopo(all_data_concat, epoch_len, srate, 'plot', 'off');
        % spec_all = mean(spec_all_matrix, 1); % average across epochs
        % num_freqs = length(freqs);
        % % cond_spectra = zeros(num_conditions, num_freqs); % dynamic allocation
        % % for cond = 1:num_conditions
        % %     cond_data = [];
        % %     for d = 1:num_dipoles
        % %         cond_epoch = cluster_data.icaact{d}(:, cond); % [epoch_len x 1]
        % %         cond_data = [cond_data, cond_epoch']; % accumulate [1 x epoch_len]
        % %     end
        % %     [spec_cond, freqs_cond, ~, ~] = spectopo(cond_data, epoch_len, srate, 'plot', 'off');
        % %     if ~isequal(freqs, freqs_cond)
        % %         error('Frequency vectors do not match across conditions.');
        % %     end
        % %     cond_spectra(cond, :) = spec_cond;
        % % end
        % % grand_avg_spectrum = mean(cond_spectra, 1);
        % freq_range = freqs >= 1 & freqs <= 50;
        % fig = figure('Name', [clusterLabel '_Spectrum_Comparison'], 'Color', 'w', 'Position', [100 100 1200 500]);
        % tiledlayout(1, 2, 'Padding', 'compact');
        % nexttile;
        % plot(freqs(freq_range), spec_all(freq_range), 'LineWidth', 2);
        % xlabel('Frequency (Hz)'); ylabel('Power (dB)');
        % title('Overall Mean Spectrum');
        % grid on;
        % nexttile;
        % plot(freqs(freq_range), grand_avg_spectrum(freq_range), 'LineWidth', 2);
        % xlabel('Frequency (Hz)'); ylabel('Power (dB)');
        % title('Mean of Condition-wise Spectra');
        % grid on;
        % drawnow;
        % tiffFileName = sprintf('%s_Spectrum_Comparison_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);
        % fig = figure('Name', [clusterLabel '_Condition_Spectra'], 'Color', 'w', 'Position', [100 100 1200 700]);
        % movegui(gcf, 'center');
        % t = tiledlayout(4, 3, 'Padding', 'compact', 'TileSpacing', 'compact');
        % for cond = 1:num_conditions
        %     nexttile;
        %     plot(freqs(freq_range), cond_spectra(cond, freq_range), 'LineWidth', 1.5);
        %     title(['Condition ' num2str(cond)]);
        %     xlabel('Frequency (Hz)');
        %     ylabel('Power (dB)');
        %     grid on;
        % end
        % drawnow;
        % tiffFileName = sprintf('%s_Condition_Spectra_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);


        %6. === ERP Mean Plot ===
app.StatusLabel.Text = sprintf('Status: Generating ERP plot for %s...', clusterLabel);

cluster_data = allClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
num_conditions = size(cluster_data.icaact{1}, 2);
epoch_len = size(cluster_data.icaact{1}, 1);
srate = cluster_data.srate(1);
time_ms = (0:epoch_len-1) / srate * 1000; % Time in ms
smooth_win = 40; % ~50 ms smoothing window

% Combine all dipoles and conditions
all_erp_data = zeros(epoch_len, num_dipoles * num_conditions);
col_idx = 1;
for cond = 1:num_conditions
    for d = 1:num_dipoles
        epoch = cluster_data.icaact{d}(:, cond);
        all_erp_data(:, col_idx) = epoch;
        col_idx = col_idx + 1;
    end
end

% Compute mean ERP across all dipoles & conditions
grand_avg_erp_all = mean(all_erp_data, 2);

% Plot ERP
fig = figure('Name', [clusterLabel '_ERP_Mean'], 'Color', 'w', 'Position', [100 100 800 500]);
movegui(fig, 'center');
raw = grand_avg_erp_all;
smooth_erp = smoothdata(raw, 'movmean', smooth_win);
plot(time_ms, raw, 'Color', [0.75 0.75 0.75], 'LineWidth', 1.5); hold on;
plot(time_ms, smooth_erp, 'k', 'LineWidth', 2);
xlabel('Time (ms)');
ylabel('Amplitude (\muV)');
title(['Grand Average ERP - ' clusterLabel], 'FontWeight', 'bold');
grid on;
drawnow;

% Save figure
tiffFileName = sprintf('%s_ERP_Mean_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);

        % % 6. ERP Plot
        % app.StatusLabel.Text = sprintf('Status: Generating ERP plot for %s...', clusterLabel);
        % cluster_data = allClusterData.(sprintf('cluster_%d', i));
        % num_dipoles = height(cluster_data);
        % num_conditions = size(cluster_data.icaact{1}, 2);
        % epoch_len = size(cluster_data.icaact{1}, 1);
        % srate = cluster_data.srate(1);
        % time_ms = (0:epoch_len-1) / srate * 1000; % Time in ms
        % smooth_win = 40; % ~50 ms smoothing window
        % all_erp_data = zeros(epoch_len, num_dipoles * num_conditions);
        % cond_erps = zeros(epoch_len, num_conditions); % averaged across dipoles
        % col_idx = 1;
        % for cond = 1:num_conditions
        %     cond_matrix = zeros(epoch_len, num_dipoles);
        %     for d = 1:num_dipoles
        %         epoch = cluster_data.icaact{d}(:, cond); % [epoch_len x 1]
        %         all_erp_data(:, col_idx) = epoch; % for overall average
        %         cond_matrix(:, d) = epoch; % for per-condition ERP
        %         col_idx = col_idx + 1;
        %     end
        %     cond_erps(:, cond) = mean(cond_matrix, 2); % average across dipoles
        % end
        % grand_avg_erp_all = mean(all_erp_data, 2); % all epochs
        % grand_avg_cond = mean(cond_erps, 2); % mean of condition-wise ERPs
        % fig = figure('Name', [clusterLabel '_ERP_Comparison'], 'Color', 'w', 'Position', [100 100 1200 500]);
        % tiledlayout(1, 2, 'Padding', 'compact');
        % nexttile;
        % raw1 = grand_avg_erp_all;
        % smooth1 = smoothdata(raw1, 'movmean', smooth_win);
        % plot(time_ms, raw1, 'Color', [0.7 0.7 0.7], 'LineWidth', 1.5); hold on;
        % plot(time_ms, smooth1, 'k', 'LineWidth', 2);
        % xlabel('Time (ms)'); ylabel('Amplitude (\muV)');
        % title('Grand Average ERP (All Dipoles & Epochs)');
        % grid on;
        % nexttile;
        % raw2 = grand_avg_cond;
        % smooth2 = smoothdata(raw2, 'movmean', smooth_win);
        % plot(time_ms, raw2, 'Color', [0.6 0.7 1], 'LineWidth', 1.5); hold on;
        % plot(time_ms, smooth2, 'b', 'LineWidth', 2);
        % xlabel('Time (ms)'); ylabel('Amplitude (\muV)');
        % title('Mean of Condition-wise ERPs');
        % grid on;
        % drawnow;
        % tiffFileName = sprintf('%s_ERP_Comparison_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);
        % fig = figure('Name', [clusterLabel '_ERP_Conditions'], 'Color', 'w', 'Position', [100 100 1200 700]);
        % movegui(gcf, 'center');
        % t = tiledlayout(4, 3, 'Padding', 'compact', 'TileSpacing', 'compact');
        % colors = lines(num_conditions); % MATLAB's distinct color map
        % for cond = 1:num_conditions
        %     nexttile;
        %     raw_erp = cond_erps(:, cond);
        %     smoothed_erp = smoothdata(raw_erp, 'movmean', smooth_win);
        %     plot(time_ms, raw_erp, 'Color', [0.8 0.8 0.8], 'LineWidth', 1.2); hold on;
        %     plot(time_ms, smoothed_erp, 'Color', colors(cond, :), 'LineWidth', 1.8);
        %     title(['Condition ' num2str(cond)]);
        %     xlabel('Time (ms)');
        %     ylabel('Amplitude (\muV)');
        %     grid on;
        % end
        % drawnow;
        % tiffFileName = sprintf('%s_ERP_Conditions_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);
        % 
        
        % 7.=== ERSP Mean Plot ===
app.StatusLabel.Text = sprintf('Status: Generating ERSP plot for %s...', clusterLabel);

cluster_data = allClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
num_conditions = size(cluster_data.icaact{1}, 2);
epoch_len = size(cluster_data.icaact{1}, 1);
srate = cluster_data.srate(1);
frames = epoch_len;
time_range = [0 epoch_len / srate * 1000]; % in ms

% Use one sample dipole to get frequency/time vectors
sample_dipole = cluster_data.icaact{1}(:, 1);
[ersp_sample, ~, ~, times_ms, freqs_hz] = newtimef(sample_dipole, frames, time_range, srate, ...
    'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
    'freqs', [0.7324 50], 'verbose', 'off');

freq_mask = freqs_hz >= 1 & freqs_hz <= 50;
freqs = freqs_hz(freq_mask);
times = times_ms;

% Compute ERSP for all dipoles and conditions
ersp_all = zeros(length(freqs), length(times), num_dipoles * num_conditions);
col = 1;
for cond = 1:num_conditions
    for d = 1:num_dipoles
        data = cluster_data.icaact{d}(:, cond);
        [ersp, ~, ~, ~, ~] = newtimef(data, frames, time_range, srate, ...
            'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
            'freqs', [0.7324 50], 'verbose', 'on');
        ersp_all(:, :, col) = ersp(freq_mask, :);
        col = col + 1;
    end
end

% Mean ERSP across all dipoles & conditions
grand_avg_ersp = mean(ersp_all, 3);

% Plot ERSP
fig = figure('Name', [clusterLabel '_ERSP_Mean'], 'Color', 'w', 'Position', [100 100 900 600]);
movegui(fig, 'center');
imagesc(times, freqs, grand_avg_ersp); 
axis xy;
xlabel('Time (ms)');
ylabel('Frequency (Hz)');
title(['Grand Average ERSP - ' clusterLabel], 'FontWeight', 'bold');
colorbar;
drawnow;

% Save figure
tiffFileName = sprintf('%s_ERSP_Mean_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);

        % % 7. ERSP Plot
        % app.StatusLabel.Text = sprintf('Status: Generating ERSP plot for %s...', clusterLabel);
        % cluster_data = allClusterData.(sprintf('cluster_%d', i));
        % num_dipoles = height(cluster_data);
        % num_conditions = size(cluster_data.icaact{1}, 2);
        % epoch_len = size(cluster_data.icaact{1}, 1);
        % srate = cluster_data.srate(1);
        % frames = epoch_len;
        % time_range = [0 epoch_len / srate * 1000]; % in ms
        % sample_dipole = cluster_data.icaact{1}(:, 1);
        % [ersp_sample, ~, ~, times_ms, freqs_hz] = newtimef(sample_dipole, frames, time_range, srate, ...
        %     'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
        %     'freqs', [0.7324 50], 'verbose', 'on');
        % freq_mask = freqs_hz >= 1 & freqs_hz <= 50;
        % freqs = freqs_hz(freq_mask);
        % times = times_ms;
        % ersp_all = zeros(length(freqs), length(times), num_dipoles * num_conditions);
        % cond_ersps = zeros(length(freqs), length(times), num_conditions);
        % col = 1;
        % for cond = 1:num_conditions
        %     cond_mat = zeros(length(freqs), length(times), num_dipoles);
        %     for d = 1:num_dipoles
        %         data = cluster_data.icaact{d}(:, cond);
        %         [ersp, ~, ~, ~, ~] = newtimef(data, frames, time_range, srate, ...
        %             'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
        %             'freqs', [0.7324 50], 'verbose', 'on');
        %         ersp = ersp(freq_mask, :);
        %         ersp_all(:, :, col) = ersp;
        %         cond_mat(:, :, d) = ersp;
        %         col = col + 1;
        %     end
        %     cond_ersps(:, :, cond) = mean(cond_mat, 3); % Avg across dipoles
        % end
        % grand_avg_ersp = mean(ersp_all, 3);
        % grand_avg_ersp_cond = mean(cond_ersps, 3);
        % fig = figure('Name', [clusterLabel '_ERSP_Average'], 'Color', 'w', 'Position', [100 100 1200 500]);
        % tiledlayout(1, 2, 'Padding', 'compact');
        % nexttile;
        % imagesc(times, freqs, grand_avg_ersp); axis xy;
        % xlabel('Time (ms)'); ylabel('Frequency (Hz)');
        % title('Grand Average ERSP (All Dipoles & Conditions)');
        % colorbar;
        % nexttile;
        % imagesc(times, freqs, grand_avg_ersp_cond); axis xy;
        % xlabel('Time (ms)'); ylabel('Frequency (Hz)');
        % title('Mean of Condition-wise ERSPs');
        % colorbar;
        % drawnow;
        % tiffFileName = sprintf('%s_ERSP_Average_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);
        % fig = figure('Name', [clusterLabel '_ERSP_Conditions'], 'Color', 'w', 'Position', [100 100 1200 800]);
        % t = tiledlayout(4, 3, 'Padding', 'compact', 'TileSpacing', 'compact');
        % for cond = 1:num_conditions
        %     nexttile;
        %     imagesc(times, freqs, cond_ersps(:, :, cond)); axis xy;
        %     title(['Condition ' num2str(cond)]);
        %     xlabel('Time (ms)'); ylabel('Frequency (Hz)');
        %     colorbar;
        % end
        % drawnow;
        % tiffFileName = sprintf('%s_ERSP_Conditions_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
        % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
        % print(fig, tiffFilePath, '-dtiff', '-r600');
        % close(fig);

        % 8. ITC Plot
app.StatusLabel.Text = sprintf('Status: Generating ITC plot for %s...', clusterLabel);
cluster_data = allClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
num_conditions = size(cluster_data.icaact{1}, 2);
epoch_len = size(cluster_data.icaact{1}, 1);
srate = cluster_data.srate(1);
frames = epoch_len;
time_range = [0 epoch_len / srate * 1000]; % in ms
sample_dipole = cluster_data.icaact{1}(:, 1);
[~, itc_sample, ~, times_ms, freqs_hz] = newtimef(sample_dipole, frames, time_range, srate, ...
    'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
    'freqs', [0.7324 50], 'verbose', 'off');
freq_mask = freqs_hz >= 1 & freqs_hz <= 50;
if clusterIdx == 1  % Initialize global srate, times, and freqs for the first cluster
    srate = cluster_data.srate(1);
    times = times_ms;
    freqs = freqs_hz(freq_mask);
end
itc_all = zeros(length(freqs), length(times), num_dipoles);
for d = 1:num_dipoles
    data_matrix = cluster_data.icaact{d}; % [time x conditions]
    [~, itc, ~, ~, ~] = newtimef(data_matrix, frames, time_range, srate, ...
        'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...  % Changed to 'off'
        'freqs', [0.7324 50], 'verbose', 'off');  % Changed to 'off'
    itc_all(:, :, d) = abs(itc(freq_mask, :)); % magnitude of complex ITC
end
grand_avg_itc = mean(itc_all, 3);
fig = figure('Name', [clusterLabel '_ITC_Average'], 'Color', 'w', 'Position', [100 100 600 500]);
imagesc(times, freqs, grand_avg_itc); axis xy;
xlabel('Time (ms)'); ylabel('Frequency (Hz)');
title(['ITC across ' num2str(num_conditions) ' Conditions (Avg across ' num2str(num_dipoles) ' Dipoles)']);
colorbar;
drawnow;
tiffFileName = sprintf('%s_ITC_Average_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);

    end

    % 9. All Centroid Plot
app.StatusLabel.Text = 'Status: Generating all centroid plot for all groups...';

% Validate centroids
valid_dipoles = false(numClusters, 1);
for k = 1:numClusters
    valid_dipoles(k) = isfield(dipModelCentroids(k), 'posxyz') && ~isempty(dipModelCentroids(k).posxyz) && ~any(isnan(dipModelCentroids(k).posxyz));
end

% Debug centroid data
fprintf('Number of valid centroids: %d\n', sum(valid_dipoles));
for k = 1:numClusters
    if isfield(dipModelCentroids(k), 'posxyz')
        fprintf('Centroid %d posxyz: [%f %f %f]\n', k, dipModelCentroids(k).posxyz);
    else
        fprintf('Centroid %d posxyz not defined\n', k);
    end
end

if sum(valid_dipoles) ~= numClusters
    warning('Not all centroids are valid. Plotting only valid centroids (%d out of %d).', sum(valid_dipoles), numClusters);
end

centFig = figure('Name', 'All Centroids - All Groups', 'NumberTitle', 'off');
dipplot(dipModelCentroids(valid_dipoles), 'coordformat', 'MNI', 'color', colorListCell(valid_dipoles), ...
    'gui', 'off', 'dipolesize', 75, 'view', [45 35], 'projlines', 'on', 'mri', app.mriData);
drawnow;

title('All Centroids - All Groups');
xlabel('X (MNI)');
ylabel('Y (MNI)');
zlabel('Z (MNI)');
grid on;

tiffFileName = sprintf('All_Centroids_All_Groups_%s.tiff', datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(combinedFolder, tiffFileName);
savefig(centFig, strrep(tiffFilePath, '.tiff', '.fig'));
print(centFig, tiffFilePath, '-dtiff', '-r600');
close(centFig);

    % 10. All Dipoles Plot
    app.StatusLabel.Text = 'Status: Generating all dipoles plot for all groups...';
    dipFig = figure('Name', 'All Dipoles - All Groups', 'NumberTitle', 'off');
    dipplot(all_dipoles, 'coordformat', 'MNI', 'color', all_colors, ...
        'gui', 'off', 'dipolesize', 25, 'view', [45 35], 'projlines', 'off', ...
        'mri', app.mriData, 'normlen', 'on', 'spheres', 'on');
    drawnow;
    title('All Dipoles - All Groups');
    xlabel('X (MNI)');
    ylabel('Y (MNI)');
    zlabel('Z (MNI)');
    grid on;
    tiffFileName = sprintf('All_Dipoles_All_Groups_%s.tiff', datestr(now, 'HH-MM'));
    tiffFilePath = app.getUniqueFilePath(combinedFolder, tiffFileName);
    savefig(dipFig, strrep(tiffFilePath, '.tiff', '.fig'));
    print(dipFig, tiffFilePath, '-dtiff', '-r600');
    close(dipFig);

    % Save all outputs into a single group-combined_data.mat
    fprintf('Saving to %s\n', combinedDataFile);
    % save(combinedDataFile, 'allClusterData', 'allCentroidData', 'allDipoles', 'icaactAll', 'spectraAll', 'erpAll', 'erspAll', 'itcAll', 'timesOutAll', 'freqsOutAll', 'dipModelCentroids', 'all_dipoles', 'all_colors', 'densityColorListCell', 'realClusterIndices', 'numClusters', 'srate', 'times', 'freqs', '-v7.3');
    fprintf('All combined group data saved to %s\n', combinedDataFile);
    end

else
    fprintf('⏭️ Skipping Step 2.3 as per user selection.\n');
end


% Step 2.4: All Clusters, Group-wise Data Preparation, Saving, and Plotting
if runStep24
realClusterIndices = clusterIndices(clusterIndices > 0);

fprintf('Number of real clusters for further analysis: %d\n', length(realClusterIndices));
fprintf('Real cluster indices: %s\n', mat2str(realClusterIndices));

if ~isempty(realClusterIndices)
    % Ensure realClusterIndices is a numeric row vector
    if ischar(realClusterIndices) || isstring(realClusterIndices)
        realClusterIndices = str2double(regexp(realClusterIndices, '\d+', 'match'));
    elseif iscell(realClusterIndices)
        realClusterIndices = cellfun(@str2double, realClusterIndices);
    end
    realClusterIndices = realClusterIndices(:)';

    numClusters = length(realClusterIndices);
    numGroups = app.numGroups;

    % Debug print to verify group settings
    fprintf('app.numGroups = %d, app.groupPrefixes = %s\n', app.numGroups, sprintf('%s ', app.groupPrefixes{:}));

    % Validate numClusters and numGroups
    if numClusters < 2 || numClusters > 20
        error('Number of clusters must be between 2 and 20 inclusive.');
    end
    if numGroups < 1
        error('Number of groups must be at least 1.');
    end

    % Initialize global variables for consistency across groups
    srate = NaN;
    times = [];
    freqs = [];

    % Loop over each group
    for g = 1:numGroups
        groupPrefix = app.groupPrefixes{g};
        groupFolder = fullfile(app.studyFilePath, groupPrefix);
        if ~exist(groupFolder, 'dir')
            mkdir(groupFolder);
        end

        % Create cluster subfolders within the group folder
        for i = 1:numClusters
            clusterID = realClusterIndices(i);
            clusterSubfolder = fullfile(groupFolder, sprintf('Cluster_%d', clusterID));
            if ~exist(clusterSubfolder, 'dir')
                mkdir(clusterSubfolder);
            end
        end

        groupDataFile = fullfile(groupFolder, sprintf('%s_data.mat', groupPrefix));

        % Initialize structures to hold all data for the current group
        groupClusterData = struct();
        groupCentroidData = struct();
        groupDipoles = repmat(struct('posxyz', [], 'srate', []), 1, numClusters);
        groupIcaactAll = cell(1, numClusters);
        groupSpectraAll = cell(1, numClusters);
        groupErpAll = cell(1, numClusters);
        groupErspAll = cell(1, numClusters);
        groupItcAll = cell(1, numClusters);
        groupTimesOutAll = cell(1, numClusters);
        groupFreqsOutAll = cell(1, numClusters);
        groupDipModelCentroids = repmat(struct('posxyz', [], 'momxyz', [], 'rv', []), numClusters, 1);
        groupDipoles = repmat(struct('posxyz', [], 'momxyz', [], 'rv', []), 0, 1); % Dynamic growth
        groupColors = cell(0, 1);

        % Pre-extract data for all clusters within this group
        for clusterIdx = 1:numClusters
            i = realClusterIndices(clusterIdx);
            clusterLabel = sprintf('Cluster %d', i);
            clusterIDForPlots = i;

            app.StatusLabel.Text = sprintf('Status: Preparing data for %s (group-wise, %s)...', clusterLabel, groupPrefix);
            drawnow;

            cluster_data = app.dipxyz_clust_table(app.dipxyz_clust_table.ClustersID == clusterIDForPlots, :);
            fprintf('Cluster %s: Number of dipoles extracted: %d\n', clusterLabel, height(cluster_data));

            if isempty(cluster_data)
                warning('No data for %s. Skipping cluster for group-wise preparation (%s).', clusterLabel, groupPrefix);
                groupIcaactAll{clusterIdx} = [];
                groupSpectraAll{clusterIdx} = [];
                groupErpAll{clusterIdx} = [];
                groupErspAll{clusterIdx} = [];
                groupItcAll{clusterIdx} = [];
                groupTimesOutAll{clusterIdx} = [];
                groupFreqsOutAll{clusterIdx} = [];
                groupDipoles(clusterIdx).posxyz = [];
                groupDipoles(clusterIdx).srate = [];
                continue;
            end

            % Filter data for the current group
            groupTable = cluster_data(ismember(cluster_data.SubjectID, groupSubjectIDs{g}), :);
            varName = sprintf('cluster_%d', i);
            groupClusterData.(varName) = groupTable;

            if ~isempty(groupTable)
                % Store dipole positions and srate for the group
                cluster_data_pos = table2array(groupTable(:, {'posx', 'posy', 'posz'}));
                groupDipoles(clusterIdx).posxyz = cluster_data_pos;
                groupDipoles(clusterIdx).srate = mode(groupTable.srate(groupTable.srate > 0));
                fprintf('Cluster %s (Group %s): Number of dipoles extracted: %d\n', clusterLabel, groupPrefix, height(groupTable));
                fprintf('Cluster %s (Group %s): posxyz size = [%d %d]\n', clusterLabel, groupPrefix, size(cluster_data_pos, 1), size(cluster_data_pos, 2));

                % Prepare centroid data
                if ~isempty(groupDipoles(clusterIdx).posxyz)
                    cluster_mean = mean(groupDipoles(clusterIdx).posxyz, 1);
                    mni_coord = cluster_mean;
                    try
                        tal_coord = mni2tal(mni_coord);
                    catch ME
                        warning('Failed to convert MNI to Talairach for %s (Group %s): %s', clusterLabel, groupPrefix, ME.message);
                        tal_coord = [NaN NaN NaN];
                    end
                    varNameCentroid = sprintf('centroid_%d', i);
                    groupCentroidData.(varNameCentroid) = struct('clusterID', i, 'mni_x', mni_coord(1), 'mni_y', mni_coord(2), 'mni_z', mni_coord(3), ...
                        'tal_x', tal_coord(1), 'tal_y', tal_coord(2), 'tal_z', tal_coord(3));
                end
            end
        end

        % Assign validated data to final structures
        allDipoles = groupDipoles;

        baseColors = [0.5 0 0.5; 1 0.5 0];
        densityBaseColors = [0.1 0.4 0.7; 0.9 0.4 0.3];
        if numClusters > 2
            additionalColors = hsv(numClusters - 2);
            colorList = [baseColors; additionalColors(1:(numClusters-2), :)];
            additionalDensityColors = hsv(numClusters - 2);
            densityColorList = [densityBaseColors; additionalDensityColors(1:(numClusters - 2), :)];
            if numClusters > 6
                additionalDensityColors = hsv(numClusters - 6);
                densityColorList = [densityBaseColors; additionalDensityColors(1:(numClusters-6), :)];
            end
            colorList = colorList(1:numClusters, :);
            densityColorList = densityColorList(1:numClusters, :);
        else
            colorList = baseColors(1:numClusters, :);
            densityColorList = densityBaseColors(1:numClusters, :);
        end
        colorListCell = num2cell(colorList, 2);
        densityColorListCell = num2cell(densityColorList, 2);

        % Prepare centroid and all dipoles data for plotting
        dipModelCentroids = repmat(struct('posxyz', [], 'momxyz', [], 'rv', []), numClusters, 1);
        all_dipoles = repmat(struct('posxyz', [], 'momxyz', [], 'rv', []), 0, 1); % Dynamic growth
        all_colors = cell(0, 1);
        dipole_count = 1;
        for clusterIdx = 1:numClusters
            if isfield(allDipoles(clusterIdx), 'posxyz') && ~isempty(allDipoles(clusterIdx).posxyz)
                cluster_mean = mean(allDipoles(clusterIdx).posxyz, 1);
                dipModelCentroids(clusterIdx).posxyz = cluster_mean;
                dipModelCentroids(clusterIdx).momxyz = [0 0 0];
                dipModelCentroids(clusterIdx).rv = 0;
                cluster_data = allDipoles(clusterIdx).posxyz;
                numDipoles = size(cluster_data, 1);
                for j = 1:numDipoles
                    all_dipoles(dipole_count).posxyz = cluster_data(j, :);
                    all_dipoles(dipole_count).momxyz = [0 0 0];
                    all_dipoles(dipole_count).rv = 0;
                    all_colors{dipole_count} = colorListCell{clusterIdx};
                    dipole_count = dipole_count + 1;
                end
            end
        end

        % Loop over real clusters for plotting within this group
        for clusterIdx = 1:numClusters
            i = realClusterIndices(clusterIdx);
            clusterLabel = sprintf('Cluster %d', i);
            clusterSubfolder = fullfile(groupFolder, sprintf('Cluster_%d', i));

            app.StatusLabel.Text = sprintf('Status: Processing %s (group-wise, %s)...', clusterLabel, groupPrefix);
            drawnow;

% 1. Cluster Plot - Generate 4 views for group
app.StatusLabel.Text = sprintf('Status: Generating cluster plots for %s (Group %s)...', clusterLabel, groupPrefix);
cluster_data = groupClusterData.(sprintf('cluster_%d', i));
cluster_data_pos = array2table(allDipoles(clusterIdx).posxyz, 'VariableNames', {'posx', 'posy', 'posz'});

% Filter dipoles for the current group
indices = ismember(cluster_data.SubjectID, groupSubjectIDs{g});
group_data = cluster_data_pos(indices, :);
n_group = height(group_data);
cluster_mean = mean(allDipoles(clusterIdx).posxyz, 1);
cluster_mean_table = array2table(cluster_mean, 'VariableNames', {'posx', 'posy', 'posz'});
cluster_data_pos = [group_data; cluster_mean_table]; % Only include group dipoles and centroid
cluster_data_array = table2array(cluster_data_pos);
n = size(cluster_data_array, 1);
n_dipoles = n - 1;
rgbMatrix = zeros(n, 3);

% Assign color based on group index (matching Step 2.3)
if n_group > 0
    switch g
        case 1
            rgbMatrix(1:n_group, :) = repmat(color1 / 255, n_group, 1);
        case 2
            rgbMatrix(1:n_group, :) = repmat(color2 / 255, n_group, 1);
        case 3
            rgbMatrix(1:n_group, :) = repmat(color3 / 255, n_group, 1);
    end
end

% Assign centroid color (matching Step 2.3)
rgbMatrix(n, :) = centroid_color / 255;
max_value = max(rgbMatrix(:));
if max_value == 0
    error('No valid color values for %s (Group %s).', clusterLabel, groupPrefix);
else
    rgbMatrix = rgbMatrix / max_value;
end

% Define 4 views
viewAngles = {[45 35], [0 90], [90 0], [0 0]};
viewLabels = {'Oblique', 'Top', 'Sagittal', 'Frontal'};

% Store file paths for composite plot
tiffFilePaths = cell(4, 1);

% Loop through each view
for viewIdx = 1:length(viewAngles)
    fig = figure('Name', sprintf('%s Dipoles - %s - %s View', clusterLabel, groupPrefix, viewLabels{viewIdx}), 'NumberTitle', 'off');
    plotDipoles(cluster_data_array, rgbMatrix, app.mriData, viewAngles{viewIdx});
    drawnow;
    %title(sprintf('%s Dipoles - %s - %s View', clusterLabel, groupPrefix, viewLabels{viewIdx}));
    xlabel('X (MNI)');
    ylabel('Y (MNI)');
    zlabel('Z (MNI)');
    grid on;

    % Save with view label in filename
    tiffFileName = sprintf('%s_Dipoles_%s_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, viewLabels{viewIdx}, datestr(now, 'HH-MM'));
    tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
    savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
    print(fig, tiffFilePath, '-dtiff', '-r600');

    % Store the file path for composite plot
    tiffFilePaths{viewIdx} = tiffFilePath;

    close(fig);
end

% 2. Group-Wise High-Quality Density Plot with 3 Views (Axial, Sagittal, Coronal)
app.StatusLabel.Text = sprintf('Status: Generating density plots for %s (Group %s)...', clusterLabel, groupPrefix);
drawnow;

% ========================================================================
% STEP 1: Add FieldTrip to path (required for dipoledensity_hq)
% ========================================================================
eeglabRoot = fileparts(which('eeglab'));
fieldtripPath = fullfile(eeglabRoot, 'plugins', 'Fieldtrip-lite250523');

if ~exist(fieldtripPath, 'dir')
    ftPaths = dir(fullfile(eeglabRoot, 'plugins', 'Fieldtrip*'));
    if ~isempty(ftPaths)
        fieldtripPath = fullfile(ftPaths(1).folder, ftPaths(1).name);
    end
end

if exist(fieldtripPath, 'dir')
    % Suppress path warnings temporarily
    warning('off', 'MATLAB:rmpath:DirNotFound');
    
    % Get all subfolders but exclude compatibility folders
    ftSubfolders = genpath(fieldtripPath);
    
    % Remove all 'compat' folders from path (they cause function name conflicts)
    ftSubfolders = regexprep(ftSubfolders, '[^;]*compat[^;]*;', '');
    
    % Add the cleaned path
    addpath(ftSubfolders);
    fprintf('Debug - FieldTrip path added (excluding compat folders): %s\n', fieldtripPath);
    
    warning('on', 'MATLAB:rmpath:DirNotFound');
else
    warning('FieldTrip not found. Density plotting may fail.');
end

% ========================================================================
% STEP 2: Verify cluster data exists
% ========================================================================
cluster_data_array = allDipoles(clusterIdx).posxyz;
n_dipoles_all = size(cluster_data_array, 1);

if n_dipoles_all == 0
    warning('No dipoles found for cluster %d (Group %s). Skipping density plot.', clusterIdx, groupPrefix);
    app.StatusLabel.Text = sprintf('Status: No dipoles found for %s (Group %s). Skipped.', clusterLabel, groupPrefix);
    return;
end

fprintf('Debug - Processing %d dipoles for density plot (Group %s).\n', n_dipoles_all, groupPrefix);

% ========================================================================
% STEP 3: Build dipole model structure
% ========================================================================
dipModelAll = struct('posxyz', [], 'momxyz', [], 'rv', []);
for dipIdx = 1:n_dipoles_all
    dipModelAll(dipIdx).posxyz = cluster_data_array(dipIdx, :);
    dipModelAll(dipIdx).momxyz = [0 0 0];  % Not used for density
    dipModelAll(dipIdx).rv = 0;             % Not used for density
end

fprintf('Debug - Dipole model built with %d dipoles for Group %s.\n', n_dipoles_all, groupPrefix);

% Display coordinate ranges for verification
all_coords = cluster_data_array;
fprintf('Debug - Dipole coordinate ranges (Group %s):\n', groupPrefix);
fprintf('  X: [%.2f, %.2f] mm\n', min(all_coords(:,1)), max(all_coords(:,1)));
fprintf('  Y: [%.2f, %.2f] mm\n', min(all_coords(:,2)), max(all_coords(:,2)));
fprintf('  Z: [%.2f, %.2f] mm\n', min(all_coords(:,3)), max(all_coords(:,3)));

% ========================================================================
% STEP 4: Compute density data using dipoledensity_hq
% ========================================================================
app.StatusLabel.Text = sprintf('Status: Computing density data for %s (Group %s)...', clusterLabel, groupPrefix);
drawnow;

try
    fprintf('Debug - Calling dipoledensity_hq to compute density (Group %s)...\n', groupPrefix);
    
    % Call high-quality density computation (no plotting yet)
    [dens3d, mri_output] = dipoledensity_hq(dipModelAll, ...
        'method', 'alldistance', ...
        'methodparam', 20, ...
        'plot', 'off', ...
        'subsample', 2, ...
        'normalization', 'on', ...
        'smooth', 0);
    
    fprintf('Debug - Density computation successful (Group %s).\n', groupPrefix);
    fprintf('  - Density size: %s\n', mat2str(size(dens3d{1})));
    fprintf('  - Max density: %.6f dipoles/cc\n', max(dens3d{1}(:)));
    fprintf('  - MRI dimensions: %s\n', mat2str(mri_output.dim));
    
catch ME
    fprintf('ERROR - dipoledensity_hq failed (Group %s): %s\n', groupPrefix, ME.message);
    fprintf('Error stack:\n');
    for k = 1:length(ME.stack)
        fprintf('  File: %s, Line: %d, Function: %s\n', ...
            ME.stack(k).file, ME.stack(k).line, ME.stack(k).name);
    end
    app.StatusLabel.Text = sprintf('Status: Error computing density for %s (Group %s)', clusterLabel, groupPrefix);
    rethrow(ME);
end

% ========================================================================
% STEP 5: Generate and save 3 views (Axial, Sagittal, Coronal)
% ========================================================================
views = {'top', 'side', 'rear'};
viewNames = {'Axial', 'Sagittal', 'Coronal'};
viewDescriptions = {'Top', 'Side', 'Front'};

fprintf('Debug - Generating 3 view plots for Group %s...\n', groupPrefix);

for viewIdx = 1:length(views)
    try
        % Update status
        app.StatusLabel.Text = sprintf('Status: Generating %s view for %s (Group %s)...', ...
            viewNames{viewIdx}, clusterLabel, groupPrefix);
        drawnow;
        
        fprintf('Debug - Creating %s view (%s) for Group %s...\n', ...
            viewNames{viewIdx}, views{viewIdx}, groupPrefix);
        
        % Create figure with proper settings
        hFig = figure('Name', sprintf('%s - %s View (%s) - Group %s', ...
                      clusterLabel, viewNames{viewIdx}, viewDescriptions{viewIdx}, groupPrefix), ...
                      'NumberTitle', 'off', ...
                      'Color', 'k', ...
                      'Position', [100 100 1200 900]);
        
        % Plot density on MRI slices
        mri3dplot(dens3d, mri_output, 'mriview', views{viewIdx}, 'fighandle', hFig);
        
        fprintf('Debug - %s view plot generated successfully (Group %s).\n', ...
            viewNames{viewIdx}, groupPrefix);
        
        % Force rendering
        drawnow;
        pause(0.5);  % Small pause to ensure rendering completes
        
        % ================================================================
        % STEP 6: Save figure and high-resolution TIFF
        % ================================================================
        
        % Generate filenames with group prefix
        timestamp = datestr(now, 'HH-MM-SS');
        baseFileName = sprintf('%s_Density_%s_%s_%s', ...
            strrep(clusterLabel, ' ', '_'), viewNames{viewIdx}, groupPrefix, timestamp);
        
        figFileName = [baseFileName, '.fig'];
        tiffFileName = [baseFileName, '.tiff'];
        
        figFilePath = app.getUniqueFilePath(clusterSubfolder, figFileName);
        tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
        
        % Save MATLAB figure
        try
            savefig(hFig, figFilePath);
            fprintf('Debug - Saved .fig file: %s\n', figFilePath);
        catch saveError
            fprintf('Warning - Could not save .fig file: %s\n', saveError.message);
        end
        
        % Save high-resolution TIFF
        try
            print(hFig, tiffFilePath, '-dtiff', '-r600');
            fprintf('Debug - Saved .tiff file (600 DPI): %s\n', tiffFilePath);
        catch saveError
            fprintf('Warning - Could not save .tiff file: %s\n', saveError.message);
        end
        
        % Close figure
        close(hFig);
        
    catch viewError
        fprintf('ERROR - Failed to generate %s view (Group %s): %s\n', ...
            viewNames{viewIdx}, groupPrefix, viewError.message);
        fprintf('Error stack:\n');
        for k = 1:length(viewError.stack)
            fprintf('  File: %s, Line: %d, Function: %s\n', ...
                viewError.stack(k).file, viewError.stack(k).line, viewError.stack(k).name);
        end
        
        % Close figure if it exists
        if exist('hFig', 'var') && ishandle(hFig)
            close(hFig);
        end
        
        % Continue to next view instead of stopping
        fprintf('Warning - Skipping %s view and continuing...\n', viewNames{viewIdx});
    end
end

% ========================================================================
% STEP 7: Final cleanup and status update
% ========================================================================
close all;  % Ensure all figures are closed

fprintf('Debug - All density plots completed for %s (Group %s).\n', clusterLabel, groupPrefix);
app.StatusLabel.Text = sprintf('Status: Density plots saved for %s (Group %s, 3 views)', ...
    clusterLabel, groupPrefix);
drawnow;

% Optional: Display summary
fprintf('\n=== DENSITY PLOT SUMMARY for %s (Group %s) ===\n', clusterLabel, groupPrefix);
fprintf('Dipoles processed: %d\n', n_dipoles_all);
fprintf('Max density: %.6f dipoles/cc\n', max(dens3d{1}(:)));
fprintf('Views generated: Axial, Sagittal, Coronal\n');
fprintf('Files saved to: %s\n', clusterSubfolder);
fprintf('================================================\n\n');

            % 
            % 3. Density Stats
            app.StatusLabel.Text = sprintf('Status: Computing density stats for %s (Group %s)...', clusterLabel, groupPrefix);
            cluster_data_array = allDipoles(clusterIdx).posxyz;
            cluster_mean = mean(cluster_data_array, 1);
            coordinates = cluster_data_array;
            standardDeviation = std(coordinates);
            standardError = standardDeviation / sqrt(size(coordinates, 1));
            try
                centroidTal = mni2tal(cluster_mean);
            catch ME
                warning('Failed to convert MNI to Talairach for %s (Group %s): %s', clusterLabel, groupPrefix, ME.message);
                centroidTal = [NaN NaN NaN];
            end
            dipoles1 = struct('posxyz', []);
            for j = 1:size(cluster_data_array, 1)
                dipoles1(j).posxyz = cluster_data_array(j, :);
            end
            dipoles2 = struct('posxyz', []);
            dip2_idx = 1;
            for other_cluster_idx = 1:numClusters
                if other_cluster_idx ~= clusterIdx
                    other_cluster_dipoles = allDipoles(other_cluster_idx).posxyz;
                    for dipIdx = 1:size(other_cluster_dipoles, 1)
                        dipoles2(dip2_idx).posxyz = other_cluster_dipoles(dipIdx, :);
                        dip2_idx = dip2_idx + 1;
                    end
                end
            end
            gridDims = [91 109 91];
            voxelSize = 2;
            [X, Y, Z] = ndgrid(1:gridDims(1), 1:gridDims(2), 1:gridDims(3));
            mniX = (X - gridDims(1)/2) * voxelSize;
            mniY = (Y - gridDims(2)/2) * voxelSize;
            mniZ = (Z - gridDims(3)/2) * voxelSize;
            radius = 20;
            distances = sqrt((mniX - cluster_mean(1)).^2 + (mniY - cluster_mean(2)).^2 + (mniZ - cluster_mean(3)).^2);
            roiMask = distances <= radius;
            pos1 = zeros(numel(dipoles1), 3);
            for j = 1:numel(dipoles1)
                pos1(j, :) = dipoles1(j).posxyz;
            end
            if ~isempty(dipoles2)
                pos2 = zeros(numel(dipoles2), 3);
                for j = 1:numel(dipoles2)
                    pos2(j, :) = dipoles2(j).posxyz;
                end
            else
                pos2 = zeros(0, 3); % Handle empty dipoles2 case
            end
            sigma = 5 / voxelSize;
            kernelSize = ceil(3 * sigma);
            density1 = zeros(gridDims);
            density2 = zeros(gridDims);
            [dx, dy, dz] = ndgrid(-kernelSize:kernelSize, -kernelSize:kernelSize, -kernelSize:kernelSize);
            gaussKernel = exp(-(dx.^2 + dy.^2 + dz.^2) / (2 * sigma^2)) / (sqrt(2 * pi) * sigma)^3;
            for j = 1:size(pos1, 1)
                voxel = round((pos1(j, :) / voxelSize) + (gridDims / 2));
                voxel = max(1, min(voxel, gridDims));
                xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
                yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
                zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
                kxRange = xRange - voxel(1) + kernelSize + 1;
                kyRange = yRange - voxel(2) + kernelSize + 1;
                kzRange = zRange - voxel(3) + kernelSize + 1;
                density1(xRange, yRange, zRange) = density1(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
            end
            if ~isempty(pos2)
                for j = 1:size(pos2, 1)
                    voxel = round((pos2(j, :) / voxelSize) + (gridDims / 2));
                    voxel = max(1, min(voxel, gridDims));
                    xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
                    yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
                    zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
                    kxRange = xRange - voxel(1) + kernelSize + 1;
                    kyRange = yRange - voxel(2) + kernelSize + 1;
                    kzRange = zRange - voxel(3) + kernelSize + 1;
                    density2(xRange, yRange, zRange) = density2(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
                end
            end
            voxelVolume = voxelSize^3;
            density1 = density1 / voxelVolume / size(pos1, 1);
            density2 = density2 / voxelVolume / size(pos2, 1);
            [maxDensity, maxIdx] = max(density1(:));
            [peakX, peakY, peakZ] = ind2sub(gridDims, maxIdx);
            densityPeakMNI = [(peakX - gridDims(1)/2) * voxelSize, (peakY - gridDims(2)/2) * voxelSize, (peakZ - gridDims(3)/2) * voxelSize];
            try
                densityPeakTal = mni2tal(densityPeakMNI);
                meanSD = mean(standardDeviation);
                labels = simulated_talairach_lookup(densityPeakTal, meanSD/2);
            catch ME
                warning('Failed to retrieve labels for %s (Group %s): %s', clusterLabel, groupPrefix, ME.message);
                labels = struct('Unknown', 1.0);
            end
            trueDifference = density1 - density2;
            numPermutations = 5000;
            allPos = [pos1; pos2];
            n1 = size(pos1, 1);
            n2 = size(pos2, 1);
            permutedMaxDiff = zeros(numPermutations, 1);
            roiIndices = find(roiMask);
            numVoxelsInROI = nnz(roiMask);
            trueDiffROI = trueDifference(roiIndices);
            permutedDiffROI = zeros(numVoxelsInROI, numPermutations);
            % Initialize for cluster-wise correction
            maxClusterSizes = zeros(numPermutations, 1);
            for p = 1:numPermutations
                if mod(p, 1000) == 0
                    fprintf('%s (Group %s): Permutation %d of %d completed...\n', clusterLabel, groupPrefix, p, numPermutations);
                end
                permIdx = randperm(n1 + n2);
                permPos1 = allPos(permIdx(1:n1), :);
                permPos2 = allPos(permIdx(n1+1:end), :);
                permDensity1 = zeros(gridDims);
                permDensity2 = zeros(gridDims);
                for j = 1:size(permPos1, 1)
                    voxel = round((permPos1(j, :) / voxelSize) + (gridDims / 2));
                    voxel = max(1, min(voxel, gridDims));
                    xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
                    yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
                    zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
                    kxRange = xRange - voxel(1) + kernelSize + 1;
                    kyRange = yRange - voxel(2) + kernelSize + 1;
                    kzRange = zRange - voxel(3) + kernelSize + 1;
                    permDensity1(xRange, yRange, zRange) = permDensity1(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
                end
                for j = 1:size(permPos2, 1)
                    voxel = round((permPos2(j, :) / voxelSize) + (gridDims / 2));
                    voxel = max(1, min(voxel, gridDims));
                    xRange = max(1, voxel(1) - kernelSize):min(gridDims(1), voxel(1) + kernelSize);
                    yRange = max(1, voxel(2) - kernelSize):min(gridDims(2), voxel(2) + kernelSize);
                    zRange = max(1, voxel(3) - kernelSize):min(gridDims(3), voxel(3) + kernelSize);
                    kxRange = xRange - voxel(1) + kernelSize + 1;
                    kyRange = yRange - voxel(2) + kernelSize + 1;
                    kzRange = zRange - voxel(3) + kernelSize + 1;
                    permDensity2(xRange, yRange, zRange) = permDensity2(xRange, yRange, zRange) + gaussKernel(kxRange, kyRange, kzRange);
                end
                permDensity1 = permDensity1 / voxelVolume / size(permPos1, 1);
                permDensity2 = permDensity2 / voxelVolume / size(permPos2, 1);
                permDiff = permDensity1 - permDensity2;
                permutedDiffROI(:, p) = permDiff(roiIndices);
                permutedMaxDiff(p) = max(abs(permDiff(roiIndices)));
                % Cluster-wise correction: Identify clusters in permuted data
                permPvaluesROI = zeros(numVoxelsInROI, 1);
                for v = 1:numVoxelsInROI
                    permPvaluesROI(v) = mean(abs(permutedDiffROI(v, 1:p)) >= abs(trueDiffROI(v)));
                end
                permPvalues3D = zeros(gridDims);
                permPvalues3D(roiIndices) = permPvaluesROI;
                permSigMask = permPvalues3D < 0.05 & roiMask;
                [permLabeled, permNumClusters] = bwlabeln(permSigMask, 26);
                if permNumClusters > 0
                    clusterSizes = arrayfun(@(x) nnz(permLabeled == x), 1:permNumClusters);
                    maxClusterSizes(p) = max(clusterSizes, [], 'all', 'omitnan');
                else
                    maxClusterSizes(p) = 0;
                end
            end
            % Compute uncorrected and FDR-corrected p-values
            uncorrectedPvalues3D = zeros(gridDims);
            uncorrectedPvaluesROI = zeros(numVoxelsInROI, 1);
            for v = 1:numVoxelsInROI
                uncorrectedPvaluesROI(v) = mean(abs(permutedDiffROI(v, :)) >= abs(trueDiffROI(v)));
            end
            uncorrectedPvalues3D(roiIndices) = uncorrectedPvaluesROI;
            fdrPvaluesROI = mafdr(uncorrectedPvaluesROI, 'BHFDR', true);
            correctionMask3D = zeros(gridDims);
            correctionMaskROI = zeros(numVoxelsInROI, 1);
            significantVoxelsROI = fdrPvaluesROI < 0.1;
            correctionMaskROI(significantVoxelsROI) = 1;
            correctionMask3D(roiIndices) = correctionMaskROI;
            % Compute Cohen's d
            cohensD3D = zeros(gridDims);
            cohensDROI = zeros(numVoxelsInROI, 1);
            for v = 1:numVoxelsInROI
                stdPermDiff = std(permutedDiffROI(v, :), 0, 2, 'omitnan');
                if stdPermDiff > 1e-6 % Avoid division by zero
                    cohensDROI(v) = trueDiffROI(v) / stdPermDiff;
                else
                    cohensDROI(v) = 0;
                end
            end
            cohensD3D(roiIndices) = cohensDROI;
            meanCohensD = mean(cohensDROI(significantVoxelsROI), 'omitnan');
            if isnan(meanCohensD) || isempty(significantVoxelsROI)
                meanCohensD = mean(cohensDROI, 'omitnan');
            end
            % Cluster-wise correction: Identify significant clusters
            sigMask = uncorrectedPvalues3D < 0.05 & roiMask;
            [labeledClusters, numClusters] = bwlabeln(sigMask, 26);
            clusterSizes = zeros(numClusters, 1);
            clusterPvalues = zeros(numClusters, 1);
            for c = 1:numClusters
                clusterSizes(c) = nnz(labeledClusters == c);
                clusterPvalues(c) = mean(maxClusterSizes >= clusterSizes(c));
            end
            clusterCorrectionMask3D = zeros(gridDims);
            significantClustersIdx = find(clusterPvalues < 0.05);
            if ~isempty(significantClustersIdx)
                for c = significantClustersIdx'
                    clusterCorrectionMask3D(labeledClusters == c) = 1;
                end
            end
            propSignificantClusterCorrected = nnz(clusterCorrectionMask3D & roiMask) / numVoxelsInROI;
            % Spatial extent analysis
            [labeledFDRClusters, numSignificantClusters] = bwlabeln(correctionMask3D & roiMask, 26);
            totalSignificantVolume = nnz(correctionMask3D & roiMask) * (voxelSize^3) / 1000; % Convert to cc
            significantClusters = struct();
            if numSignificantClusters > 0
                clusterSizesFDR = zeros(numSignificantClusters, 1);
                clusterPeaksMNI = zeros(numSignificantClusters, 3);
                clusterPeaksTal = zeros(numSignificantClusters, 3);
                clusterLabels = cell(numSignificantClusters, 1);
                for c = 1:numSignificantClusters
                    clusterVoxels = find(labeledFDRClusters == c);
                    clusterSizesFDR(c) = length(clusterVoxels) * (voxelSize^3) / 1000; % Volume in cc
                    clusterDiff = trueDifference(clusterVoxels);
                    [~, peakIdx] = max(abs(clusterDiff));
                    [peakX, peakY, peakZ] = ind2sub(gridDims, clusterVoxels(peakIdx));
                    clusterPeaksMNI(c, :) = [(peakX - gridDims(1)/2) * voxelSize, (peakY - gridDims(2)/2) * voxelSize, (peakZ - gridDims(3)/2) * voxelSize];
                    try
                        clusterPeaksTal(c, :) = mni2tal(clusterPeaksMNI(c, :));
                        clusterLabels{c} = simulated_talairach_lookup(clusterPeaksTal(c, :), meanSD/2);
                    catch ME
                        warning('Failed to retrieve labels for cluster %d in %s (Group %s): %s', c, clusterLabel, groupPrefix, ME.message);
                        clusterLabels{c} = struct('Unknown', 1.0);
                    end
                end
                % Sort clusters by size
                [~, sortIdx] = sort(clusterSizesFDR, 'descend');
                clusterSizesFDR = clusterSizesFDR(sortIdx);
                clusterPeaksMNI = clusterPeaksMNI(sortIdx, :);
                clusterPeaksTal = clusterPeaksTal(sortIdx, :);
                clusterLabels = clusterLabels(sortIdx);
                for c = 1:numSignificantClusters
                    significantClusters(c).volume = clusterSizesFDR(c);
                    significantClusters(c).peakMNI = clusterPeaksMNI(c, :);
                    significantClusters(c).peakTal = clusterPeaksTal(c, :);
                    significantClusters(c).labels = clusterLabels{c};
                end
            else
                significantClusters = struct('volume', [], 'peakMNI', [], 'peakTal', [], 'labels', []);
            end
            % Compute standard metrics
            scaleFactor = 1000;
            trueDifferenceROI = trueDifference(roiMask) * scaleFactor;
            meanDifference = mean(trueDifferenceROI(:), 'omitnan');
            maxDifference = max(abs(trueDifferenceROI(:)), [], 'omitnan');
            if maxDifference < 0.001
                warning('Density differences for %s (Group %s) are too small (Max Difference: %.4f dipoles/cc). Skipping significance.', clusterLabel, groupPrefix, maxDifference);
                propSignificantUncorrected = NaN;
                propSignificantCorrected = NaN;
                propSignificantClusterCorrected = NaN;
                meanCohensD = NaN;
                centroidSignificant = [NaN NaN NaN];
                numSignificantClusters = 0;
                totalSignificantVolume = 0;
                significantClusters = struct('volume', [], 'peakMNI', [], 'peakTal', [], 'labels', []);
            else
                propSignificantUncorrected = mean(uncorrectedPvaluesROI < 0.05, 'omitnan');
                propSignificantCorrected = mean(correctionMaskROI > 0, 'omitnan');
                if nnz(correctionMaskROI) >= 3
                    significantVoxels = find(roiMask & correctionMask3D);
                    [sigX, sigY, sigZ] = ind2sub(gridDims, significantVoxels);
                    sigMniX = (sigX - gridDims(1)/2) * voxelSize;
                    sigMniY = (sigY - gridDims(2)/2) * voxelSize;
                    sigMniZ = (sigZ - gridDims(3)/2) * voxelSize;
                    centroidSignificant = mean([sigMniX(:), sigMniY(:), sigMniZ(:)], 1, 'omitnan');
                else
                    centroidSignificant = [NaN NaN NaN];
                end
            end
            % Generate report
            clusterReport = sprintf('\n%s Density Statistics (Group %s):\nCentroid in MNI: [%.1f %.1f %.1f]\nCentroid in Talairach: [%.1f %.1f %.1f]\nStandard Deviation: [%.1f %.1f %.1f]\nStandard Error: [%.1f %.1f %.1f]\nMean Density Difference: %.4f dipoles/cc\nMax Density Difference: %.4f dipoles/cc\nMean Cohen''s d (ROI): %.3f\nProportion Significant (Uncorrected, p<0.05): %.5f\nProportion Significant (FDR-corrected, p<0.1): %.5f\nProportion Significant (Cluster-corrected, p<0.05): %.5f\nCentroid of Significant Differences (MNI): [%.1f %.1f %.1f]\nDipole Density Peak (MNI): [%.1f %.1f %.1f]\nNumber of Significant Clusters (FDR-corrected): %d\nTotal Significant Volume (FDR-corrected): %.3f cc\n\nProbabilistic Labels (confusion sphere diameter = %.1f mm):\n', ...
                clusterLabel, groupPrefix, cluster_mean(1), cluster_mean(2), cluster_mean(3), centroidTal(1), centroidTal(2), centroidTal(3), ...
                standardDeviation(1), standardDeviation(2), standardDeviation(3), standardError(1), standardError(2), standardError(3), ...
                meanDifference, maxDifference, meanCohensD, propSignificantUncorrected, propSignificantCorrected, propSignificantClusterCorrected, ...
                centroidSignificant(1), centroidSignificant(2), centroidSignificant(3), ...
                densityPeakMNI(1), densityPeakMNI(2), densityPeakMNI(3), numSignificantClusters, totalSignificantVolume, meanSD);
            labelNames = fieldnames(labels);
            for l = 1:length(labelNames)
                labelName = labelNames{l};
                labelProb = labels.(labelName);
                labelName = strrep(labelName, '_', ' ');
                clusterReport = [clusterReport sprintf('%-30s %.3f\n', labelName, labelProb)];
            end
            if numSignificantClusters > 0
                clusterReport = [clusterReport sprintf('\nSignificant Clusters (FDR-corrected):\n')];
                for c = 1:numSignificantClusters
                    clusterReport = [clusterReport sprintf('Cluster %d: Volume = %.3f cc, Peak MNI = [%.1f %.1f %.1f], Peak Talairach = [%.1f %.1f %.1f]\nProbabilistic Labels:\n', ...
                        c, significantClusters(c).volume, significantClusters(c).peakMNI(1), significantClusters(c).peakMNI(2), significantClusters(c).peakMNI(3), ...
                        significantClusters(c).peakTal(1), significantClusters(c).peakTal(2), significantClusters(c).peakTal(3))];
                    labelNames = fieldnames(significantClusters(c).labels);
                    for l = 1:length(labelNames)
                        labelName = labelNames{l};
                        labelProb = significantClusters(c).labels.(labelName);
                        labelName = strrep(labelName, '_', ' ');
                        clusterReport = [clusterReport sprintf('  %-28s %.3f\n', labelName, labelProb)];
                    end
                end
            end
            disp(clusterReport);
            clusterFileName = sprintf('%s_Statistics_%s_%s.mat', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            clusterFilePath = app.getUniqueFilePath(clusterSubfolder, clusterFileName);
            save(clusterFilePath, 'clusterReport', 'uncorrectedPvalues3D', 'correctionMask3D', 'clusterCorrectionMask3D', 'propSignificantClusterCorrected', ...
                'meanCohensD', 'cohensD3D', 'numSignificantClusters', 'totalSignificantVolume', 'significantClusters', 'trueDifference', 'density1', 'density2');

% 4. Scalp Topoplot (Mean Only)
app.StatusLabel.Text = sprintf('Status: Generating mean scalp topoplot for %s (Group %s)...', clusterLabel, groupPrefix);

cluster_data = groupClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
n_channels = 60;

% Compute mean ICA weights
icawinv_all = zeros(n_channels, num_dipoles);
for d = 1:num_dipoles
    icawinv = cluster_data.icawinv{d};
    if length(icawinv) < n_channels
        icawinv(end+1:n_channels) = mean(icawinv);
    end
    icawinv_all(:, d) = icawinv;
end
mean_map = mean(icawinv_all, 2);

% Plot mean scalp map
fig = figure('Name', sprintf('%s - Mean Scalp Topoplot (%s)', clusterLabel, groupPrefix), 'Color', 'w');
topoplot(mean_map, cluster_data.chanlocs{1});
title(sprintf('Mean ICA Weights (%s)', groupPrefix), 'FontWeight', 'bold');
colorbar;
drawnow;

% Save figure
tiffFileName = sprintf('%s_MeanScalpTopoplot_%s_%s.tiff', ...
    strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);


            % % 4. Scalp Topoplot
            % app.StatusLabel.Text = sprintf('Status: Generating scalp topoplot for %s (Group %s)...', clusterLabel, groupPrefix);
            % cluster_data = groupClusterData.(sprintf('cluster_%d', i));
            % num_dipoles = height(cluster_data);
            % num_conditions = size(cluster_data.icaact{1}, 2);
            % n_channels = 60;
            % icawinv_all = zeros(n_channels, num_dipoles);
            % for d = 1:num_dipoles
            %     icawinv = cluster_data.icawinv{d};
            %     if length(icawinv) < n_channels
            %         icawinv(end+1:n_channels) = mean(icawinv);
            %     end
            %     icawinv_all(:, d) = icawinv;
            % end
            % mean_map = mean(icawinv_all, 2);
            % avg_map_all = zeros(n_channels, num_dipoles);
            % for d = 1:num_dipoles
            %     icawinv = cluster_data.icawinv{d};
            %     if length(icawinv) < n_channels
            %         icawinv(end+1:n_channels) = mean(icawinv);
            %     end
            %     avg_act = mean(cluster_data.icaact{d}(:));
            %     avg_map_all(:, d) = icawinv * avg_act;
            % end
            % avg_map = mean(avg_map_all, 2);
            % fig = figure('Name', [clusterLabel ' Comparison of Maps - ' groupPrefix]);
            % subplot(1, 2, 1);
            % topoplot(mean_map, cluster_data.chanlocs{1});
            % title('Mean ICA Weights (Unweighted)');
            % subplot(1, 2, 2);
            % topoplot(avg_map, cluster_data.chanlocs{1});
            % title('Activation-Weighted Mean ICA');
            % colorbar('Position', [0.93 0.11 0.02 0.8]);
            % drawnow;
            % tiffFileName = sprintf('%s_Mean_vs_ActivationWeighted_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);
            % 
            % % Cluster Figure B: Condition-wise Topoplots
            % cond_maps = zeros(n_channels, num_conditions);
            % for cond = 1:num_conditions
            %     cond_map_all = zeros(n_channels, num_dipoles);
            %     for d = 1:num_dipoles
            %         icawinv = cluster_data.icawinv{d};
            %         if length(icawinv) < n_channels
            %             icawinv(end+1:n_channels) = mean(icawinv);
            %         end
            %         cond_mean = mean(cluster_data.icaact{d}(:, cond));
            %         cond_map_all(:, d) = icawinv * cond_mean;
            %     end
            %     cond_maps(:, cond) = mean(cond_map_all, 2);
            % end
            % fig = figure('Name', [clusterLabel ' Condition-wise Scalp Maps - ' groupPrefix]);
            % for cond = 1:num_conditions
            %     subplot(3, 4, cond);
            %     topoplot(cond_maps(:, cond), cluster_data.chanlocs{1});
            %     title(['Condition ' num2str(cond)]);
            % end
            % subplot(3, 4, 12);
            % axis off;
            % c = colorbar('southoutside');
            % c.Position = [0.363 0.05 0.3 0.03];
            % drawnow;
            % tiffFileName = sprintf('%s_ConditionWise_ScalpTopoplots_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);

% 5. Spectra Plot (Mean Only)
app.StatusLabel.Text = sprintf('Status: Generating mean spectra plot for %s (Group %s)...', clusterLabel, groupPrefix);

cluster_data = groupClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
epoch_len = size(cluster_data.icaact{1}, 1);
srate = cluster_data.srate(1);

% Combine all dipoles and conditions into one array
all_data_concat = [];
for d = 1:num_dipoles
    ica = cluster_data.icaact{d};
    all_data_concat = [all_data_concat, reshape(ica, 1, [])];
end

% Compute overall mean spectrum
[spec_all_matrix, freqs, ~, ~] = spectopo(all_data_concat, epoch_len, srate, 'plot', 'off');
mean_spectrum = mean(spec_all_matrix, 1);

% Plot mean spectrum
freq_range = freqs >= 1 & freqs <= 50;
fig = figure('Name', sprintf('%s - Mean Spectrum (%s)', clusterLabel, groupPrefix), ...
    'Color', 'w', 'Position', [100 100 900 500]);

plot(freqs(freq_range), mean_spectrum(freq_range), 'k', 'LineWidth', 2);
xlabel('Frequency (Hz)');
ylabel('Power (dB)');
title(sprintf('Mean Spectrum - %s', groupPrefix), 'FontWeight', 'bold');
grid on;
drawnow;

% Save figure
tiffFileName = sprintf('%s_MeanSpectrum_%s_%s.tiff', ...
    strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);

            % % 5. Spectra Plot
            % app.StatusLabel.Text = sprintf('Status: Generating spectra plot for %s (Group %s)...', clusterLabel, groupPrefix);
            % cluster_data = groupClusterData.(sprintf('cluster_%d', i));
            % num_dipoles = height(cluster_data);
            % num_conditions = size(cluster_data.icaact{1}, 2);
            % epoch_len = size(cluster_data.icaact{1}, 1);
            % srate = cluster_data.srate(1);
            % all_data_concat = [];
            % for d = 1:num_dipoles
            %     ica = cluster_data.icaact{d};
            %     all_data_concat = [all_data_concat, reshape(ica, 1, [])];
            % end
            % [spec_all_matrix, freqs, ~, ~] = spectopo(all_data_concat, epoch_len, srate, 'plot', 'off');
            % spec_all = mean(spec_all_matrix, 1);
            % num_freqs = length(freqs);
            % cond_spectra = zeros(num_conditions, num_freqs);
            % for cond = 1:num_conditions
            %     cond_data = [];
            %     for d = 1:num_dipoles
            %         cond_epoch = cluster_data.icaact{d}(:, cond);
            %         cond_data = [cond_data, cond_epoch'];
            %     end
            %     [spec_cond, freqs_cond, ~, ~] = spectopo(cond_data, epoch_len, srate, 'plot', 'off');
            %     if ~isequal(freqs, freqs_cond)
            %         error('Frequency vectors do not match across conditions.');
            %     end
            %     cond_spectra(cond, :) = spec_cond;
            % end
            % grand_avg_spectrum = mean(cond_spectra, 1);
            % freq_range = freqs >= 1 & freqs <= 50;
            % fig = figure('Name', [clusterLabel '_Spectrum_Comparison - ' groupPrefix], 'Color', 'w', 'Position', [100 100 1200 500]);
            % tiledlayout(1, 2, 'Padding', 'compact');
            % nexttile;
            % plot(freqs(freq_range), spec_all(freq_range), 'LineWidth', 2);
            % xlabel('Frequency (Hz)'); ylabel('Power (dB)');
            % title('Overall Mean Spectrum');
            % grid on;
            % nexttile;
            % plot(freqs(freq_range), grand_avg_spectrum(freq_range), 'LineWidth', 2);
            % xlabel('Frequency (Hz)'); ylabel('Power (dB)');
            % title('Mean of Condition-wise Spectra');
            % grid on;
            % drawnow;
            % tiffFileName = sprintf('%s_Spectrum_Comparison_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);
            % fig = figure('Name', [clusterLabel '_Condition_Spectra - ' groupPrefix], 'Color', 'w', 'Position', [100 100 1200 700]);
            % movegui(gcf, 'center');
            % t = tiledlayout(4, 3, 'Padding', 'compact', 'TileSpacing', 'compact');
            % for cond = 1:num_conditions
            %     nexttile;
            %     plot(freqs(freq_range), cond_spectra(cond, freq_range), 'LineWidth', 1.5);
            %     title(['Condition ' num2str(cond)]);
            %     xlabel('Frequency (Hz)');
            %     ylabel('Power (dB)');
            %     grid on;
            % end
            % drawnow;
            % tiffFileName = sprintf('%s_Condition_Spectra_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);


            % 6. ERP Plot (Mean Only)
app.StatusLabel.Text = sprintf('Status: Generating mean ERP plot for %s (Group %s)...', clusterLabel, groupPrefix);

cluster_data = groupClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
epoch_len = size(cluster_data.icaact{1}, 1);
srate = cluster_data.srate(1);
time_ms = (0:epoch_len-1) / srate * 1000; % Time in ms
smooth_win = 40; % ~50 ms smoothing window

% Concatenate all dipoles and conditions
all_erp_data = [];
for d = 1:num_dipoles
    ica = cluster_data.icaact{d};
    all_erp_data = [all_erp_data, ica];
end

% Compute grand average ERP
mean_erp = mean(all_erp_data, 2);
smooth_erp = smoothdata(mean_erp, 'movmean', smooth_win);

% Plot ERP
fig = figure('Name', sprintf('%s - Mean ERP (%s)', clusterLabel, groupPrefix), ...
    'Color', 'w', 'Position', [100 100 900 500]);
plot(time_ms, mean_erp, 'Color', [0.7 0.7 0.7], 'LineWidth', 1.5); hold on;
plot(time_ms, smooth_erp, 'k', 'LineWidth', 2);
xlabel('Time (ms)'); ylabel('Amplitude (\muV)');
title(sprintf('Mean ERP - %s', groupPrefix), 'FontWeight', 'bold');
grid on;
drawnow;

% Save figure
tiffFileName = sprintf('%s_MeanERP_%s_%s.tiff', ...
    strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);

            % % 6. ERP Plot
            % app.StatusLabel.Text = sprintf('Status: Generating ERP plot for %s (Group %s)...', clusterLabel, groupPrefix);
            % cluster_data = groupClusterData.(sprintf('cluster_%d', i));
            % num_dipoles = height(cluster_data);
            % num_conditions = size(cluster_data.icaact{1}, 2);
            % epoch_len = size(cluster_data.icaact{1}, 1);
            % srate = cluster_data.srate(1);
            % time_ms = (0:epoch_len-1) / srate * 1000;
            % smooth_win = 40;
            % all_erp_data = zeros(epoch_len, num_dipoles * num_conditions);
            % cond_erps = zeros(epoch_len, num_conditions);
            % col_idx = 1;
            % for cond = 1:num_conditions
            %     cond_matrix = zeros(epoch_len, num_dipoles);
            %     for d = 1:num_dipoles
            %         epoch = cluster_data.icaact{d}(:, cond);
            %         all_erp_data(:, col_idx) = epoch;
            %         cond_matrix(:, d) = epoch;
            %         col_idx = col_idx + 1;
            %     end
            %     cond_erps(:, cond) = mean(cond_matrix, 2);
            % end
            % grand_avg_erp_all = mean(all_erp_data, 2);
            % grand_avg_cond = mean(cond_erps, 2);
            % fig = figure('Name', [clusterLabel '_ERP_Comparison - ' groupPrefix], 'Color', 'w', 'Position', [100 100 1200 500]);
            % tiledlayout(1, 2, 'Padding', 'compact');
            % nexttile;
            % raw1 = grand_avg_erp_all;
            % smooth1 = smoothdata(raw1, 'movmean', smooth_win);
            % plot(time_ms, raw1, 'Color', [0.7 0.7 0.7], 'LineWidth', 1.5); hold on;
            % plot(time_ms, smooth1, 'k', 'LineWidth', 2);
            % xlabel('Time (ms)'); ylabel('Amplitude (\muV)');
            % title('Grand Average ERP (All Dipoles & Epochs)');
            % grid on;
            % nexttile;
            % raw2 = grand_avg_cond;
            % smooth2 = smoothdata(raw2, 'movmean', smooth_win);
            % plot(time_ms, raw2, 'Color', [0.6 0.7 1], 'LineWidth', 1.5); hold on;
            % plot(time_ms, smooth2, 'b', 'LineWidth', 2);
            % xlabel('Time (ms)'); ylabel('Amplitude (\muV)');
            % title('Mean of Condition-wise ERPs');
            % grid on;
            % drawnow;
            % tiffFileName = sprintf('%s_ERP_Comparison_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);
            % fig = figure('Name', [clusterLabel '_ERP_Conditions - ' groupPrefix], 'Color', 'w', 'Position', [100 100 1200 700]);
            % movegui(gcf, 'center');
            % t = tiledlayout(4, 3, 'Padding', 'compact', 'TileSpacing', 'compact');
            % colors = lines(num_conditions);
            % for cond = 1:num_conditions
            %     nexttile;
            %     raw_erp = cond_erps(:, cond);
            %     smoothed_erp = smoothdata(raw_erp, 'movmean', smooth_win);
            %     plot(time_ms, raw_erp, 'Color', [0.8 0.8 0.8], 'LineWidth', 1.2); hold on;
            %     plot(time_ms, smoothed_erp, 'Color', colors(cond, :), 'LineWidth', 1.8);
            %     title(['Condition ' num2str(cond)]);
            %     xlabel('Time (ms)');
            %     ylabel('Amplitude (\muV)');
            %     grid on;
            % end
            % drawnow;
            % tiffFileName = sprintf('%s_ERP_Conditions_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);
            % 
 

  % 7. ERSP Plot (Mean Only)
app.StatusLabel.Text = sprintf('Status: Generating mean ERSP plot for %s (Group %s)...', clusterLabel, groupPrefix);

cluster_data = groupClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
epoch_len = size(cluster_data.icaact{1}, 1);
srate = cluster_data.srate(1);
frames = epoch_len;
time_range = [0 epoch_len / srate * 1000]; % in ms

% Compute ERSP for one sample to get freq/time info
sample_dipole = cluster_data.icaact{1}(:, 1);
[ersp_sample, ~, ~, times_ms, freqs_hz] = newtimef(sample_dipole, frames, time_range, srate, ...
    'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
    'freqs', [0.7324 50], 'verbose', 'on');

freq_mask = freqs_hz >= 1 & freqs_hz <= 50;
freqs = freqs_hz(freq_mask);
times = times_ms;

% Combine all dipoles and conditions for mean ERSP
ersp_all = zeros(length(freqs), length(times), num_dipoles);
for d = 1:num_dipoles
    data = cluster_data.icaact{d}(:, 1); % use 1st condition (or combine all)
    [ersp, ~, ~, ~, ~] = newtimef(data, frames, time_range, srate, ...
        'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
        'freqs', [0.7324 50], 'verbose', 'off');
    ersp_all(:, :, d) = ersp(freq_mask, :);
end
mean_ersp = mean(ersp_all, 3);

% Plot mean ERSP
fig = figure('Name', sprintf('%s - Mean ERSP (%s)', clusterLabel, groupPrefix), ...
    'Color', 'w', 'Position', [100 100 900 600]);
imagesc(times, freqs, mean_ersp);
axis xy; colorbar;
xlabel('Time (ms)'); ylabel('Frequency (Hz)');
title(sprintf('Mean ERSP - %s', groupPrefix), 'FontWeight', 'bold');
drawnow;

% Save figure
tiffFileName = sprintf('%s_MeanERSP_%s_%s.tiff', ...
    strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);


            % % 7. ERSP Plot
            % app.StatusLabel.Text = sprintf('Status: Generating ERSP plot for %s (Group %s)...', clusterLabel, groupPrefix);
            % cluster_data = groupClusterData.(sprintf('cluster_%d', i));
            % num_dipoles = height(cluster_data);
            % num_conditions = size(cluster_data.icaact{1}, 2);
            % epoch_len = size(cluster_data.icaact{1}, 1);
            % srate = cluster_data.srate(1);
            % frames = epoch_len;
            % time_range = [0 epoch_len / srate * 1000];
            % sample_dipole = cluster_data.icaact{1}(:, 1);
            % [ersp_sample, ~, ~, times_ms, freqs_hz] = newtimef(sample_dipole, frames, time_range, srate, ...
            %     'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
            %     'freqs', [0.7324 50], 'verbose', 'on');
            % freq_mask = freqs_hz >= 1 & freqs_hz <= 50;
            % freqs = freqs_hz(freq_mask);
            % times = times_ms;
            % ersp_all = zeros(length(freqs), length(times), num_dipoles * num_conditions);
            % cond_ersps = zeros(length(freqs), length(times), num_conditions);
            % col = 1;
            % for cond = 1:num_conditions
            %     cond_mat = zeros(length(freqs), length(times), num_dipoles);
            %     for d = 1:num_dipoles
            %         data = cluster_data.icaact{d}(:, cond);
            %         [ersp, ~, ~, ~, ~] = newtimef(data, frames, time_range, srate, ...
            %             'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
            %             'freqs', [0.7324 50], 'verbose', 'on');
            %         ersp = ersp(freq_mask, :);
            %         ersp_all(:, :, col) = ersp;
            %         cond_mat(:, :, d) = ersp;
            %         col = col + 1;
            %     end
            %     cond_ersps(:, :, cond) = mean(cond_mat, 3);
            % end
            % grand_avg_ersp = mean(ersp_all, 3);
            % grand_avg_ersp_cond = mean(cond_ersps, 3);
            % fig = figure('Name', [clusterLabel '_ERSP_Average - ' groupPrefix], 'Color', 'w', 'Position', [100 100 1200 500]);
            % tiledlayout(1, 2, 'Padding', 'compact');
            % nexttile;
            % imagesc(times, freqs, grand_avg_ersp); axis xy;
            % xlabel('Time (ms)'); ylabel('Frequency (Hz)');
            % title('Grand Average ERSP (All Dipoles & Conditions)');
            % colorbar;
            % nexttile;
            % imagesc(times, freqs, grand_avg_ersp_cond); axis xy;
            % xlabel('Time (ms)'); ylabel('Frequency (Hz)');
            % title('Mean of Condition-wise ERSPs');
            % colorbar;
            % drawnow;
            % tiffFileName = sprintf('%s_ERSP_Average_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);
            % fig = figure('Name', [clusterLabel '_ERSP_Conditions - ' groupPrefix], 'Color', 'w', 'Position', [100 100 1200 800]);
            % t = tiledlayout(4, 3, 'Padding', 'compact', 'TileSpacing', 'compact');
            % for cond = 1:num_conditions
            %     nexttile;
            %     imagesc(times, freqs, cond_ersps(:, :, cond)); axis xy;
            %     title(['Condition ' num2str(cond)]);
            %     xlabel('Time (ms)'); ylabel('Frequency (Hz)');
            %     colorbar;
            % end
            % drawnow;
            % tiffFileName = sprintf('%s_ERSP_Conditions_%s_%s.tiff', strrep(clusterLabel, ' ', '_'), groupPrefix, datestr(now, 'HH-MM'));
            % tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
            % savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
            % print(fig, tiffFilePath, '-dtiff', '-r600');
            % close(fig);


% 8. ITC Plot
app.StatusLabel.Text = sprintf('Status: Generating ITC plot for %s...', clusterLabel);
cluster_data = allClusterData.(sprintf('cluster_%d', i));
num_dipoles = height(cluster_data);
num_conditions = size(cluster_data.icaact{1}, 2);
epoch_len = size(cluster_data.icaact{1}, 1);
srate = cluster_data.srate(1);
frames = epoch_len;
time_range = [0 epoch_len / srate * 1000]; % in ms
sample_dipole = cluster_data.icaact{1}(:, 1);
[~, itc_sample, ~, times_ms, freqs_hz] = newtimef(sample_dipole, frames, time_range, srate, ...
    'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...
    'freqs', [0.7324 50], 'verbose', 'off');
freq_mask = freqs_hz >= 1 & freqs_hz <= 50;
if clusterIdx == 1  % Initialize global srate, times, and freqs for the first cluster
    srate = cluster_data.srate(1);
    times = times_ms;
    freqs = freqs_hz(freq_mask);
end
itc_all = zeros(length(freqs), length(times), num_dipoles);
for d = 1:num_dipoles
    data_matrix = cluster_data.icaact{d}; % [time x conditions]
    [~, itc, ~, ~, ~] = newtimef(data_matrix, frames, time_range, srate, ...
        'cycles', [3 0.5], 'padratio', 4, 'plotersp', 'off', 'plotitc', 'off', ...  % Changed to 'off'
        'freqs', [0.7324 50], 'verbose', 'off');  % Changed to 'off'
    itc_all(:, :, d) = abs(itc(freq_mask, :)); % magnitude of complex ITC
end
grand_avg_itc = mean(itc_all, 3);
fig = figure('Name', [clusterLabel '_ITC_Average'], 'Color', 'w', 'Position', [100 100 600 500]);
imagesc(times, freqs, grand_avg_itc); axis xy;
xlabel('Time (ms)'); ylabel('Frequency (Hz)');
title(['ITC across ' num2str(num_conditions) ' Conditions (Avg across ' num2str(num_dipoles) ' Dipoles)']);
colorbar;
drawnow;
tiffFileName = sprintf('%s_ITC_Average_%s.tiff', strrep(clusterLabel, ' ', '_'), datestr(now, 'HH-MM'));
tiffFilePath = app.getUniqueFilePath(clusterSubfolder, tiffFileName);
savefig(fig, strrep(tiffFilePath, '.tiff', '.fig'));
print(fig, tiffFilePath, '-dtiff', '-r600');
close(fig);

        end

        % Group-level plots (All Centroid and All Dipoles for this group)
        app.StatusLabel.Text = sprintf('Status: Generating group plots for %s...', groupPrefix);

        % 9. All Centroid Plot
        valid_dipoles = false(numClusters, 1);
        for k = 1:numClusters
            valid_dipoles(k) = isfield(dipModelCentroids(k), 'posxyz') && ~isempty(dipModelCentroids(k).posxyz) && ~any(isnan(dipModelCentroids(k).posxyz));
        end
        fprintf('Number of valid centroids for %s: %d\n', groupPrefix, sum(valid_dipoles));
        for k = 1:numClusters
            if isfield(dipModelCentroids(k), 'posxyz')
                fprintf('Centroid %d posxyz: [%f %f %f]\n', k, dipModelCentroids(k).posxyz);
            else
                fprintf('Centroid %d posxyz not defined\n', k);
            end
        end
        if sum(valid_dipoles) ~= numClusters
            warning('Not all centroids are valid for %s. Plotting only valid centroids (%d out of %d).', groupPrefix, sum(valid_dipoles), numClusters);
        end
        centFig = figure('Name', ['All Centroids - ' groupPrefix], 'NumberTitle', 'off');
        dipplot(dipModelCentroids(valid_dipoles), 'coordformat', 'MNI', 'color', colorListCell(valid_dipoles), ...
            'gui', 'off', 'dipolesize', 75, 'view', [45 35], 'projlines', 'on', 'mri', app.mriData);
        drawnow;
        title(['All Centroids - ' groupPrefix]);
        xlabel('X (MNI)');
        ylabel('Y (MNI)');
        zlabel('Z (MNI)');
        grid on;
        tiffFileName = sprintf('All_Centroids_%s_%s.tiff', groupPrefix, datestr(now, 'HH-MM'));
        tiffFilePath = app.getUniqueFilePath(groupFolder, tiffFileName);
        savefig(centFig, strrep(tiffFilePath, '.tiff', '.fig'));
        print(centFig, tiffFilePath, '-dtiff', '-r600');
        close(centFig);

        % 10. All Dipoles Plot
        dipFig = figure('Name', ['All Dipoles - ' groupPrefix], 'NumberTitle', 'off');
        dipplot(all_dipoles, 'coordformat', 'MNI', 'color', all_colors, ...
            'gui', 'off', 'dipolesize', 25, 'view', [45 35], 'projlines', 'off', ...
            'mri', app.mriData, 'normlen', 'on', 'spheres', 'on');
        drawnow;
        title(['All Dipoles - ' groupPrefix]);
        xlabel('X (MNI)');
        ylabel('Y (MNI)');
        zlabel('Z (MNI)');
        grid on;
        tiffFileName = sprintf('All_Dipoles_%s_%s.tiff', groupPrefix, datestr(now, 'HH-MM'));
        tiffFilePath = app.getUniqueFilePath(groupFolder, tiffFileName);
        savefig(dipFig, strrep(tiffFilePath, '.tiff', '.fig'));
        print(dipFig, tiffFilePath, '-dtiff', '-r600');
        close(dipFig);

        % Save group-specific data
        fprintf('Saving to %s\n', groupDataFile);
        save(groupDataFile, 'groupClusterData', 'groupCentroidData', 'groupDipoles', 'groupIcaactAll', 'groupSpectraAll', 'groupErpAll', 'groupErspAll', 'groupItcAll', 'groupTimesOutAll', 'groupFreqsOutAll', 'groupDipModelCentroids', 'all_dipoles', 'all_colors', 'densityColorListCell', 'realClusterIndices', 'numClusters', 'srate', 'times', 'freqs', '-v7.3');
        fprintf('Group %s data saved to %s\n', groupPrefix, groupDataFile);
    end
end
else
    fprintf('⏭️ Skipping Step 2.4 as per user selection.\n');
end

% Step 3: Final UI Update
app.StatusLabel.Text = 'Status: All cluster plots, centroids, and dipoles generated successfully!';
app.ResultLabel.Text = 'All plots and statistics have been generated and saved.';
fprintf('Debug: Completed generation of all plots and statistics for %d clusters.\n', length(clusterIndices));

% Plot progress completion figure as a visual cue
f = figure('Name', 'Progress', 'NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', ...
           'Color', 'w', 'Position', [500, 500, 400, 100]);
axes('Position', [0.05 0.4 0.9 0.3], 'XColor', 'none', 'YColor', 'none');
rectangle('Position', [0 0 1 1], 'FaceColor', [0.2 0.8 0.2], 'EdgeColor', 'k');
text(0.5, 0.5, '✓ 100% Completed', 'HorizontalAlignment', 'center', ...
     'VerticalAlignment', 'middle', 'FontSize', 14, 'FontWeight', 'bold');
drawnow;
pause(5);  % Show the progress bar for 5 seconds
close(f);

% Display summary
summaryText = sprintf('Summary:\n- Total Clusters: %d\n- Real Clusters: %d\n- Outlier Cluster: %d\n- Groups Analyzed: %d', ...
    length(clusterIndices), nnz(clusterIndices > 0), hasOutlier, app.numGroups);
fprintf('%s\n', summaryText);
drawnow;

pause(5);
close all;

   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectsetfilesfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectsetfilesfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsetfilesfolderButtonPushed, true);
            app.SelectsetfilesfolderButton.Text = 'Select .set files folder';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status: Not ready';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result: No result';
            app.NumberofGroupsDropDownLabel = uilabel(app.UIFigure);
            app.NumberofGroupsDropDownLabel.Enable = 'off';
            app.NumberofGroupsDropDownLabel.Text = 'Number of Groups:';
            app.NumberofGroupsDropDown = uidropdown(app.UIFigure);
            app.NumberofGroupsDropDown.Items = {'', '1', '2', '3'};
            app.NumberofGroupsDropDown.Editable = 'on';
            app.NumberofGroupsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofGroupsDropDownValueChanged, true);
            app.NumberofGroupsDropDown.Enable = 'off';
            app.NumberofGroupsDropDown.Value = '';
            app.FilteringusingResidualVarianceDropDownLabel = uilabel(app.UIFigure);
            app.FilteringusingResidualVarianceDropDownLabel.Enable = 'off';
            app.FilteringusingResidualVarianceDropDownLabel.Text = 'Filtering using Residual Variance:';
            app.FilteringusingResidualVarianceDropDown = uidropdown(app.UIFigure);
            app.FilteringusingResidualVarianceDropDown.Items = {'', '0', '5', '10', '15', '20', '25', '30'};
            app.FilteringusingResidualVarianceDropDown.ValueChangedFcn = createCallbackFcn(app, @FilteringusingResidualVarianceDropDownValueChanged, true);
            app.FilteringusingResidualVarianceDropDown.Enable = 'off';
            app.FilteringusingResidualVarianceDropDown.Value = '';
            app.ClusteringMethodDropDownLabel = uilabel(app.UIFigure);
            app.ClusteringMethodDropDownLabel.Enable = 'off';
            app.ClusteringMethodDropDownLabel.Text = 'Clustering Method:';
            app.ClusteringMethodDropDown = uidropdown(app.UIFigure);
            app.ClusteringMethodDropDown.Items = {'', 'Kmeans (stat. toolbox)', 'Neural Network (stat. toolbox)', 'Kmeanscluster (no toolbox)', 'Affinity Propagation', 'Optimal Kmeans'};
            app.ClusteringMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ClusteringMethodDropDownValueChanged, true);
            app.ClusteringMethodDropDown.Enable = 'off';
            app.ClusteringMethodDropDown.Value = '';
            app.NumberofClustersDropDownLabel = uilabel(app.UIFigure);
            app.NumberofClustersDropDownLabel.Enable = 'off';
            app.NumberofClustersDropDownLabel.Text = 'Number of Clusters:';
            app.NumberofClustersDropDown = uidropdown(app.UIFigure);
            app.NumberofClustersDropDown.Items = {'', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.NumberofClustersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofClustersDropDownValueChanged, true);
            app.NumberofClustersDropDown.Enable = 'off';
            app.NumberofClustersDropDown.Value = '';
            app.StandardDeviationremoveoutliersDropDownLabel = uilabel(app.UIFigure);
            app.StandardDeviationremoveoutliersDropDownLabel.Enable = 'off';
            app.StandardDeviationremoveoutliersDropDownLabel.Text = 'Standard Deviation (remove outliers):';
            app.StandardDeviationremoveoutliersDropDown = uidropdown(app.UIFigure);
            app.StandardDeviationremoveoutliersDropDown.Items = {'', '''No outlier removal''', '1', '2', '3'};
            app.StandardDeviationremoveoutliersDropDown.ValueChangedFcn = createCallbackFcn(app, @StandardDeviationremoveoutliersDropDownValueChanged, true);
            app.StandardDeviationremoveoutliersDropDown.Enable = 'off';
            app.StandardDeviationremoveoutliersDropDown.Value = '';
            app.LoadclusteredEEGSTUDYfileButton = uibutton(app.UIFigure, 'push');
            app.LoadclusteredEEGSTUDYfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadclusteredEEGSTUDYfileButtonPushed, true);
            app.LoadclusteredEEGSTUDYfileButton.Enable = 'off';
            app.LoadclusteredEEGSTUDYfileButton.Text = 'Load clustered EEG STUDY file';
            app.GenerateplotsandstatisticsButton = uibutton(app.UIFigure, 'push');
            app.GenerateplotsandstatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateplotsandstatisticsButtonPushed, true);
            app.GenerateplotsandstatisticsButton.Enable = 'off';
            app.GenerateplotsandstatisticsButton.Text = 'Generate plots and statistics';
            app.SubmitGroupRangesButton = uibutton(app.UIFigure, 'push');
            app.SubmitGroupRangesButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGroupRangesButtonPushed, true);
            app.SubmitGroupRangesButton.Enable = 'off';
            app.SubmitGroupRangesButton.Text = 'Submit Group Ranges';
            app.Group1SubjectRangeEg115EditFieldLabel = uilabel(app.UIFigure);
            app.Group1SubjectRangeEg115EditFieldLabel.Enable = 'off';
            app.Group1SubjectRangeEg115EditFieldLabel.Text = 'Group1 Subject Range: (Eg, 1:15)';
            app.Group1SubjectRange = uieditfield(app.UIFigure, 'text');
            app.Group1SubjectRange.Enable = 'off';
            app.Group2SubjectRangeEg1630Label = uilabel(app.UIFigure);
            app.Group2SubjectRangeEg1630Label.Enable = 'off';
            app.Group2SubjectRangeEg1630Label.Text = 'Group2 Subject Range: (Eg, 16:30)';
            app.Group2SubjectRange = uieditfield(app.UIFigure, 'text');
            app.Group2SubjectRange.Enable = 'off';
            app.Group3SubjectRangeEg3145EditFieldLabel = uilabel(app.UIFigure);
            app.Group3SubjectRangeEg3145EditFieldLabel.Enable = 'off';
            app.Group3SubjectRangeEg3145EditFieldLabel.Text = 'Group3 Subject Range: (Eg, 31:45)';
            app.Group3SubjectRange = uieditfield(app.UIFigure, 'text');
            app.Group3SubjectRange.Enable = 'off';
            app.SubmitParametersButton = uibutton(app.UIFigure, 'push');
            app.SubmitParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitParametersButtonPushed, true);
            app.SubmitParametersButton.Enable = 'off';
            app.SubmitParametersButton.Text = 'Submit Parameters';
            app.SubmitNumberofClustersButton = uibutton(app.UIFigure, 'push');
            app.SubmitNumberofClustersButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitNumberofClustersButtonPushed, true);
            app.SubmitNumberofClustersButton.Enable = 'off';
            app.SubmitNumberofClustersButton.Text = 'Submit required Number of Clusters ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Clustering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end